classdef ARPESfitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CLOSEFIGS_Button            matlab.ui.control.Button
        RESTART_Button              matlab.ui.control.Button
        RESIZEGUI_Button            matlab.ui.control.Button
        ARPESGUI_Button             matlab.ui.control.Button
        SAVE_Button                 matlab.ui.control.Button
        TRANSFER_Button             matlab.ui.control.Button
        APPINFO_Button              matlab.ui.control.Button
        LOAD_Button                 matlab.ui.control.Button
        AppTitle                    matlab.ui.control.Label
        ViewSeries_Button           matlab.ui.control.Button
        RESET_Button                matlab.ui.control.Button
        DataManipulationPanel       matlab.ui.container.Panel
        FilterDatagloballyPanel     matlab.ui.container.Panel
        ExecuteFiltering_Button     matlab.ui.control.Button
        FilterTypeDropDownLabel     matlab.ui.control.Label
        FilterType_DropDown         matlab.ui.control.DropDown
        hwXhwYLabel                 matlab.ui.control.Label
        FilterParams_EditField      matlab.ui.control.EditField
        ColormapLabel               matlab.ui.control.Label
        Colormap_DropDown           matlab.ui.control.DropDown
        RealignEbscanPanel          matlab.ui.container.Panel
        ExecuteAlignment_Button     matlab.ui.control.Button
        ebWinEditFieldLabel         matlab.ui.control.Label
        ebWin_EditField             matlab.ui.control.EditField
        dEWinLabel                  matlab.ui.control.Label
        dEWin_EditField             matlab.ui.control.EditField
        dESmoothEditFieldLabel      matlab.ui.control.Label
        dESmooth_EditField          matlab.ui.control.EditField
        featDropDownLabel           matlab.ui.control.Label
        feat_DropDown               matlab.ui.control.DropDown
        RealignkxscanPanel          matlab.ui.container.Panel
        ExecuteAlignKx_Button       matlab.ui.control.Button
        kxShiftLabel                matlab.ui.control.Label
        kxShift_EditField           matlab.ui.control.EditField
        RescalekxscanPanel          matlab.ui.container.Panel
        ExecuteScaleKx_Button       matlab.ui.control.Button
        kxScaleLabel                matlab.ui.control.Label
        kxScale_EditField           matlab.ui.control.EditField
        ROIandBSPanel               matlab.ui.container.Panel
        BackgroundSubtractionPanel  matlab.ui.container.Panel
        polyOrderLabel              matlab.ui.control.Label
        polyOrder_EditField         matlab.ui.control.EditField
        ExecuteBS_Button            matlab.ui.control.Button
        ROICroppingPanel            matlab.ui.container.Panel
        kLimsLabel                  matlab.ui.control.Label
        kLimsROI_EditField          matlab.ui.control.EditField
        ebLimsLabel                 matlab.ui.control.Label
        ebLimsROI_EditField         matlab.ui.control.EditField
        kLimsLabel_2                matlab.ui.control.Label
        kLimsBS_EditField           matlab.ui.control.EditField
        ebLimsLabel_2               matlab.ui.control.Label
        ebLimsBS_EditField          matlab.ui.control.EditField
        ROI_Label                   matlab.ui.control.Label
        BACKGROUND_Label            matlab.ui.control.Label
        ExtractROI_Button           matlab.ui.control.Button
        ViewROI_Button              matlab.ui.control.Button
        SaveROI_Button              matlab.ui.control.Button
        DataFile_Label              matlab.ui.control.Label
        DatabrowserPanel            matlab.ui.container.Panel
        CutIndexLabel_2             matlab.ui.control.Label
        CutScanIndex_EditField      matlab.ui.control.NumericEditField
        CutValueLabel               matlab.ui.control.Label
        CutScanValue_EditField      matlab.ui.control.NumericEditField
        isoCutTypeDropDownLabel     matlab.ui.control.Label
        isoCutType_DropDown         matlab.ui.control.DropDown
        CutIndexLabel               matlab.ui.control.Label
        CutIndex_Slider             matlab.ui.control.Slider
        isoCutWinLabel              matlab.ui.control.Label
        isoCutWin_EditField         matlab.ui.control.EditField
        ScanIndexLabel              matlab.ui.control.Label
        ScanIndex_EditField         matlab.ui.control.NumericEditField
        ScanValueLabel              matlab.ui.control.Label
        ScanValue_EditField         matlab.ui.control.NumericEditField
        ScanIndexSliderLabel        matlab.ui.control.Label
        ScanIndex_Slider            matlab.ui.control.Slider
        FittingPanel                matlab.ui.container.Panel
        fdd_UITable                 matlab.ui.control.Table
        para_UITable                matlab.ui.control.Table
        ExtractPara_Button          matlab.ui.control.Button
        ViewPara_Button             matlab.ui.control.Button
        ExtractFDD_Button           matlab.ui.control.Button
        ViewFDD_Button              matlab.ui.control.Button
        edc_UITable                 matlab.ui.control.Table
        mdc_UITable                 matlab.ui.control.Table
        FittingalgorithmLabel       matlab.ui.control.Label
        fitType_DropDown            matlab.ui.control.DropDown
        fitMDC_CheckBox             matlab.ui.control.CheckBox
        fitEDC_CheckBox             matlab.ui.control.CheckBox
        VoigtMixingGEditFieldLabel  matlab.ui.control.Label
        voigtMix_EditField          matlab.ui.control.NumericEditField
        SaveFitParams_Button        matlab.ui.control.Button
        LoadFitParams_Button        matlab.ui.control.Button
        ViewEDCTab_Button           matlab.ui.control.Button
        ViewMDCTab_Button           matlab.ui.control.Button
        ViewFitSummary_Button       matlab.ui.control.Button
        ExecuteFits_Button          matlab.ui.control.Button
        DeleteFits_Button           matlab.ui.control.Button
        edcSeries_Button            matlab.ui.control.Button
        mdcSeries_Button            matlab.ui.control.Button
    end

    % Properties that correspond to public variables within the app
    properties (Access = public)
        % Defining the data manipulation structures
        colmap_arg      = 'hot';
        align_args      = cell(1,4);
        filter_args     = cell(1,2);
        kxalign_args    = cell(1,1);
        kxscale_args    = cell(1,1);
        % Defining the data browser structures
        scanIndex       = 1;
        isocut_args     = cell(1,4);
        % Defining the ROI and BS data structures
        roi_args        = cell(1,4);
        bs_args         = cell(1,1);
        % Defining the fitting argument structures
        fdd_args        = cell(1,3);
        para_args       = cell(4,5);
        edc_args        = cell(4,6);
        mdc_args        = cell(4,6);
        fit_args        = cell(1,4);
        % Defining the main data structure
        myData = struct();
    end
    
    methods (Access = public)
        % ---  Function to load in a single ARPES data-file that is unprocessed or previously processed
        function dataStr = load_arpes_data(~, FileName)
            % dataStr = load_arpes_data(FileName)
            %   This function loads in the HDataF5 H5files of ARPES data from 
            %   the ADRESS beamline at the SLS. The output is a MATLAB
            %   data-structure that yields all the data and information.
            %
            %   REQ. FUNCTIONS:
            %   -   [data, axes, note] = ReaderHDF5(fname);
            %   -   [DataXC,OffsE] = SumScanXC(Energy,Data,maxLagE[,WinE]);
            %
            %   IN:
            %   -   FileName:           char of the input .h5 or .mat file-name.
            %
            %   OUT:
            %   dataStr - MATLAB data structure containing all data below.
            %   -   .(H5file):          string of the raw .H5 filename of the data.
            %	-   .(Type):            string "Eb(k)", "Eb(kx,ky)" or "Eb(kx,kz)".
            %	-   .(meta.info):       [1xN] char array of scan information.
            %	-   .(meta.ep):         scalar of the Pass energy.
            %	-   .(raw_tht):         [1×nAngle] row vector of angles.
            % 	-   .(raw_eb):          [nEnergyx1] column vector of energy.
            %	-   .(deb):             scalar of the Analyser resolution.
            %   -   .(tltM):            scalar or [1×nScan] row vector (if Scan parameter for 3Data data).
            %	-   .(hv):              scalar or [1×nScan] row vector (if Scan parameter for 3Data data).
            %	-   .(dhv):             scalar of the Beamline resolution.
            %	-   .(raw_data):        2Data [nEnergy x nAngle] or 3Data [nEnergy x nAngle xnScan] data array.
            %	-   .(thtM):            scalar of the manipular Theta angle.
            %	-   .(Temp):            scalar of the sample temperature.
            % -- after processing (1) Eb alignment
            %	-   .(tht):             aligned 2D or 3D array of theta.
            %	-   .(eb):              aligned 2D or 3D array of energy.
            % -- after processing (2) intensity normalisation
            %	-   .(data):            2D or 3D array of the normalised data.
            %   -   .(surfNormX):       double or vector of surface normal vector.
            % -- after processing (3) k conversions
            %   -   .(kx):              2D or 3D array of kx from the Theta angle.
            %	-   .(ky):              1D, 2D or 3D array of kx from the Tilt angle.
            %	-   .(kz):              1D, 2D or 3D array of kx from the Photon Energy.
            % -- general slices / cuts of the data
            %	-   .(slices):          MATLAB data structure containing 2D slices through data.
            %	-   .(cuts):            MATLAB data structure containing 1D cuts through data.
            % -- after analysing kf
            %	-   .(kf):              MATLAB data structure containing all kf analysis.
            % -- after analysing isoe
            %	-   .(isoe):            MATLAB data structure containing all isoe analysis.
            %	-   .(bz):              MATLAB data structure containing all Brilluoin Zone analysis.
            % -- after executing state-fitting
            %	-   .(fits):            MATLAB data structure containing all fitting analysis.
            
            disp('Loading ARPES data...')
            wbar = waitbar(0.5, 'Loading in ARPES data...', 'Name', 'load_arpes_data');
            wbar.Children.Title.Interpreter = 'none';
            %% 1 - Reading in the .mat data that has been processed
            arpes_data = load(FileName); dataStr = arpes_data.dataStruc;
            %% Close wait-bar
            close(wbar);
        end
        
        % --- General function to find the stage of the processing
        function [xField, yField, zField, dField] = find_data_fields(~, dataStr)
            % [xField, yField, zField, dField] = find_data_fields(dataStr)
            %   This function determines the most recent processed 
            %   fields of the ARPES data-structure so that the correct
            %   post-processing can be performed in any order. The
            %   fields can be used to explicitly call a field within dataStr
            %   by using dataStr.(xField) for example.
            %
            %   REQ. FUNCTIONS: none
            %
            %   IN:
            %   -   dataStr              loaded MATLAB data structure.
            %
            %   OUT:
            %   -   xField:            char of the most recent x-field ('raw_tht', 'tht', 'kx')
            %	-   yField:            char of the most recent y-field ('raw_eb', 'eb')
            %   -   zField:            char of the most recent z-field ('hv / tltM', 'kz / ky')
            %	-   dField:            char of the most recent d-field ('raw_data', 'data')
            
            % 1 - EbAlign->Normalise->kConvert fields
            if isfield(dataStr, 'kx')
                xField = 'kx'; yField = 'eb'; dField = 'data';
                if dataStr.Type == "Eb(k)"; zField = 'hv';
                elseif dataStr.Type == "Eb(kx,ky)"; zField = 'ky';
                elseif dataStr.Type == "Eb(kx,kz)"; zField = 'kz';
                end
            % 2 - EbAlign->Normalise fields
            elseif isfield(dataStr, 'data')
                xField = 'tht'; yField = 'eb'; dField = 'data';
                if dataStr.Type == "Eb(k)"; zField = 'hv';
                elseif dataStr.Type == "Eb(kx,ky)"; zField = 'tltM';
                elseif dataStr.Type == "Eb(kx,kz)"; zField = 'hv';
                end
            % 3 - EbAlign fields
            elseif isfield(dataStr, 'eb')
                xField = 'tht'; yField = 'eb'; dField = 'raw_data';
                if dataStr.Type == "Eb(k)"; zField = 'hv';
                elseif dataStr.Type == "Eb(kx,ky)"; zField = 'tltM';
                elseif dataStr.Type == "Eb(kx,kz)"; zField = 'hv';
                end
            % 4 - Raw, unprocessed data fields
            else
                xField = 'raw_tht'; yField = 'raw_eb'; dField = 'raw_data';
                if dataStr.Type == "Eb(k)"; zField = 'hv';
                elseif dataStr.Type == "Eb(kx,ky)"; zField = 'tltM';
                elseif dataStr.Type == "Eb(kx,kz)"; zField = 'hv';
                end
            end
        end
        
        % --- Function to crop the ARPES data along any dimension
        function dataStr = crop_data(app, dataStr, xField_lims, yField_lims, zField_lims)
            % dataStr = crop_data(dataStr, xField_lims, yField_lims, zField_lims)
            %   This function crops the ARPES x-, y- and z-independent variables over
            %   a given range. The function crops the most recently processed data and
            %   ensures consistency across both the independent variables and data
            %   matrix.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %
            %   IN:
            %   -   dataStr:        loaded MATLAB data structure.
            % 	-   xField_lims:    [1 x 2] row vector of xField ('raw_tht', 'tht', 'kx') crop limits.
            % 	-   yField_lims:    [1 x 2] row vector of yField ('raw_eb', 'eb') crop limits.
            %	-   zField_lims:    [1 x 2] row vector of zField ('hv / tltM', 'kz / ky') crop limits.
            %
            %   OUT:
            %   -   dataStr                       modified and cropped ARPES data structure.
            
            disp('Data cropping...')
            wbar = waitbar(0.1, 'Extracting cropping indices...', 'Name', 'crop_data');
            
            %% Default parameters
            maxLim = 1e4;
            if nargin < 2; xField_lims=[-1 1]*maxLim; yField_lims=[-1 1]*maxLim; zField_lims=[-1 1]*maxLim; end
            if nargin < 3; yField_lims=[-1 1]*maxLim; zField_lims=[-1 1]*maxLim; end
            if nargin < 4; zField_lims=[-1 1]*maxLim; end
            if isempty(xField_lims); xField_lims=[-1 1]*maxLim;  end
            if isempty(yField_lims); yField_lims=[-1 1]*maxLim;  end
            if isempty(zField_lims); zField_lims=[-1 1]*maxLim;  end
            if length(xField_lims) < 2; xField_lims=[-1 1]*maxLim;  end
            if length(yField_lims) < 2; yField_lims=[-1 1]*maxLim;  end
            if length(zField_lims) < 2; zField_lims=[-1 1]*maxLim;  end
            % - Sorting the cropping limits in ascending order
            xField_lims = sort(xField_lims);
            yField_lims = sort(yField_lims);
            zField_lims = sort(zField_lims);
            % - Extracting the fields to be used with most recent processing
            [xField, yField, zField, dField] = find_data_fields(app, dataStr);
            %% 1 - First determine the zField indices of the crop over scan parameter
            if string(zField) == "hv" || string(zField) == "tltM"
                [~, zIndxL] = min(abs(dataStr.(zField)(1,:) - zField_lims(1)));
                [~, zIndxU] = min(abs(dataStr.(zField)(1,:) - zField_lims(2)));
                zField_indx = [zIndxL zIndxU];
            else
                thtIndx = ceil(size(dataStr.(dField), 2)/2);
                ebIndx = ceil(size(dataStr.(dField), 1)/2);
                [~, zIndxL] = min(abs(dataStr.(zField)(ebIndx,thtIndx,:) - zField_lims(1)));
                [~, zIndxU] = min(abs(dataStr.(zField)(ebIndx,thtIndx,:) - zField_lims(2)));
                zField_indx = [zIndxL zIndxU];
            end
            %% 2 - Next determine the xField and yField indices across each scan
            for i = 1:diff(zField_indx)+1
                z_index = i  - 1 + min(zField_indx);
                waitbar(i/diff(zField_indx)+1, wbar, 'Extracting cropping indices...', 'Name', 'crop_data');
                %% 2.1 - xField cropping indices
                if string(xField) == "raw_tht"
                    [~, xIndx_L] = min(abs(dataStr.(xField)(1,:) - xField_lims(1)));
                    [~, xIndx_U] = min(abs(dataStr.(xField)(1,:) - xField_lims(2)));
                    xField_indx{i} = [xIndx_L xIndx_U];
                else
                    ebIndx = ceil(size(dataStr.(dField), 1)/2);
                    [~, xIndx_L] = min(abs(dataStr.(xField)(ebIndx,:,z_index) - xField_lims(1)));
                    [~, xIndx_U] = min(abs(dataStr.(xField)(ebIndx,:,z_index) - xField_lims(2)));
                    xField_indx{i} = [xIndx_L xIndx_U];
                end
                %% - 2.2 - yField cropping indices
                if string(yField) == "raw_eb"
                    [~, yIndx_L] = min(abs(dataStr.(yField)(:,1) - yField_lims(1)));
                    [~, yIndx_U] = min(abs(dataStr.(yField)(:,1) - yField_lims(2)));
                    yField_indx{i} = [yIndx_L yIndx_U];
                else
                    thtIndx = ceil(size(dataStr.(dField), 2)/2);
                    [~, yIndx_L] = min(abs(dataStr.(yField)(:,thtIndx,z_index) - yField_lims(1)));
                    [~, yIndx_U] = min(abs(dataStr.(yField)(:,thtIndx,z_index) - yField_lims(2)));
                    yField_indx{i} = [yIndx_L yIndx_U];
                end
            end
            %% 3 - Validity check that the cropping indices for x- and y- are consistent
            % x-field consistency checks
            xField_diff = diff(cat(1, xField_indx{:}),[], 2);
            for i = 1:length(xField_diff)
                waitbar(i/length(xField_diff), wbar, 'Validity checks on x-field indices...', 'Name', 'crop_data');
                if xField_diff(i) ~= min(xField_diff)
                    xField_indx{i}(1) = xField_indx{i}(1) - floor((min(xField_diff)-xField_diff(i))/2);
                    xField_indx{i}(2) = xField_indx{i}(2) + ceil((min(xField_diff)-xField_diff(i))/2);   
                end
            end
            %y-field consistency checks
            yField_diff = diff(cat(1, yField_indx{:}),[], 2);
            for i = 1:length(yField_diff)
                waitbar(i/length(yField_diff), wbar, 'Validity checks on y-field indices...', 'Name', 'crop_data');
                if yField_diff(i) ~= min(yField_diff) && yField_diff(i) > min(yField_diff)
                    yField_indx{i}(2) = yField_indx{i}(2) - abs(yField_diff(i) - min(yField_diff));        
                elseif yField_diff(i) ~= min(yField_diff) && yField_diff(i)< min(yfield_diff)
                    yField_indx{i}(2) = yField_indx{i}(2) + abs(yField_diff(i) - min(yField_diff));        
                end
            end
            %% 4 - Filing through each scan parameter and applying a subsequent crop to x and y variables
            for i = 1:diff(zField_indx)+1
                z_index = i -1 + min(zField_indx);
                waitbar(i/diff(zField_indx)+1, wbar, 'Cropping ARPES data...', 'Name', 'crop_data');
                % - Applying cropping operations
                if string(xField) == "kx"
                    crp_xField{i} = dataStr.(xField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                    crp_yField{i} = dataStr.(yField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                    crp_dField{i} = dataStr.(dField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                    if dataStr.Type == "Eb(k)"; crp_zField = dataStr.(zField)(1, zField_indx(1):zField_indx(2));
                    elseif dataStr.Type == "Eb(kx,ky)" || dataStr.Type == "Eb(kx,kz)"; crp_zField{i} = dataStr.(zField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index); 
                    end
                    % Cropping other fields for consistency
                    crp_thtField{i} = dataStr.tht(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                elseif string(yField) == "eb"
                    crp_xField{i} = dataStr.(xField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                    crp_yField{i} = dataStr.(yField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                    if i == diff(zField_indx)+1; crp_zField = dataStr.(zField)(1, zField_indx(1):zField_indx(2)); end
                    crp_dField{i} = dataStr.(dField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                elseif string(xField) == "raw_tht"
                    if i == diff(zField_indx)+1
                        crp_xField = dataStr.(xField)(1, xField_indx{1}(1):xField_indx{i}(2));
                        crp_yField = dataStr.(yField)(yField_indx{1}(1):yField_indx{i}(2), 1);
                        crp_zField = dataStr.(zField)(1, zField_indx(1):zField_indx(2));
                    end
                    crp_dField{i} = dataStr.(dField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                end
            end
            %% 5 - Converting cell arrays into 3D matrices of data
            if iscell(crp_xField); crp_xField = cat(3, crp_xField{:});
            else; crp_xField = crp_xField; end
            if iscell(crp_yField); crp_yField = cat(3, crp_yField{:});
            else; crp_yField = crp_yField; end
            if iscell(crp_zField); crp_zField = cat(3, crp_zField{:});
            else; crp_zField = crp_zField; end
            if iscell(crp_dField); crp_dField = cat(3, crp_dField{:});
            else; crp_dField = crp_dField; end
            %% 6 - Assigning the cropped variables / data to new matrices
            % - Assigning the first set of variables
            dataStr.(xField) = []; dataStr.(yField) = []; dataStr.(zField) = []; dataStr.(dField) = [];
            dataStr.(xField) = crp_xField;
            dataStr.(yField) = crp_yField;
            dataStr.(zField) = crp_zField;
            dataStr.(dField) = crp_dField;
            if exist('crp_thtField', 'var'); crp_thtField = cat(3, crp_thtField{:}); dataStr.tht = crp_thtField; end
            % - Saving the crop to the meta data
            dataStr.meta.crp_lims = [];
            dataStr.meta.crp_lims = [xField_lims, yField_lims, zField_lims];
            dataStr.meta.crp_xIndx = xField_indx;
            dataStr.meta.crp_yIndx = yField_indx;
            dataStr.meta.crp_zIndx = zField_indx;
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to re-align eb (scan)
        function dataStr = align_energy(app, scanIndex, dataStr, align_args)
            % dataStr = align_energy(scanIndex, dataStr, align_args)
            %   This is a function that will align the dataStr ARPES data 
            %   to the Fermi-level, VBM or a CB state. It operates using the
            %   AlignEF function conditions.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   [EAlign, EF, Fail] = AlignEF(Data, ECorr [,eWin] [,dEWin] [,dESmooth] [,feat]);
            %
            %   IN:
            %   -   dataStr:            data structure of the ARPES data.
            %   -   align_args:         1x4 cell of {eWin, dEWin, dESmooth, feat}.
            %
            %   OUT:
            %   dataStr

            disp('Re-align Eb...')
            wbar = waitbar(0., 'Re-align Eb...', 'Name', 'align_energy');
            
            %% Default parameters
            % - Extracting alignment parameters
            eWin        = align_args{1}; if ischar(eWin); eWin = []; end
            dEWin       = align_args{2}; if ischar(dEWin); dEWin = []; end
            dESmooth    = align_args{3}; if ischar(dESmooth); dESmooth = []; end
            feat        = align_args{4};
            %% 1 - Performing the re-alignment operation
            [~, yField, ~, dField] = find_data_fields(app, dataStr);
            % -- The plot depends on how far in the analysis 
            if isfield(dataStr, 'kx') || isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                [~, eb_shift, ~] = AlignEF(dataStr.(dField)(:,:,scanIndex), dataStr.(yField)(:,1,scanIndex), eWin, dEWin, dESmooth, feat);
                dataStr.(yField)(:,:,scanIndex) = dataStr.(yField)(:,:,scanIndex) - eb_shift;
            else
                [~, eb_shift, ~] = AlignEF(dataStr.(dField)(:,:,scanIndex), dataStr.(yField), eWin, dEWin, dESmooth, feat);
                dataStr.(yField) = dataStr.(yField) - eb_shift;
            end
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to filter the ARPES data (globally)
        function dataStr = filter_data(app, dataStr, filter_args)
            % dataStr = filter_data(dataStr, filter_args)
            %   This function filters the ARPES data given the filter arguments, which
            %   includes the filter type ("Gaco2", "GaussFlt2", "CurvatureFlt2" or
            %   "LaplaceFlt2") and filter parameters.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   AA = Gaco2(A,hwX,hwY [,hsX] [,hsY])
            %   -   AA = GaussFlt2(Img,hwX,hwY,hsX,hsY)
            %   -   AA = CurvatureFlt2(Img [,order] [,CX] [,CY]) 
            %   -   AA = LaplaceFlt2(Img [,y2xRatio] [,order])
            %
            %   IN:
            %   -   dataStr                      loaded MATLAB data structure.
            % 	-   filter_args:                1x2 cell of {filter_type, filter_val}.
            %
            %   OUT:
            %   -   dataStr                       modified and filtered ARPES data structure.
            
            disp('Data filtering...')
            wbar = waitbar(0, 'Executing data filtering...', 'Name', 'filter_data');
            
            %% 1 - Initialising the filter parameters
            dataStr.meta.filter_args = filter_args;
            % - Extracting the fields to be used with most recent processing
            [~, ~, ~, dField] = find_data_fields(app, dataStr);
            % - Extracting filter parameters
            filter_type = filter_args{1};
            filter_val =  filter_args{2};
            %% 2 - Performing the filtering operation over all scans
            for i = 1:size(dataStr.(dField), 3)
                waitbar(i/size(dataStr.(dField), 3), wbar, 'Filtering ARPES data...', 'Name', 'filter_data');
                if filter_type == "Gaco2"
                    filtered_data = Gaco2(dataStr.(dField)(:,:,i), filter_val(1), filter_val(2)); 
                elseif filter_type == "GaussFlt2"
                    filtered_data = GaussFlt2(dataStr.(dField)(:,:,i), filter_val(1), filter_val(2), 40, 40); 
                elseif filter_type == "LaplaceFlt2"
                    filtered_data = GaussFlt2(dataStr.(dField)(:,:,i), 5, 5, 40, 40);
                    filtered_data = SetContrast(filtered_data, 0.4, 0.999, 1.5);
                    filtered_data = LaplaceFlt2(filtered_data, filter_val(1));
                elseif filter_type == "CurvatureFlt2"
                    filtered_data=GaussFlt2(dataStr.(dField)(:,:,i), 100, 100, 500, 500);
                    filtered_data = SetContrast(filtered_data, 0.4, 0.999, 1.5);
                    filtered_data=CurvatureFlt2(filtered_data, '2D', filter_val(1), filter_val(2));
                    filtered_data = GaussFlt2(filtered_data, 1, 1, 10, 10);
                    filtered_data = SetContrast(filtered_data, 0.2, 0.999);
                end
                dataStr.(dField)(:,:,i) = filtered_data;
            end
            %% 3 - Setting NaN values to zero
            dataStr.(dField)(isnan(dataStr.(dField))) = 0;
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to re-align kx (scan)
        function dataStr = align_kx(app, scanIndex, dataStr, kxalign_args)
            % dataStr = align_kx(scanIndex, dataStr, kxalign_args)
            %   This is a function that will shift the origin in kx.
            %
            %   REQ. FUNCTIONS:
            %   -   none
            
            disp(' Kx alignment...')
            wbar = waitbar(0.5, 'Executing kx alignment...', 'Name', 'align_kx');
            
            %% Default parameters
            % - Extracting alignment parameters
            kxshift = kxalign_args{1};
            %% 1 - Performing the re-alignment operation
            [xField, ~, ~, ~] = find_data_fields(app, dataStr);
            % -- The plot depends on how far in the analysis 
            if isfield(dataStr, 'kx')
                dataStr.(xField)(:,:,scanIndex) = dataStr.(xField)(:,:,scanIndex) - kxshift;
            elseif isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                dataStr.(xField)(:,:,scanIndex) = dataStr.(xField)(:,:,scanIndex) - kxshift;
            else
                dataStr.(xField) = dataStr.(xField) - kxshift;
            end
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to re-scale kx (scan)
        function dataStr = scale_kx(app, scanIndex, dataStr, kxscale_args)
            % dataStr = scale_kx(scanIndex, dataStr, kxscale_args)
            %   This is a function that will scale the axis along kx.
            %
            %   REQ. FUNCTIONS:
            %   -   none
            
            disp(' Kx scaling...')
            wbar = waitbar(0.5, 'Executing kx scaling...', 'Name', 'scale_kx');
            
            %% Default parameters
            % - Extracting scaling parameters
            kxscale = kxscale_args{1};
            %% 1 - Performing the re-scaling operation
            [xField, ~, ~, ~] = find_data_fields(app, dataStr);
            % -- The plot depends on how far in the analysis 
            if isfield(dataStr, 'kx')
                dataStr.(xField)(:,:,scanIndex) = dataStr.(xField)(:,:,scanIndex) .* kxscale;
            elseif isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                dataStr.(xField)(:,:,scanIndex) = dataStr.(xField)(:,:,scanIndex) .* kxscale;
            else
                dataStr.(xField) = dataStr.(xField) .* kxscale;
            end
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to extract the ROI and the fundamental object to the fitting
        function dataStr = extract_roi(app, scanIndex, dataStr, roi_args)
           % dataStr = extract_roi(app, scanIndex, dataStr, roi_args)
            %   This function extracts the ROI and background from the
            %   given ARPES data. This is then used as the fundamental
            %   object where the fitting algorithms are applied.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   [h=] ImData(X,Y,Z[,style])
            %   -   [XCut,DCut] = Cut(ACorr,ECorr,Data,xMode,Win)
            %
            %   IN:
            %   -   dataStr:        data structure of the ARPES data.
            %   -   scanIndex:      integer of the scan index to plot.
            %   -   roi_args:       1x4 cell of {kLimsROI, ebLimsROI, kLimsBS, ebLimsBS}.
            %
            %   OUT:
            % -- after executing dataStr.(fits) contains the following;
            %	-   .(scanIndex)
            %	-   .(roi_args)
            %	-   .(bs_kx)
            %	-   .(bs_eb)
            %	-   .(bs_data)
            %	-   .(kx_roi)
            %	-   .(eb_roi)
            %	-   .(scan_roi)
            %	-   .(data_roi)
        
            disp(' ROI and Background extraction...')
            wbar = waitbar(0.5, 'Executing kx scaling...', 'Name', 'extract_roi');
            
            %% Default parameters
            %% Initialising and extracting parameters
            kLimsROI    = roi_args{1};
            ebLimsROI   = roi_args{2};
            kLimsBS     = roi_args{3};
            ebLimsBS    = roi_args{4};
            %% - Finding the data field labels
            [xField, yField, zField, dField] = find_data_fields(app, dataStr);
            
            %% 1 - Cropping around the ROI region
            % 1.1 - Finding the cropping indices
            [~, XminInd] = min((abs(dataStr.(xField)(1,:,app.scanIndex) - kLimsROI(1))));
            [~, XmaxInd] = min((abs(dataStr.(xField)(1,:,app.scanIndex) - kLimsROI(2))));
            [~, YminInd] = min((abs(dataStr.(yField)(:,1,app.scanIndex) - ebLimsROI(1))));
            [~, YmaxInd] = min((abs(dataStr.(yField)(:,1,app.scanIndex) - ebLimsROI(2))));
            kIndsROI = [XminInd, XmaxInd];
            ebIndsROI = [YminInd, YmaxInd];
            % 1.2 - Cropping around ROI
            Xroi = dataStr.(xField)(YminInd:YmaxInd,XminInd:XmaxInd,scanIndex);
            Yroi = dataStr.(yField)(YminInd:YmaxInd,XminInd:XmaxInd,scanIndex);
            Droi = dataStr.(dField)(YminInd:YmaxInd,XminInd:XmaxInd,scanIndex);
            % - For 2D data only
            if dataStr.Type == "Eb(k)"
                Zroi = mean(mean(dataStr.(zField)(scanIndex)));
            % - For 3D data
            elseif dataStr.Type == "Eb(kx,ky)" || dataStr.Type == "Eb(kx,kz)"
                Zroi = mean(mean(dataStr.(dField)(YminInd:YmaxInd,XminInd:XmaxInd,scanIndex)));
            end
            %% 2 - Cropping around the BS region
            % 2.1 - Finding the cropping indices
            [~, XminInd] = min(abs(dataStr.(xField)(1,:,app.scanIndex) - kLimsBS(1)));
            [~, XmaxInd] = min(abs(dataStr.(xField)(1,:,app.scanIndex) - kLimsBS(2)));
            [~, YminInd] = min(abs(dataStr.(yField)(:,1,app.scanIndex) - ebLimsBS(1)));
            [~, YmaxInd] = min(abs(dataStr.(yField)(:,1,app.scanIndex) - ebLimsBS(2)));
            kIndsBS = [XminInd, XmaxInd];
            ebIndsBS = [YminInd, YmaxInd];
            % 1.2 - Cropping around BS region
            Xbs = dataStr.(xField)(YminInd:YmaxInd,XminInd:XmaxInd,scanIndex);
            Ybs = dataStr.(yField)(YminInd:YmaxInd,XminInd:XmaxInd,scanIndex);
            Dbs = dataStr.(dField)(YminInd:YmaxInd,XminInd:XmaxInd,scanIndex);
            % - For 2D data only
            if dataStr.Type == "Eb(k)"
                Zbs = mean(mean(dataStr.(zField)(scanIndex)));
            % - For 3D data
            elseif dataStr.Type == "Eb(kx,ky)" || dataStr.Type == "Eb(kx,kz)"
                Zbs = mean(mean(dataStr.(zField)(YminInd:YmaxInd,XminInd:XmaxInd,scanIndex)));
            end
            %% 3 - Ensuring consistency between the array sizes
            if size(Xroi, 1) ~= size(Xbs, 1)
                dim1Size = abs(size(Xroi, 1) - size(Xbs, 1));
                Xbs = Xbs(dim1Size:end,:);
                Ybs = Ybs(dim1Size:end,:);
                Dbs = Dbs(dim1Size:end,:);
            end
            if size(Xroi, 2) ~= size(Xbs, 2)
                dim1Size = abs(size(Xroi, 2) - size(Xbs, 2));
                Xbs = Xbs(:,dim1Size:end);
                Ybs = Ybs(:,dim1Size:end);
                Dbs = Dbs(:,dim1Size:end);
            end
            %% 4 - Appending to the new .fits field;
            dataStr.fits = struct();
            dataStr.fits.FileName = dataStr.FileName;
            dataStr.fits.meta.kIndsROI = kIndsROI;
            dataStr.fits.meta.kIndsROI = ebIndsROI;
            dataStr.fits.meta.kIndsROI = kIndsBS;
            dataStr.fits.meta.kIndsROI = ebIndsBS;
            dataStr.fits.scanIndex = scanIndex;
            dataStr.fits.roi_args  = roi_args;
            dataStr.fits.kx_bs     = Xbs;
            dataStr.fits.eb_bs     = Ybs;
            dataStr.fits.data_bs   = Dbs;
            dataStr.fits.kx_roi    = Xroi;
            dataStr.fits.eb_roi    = Yroi;
            dataStr.fits.scan_roi  = Zroi;
            dataStr.fits.data_roi  = Droi;
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to perform background subtraction
        function  bs_data_roi = background_subtraction(app, fitStr, bs_args)
            % bs_data_roi = background_subtraction(fitStr, backsub_args)
            %   This function subtracts the background of the ROI relative
            %   to the background defined initially from the cropping.
            %
            %   REQ. FUNCTIONS: none.
            %
            %   IN:
            %   -   bs_data_roi:    background subtracted data.
            %   -   bs_args:        1x1 cell of {polyOrder}.
            %
            %   OUT:
            %   -   fitStr.back_sub:    new data structure with all the background subtraction outputs.
            
            disp('Background subtraction of ROI...')
            wbar = waitbar(0, 'Executing background subtraction...', 'Name', 'background_subtraction');
            
            %% Default parameters
            pp = plot_props(app);
            %% Initialising and extracting parameters
            polyOrder    = bs_args{1};
            
            %% 1 - Initialising the variables
            data_roi_prior  = fitStr.data_roi;
            data_bs_prior   = fitStr.data_bs;
            
            %% 2 -Extracting the polynomial background over all scans
            for s = 1:size(fitStr.data_roi, 3)
                waitbar(s/size(fitStr.data_roi, 3), wbar, 'Extracting polynomial background...', 'Name', 'background_subtraction');
                roi_polyfit(:,:,s)  = polyval(polyfit(fitStr.eb_bs(:,:,s), fitStr.data_bs(:,:,s), polyOrder), fitStr.eb_roi(:,:,s));
                back_polyfit(:,:,s) = polyval(polyfit(fitStr.eb_bs(:,:,s), fitStr.data_bs(:,:,s), polyOrder), fitStr.eb_bs(:,:,s));
            end
            
            %% 3 - Performing the background subtraction over all scans
            % Performing the background subtraction
            for s = 1:size(fitStr.data_roi, 3)
                waitbar(s/size(fitStr.data_roi, 3), wbar, 'Background subtracting data...', 'Name', 'background_subtraction');
                % - Subtracting the polynomial background
                data_roi_after(:,:,s) = fitStr.data_roi(:,:,s) - roi_polyfit(:,:,s);
                data_bs_after(:,:,s) = fitStr.data_bs(:,:,s) - back_polyfit(:,:,s);
            end
            % - Forcing the minimum value to be zero
            data_roi_after  = data_roi_after - min(data_roi_after(:));
            data_bs_after   = data_bs_after - min(data_bs_after(:));
            % - Scaling the curves to unity
            data_roi_after = data_roi_after / max(data_roi_after(:));
            data_bs_after = data_bs_after / max(data_bs_after(:));
            % - Scaling the curves to maximum value of ROIs
            data_roi_after = data_roi_after .* max(data_roi_prior(:));
            data_bs_after = data_bs_after .* max(data_bs_prior(:));
            
            %% 4 - Plotting the background subtraction
            % - Defining color constants to be used
            col_roi = pp.roi.color.*gray(size(data_roi_prior, 2));
            col_back = pp.back.color.*gray(size(data_bs_prior, 2));
            % Defining the figure object
            fig = figure(); set(fig, 'Name', char("State fitting: Back. Sub."));
            
            %% 4.1 - Plotting the pre-background subtracted background data
            subplot(221); hold on;
            % Plotting the ARPES image
            ImData(fitStr.kx_bs, fitStr.eb_bs, data_bs_prior);
            % Formatting the ARPES image
            gca_properties(app, 'kx'); colorbar('off');
            axis([min(fitStr.kx_bs(:)), max(fitStr.kx_bs(:)),min(fitStr.eb_bs(:)), max(fitStr.eb_bs(:))]);
            title(' \bf Background; pre-subtraction', 'interpreter', 'latex', 'fontsize', pp.fontsize);
            % On a new axis, plot all the line profiles
            ax1 = axes('Position',[.31 .61 .15 .10]); hold on;
            ax1.XColor = [0 0 0]; ax1.YColor = [0 0 0];
            ax1.XAxisLocation = 'bottom';
            ax1.YAxisLocation = 'left';
            ylabel(''); yticks([]);
            for i = 1:size(col_back, 1)
                plot(fitStr.eb_bs(:,i), data_bs_prior(:,i), '.', 'color', col_back(i,:), 'markersize', 4);
            end
            plot(fitStr.eb_bs, back_polyfit, 'k-',...
                'color', pp.roipoly.color, 'linestyle', pp.roipoly.linestyle, 'linewidth', pp.roipoly.linewidth);
            axis([min(fitStr.eb_bs(:)), max(fitStr.eb_bs(:)),min(data_bs_prior(:)), max(data_bs_prior(:))]);
            
            %% 4.2 - Plotting the pre-background subtracted background data
            subplot(222); hold on;
            % Plotting the ARPES image
            ImData(fitStr.kx_roi, fitStr.eb_roi, data_roi_prior);
            % Formatting the ARPES image
            gca_properties(app, 'kx'); colorbar('off');
            axis([min(fitStr.kx_roi(:)), max(fitStr.kx_roi(:)),min(fitStr.eb_roi(:)), max(fitStr.eb_roi(:))]);
            title(' \bf ROI; pre-subtraction', 'interpreter', 'latex', 'fontsize', pp.fontsize);
            % On a new axis, plot all the line profiles
            ax2 = axes('Position',[.752 .61 .15 .1]); hold on;
            ax2.XColor = [1 1 1]; ax2.YColor = [1 1 1];
            ax2.XAxisLocation = 'bottom';
            ax2.YAxisLocation = 'right';
            ylabel(''); yticks([]);
            for i = 1:size(col_roi, 1)
                plot(fitStr.eb_roi(:,i), data_roi_prior(:,i), '.', 'color', col_roi(i,:), 'markersize', 4);
            end
            plot(fitStr.eb_roi, roi_polyfit, 'k-',...
                'color', pp.roipoly.color, 'linestyle', pp.roipoly.linestyle, 'linewidth', pp.roipoly.linewidth);
            axis([min(fitStr.eb_roi(:)), max(fitStr.eb_roi(:)), min(data_roi_prior(:)), max(data_roi_prior(:))]);

            %% 4.3 - Plotting the post-background subtracted background data
            subplot(223); hold on;
            % Plotting the ARPES image
            ImData(fitStr.kx_bs, fitStr.eb_bs, fitStr.data_bs);
            % Formatting the ARPES image
            gca_properties(app, 'kx'); colorbar('off');
            axis([min(fitStr.kx_bs(:)), max(fitStr.kx_bs(:)),min(fitStr.eb_bs(:)), max(fitStr.eb_bs(:))]);
            title(' \bf Background; post-subtraction', 'interpreter', 'latex', 'fontsize', pp.fontsize);
            % Colorbar properties
            colormap hot; cb = colorbar; 
            cb.FontName = 'Helvetica'; cb.FontWeight = 'normal'; cb.FontSize = 10;
            cb.TickLabelInterpreter = 'latex';
            cb.TickLength = 0.04; cb.TickDirection = 'out';
            cb.YAxisLocation = 'right';
            pos = get(cb, 'Position'); cb.Position = [0.11*pos(1) 0.75*pos(2) 0.02 0.1];
            cb.Color = [0 0 0]; cb.Box = 'on'; cb.LineWidth = 1.2;
            % On a new axis, plot all the line profiles
            ax3 = axes('Position',[.31 .14 .15 .1]); hold on;
            ax3.XColor = [0 0 0]; ax3.YColor = [0 0 0];
            ax3.XAxisLocation = 'bottom';
            ax3.YAxisLocation = 'right';
            ylabel(''); yticks([]);
            for i = 1:size(col_back, 1)
                plot(fitStr.eb_bs(:,i), data_bs_after(:,i), '.', 'color', col_back(i,:), 'markersize', 4);
            end
            axis([min(fitStr.eb_bs(:)), max(fitStr.eb_bs(:)), min(data_bs_after(:)), max(data_bs_after(:))]);
            
            %% 4.4 - Plotting the post-background subtracted ROI data
            subplot(224); hold on;
            % Plotting the ARPES image
            ImData(fitStr.kx_roi, fitStr.eb_roi, fitStr.data_roi);
            % Formatting the ARPES image
            gca_properties(app, 'kx'); colorbar('off');
            axis([min(fitStr.kx_roi(:)), max(fitStr.kx_roi(:)),min(fitStr.eb_roi(:)), max(fitStr.eb_roi(:))]);
            title(' \bf ROI; post-subtraction', 'interpreter', 'latex', 'fontsize', pp.fontsize);
            % Colorbar properties
            colormap hot; cb = colorbar; 
            % Colorbar font properties
            cb.FontName = 'Helvetica'; cb.FontWeight = 'normal'; cb.FontSize = 10;
            % Colorbar tick properties
            cb.TickLabelInterpreter = 'latex';
            cb.TickLength = 0.04; cb.TickDirection = 'out';
            cb.YAxisLocation = 'right';
            % Colorbar position properties
            pos = get(cb, 'Position'); cb.Position = [1.08*pos(1) 0.75*pos(2) 0.02 0.1];
            % Colorbar box properties
            cb.Color = [0 0 0]; cb.Box = 'on'; cb.LineWidth = 1.2;
            % On a new axis, plot all the line profiles
            ax4 = axes('Position',[.752 .14 .15 .1]); hold on;
            ax4.XColor = [1 1 1]; ax4.YColor = [1 1 1];
            ax4.XAxisLocation = 'bottom';
            ax4.YAxisLocation = 'right';
            ylabel(''); yticks([]);
            for i = 1:size(col_roi, 1)
                plot(fitStr.eb_roi(:,i), data_roi_after(:,i), '.', 'color', col_roi(i,:), 'markersize', 4);
            end
            axis([min(fitStr.eb_roi(:)), max(fitStr.eb_roi(:)), min(data_roi_after(:)), max(data_roi_after(:))]);
            
            % Resetting the size of the figure
            set(fig, 'Position', [100, 100, 800, 600]);
            
            %% 5 - Appending the post background subtraction data
            bs_data_roi = data_roi_after;
            
            %% Close wait-bar
            close(wbar);
        end

        % --- Constant, global formatting for the figures
        function gca_properties(app, type)
            % gca_properties(type)
            %   This function outlines the consistent axes and figure
            %   parameters to be used. Each field can be edited to what
            %   the user desires. Specific inputs are used to isolate the
            %   different types of figures that are plotted.
            %
            % IN:
            %   -   type:	string of either "theta" or "kx" for the x-axis label
            
            %% Default parameters
            if nargin < 2; type="raw_tht"; end
            if isempty(type); type="raw_tht";  end
            
            %% 1 - Defining the axes properties
            ax = gca;
            % Font properties
            ax.FontName = 'Helvetica'; ax.FontWeight = 'normal'; ax.FontSize = 15;
            % Tick properties
            ax.TickLabelInterpreter = 'latex';
            ax.XMinorTick = 'off'; ax.YMinorTick = 'off';
            ax.TickDir = 'out';
            ax.TickLength = [0.01 0.025];
            ax.XColor = [0 0 0]; ax.YColor = [0 0 0];
            % Ruler properties
            ax.XAxisLocation = 'bottom';            % 'bottom' | 'top' | 'origin'
            ax.YAxisLocation = 'left';              % 'left' | 'right' | 'origin'
            % Box Styling properties
            ax.Color = [0, 0, 0];
            ax.LineWidth = 1.5;
            ax.Box = 'off'; ax.Layer = 'Top';
            % Axis labels and limits
            if type == "raw_tht" || type == "tht"
                xlabel('$$ \bf  \theta (^{\circ}) $$', 'Interpreter', 'latex');
            elseif type == "kx"
                xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
            end
            ylabel('$$ \bf  E_B (eV) $$', 'Interpreter', 'latex');
            %% 2 - Defining the figure properties
            fig = gcf; fig.Color = [1 1 1]; fig.InvertHardcopy = 'off';
            %% 3 - Colorbar properties
            colormap(app.colmap_arg);
            cb = colorbar; 
            % Colorbar font properties
            cb.FontName = 'Helvetica'; cb.FontWeight = 'normal'; cb.FontSize = 10;
            cb.TickLabelInterpreter = 'latex';
            cb.TickLength = 0.04; cb.TickDirection = 'out';
            % Colorbar position properties
            pos = get(cb, 'Position'); cb.Position = [1.09*pos(1) 7.0*pos(2) 0.03 0.1];
            % Colorbar box properties
            cb.Color = [0 0 0]; cb.Box = 'on'; cb.LineWidth = 1.2;
            %% 4 - Plotting the x- and y-axes
            xl = xlim; yl = ylim;
            axis([xl(1), xl(2), yl(1), yl(2)]);
            line([0 0], [-1e5, 1e5], 'Color', [1 1 1 0.5], 'LineWidth', 0.75, 'Linestyle', '--');
            line([-1e5, 1e5], [0 0], 'Color', [1 1 1 0.5], 'LineWidth', 0.75, 'Linestyle', '--');
        end
   
        % --- Constant, global formatting for the plot properties
        function pp = plot_props(app)
            % pp = plot_props()
            %   This function outlines theconsistent colors, markers and lines to be
            %   used when plotting the figures associated with all the state fitting
            %   analysis.
            
            %% - Initialising plot property structure
            pp = struct();
            %% General properties
            pp.fontsize = 12;
            %% 1 - Defining background and ROI properties
            pp.roi.color = [0.1 0.1 0.6];
            pp.roi.linestyle = '-';
            pp.roi.linewidth = 2;
            pp.back.color = [0.1 0.6 0.1];
            pp.back.linestyle = '-';
            pp.back.linewidth = 2;
            pp.roipoly.color = [0.6, 0.1, 0.1];
            pp.roipoly.linestyle = '-';
            pp.roipoly.linewidth = 2;
            %% 2 - Defining FDD properties
            pp.fdd.color = [0, 0, 0];
            pp.fdd.linestyle =':';
            pp.fdd.linewidth = 2;
            %% 3 - Defining EDC properties
            pp.edc.color = [0.2, 0.6, 0.2];
            pp.edc.linestyle = '-';
            pp.edc.linewidth = 1.5;
            pp.edc.markerstyle = 's';
            pp.edc.markersize = 5;
            %% 4 - Defining MDC properties
            pp.mdc.color = [0.2, 0.2, 0.8];
            pp.mdc.linestyle = '-';
            pp.mdc.linewidth = 1.5;
            pp.mdc.markerstyle = '^';
            pp.mdc.markersize = 5;
            %% 5 - Defining fit properties
            % - Colours for the fits
            cols = [...
                0, 0.4470, 0.7410;...
                0.4660, 0.6740, 0.1880;...
                0.4940, 0.1840, 0.5560;...
                0.8500, 0.3250, 0.0980];
            % - Marker styles for the fits
            mstyle = ['x', '+', 's', '^'];
            for i = 1:4
                pp.fit.color{i} = cols(i,:);
                pp.fit.mstyle{i} = mstyle(i);
            end
            pp.fit.linestyle = '-';
            pp.fit.linewidth = 1.5;
            pp.fit.alpha = 0.55;
        end
        
        % --- General function to view a series of all scans from 3D ARPES data - *Eb(kx,ky) or Eb(kx,kz) scans only*
        function view_data_series(app, dataStr)
            % view_data_series(dataStr)
            %   This function plots the ARPES data in the form of D(X,Y)
            %   for all the scan parameter values (tltM or hv) in the form of a 
            %   downsampled image series. This function ensures that the 
            %   most recent processing is shown in the figure.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   gca_properties(type)
            %   -   [h=] ImData(X,Y,Z[,style])
            %
            %   IN:
            %   -   dataStr:          data structure of the ARPES data.
            
            disp('-> view series of all scans...')
            wbar = waitbar(0., 'Plotting a view series of all scans...', 'Name', 'view_data_series');
            
            %% Default parameters
            % - Extracting the fields to be used with most recent processing
            [xField, yField, zField, dField] = find_data_fields(app, dataStr);
            % - Each series is an nRows x nCols sub-plot
            nRows = 3; nCols = 3;
            %% 1 - Down-sampling the form of the data
            step_size = 2;
            zMidIndx = ceil(size(dataStr.(dField), 3)/2);
            %% 2 - Plotting a series of Eb(k) Images
            % Filing through all the pages of the figures
            for ipage=1:ceil(size(dataStr.(dField), 3)/nRows/nCols)
                figure_cell{ipage} = figure(); orient tall; 
                set(figure_cell{ipage}, 'Position', [1, 1, 800, 700], 'Name', dataStr.FileName);
                % Filing through all the frames in a single figure
                for iframe=1:nRows*nCols
                    % - Finding the n'th data object to be plotted
                    n = (ipage-1)*nRows*nCols+iframe;
                    waitbar(n/size(dataStr.(dField), 3), wbar, 'Plotting a view series of all scans...', 'Name', 'view_data_series');
                    % - If n > number of plotting elements, break the statement
                    if n > size(dataStr.(dField), 3)
                        break; 
                    else
                        % -- Plotting the n'th data object
                        subplot(nRows,nCols,iframe); 
                        gca_properties(app, string(xField)); colorbar('off'); 
                        xlabel(''); ylabel('');
                        % -- The plot depends on how far in the analysis 
                        % - 2.1 - EbAlign->Normalise->kConvert fields
                        if isfield(dataStr, 'kx')
                            ImData(dataStr.(xField)(1:step_size:end,1:step_size:end,n), dataStr.(yField)(1:step_size:end,1:step_size:end,n), dataStr.(dField)(1:step_size:end,1:step_size:end,n));
                            title(sprintf('scan = %.2f', dataStr.(zField)(zMidIndx,zMidIndx,n)), 'fontsize', 10);
                        % - 2.2 - EbAlign->Normalise fields
                        elseif isfield(dataStr, 'data')
                            ImData(dataStr.(xField)(1:step_size:end,1:step_size:end,n), dataStr.(yField)(1:step_size:end,1:step_size:end,n), dataStr.(dField)(1:step_size:end,1:step_size:end,n));
                            title(sprintf('scan = %.2f', dataStr.(zField)(n)), 'fontsize', 10);
                        % - 2.3 - EbAlign fields
                        elseif isfield(dataStr, 'eb')
                            ImData(dataStr.(xField)(1:step_size:end,1:step_size:end,n), dataStr.(yField)(1:step_size:end,1:step_size:end,n), dataStr.(dField)(1:step_size:end,1:step_size:end,n));
                            title(sprintf('scan = %.2f', dataStr.(zField)(n)), 'fontsize', 10);
                        % - 2.4 - Raw, unprocessed data fields
                        else
                            ImData(dataStr.(xField)(1:step_size:end), dataStr.(yField)(1:step_size:end), dataStr.(dField)(1:step_size:end,1:step_size:end,n));
                            title(sprintf('scan = %.2f', dataStr.(zField)(n)), 'fontsize', 10);
                        end
                        % - 2.5 - Formatting the figure
                        minC = min(min(min(dataStr.(dField)(1:step_size:end,1:step_size:end,iframe))));
                        maxC = max(max(max(dataStr.(dField)(1:step_size:end,1:step_size:end,iframe))));
                        caxis([minC, maxC]);
    
                        % -- Remove the colorbar for all plots, except the first one
                        if iframe == 1
                            % Setting the colorbar properties
                            cb = colorbar; 
                            % Colorbar font properties
                            cb.FontName = 'Helvetica'; cb.FontWeight = 'normal'; cb.FontSize = 10;
                            % Colorbar tick properties
                            if cb.Limits(1) < 0 || cb.Limits(1) == 0; cb.Ticks = sort(round([0, 0.5*(0.95*cb.Limits(2)), 0.95*cb.Limits(2)], 3));
                            else; cb.Ticks = sort(round([1.1*cb.Limits(1), 0.5*(0.95*cb.Limits(2)), 0.95*cb.Limits(2)], 3)); 
                            end
                            cb.TickLabelInterpreter = 'latex'; cb.TickLength = 0.04;
                            cb.TickDirection = 'out';
                            % Colorbar position properties
                            cb.Position = [0.92, 0.8, 0.02 0.1];
                            % Colorbar box properties
                            cb.Color = [0 0 0]; cb.Box = 'on'; cb.LineWidth = 1.2;
                        end
                    end
                end
            end
            close(wbar);
        end
    
        % --- General function to view the ARPES scan vs scan index
        function view_scan_fig(app, scanIndex, dataStr)
            % view_2D_data(scanIndex, dataStr)
            %   This function plots the 2D ARPES spectrum in the form of D(X,Y), along
            %   with a consistent formatting for a particular scan value.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   gca_properties(type)
            %   -   [h=] ImData(X,Y,Z[,style])
            %
            %   IN:
            %   -   dataStr:        data structure of the ARPES data.
            %   -   scanIndex:    integer of the scan index to plot.
            %
            %   OUT:
            %   -   figure output.
            
            %% Default plot properties
            pp = plot_props(app);
            %% Default parameters
            % - Extracting the fields to be used with most recent processing
            [xField, yField, ~, dField] = find_data_fields(app, dataStr);
            % -- The plot depends on how far in the analysis 
            if isfield(dataStr, 'kx')
                ImData(dataStr.(xField)(:,:,scanIndex), dataStr.(yField)(:,:,scanIndex), dataStr.(dField)(:,:,scanIndex));
            elseif isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                ImData(dataStr.(xField)(:,:,scanIndex), dataStr.(yField)(:,:,scanIndex), dataStr.(dField)(:,:,scanIndex));
            else
                ImData(dataStr.(xField), dataStr.(yField), dataStr.(dField)(:,:,scanIndex));
            end
            gca_properties(app, string(xField)); colorbar('off'); 
            colorbar;
            axis([min(dataStr.(xField)(:)), max(dataStr.(xField)(:)),min(dataStr.(yField)(:)), max(dataStr.(yField)(:))]);
            title(string(dataStr.FileName), 'interpreter', 'none', 'fontsize', pp.fontsize);
        end
        
        % --- General function to view cut ARPES data
        function view_cut_fig(app, scanIndex, dataStr, isocut_args)
            % view_isoCut(dataStr, isocut_args)
            %   This function plots a EDC/MDC cut through the ARPES data in 
            %   D(X,Y,cutIndx) into a single figure that includes the Eb(k) 
            %   dispersion.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   [h=] ImData(X,Y,Z[,style])
            %   -   [XCut,DCut] = Cut(ACorr,ECorr,Data,xMode,Win)
            %
            %   IN:
            %   -   dataStr:            data structure of the ARPES data.
            %   -   scanIndex:          integer of the scan index to plot.
            %   -   isoslice_args:      1x5 cell of {isoType, isoWin, cutIndex, cutVal}.
            %
            %   OUT:
            %   -   figure output.
            
            %% Default plot properties
            pp = plot_props(app);
            %% Initialising variables for the figure
            isoType         = isocut_args{1};
            isoWin          = isocut_args{2};
            cutIndex        = isocut_args{3};
            cutVal          = isocut_args{4};
            % - Extracting the fields to be used with most recent processing
            [xField, yField, ~, dField] = find_data_fields(app, dataStr);
            % - Capping the window to the max/min of the ARPES data
            if isempty(isoWin); isoWin = 0.1; end
            WinLims = sort(cutVal + [-1, 1] * isoWin);
            step_size = 0.05;
            if isoType == "MDC"
                if WinLims(1) < min(dataStr.(yField)(:)); WinLims(1) = min(dataStr.(yField)(:)) + step_size; end
                if WinLims(2) > max(dataStr.(yField)(:)); WinLims(2) = max(dataStr.(yField)(:)) - step_size; end
            elseif isoType == "EDC"
                if WinLims(1) < min(dataStr.(xField)(:)); WinLims(1) = min(dataStr.(xField)(:)) + step_size; end
                if WinLims(2) > max(dataStr.(xField)(:)); WinLims(2) = max(dataStr.(xField)(:)) - step_size; end
            end
            
            %% 1 - Extracting the Iso cuts
            if isoType == "MDC"
                if isfield(dataStr, 'kx') || isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                    [XCut, DCut] = Cut(dataStr.(xField)(:,:,scanIndex), dataStr.(yField)(:,:,scanIndex), dataStr.(dField)(:,:,scanIndex), 'mdc', WinLims);
                else
                    [XCut, DCut] = Cut(dataStr.(xField), dataStr.(yField), dataStr.(dField)(:,:,scanIndex), 'mdc', WinLims);
                end
            elseif isoType== "EDC"
                if isfield(dataStr, 'kx') || isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                    [XCut, DCut] = Cut(dataStr.(xField)(:,:,scanIndex), dataStr.(yField)(:,:,scanIndex), dataStr.(dField)(:,:,scanIndex), 'edc', WinLims);
                else
                    [XCut, DCut] = Cut(dataStr.(xField), dataStr.(yField), dataStr.(dField)(:,:,scanIndex), 'edc', WinLims);
                end
            end

            %% 2 - Plotting the Eb(k) at the scan value
            if isoType == "MDC"
                fig = figure(); set(fig, 'Position', [5,5, 600, 600]);
                subplot(3,1,2:3); hold on;
            elseif isoType== "EDC"
                fig = figure(); set(fig, 'Position', [5,5, 800, 600]);
                subplot(1,3,1:2); hold on;
            end
            % -- The plot depends on how far in the analysis 
            if isfield(dataStr, 'kx')
                ImData(dataStr.(xField)(:,:,scanIndex), dataStr.(yField)(:,:,scanIndex), dataStr.(dField)(:,:,scanIndex));
            elseif isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                ImData(dataStr.(xField)(:,:,scanIndex), dataStr.(yField)(:,:,scanIndex), dataStr.(dField)(:,:,scanIndex));
            else
                ImData(dataStr.(xField), dataStr.(yField), dataStr.(dField)(:,:,scanIndex));
            end
            gca_properties(app, string(xField)); colorbar('off');
            axis([min(dataStr.(xField)(:)), max(dataStr.(xField)(:)),min(dataStr.(yField)(:)), max(dataStr.(yField)(:))]);
            % -- Plotting an outline over the integrated sliced region
            if isoType == "MDC"
                patch([-1e3, 1e3, 1e3, -1e3, -1e3], [WinLims(1), WinLims(1), WinLims(2), WinLims(2), WinLims(1)],...
                    pp.mdc.color, 'linewidth', 1, 'facealpha', 0, 'edgecolor', pp.mdc.color);
            elseif isoType== "EDC"
                patch([WinLims(1), WinLims(1), WinLims(2), WinLims(2), WinLims(1)],[-1e3, 1e3, 1e3, -1e3, -1e3],...
                    pp.edc.color, 'linewidth', 1, 'facealpha', 0, 'edgecolor', pp.edc.color);
            end    
            % -- Adding title to the figure
            title(sprintf(string(dataStr.FileName) + "; scan %i", scanIndex), 'interpreter', 'none', 'fontsize', pp.fontsize);
            
            %% 3 - Plotting the Iso-Cut
            if isoType == "MDC"; subplot(3,1,1); hold on;
                plot(XCut, DCut, 'k.-', 'color', pp.mdc.color, 'LineWidth', pp.mdc.linewidth,...
                    'marker', pp.mdc.markerstyle, 'markersize', pp.mdc.markersize);
                %% 1 - Defining the axes properties
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 12;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'out';
                % Ruler properties
                ax.XAxisLocation = 'top';               % 'bottom' | 'top' | 'origin'
                ax.YAxisLocation = 'left';              % 'left' | 'right' | 'origin'
                % Axis labels and limits
                if xField == "raw_tht" || xField == "tht"
                    xlabel('$$ \bf  \theta (^{\circ}) $$', 'Interpreter', 'latex');
                elseif xField == "kx"
                    xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                end
                ylabel('$$ \bf  Intensity $$', 'Interpreter', 'latex');
                %% 2 - Plotting the x- and y-axes
                xl = [min(dataStr.(xField)(:)), max(dataStr.(xField)(:))]; yl = ylim;
                axis([xl(1), xl(2), yl(1), yl(2)]);
                line([0 0], [-1e5, 1e5], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
                line([-1e5, 1e5], [0 0], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
            elseif isoType== "EDC"; subplot(1,3,3); hold on;
                plot(DCut, XCut, 'k.-', 'color', pp.edc.color, 'LineWidth', pp.edc.linewidth,...
                    'marker', pp.edc.markerstyle, 'markersize', pp.edc.markersize);
                %% 1 - Defining the axes properties
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 12;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'out';
                % Ruler properties
                ax.XAxisLocation = 'bottom';            % 'bottom' | 'top' | 'origin'
                ax.YAxisLocation = 'right';             % 'left' | 'right' | 'origin'
                % Axis labels and limits
                ylabel('$$ \bf  E_{B} (eV) $$', 'Interpreter', 'latex');
                xlabel('$$ \bf  Intensity $$', 'Interpreter', 'latex');
                %% 2 - Plotting the x- and y-axes
                xl = xlim; yl = [min(dataStr.(yField)(:)), max(dataStr.(yField)(:))];
                axis([xl(1), xl(2), yl(1), yl(2)]);
                line([0 0], [-1e5, 1e5], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
                line([-1e5, 1e5], [0 0], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
            end
        end
        
        % --- Function to observe the cropped ROI and background region
        function view_roi_fig(app, scanIndex, dataStr, roi_args)
            % fig = view_roi_fig(fitStr, scanIndex, roi_args)
            %   This function plots a summary of the ROI and background
            %   region to be used for the fitting.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   [h=] ImData(X,Y,Z[,style])
            %   -   [XCut,DCut] = Cut(ACorr,ECorr,Data,xMode,Win)
            %
            %   IN:
            %   -   dataStr:        data structure of the ARPES data.
            %   -   scanIndex:      integer of the scan index to plot.
            %   -   roi_args:       1x4 cell of {kLimsROI, ebLimsROI, kLimsBS, ebLimsBS}.
            %
            %   OUT:
            %   -   figure output.
            
            %% Default plot properties
            pp = plot_props(app);
            %% Initialising and extracting parameters
            kLimsROI    = roi_args{1};
            ebLimsROI   = roi_args{2};
            kLimsBS     = roi_args{3};
            ebLimsBS    = roi_args{4};
            
            %% 1 - View the ROI and background areas that were cropped
            fig = figure(); set(fig, 'Name', 'Crop ROI figure');
            view_scan_fig(app, scanIndex, dataStr);
            title(string(dataStr.FileName) + " - scan " + string(scanIndex),...
                'interpreter', 'none', 'fontsize', pp.fontsize);
            
            %% 2 - Plot patches of color representing the ROI and background
            % - ROI patch and label
            patch([kLimsROI(1), kLimsROI(1),...
                kLimsROI(2), kLimsROI(2),...
                kLimsROI(1)],...
                [ebLimsROI(1), ebLimsROI(2),...
                ebLimsROI(2), ebLimsROI(1),...
                ebLimsROI(1)],...
                pp.roi.color, 'linewidth', pp.roi.linewidth, 'facealpha', 0.25, 'edgecolor', pp.roi.color);
            text(mean(kLimsROI), min(ebLimsROI), 'ROI',...
                'Fontsize', 13, 'Fontweight', 'bold', 'HorizontalAlignment', 'center', 'color', pp.roi.color);
            % - Background patch and label
            patch([kLimsBS(1), kLimsBS(1),...
                kLimsBS(2), kLimsBS(2),...
                kLimsBS(1)],...
                [ebLimsBS(1), ebLimsBS(2),...
                ebLimsBS(2), ebLimsBS(1),...
                ebLimsBS(1)],...
                pp.back.color, 'linewidth', pp.back.linewidth, 'facealpha', 0.25, 'edgecolor', pp.back.color);
            text(mean(kLimsBS), max(ebLimsBS),...
                'Background', 'Fontsize', 13, 'Fontweight', 'bold', 'HorizontalAlignment', 'center', 'color', pp.back.color);
        end
        
        % --- Function to view the final figure after ROI cropping
        function fig = view_final_fig(app, fitStr, isocut_args)
            % fig = view_final_fig(fitStr, isocut_args)
            %   This function plots a summary of the ROI and background
            %   region to be used for the fitting.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   [h=] ImData(X,Y,Z[,style])
            %   -   [XCut,DCut] = Cut(ACorr,ECorr,Data,xMode,Win)
            %
            %   IN:
            %   -   fitStr:         data structure of the fitted ARPES data.
            %   -   isoslice_args:      1x5 cell of {isoType, isoWin, cutIndex, cutVal}.
            %
            %   OUT:
            %   -   figure output.
            
            %% Default plot properties
            pp = plot_props(app);
            %% Initialising and extracting parameters
            %% Initialising variables for the figure
            isoType         = isocut_args{1};
            isoWin          = isocut_args{2};
            cutIndex        = isocut_args{3};
            cutVal          = isocut_args{4};
            % - Capping the window to the max/min of the ARPES data
            if isempty(isoWin); isoWin = 0.1; end
            WinLims = sort(cutVal + [-1, 1] * isoWin);
            step_size = 0.05;
            if isoType == "MDC"
                if WinLims(1) < min(fitStr.eb_roi(:)); WinLims(1) = min(fitStr.eb_roi(:)) + step_size; end
                if WinLims(2) > max(fitStr.eb_roi(:)); WinLims(2) = max(fitStr.eb_roi(:)) - step_size; end
            elseif isoType == "EDC"
                if WinLims(1) < min(fitStr.kx_roi(:)); WinLims(1) = min(fitStr.kx_roi(:)) + step_size; end
                if WinLims(2) > max(fitStr.kx_roi(:)); WinLims(2) = max(fitStr.kx_roi(:)) - step_size; end
            end
            
            %% 1 - Extracting the Iso cuts
            if isoType == "MDC"
                [XCut, DCut] = Cut(fitStr.kx_roi, fitStr.eb_roi, fitStr.data_roi, 'mdc', WinLims);
            elseif isoType== "EDC"
                [XCut, DCut] = Cut(fitStr.kx_roi, fitStr.eb_roi, fitStr.data_roi, 'edc', WinLims);
            end
            
            %% 2 - Plotting the Eb(k) of the ROI
            if isoType == "MDC"
                fig = figure(); set(fig, 'Position', [5,5, 600, 800]);
                subplot(3,1,2:3); hold on;
            elseif isoType== "EDC"
                fig = figure(); set(fig, 'Position', [5,5, 800, 600]);
                subplot(1,3,1:2); hold on;
            end
            ImData(fitStr.kx_roi, fitStr.eb_roi, fitStr.data_roi);
            gca_properties(app, 'kx'); colorbar('off');
            axis([min(fitStr.kx_roi(:)), max(fitStr.kx_roi(:)),min(fitStr.eb_roi(:)), max(fitStr.eb_roi(:))]);
            % -- Plotting an outline over the integrated sliced region
            if isoType == "MDC"
                patch([-1e3, 1e3, 1e3, -1e3, -1e3], [WinLims(1), WinLims(1), WinLims(2), WinLims(2), WinLims(1)],...
                    pp.mdc.color, 'linewidth', 1, 'facealpha', 0, 'edgecolor', pp.mdc.color);
            elseif isoType== "EDC"
                patch([WinLims(1), WinLims(1), WinLims(2), WinLims(2), WinLims(1)],[-1e3, 1e3, 1e3, -1e3, -1e3],...
                    pp.edc.color, 'linewidth', 1, 'facealpha', 0, 'edgecolor', pp.edc.color);
            end    
            % -- Adding title to the figure
            title(sprintf(string(fitStr.FileName) + "; scan %i", fitStr.scanIndex), 'interpreter', 'none', 'fontsize', pp.fontsize);
            
            %% 3 - Plotting the parabolic trail
            if isfield(fitStr, 'para')
                for i = 1:size(fitStr.para.kx,2)
                    kx = fitStr.para.kx{i}; dkx = fitStr.para.dkx{i};
                    eb = fitStr.para.eb{i}; deb = fitStr.para.deb{i};
                    % fill([kx + dkx, fliplr(kx - dkx)], [eb, fliplr(eb)], pp.fit.color{i}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                    fill([kx, fliplr(kx)], [eb + deb, fliplr(eb - deb)], pp.fit.color{i}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                    % plot(kx, eb, 'k-', 'color', pp.fit.color{i}, 'linewidth', 1);
                end
            end
            
            %% 4 - Plotting the best fit data points and parabola
            if isfield(fitStr, 'mdc') && isoType == "MDC"
                for n = 1:fitStr.mdc.nStates
                    % - Plotting the best fit data-points
                    plot(fitStr.mdc.kx_pts{n}, fitStr.mdc.eb_pts{n}, 'kx',...
                        'color', pp.fit.color{n}, 'linewidth', 1, 'marker', pp.fit.mstyle{n});
                    % - Plotting the best fit parabola
                    X = linspace(-2, 2, 1e3);
                    Y = fitStr.mdc.fpara(fitStr.mdc.beta_para{n}, X);
                    plot(X, Y, 'k-', 'color', pp.fit.color{n}, 'linewidth', 1);
                end  
            elseif isfield(fitStr, 'edc') && isoType == "EDC"
                for n = 1:fitStr.edc.nStates
                    % - Plotting the best fit data-points
                    plot(fitStr.edc.kx_pts{n}, fitStr.edc.eb_pts{n}, 'kx',...
                        'color', pp.fit.color{n}, 'linewidth', 1, 'marker', pp.fit.mstyle{n});
                    % - Plotting the best fit parabola
                    X = linspace(-2, 2, 1e3);
                    Y = fitStr.edc.fpara(fitStr.edc.beta_para{n}, X);
                    plot(X, Y, 'k-', 'color', pp.fit.color{n}, 'linewidth', 1);
                end                
            end
            
            
            %% A - Plotting the Iso-Cut
            if isoType == "MDC"; subplot(3,1,1); hold on;
                %% 1 - Plotting the parabolic estimate line
                if isfield(fitStr, 'para')
                    for i = 1:size(fitStr.para.kx,2)
                        dkx = fitStr.para.dkx{i};
                        [~, minInd] = min((abs(fitStr.para.eb{i} - cutVal)));
                        parVal = fitStr.para.kx{i}(minInd);
                        paraX1 = [parVal + dkx, parVal + dkx, parVal - dkx, parVal - dkx];
                        paraX2 = -1 * [parVal + dkx, parVal + dkx, parVal - dkx, parVal - dkx];
                        paraY1 = [-1e5, 1e5, 1e5, -1e5];
                        fill(paraX1, paraY1, pp.fit.color{i}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                        fill(paraX2, paraY1, pp.fit.color{i}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                    end
                end
                %% 2 - Plotting the best fit curves
                if isfield(fitStr, 'mdc')
                    if cutIndex >= fitStr.mdc.fit_indxs(1) && cutIndex <= fitStr.mdc.fit_indxs(2)
                        % - Initialising the variables
                        newIndex = cutIndex - fitStr.mdc.fit_indxs(1) + 1;
                        xx = linspace(-8, 8, 5e3)';
                        % - Extracting the fit parameters
                        fit_params = fitStr.mdc.beta{newIndex};
                        % - Plotting the singlet fits
                        for n = 1:fitStr.edc.nStates
                            if fitStr.mdc.mdc_args{n, 5} == 1
                                args1 = [fit_params(5*n-4), fit_params(5*n-3), fit_params(5*n-2), fit_params(5*n)];
                                args2 = [fit_params(5*n-4), fit_params(5*n-3), fit_params(5*n-1), fit_params(5*n)];
                                yy_f1 = fitStr.mdc.f1(args1, xx);
                                yy_f2 = fitStr.mdc.f1(args2, xx);
                                fill(xx, yy_f1, pp.fit.color{n}, 'facealpha', pp.fit.alpha, 'EdgeColor', 'none');
                                fill(xx, yy_f2, pp.fit.color{n}, 'facealpha', pp.fit.alpha, 'EdgeColor', 'none');
                            else
                                args1 = [fit_params(6*n-5), fit_params(6*n-3), fit_params(6*n-2), fit_params(6*n)];
                                args2 = [fit_params(6*n-4), fit_params(6*n-3), fit_params(6*n-1), fit_params(6*n)];
                                yy_f1 = fitStr.mdc.f1(args1, xx);
                                yy_f2 = fitStr.mdc.f1(args2, xx);
                                fill(xx, yy_f1, pp.fit.color{n}, 'facealpha', pp.fit.alpha, 'EdgeColor', 'none');
                                fill(xx, yy_f2, pp.fit.color{n}, 'facealpha', pp.fit.alpha, 'EdgeColor', 'none');
                            end
                        end
                    end
                end
                %% 4 - Plotting the MDC cut
                plot(XCut, DCut, 'k.-', 'color', pp.mdc.color, 'LineWidth', pp.mdc.linewidth,...
                    'marker', pp.mdc.markerstyle, 'markersize', pp.mdc.markersize);
                %% 5 - Defining the axes properties
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 12;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'out';
                % Ruler properties
                ax.XAxisLocation = 'top';               % 'bottom' | 'top' | 'origin'
                ax.YAxisLocation = 'left';              % 'left' | 'right' | 'origin'
                % Axis labels and limits
                xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                ylabel('$$ \bf  Intensity $$', 'Interpreter', 'latex');
                %% 4 - Plotting the x- and y-axes
                xl = [min(fitStr.kx_roi(:)), max(fitStr.kx_roi(:))]; yl = [0, max(DCut(:))];
                axis([xl(1), xl(2), yl(1), yl(2)]);
                line([0 0], [-1e5, 1e5], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
                line([-1e5, 1e5], [0 0], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
                %% 6 - Plotting the the best general functional form to the fit
                if isfield(fitStr, 'mdc')
                    if cutIndex >= fitStr.mdc.fit_indxs(1) && cutIndex <= fitStr.mdc.fit_indxs(2)
                        newIndex = cutIndex - fitStr.mdc.fit_indxs(1) + 1;
                        xx = linspace(-8, 8, 5e3)';
                        fit_params = fitStr.mdc.beta{newIndex};
                        yy_fn = fitStr.mdc.fn(fit_params, xx);
                        plot(xx, yy_fn, 'k-', 'linewidth', pp.fit.linewidth);
                    end
                end
                
            elseif isoType== "EDC"; subplot(1,3,3); hold on;
                %% 1 - Plotting the parabolic estimate line
                if isfield(fitStr, 'para')
                    for i = 1:size(fitStr.para.kx,2)
                        deb = fitStr.para.deb{i};
                        [~, minInd] = min((abs(fitStr.para.kx{i} - cutVal)));
                        parVal = fitStr.para.eb{i}(minInd);
                        paraX = [-1e5, 1e5, 1e5, -1e5];
                        paraY = [parVal + deb, parVal + deb, parVal - deb, parVal - deb];
                        fill(paraX, paraY, pp.fit.color{i}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                    end
                end
                %% 2 - Plotting the best fit curves
                if isfield(fitStr, 'edc')
                    if cutIndex >= fitStr.edc.fit_indxs(1) && cutIndex <= fitStr.edc.fit_indxs(2)
                        % - Initialising the variables
                        newIndex = cutIndex - fitStr.edc.fit_indxs(1) + 1;
                        xx = linspace(-8, 8, 5e3)';
                        % - Extracting the fit parameters
                        fit_params = fitStr.edc.beta{newIndex};
                        % - Plotting the singlet fits
                        for n = 1:fitStr.edc.nStates
                            args        = [fit_params(4*n-3), fit_params(4*n-2), fit_params(4*n-1), fit_params(4*n)];
                            yy_fdd_f1   = fitStr.edc.fdd_f1(args, xx);
                            yy_f1       = fitStr.edc.f1(args, xx);
                            % - Plotting the fit
                            if fitStr.edc.edc_args{1,5} == 1
                                fill(yy_f1, xx, pp.fit.color{n}, 'facealpha', 0.5*pp.fit.alpha, 'EdgeColor', 'none');
                                fill(yy_fdd_f1, xx, pp.fit.color{n}, 'facealpha', pp.fit.alpha, 'EdgeColor', 'none');
                            else
                                fill(yy_f1, xx, pp.fit.color{n}, 'facealpha', pp.fit.alpha, 'EdgeColor', 'none');
                            end
                        end
                    end
                end
                %% 3 - Plotting the EDC cut
                plot(DCut, XCut, 'k.-', 'color', pp.edc.color, 'LineWidth', pp.edc.linewidth,...
                    'marker', pp.edc.markerstyle, 'markersize', pp.edc.markersize);
                %% 4 - Defining the axes properties
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 12;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'out';
                % Ruler properties
                ax.XAxisLocation = 'bottom';            % 'bottom' | 'top' | 'origin'
                ax.YAxisLocation = 'right';             % 'left' | 'right' | 'origin'
                % Axis labels and limits
                xlabel('$$ \bf  Intensity $$', 'Interpreter', 'latex');
                ylabel('$$ \bf  E_{B} (eV) $$', 'Interpreter', 'latex');
                %% 5 - Plotting the x- and y-axes
                xl = [0, max(DCut(:))]; yl = [min(fitStr.eb_roi(:)), max(fitStr.eb_roi(:))];
                axis([xl(1), xl(2), yl(1), yl(2)]);
                line([0 0], [-1e5, 1e5], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
                line([-1e5, 1e5], [0 0], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
                %% 6 - Plotting the the best general functional form to the fit
                if isfield(fitStr, 'edc')
                    if cutIndex >= fitStr.edc.fit_indxs(1) && cutIndex <= fitStr.edc.fit_indxs(2)
                        newIndex = cutIndex - fitStr.edc.fit_indxs(1) + 1;
                        xx = linspace(-8, 8, 5e3)';
                        fit_params = fitStr.edc.beta{newIndex};
                        yy_fn       = fitStr.edc.fn(fit_params, xx);
                        plot(yy_fn, xx, 'k-', 'linewidth', pp.fit.linewidth);
                    end
                end
            end
        end
        
        % --- Function to extract the Fermi-Diract distibution defined
        function fitStr = extract_fdd(app, fitStr, fdd_args)
            % fitStr = extract_fdd(fitStr, fdd_args)
            %   This function extracts the Fermi-Diract distribution of the ARPES
            %   data, given the temperature, fermi-energy and analyser resolution.
            %   The resulting Fermi-Dirac Distribution is convolvuted with the
            %   analyser resolution to give an intrinsic broadening.
            %
            %   REQ. FUNCTIONS: none.
            %
            %   IN:
            %   -   fitStr:           data structure of the fitted ARPES data.
            %   -   fdd_args:    1x3 cell array of {Temperature, Fermi-Energy, Beam/Analyser Resolution}.
            %
            %   OUT:
            %   -   fitStr.fdd:    new data structure with all the fdd outputs.
            
            disp('Extracting the Fermi-Dirac Distribution...')
            wbar = waitbar(0., 'Extracting the Fermi-Dirac Distribution...', 'Name', 'extract_fdd');
            % -- Check if a FDD function was extracted before
            if isfield(fitStr, 'fdd'); fitStr = rmfield(fitStr, 'fdd'); end
            
            %% - Initialising the Fermi and Gaussian functions
            fitStr.fdd.args = fdd_args;
            % - Fermi function
            fitStr.fdd.Fermi = @(xdat) 1 ./ (exp((xdat-fdd_args{2})/(8.617E-5*fdd_args{1})) + 1);
            % - Normalised Gaussian function
            fitStr.fdd.Gauss = @(xdat) exp(-1 * log(2) .* ((xdat - fdd_args{2}) ./ (1e-3*fdd_args{3}/2)).^2);
            %% 1 - Evaluating the Functions
            fitStr.fdd.eb = linspace(fdd_args{2}-2, fdd_args{2}+2, 1e3); 
            fitStr.fdd.y_fermi = fitStr.fdd.Fermi(fitStr.fdd.eb);
            fitStr.fdd.y_gauss = fitStr.fdd.Gauss(fitStr.fdd.eb);
            %% 2 - Convolving the Fermi and Gaussian functions
            fitStr.fdd.y_conv = conv(fitStr.fdd.y_gauss, fitStr.fdd.y_fermi, 'same'); 
            fitStr.fdd.y_conv = fitStr.fdd.y_conv / max(fitStr.fdd.y_conv);
            % - Eliminate edge effects of the convolution
            for i = 1:size(fitStr.fdd.y_conv, 2)
                waitbar(i/size(fitStr.fdd.y_conv, 2), wbar, 'Extracting the Fermi-Dirac Distribution..', 'Name', 'extract_fdd');
                y_conv_val = fitStr.fdd.y_conv(i); if y_conv_val == 1; fitStr.fdd.y_conv(1,1:i) = 1; break; end
            end
            % - 2.5 - Assigning the best fit Fermi function
            fitStr.fdd.FDD = fit(fitStr.fdd.eb', fitStr.fdd.y_conv', 'linearinterp');  
            %% Close wait-bar
            close(wbar);
        end
    
        % --- Function to view the Fermi-Dirac Distribution
        function view_fdd(app, fitStr)
            % view_fdd(fitStr)
            %   This function plots the Fermi-Diract distribution of the ARPES
            %   data and the resulting Fermi-Diract Distribution that is
            %   convolvuted with the analyser resolution to give an intrinsic broadening.
            %
            %   REQ. FUNCTIONS: none.
            %
            %   IN:
            %   -   fitStr:          data structure of the fitted ARPES data.
            %
            %   OUT:
            %   -   figure output.
            
            %% Loading in plot properties
            pp = plot_props(app);
            %% - Initialising the figure
            figure(); set(gcf, 'Name', 'FDD with ARPES spectral broadening'); hold on;
            %% - 1 - Plotting the Fermi-level analysis
            plot(fitStr.fdd.eb, fitStr.fdd.y_fermi, 'k--', 'linewidth', pp.fdd.linewidth);
            %% 2 - Plotting the Gaussian of the beam/analyser resolution
            plot(fitStr.fdd.eb, fitStr.fdd.y_gauss, 'k:', 'linewidth', pp.fdd.linewidth);
            %% 3 - Plotting the expected Fermi function with broadening
            plot(fitStr.fdd.eb, fitStr.fdd.y_conv, 'k-', 'linewidth', pp.fdd.linewidth);
            %% 4.0 - Defining the axes properties
            ax = gca;
            % Font properties
            ax.FontName = 'Helvetica'; ax.FontWeight = 'normal'; ax.FontSize = 15;
            % Tick properties
            ax.TickLabelInterpreter = 'latex';
            ax.XMinorTick = 'on'; ax.YMinorTick = 'on';
            ax.TickDir = 'out';
            ax.TickLength = [0.01 0.025];
            ax.XColor = [0 0 0]; ax.YColor = [0 0 0];
            % Ruler properties
            ax.XAxisLocation = 'bottom';            % 'bottom' | 'top' | 'origin'
            ax.YAxisLocation = 'left';                   % 'left' | 'right' | 'origin'
            % Box Styling properties
            ax.LineWidth = 1.5;
            ax.Box = 'off'; ax.Layer = 'Top';
            % Axis labels and limits
            xlabel('$$ \bf  E_B - E_F (eV) $$', 'Interpreter', 'latex');
            ylabel('$$ \bf  Intensity (arb.) $$', 'Interpreter', 'latex');
            axis([-0.4, 0.4, -0.01, 1.01]);
            %% 4.1 - Defining the figure properties
            fig = gcf; fig.Color = [1 1 1]; fig.InvertHardcopy = 'off';
            %% 4.2 - Adding a legend
            fermi_label = sprintf('FDD (T=%.2f K)', fitStr.fdd.args{1});
            gauss_label = sprintf('Gaussian (FWHM=%.2f meV)', fitStr.fdd.args{3});
            fermiconv_label = sprintf('FDD convolved with Gaussian');
            legend({fermi_label, gauss_label, fermiconv_label}, 'Fontsize', 10);
        end
        
        % --- Function to extract the parabolic dispersion defined
        function fitStr = extract_parabola(app, fitStr, para_args)
            % fitStr = extract_parabola(fitStr, para_args)
            %   This function extracts an estimate to the parabolic dispersion
            %   of the region of interest. It allows to give error bars on the
            %   estimated parabolic dispersion, that can further be used during the
            %   fitting to the ROI if required.
            %
            %   REQ. FUNCTIONS: none.
            %
            %   IN:
            %   -   fitStr:             data structure of the fitted ARPES data.
            %   -   parabolic_args:    1x5 cell array of {k0, eb0, mstar, dk, deb}
            %
            %   OUT:
            %   -   fitStr.parabola:    new data structure with all the parabola outputs
            
            disp('Extracting the parabolic dispersions...')
            wbar = waitbar(0.5, 'Extracting the parabolic dispersions...', 'Name', 'extract_parabola');
            % -- Check if a parabola function was extracted before
            if isfield(fitStr, 'para'); fitStr = rmfield(fitStr, 'para'); end
            
            %% 1 - Initialising the parabolic dispersion parameters
            fitStr.para.args = para_args;
            % - Defining the parabolic dispersion fits (in terms of Angstroms and eV)
            fitStr.para.febk = @(k0, eb0, mstar, kx) (3.8180 / mstar) * (kx - k0).^2 + eb0;
            fitStr.para.ifebk = @(k0, eb0, mstar, eb) (mstar / 3.8180) .* sqrt(eb - eb0) + k0;
            %% 2 - Evaluating the parabolic dispersions
            l = 1;
            for i = 1:size(para_args, 1)
                waitbar(i/size(para_args, 1), wbar, 'Extracting the parabolic dispersions...', 'Name', 'extract_parabola');
                valid_check = 0;
                for j = 1:size(para_args, 2); if isempty(para_args{i,j}); valid_check = 1; end; end
                % - If all the columns are filled in for the row, then extract parabolic trail
                if valid_check == 0
                    fitStr.para.kx{l} = linspace(para_args{i,1}-2, para_args{i,1}+2, 5e2);
                    fitStr.para.eb{l} = fitStr.para.febk(para_args{i,1}, para_args{i,3}, para_args{i,5}, fitStr.para.kx{l});
                    l = l +1;
                end
                %% 3 - Assigning the errors
                fitStr.para.dkx{i} = para_args{i,2};
                fitStr.para.deb{i} = para_args{i,4};
            end
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to view the parabolic dispersions
        function view_para(app, fitStr)
            % view_parabola(fitStr)
            %   This function plots the estimated parabolic dispersion trail.
            %
            %   REQ. FUNCTIONS: none.
            %
            %   IN:
            %   -   fitStr:          data structure of the fitted ARPES data.
            %
            %   OUT:
            %   -   figure output.
            
            %% Loading in plot properties
            pp = plot_props(app);
            %% Initialising and extracting parameters
            figure(); set(gcf, 'Name', 'State fitting: Approx. Parabolic dispersion');
            %% 1 - Image of the region of interest
            ImData(fitStr.kx_roi(:,:), fitStr.eb_roi(:,:), fitStr.data_roi(:,:)); hold on;
            gca_properties(app, 'kx');
            title(string(fitStr.FileName), 'interpreter', 'none', 'fontsize', 12);
            %% 2 - Plotting the parabolic dispersions
            h = zeros(size(fitStr.para.kx,2), 1);
            for i = 1:size(fitStr.para.kx,2)
                kx = fitStr.para.kx{i}; dkx = fitStr.para.dkx{i};
                eb = fitStr.para.eb{i}; deb = fitStr.para.deb{i};
                % fill([kx + dkx, fliplr(kx - dkx)], [eb, fliplr(eb)], pp.fit.color{i}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                fill([kx, fliplr(kx)], [eb + deb, fliplr(eb - deb)], pp.fit.color{i}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                plot(kx, eb, 'k-', 'color', pp.fit.color{i}, 'linewidth', 1);
                labels{i} = sprintf('Eb(k) %i; m*=%.2f', i, fitStr.para.args{i,5});
                h(i) = plot(NaN,NaN,'-', 'Color', pp.fit.color{i}, 'linewidth', pp.fit.linewidth);
            end
            legend(h, labels, 'Fontsize', 10, 'Color', [1 1 1], 'EdgeColor', [0.15 0.15 0.15], 'Location', 'NorthEast');
        end
        
        % --- Defining a general Lorentzian function
        function int = Lorz(app, A, fwhm, mu, xdat)
            % int = Lorz(A, fwhm, mu, xdat)
            %   General Lorentzian function for spectral response.
            %
            %   IN:
            %   -   A:              amplitude of peak.
            %   -   fwhm:       Lorentzian full-width half-maximum
            %   -   mu:           mean peak position.
            %   -   xdat:         1D row vector of the domain.
            %
            %   OUT:
            %   -   int:            1D row vector of the intensity distribution.
            
            % Ensuring xdat is a column vector
            if size(xdat, 2) >1; xdat = xdat'; end
            int = A ./ (1 + ((xdat - mu) ./ (fwhm/2)).^2);
        end
        
        % --- Defining a general Gaussian function
        function int = Gauss(app, A, fwhm, mu, xdat)
            % int = Gauss(A, fwhm, mu, xdat)
            %   General Gaussian function for spectral response.
            %
            %   IN:
            %   -   A:              amplitude of peak.
            %   -   fwhm:       Gaussian full-width half-maximum
            %   -   mu:           mean peak position.
            %   -   xdat:         1D row vector of the domain.
            %
            %   OUT:
            %   -   int:            1D row vector of the intensity distribution.
            
            % Ensuring xdat is a column vector
            if size(xdat, 2) > 1; xdat = xdat'; end
            int = A .* exp(-1 * log(2) .* ((xdat - mu) ./ (fwhm/2)).^2);
        end
        
        % --- Defining a general Voigt function
        function int = Voigt(app, A, fwhm, mu, xdat, mix)
            % int = Gauss(A, fwhm, mu, xdat, mix)
            %   General Gaussian function for spectral response.
            %
            %   IN:
            %   -   A:              amplitude of peak.
            %   -   fwhm:       Gauss/Lorentz full-width half-maximum ratio
            %   -   mu:           mean peak position.
            %   -   xdat:         1D row vector of the domain.
            %
            %   OUT:
            %   -   int:            1D row vector of the intensity distribution.
            
            % Ensuring xdat is a column vector
            if size(xdat, 2) >1; xdat = xdat'; end
            % Defining the parameters
            if mix == 0
                par = [mu; 1; 1e-4; fwhm];
            elseif mix == 0.5
                par = [mu; 1; mix*fwhm; mix*fwhm];
            elseif mix == 1
                par = [mu; 1; fwhm; 1e-4];
            elseif mix < 0.5
                par = [mu; 1; mix*fwhm; fwhm];
            elseif mix > 0.5
                par = [mu; 1; fwhm; mix*fwhm];
            end
            % Extracting the Voigt function
            int = voigt(xdat, par);
            int = int / max(int(:));
            int = A .* int;
            if size(int, 2) >1; int = int'; end
        end
        
        % --- Function to fit to the EDCs / MDCs of an ARPES ROI
        function fitStr = fit_algorithm(app, fitStr, fit_args, edc_args, mdc_args)
            % fitStr = fit_algorithm(fitStr, fit_args, edc_args, mdc_args)
            %   This function extracts an estimate to the parabolic dispersion
            %   of the region of interest. It allows to give error bars on the
            %   estimated parabolic dispersion, that can further be used during the
            %   fitting to the ROI if required.
            %
            %   REQ. FUNCTIONS:
            %   -   fitStr = edc_fitter(fitStr, fit_args, edc_args)
            %   -   fitStr = mdc_fitter(fitStr, fit_args, mdc_args)
            %
            %   IN:
            %   -   fitStr:         data structure of the ROI from the ARPES data.
            %   -   fit_args:       1x4 cell of fit properties {fitType, fitMDC, fitEDC, voigtMix}.
            %   -   edc_args:       1x6 cell of EDC parameters {kRange, curveType, peak, fwhm, fddFit, null}.
            %   -   mdc_args:       1x6 cell of MDC parameters {ebRange, curveType, peak, fwhm, symmetric, null}.
            %
            %   OUT:
            %   -   fitStr.edc:     new data structure with all the edc fit data.
            %   -   fitStr.mdc:     new data structure with all the mdc fit data.
            
            %% 1 - Run the EDC fitting algorithm if required
            fitStr = app.myData.fits;
            if fit_args{3} == 1
                fitStr.edc = []; 
                fitStr.edc.uitable = app.edc_UITable.Data; 
                fitStr = edc_fitter(app, fitStr, fit_args, edc_args);
            end
            %% 2 - Run the MDC fitting algorithm if required
            if fit_args{2} == 1
                fitStr.mdc = [];
                fitStr.mdc.uitable = app.edc_UITable.Data; 
                fitStr = mdc_fitter(app, fitStr, fit_args, mdc_args);
            end
            app.myData.fits = fitStr;
        end
    

        % --- Function to fit to the EDCs of an ARPES spectrum
        function fitStr = edc_fitter(app, fitStr, fit_args, edc_args)
            % fitStr = edc_fitter(fitStr, fit_args, edc_args)
            %   This is a function that will perform a fitting of a desired
            %   type over the EDC cuts of the 2D ARPES data.
            %
            %   REQ. FUNCTIONS: none.
            %
            %   IN:
            %   -   fitStr:         data structure of the ROI from the ARPES data.
            %   -   fit_args:       1x4 cell of fit properties {fitType, fitMDC, fitEDC, voigtMix}.
            %   -   edc_args:       1x6 cell of EDC parameters {kRange, curveType, peak, fwhm, fddFit, null}.
            %
            %   OUT:
            %   -   fitStr.edc:     new data structure with all the edc fit data.
            % .fit_args
            % .edc_args
            % .nStates
            % .nCuts
            % .fit_indxs
            % .f1
            % .fdd_f1
            % .fn
            % .beta
            % .ci
            % .resnorm
            % .resid
            % .kx_pts
            % .eb_pts
            % .fpara
            % .beta_para
            % .ci_para
            % .resnorm_para
            % .resid_para
            
            disp('Fitting the EDCs...')
            wbar = waitbar(0., 'Performing EDC fits...', 'Name', 'edc_fitter');
            
            %% Initialising and extracting parameters
            fitType     = fit_args{1};
            voigtMix    = fit_args{4};
            curveType   = edc_args{1, 2};
            fddFit      = edc_args{1, 5};
            %% Initialising the FDD convolved fit functions
            fddLorentz = @(A, fwhm, mu, xdat) Lorz(app, A, fwhm, mu, xdat) .* fitStr.fdd.Fermi(xdat);
            fddGauss = @(A, fwhm, mu, xdat) Gauss(app, A, fwhm, mu, xdat) .* fitStr.fdd.Fermi(xdat);
            fddVoigt = @(A, fwhm, mu, xdat) Voigt(app, A, fwhm, mu, xdat, voigtMix) .* fitStr.fdd.Fermi(xdat);
            
            %% 1 - Finding the total number of states to be fitted
            waitbar(0.2, wbar, 'Determining how many states to be fitted...', 'Name', 'edc_fitter');
            nStates = 0;
            for i = 1:size(edc_args, 1)
                if isempty(edc_args{i,1}); break;
                elseif isempty(edc_args{i,2}); break;
                elseif isempty(edc_args{i,3}); break;
                elseif isempty(edc_args{i,4}); break;
                elseif isempty(edc_args{i,5}); break;
                else; nStates = nStates + 1;
                end
            end

            %% 2 - Defining the functional form of the singlet states
            waitbar(0.4, wbar, 'Defining the singlet states...', 'Name', 'edc_fitter');
            if string(curveType) == "lorentzian"
                f1 = @(x, xdat) (Lorz(app, x(1), x(2), x(3), xdat) + x(4));
                fdd_f1 = @(x, xdat) (fddLorentz(x(1), x(2), x(3), xdat) + x(4));
            elseif string(curveType) == "gaussian"
                f1 = @(x, xdat) (Gauss(app, x(1), x(2), x(3), xdat) + x(4));
                fdd_f1 = @(x, xdat) (fddGauss(x(1), x(2), x(3), xdat) + x(4));
            elseif string(curveType) == "voigt"
                f1 = @(x, xdat) (Voigt(app, x(1), x(2), x(3), xdat, voigtMix) + x(4));
                fdd_f1 = @(x, xdat) (fddVoigt(x(1), x(2), x(3), xdat) + x(4));
            end
            
            %% 3 - Defining the full functional form of the curve used to fit to the EDC cut
            waitbar(0.6, wbar, 'Defining the fit function...', 'Name', 'edc_fitter');
            % -- Input form for n fits is: [x1=peak, x2=fwhm, x3=pos, x4=shift, ...]
            if nStates == 1
                if fddFit == 0
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (Lorz(app, x(1), x(2), x(3), xdat) + x(4));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (Gauss(app, x(1), x(2), x(3), xdat) + x(4));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) Voigt(app, x(1), x(2), x(3), xdat, voigtMix) + x(4);
                    end
                elseif fddFit == 1
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (fddLorentz(x(1), x(2), x(3), xdat) + x(4));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (fddGauss(x(1), x(2), x(3), xdat) + x(4));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (fddVoigt(x(1), x(2), x(3), xdat) + x(4));
                    end
                end
            elseif nStates == 2
                if fddFit == 0
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (Lorz(app, x(1), x(2), x(3), xdat) + Lorz(app, x(5), x(6), x(7), xdat) + 0.5*(x(4) + x(8)));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (Gauss(app, x(1), x(2), x(3), xdat) + Gauss(app, x(5), x(6), x(7), xdat) + 0.5*(x(4) + x(8)));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (Voigt(app, x(1), x(2), x(3), xdat, voigtMix) + Voigt(app, x(5), x(6), x(7), xdat, voigtMix) + 0.5*(x(4) + x(8)));
                    end
                elseif fddFit == 1
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (fddLorentz(x(1), x(2), x(3), xdat) + fddLorentz(x(5), x(6), x(7), xdat) + 0.5*(x(4) + x(8)));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (fddGauss(x(1), x(2), x(3), xdat) + fddGauss(x(5), x(6), x(7), xdat) + 0.5*(x(4) + x(8)));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (fddVoigt(x(1), x(2), x(3), xdat) + fddVoigt(x(5), x(6), x(7), xdat) + 0.5*(x(4) + x(8)));
                    end
                end
            elseif nStates == 3
                if fddFit == 0
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (Lorz(app, x(1), x(2), x(3), xdat) + Lorz(app, x(5), x(6), x(7), xdat) + Lorz(app, x(9), x(10), x(11), xdat) + 0.5*(x(4) + x(8) + x(12)));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (Gauss(app, x(1), x(2), x(3), xdat) + Gauss(app, x(5), x(6), x(7), xdat) + Gauss(app, x(9), x(10), x(11), xdat) + 0.5*(x(4) + x(8) + x(12)));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (Voigt(app, x(1), x(2), x(3), xdat, voigtMix) + Voigt(app, x(5), x(6), x(7), xdat, voigtMix) + Voigt(app, x(9), x(10), x(11), xdat, voigtMix) + 0.5*(x(4) + x(8) + x(12)));
                    end
                elseif fddFit == 1
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (fddLorentz(x(1), x(2), x(3), xdat) + fddLorentz(x(5), x(6), x(7), xdat) + fddLorentz(x(9), x(10), x(11), xdat) + 0.5*(x(4) + x(8) + x(12)));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (fddGauss(x(1), x(2), x(3), xdat) + fddGauss(x(5), x(6), x(7), xdat) + fddGauss(x(9), x(10), x(11), xdat) + 0.5*(x(4) + x(8)) + x(12));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (fddVoigt(x(1), x(2), x(3), xdat) + fddVoigt(x(5), x(6), x(7), xdat) + fddVoigt(x(9), x(10), x(11), xdat) + 0.5*(x(4) + x(8) + x(12)));
                    end
                end
            elseif nStates == 4
                if fddFit == 0
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (Lorz(app, x(1), x(2), x(3), xdat) + Lorz(app, x(5), x(6), x(7), xdat) + Lorz(app, x(9), x(10), x(11), xdat) + Lorz(app, x(13), x(14), x(15), xdat) + 0.5*(x(4) + x(8) + x(12) + x(16)));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (Gauss(app, x(1), x(2), x(3), xdat) + Gauss(app, x(5), x(6), x(7), xdat) + Gauss(app, x(9), x(10), x(11), xdat) + Gauss(app, x(13), x(14), x(15), xdat) + 0.5*(x(4) + x(8) + x(12) + x(16)));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (Voigt(app, x(1), x(2), x(3), xdat, voigtMix) + Voigt(app, x(5), x(6), x(7), xdat, voigtMix) + Voigt(app, x(9), x(10), x(11), xdat, voigtMix) + Voigt(app, x(13), x(14), x(15), xdat, voigtMix) + 0.5*(x(4) + x(8) + x(12) + x(16)));
                    end
                elseif fddFit == 1
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (fddLorentz(x(1), x(2), x(3), xdat) + fddLorentz(x(5), x(6), x(7), xdat) + fddLorentz(x(9), x(10), x(11), xdat) + fddLorentz(x(13), x(14), x(15), xdat) + 0.5*(x(4) + x(8) + x(12) + x(16)));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (fddGauss(x(1), x(2), x(3), xdat) + fddGauss(x(5), x(6), x(7), xdat) + fddGauss(x(9), x(10), x(11), xdat) + fddGauss(x(13), x(14), x(15), xdat) + 0.5*(x(4) + x(8)) + x(12) + x(16));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (fddVoigt(x(1), x(2), x(3), xdat) + fddVoigt(x(5), x(6), x(7), xdat) + fddVoigt(x(9), x(10), x(11), xdat) + fddVoigt(x(13), x(14), x(15), xdat) + 0.5*(x(4) + x(8) + x(12) + x(16)));
                    end
                end         
            end
            
            %% 4 - Determination of the initial conditions
            waitbar(0.8, wbar, 'Finding initial conditions...', 'Name', 'edc_fitter');
            % -- Input form for n fits is: [peak, fwhm, pos, peak, fwhm, pos, ...., shift]
            ic = {nStates,4}; lb = {nStates,4}; ub = {nStates,4};
            % -- Filing through the constraints of each state to be fitted
            for n = 1:nStates
                % --- Defining input limits
                pos     = edc_args{n, 1};
                peak    = edc_args{n, 3};
                fwhm    = edc_args{n, 4};
                null    = edc_args{n, 6};
                % --- Extracting the peak limits
                ic{n,1} = mean(peak);
                lb{n,1} = peak(1);
                ub{n,1} = peak(2);                
                % --- Extracting the fwhm limits 
                ic{n,2} = mean(fwhm);
                lb{n,2} = fwhm(1);
                ub{n,2} = fwhm(2);
                % --- Extracting the eb limits without the parabolic trail 
                ic{n,3} = mean(pos(:));
                lb{n,3} = min(pos(:));
                ub{n,3} = max(pos(:));
                % --- Allowing for a vertical shift in the fits
                ic{n,4} = mean(null);
                lb{n,4} = null(1);
                ub{n,4} = null(2);
            end
            
            %% 5 - Extracting the ARPES spectrum about the fitting area
            waitbar(1.0, wbar, 'Cropping around fit limits...', 'Name', 'edc_fitter');
            % - Extracting indices for the cropping
            [~, lbInd] = min((abs(fitStr.kx_roi(1,:) - min(lb{:,3}))));
            [~, ubInd] = min((abs(fitStr.kx_roi(1,:) - max(ub{:,3}))));
            fit_indxs = [lbInd, ubInd];
            % - Cropping around the region to be fitted
            kx_crp      = fitStr.kx_roi(:,lbInd:ubInd);
            eb_crp      = fitStr.eb_roi(:,lbInd:ubInd);
            data_crp    = fitStr.data_roi(:,lbInd:ubInd);
            
            %% 6 - Filing through all EDCs to determine best fits
            nCuts = size(kx_crp,2);
            cutVals = kx_crp(1,:);
            % -- Filing through all EDCs to be fitted
            for i = 1:nCuts
                wbar_txt = sprintf("Executing EDC fit #%i...", i);
                waitbar(i/nCuts, wbar, wbar_txt, 'Name', 'edc_fitter');
                %% - A - Follow this path to perform a least squares fit
                if fitType == "scan - lsqcurvefit()" || fitType == "scan - lsqnonlin()"
                    %% - A.1 - Determine the initial conditions in Eb around the parabola
                    for n = 1:nStates
                        % --- Finding the value of kx
                        kx_pts{n}(i,1) = mean(mean(kx_crp(:,i)));
                        % --- Finding the value of eB based on the parabola
                        [~, minInd] = min((abs(fitStr.para.kx{n} - kx_pts{n}(i,1))));
                        eb_pts_para{n}(i,1) = fitStr.para.eb{n}(minInd);
                        % --- Extracting the eb limits
                        ic{n,3} = eb_pts_para{n}(i,1);
                        lb{n,3} = ic{n,3} - fitStr.para.deb{n};
                        ub{n,3} = ic{n,3} + fitStr.para.deb{n};
                    end
                    %% - A.2 - Transform the initial conditions as vectors
                    ic_vector = []; lb_vector = []; ub_vector = [];
                     for n = 1:nStates
                         ic_vector = horzcat(ic_vector, [ic{n,1}, ic{n,2}, ic{n,3}, ic{n,4}]);
                         lb_vector = horzcat(lb_vector, [lb{n,1}, lb{n,2}, lb{n,3}, lb{n,4}]);
                         ub_vector = horzcat(ub_vector, [ub{n,1}, ub{n,2}, ub{n,3}, ub{n,4}]);
                     end
                    %% - A.3 - Executing the curve fitting algorithm
                    if fitType == "scan - lsqcurvefit()"
                        options = optimoptions('lsqcurvefit','Diagnostics','On', 'MaxFunctionEvaluations', 1e6, 'MaxIterations', 1e6, 'StepTolerance', 1e-13);
                        [beta1{i}, resnorm1{i}, resid1{i}, ~, ~, ~, J1] = lsqcurvefit(fn, ic_vector, eb_crp(:,i), data_crp(:,i), lb_vector, ub_vector, options);
                    elseif fitType == "scan - lsqnonlin()"
                        options = optimoptions('lsqnonlin','Diagnostics','On', 'MaxFunctionEvaluations', 1e6, 'MaxIterations', 1e6, 'StepTolerance', 1e-13);
                        fun = @ (x) fn(x, eb_crp(:,i)) - data_crp(:,i);
                        [beta1{i}, resnorm1{i}, resid1{i}, ~, ~, ~, J1] = lsqnonlin(fun, ic_vector, lb_vector, ub_vector, options);
                    end
                    ci1{i} = nlparci(beta1{i}, resid1{i}, 'jacobian', J1);
                    %% - A.4 - Determination of the fitted value of Eb
                    for n = 1:nStates
                        eb_pts{n}(i,1) = beta1{i}(4*n-1);
                    end
                end
            end
            
            %% 7 - File through all k- and eb-points to fit a parabola
            %% Defining the best fit parabola
            fpara = @(x, xdat) (3.8180 ./ x(3)) * (xdat - x(1)).^2 + x(2);
            %% - Filing through all states to fit independent parabola
            for n = 1:nStates
                wbar_txt = sprintf("Executing Parabolic fit #%i...", n);
                waitbar(i/nStates, wbar, wbar_txt, 'Name', 'edc_fitter');
                % - Defining the initial conditions and bounds of the parabola
                ic_vector = [fitStr.para.args{n,1}, fitStr.para.args{n,3}, fitStr.para.args{n,5}];
                ub_vector = [fitStr.para.args{n,1} - fitStr.para.dkx{n}, fitStr.para.args{n,3} - fitStr.para.deb{n}, fitStr.para.args{n,5} - 0.05];
                lb_vector = [fitStr.para.args{n,1} + fitStr.para.dkx{n}, fitStr.para.args{n,3} + fitStr.para.deb{n}, fitStr.para.args{n,5} + 0.05];
                % -- Executing fit algorithm
                [beta2{n}, resnorm2{n}, resid2{n}, ~, ~, ~, J2] = lsqcurvefit(fpara, ic_vector, kx_pts{n}, eb_pts{n});
                ci2{n} = nlparci(beta2{n}, resid2{n}, 'jacobian', J2);
            end
            
            %% 8 - Assigning all relevant outputs
            % - Inputs from EDC fitting
            fitStr.edc.fit_args     = fit_args;
            fitStr.edc.edc_args     = edc_args;
            fitStr.edc.nStates      = nStates;
            fitStr.edc.nCuts        = nCuts;
            fitStr.edc.cutVals      = cutVals;
            % - Outputs from EDC curve analysis
            fitStr.edc.fit_indxs    = fit_indxs;
            fitStr.edc.f1           = f1;
            fitStr.edc.fdd_f1       = fdd_f1;
            fitStr.edc.fn           = fn;
            fitStr.edc.beta         = beta1;
            fitStr.edc.ci           = ci1;
            fitStr.edc.resnorm      = resnorm1;
            fitStr.edc.resid        = resid1;
            fitStr.edc.kx_pts       = kx_pts;
            fitStr.edc.eb_pts       = eb_pts;
            % - Outputs from parabolic curve analysis
            fitStr.edc.fpara        = fpara;
            fitStr.edc.beta_para    = beta2;
            fitStr.edc.ci_para      = ci2;
            fitStr.edc.resnorm_para = resnorm2;
            fitStr.edc.resid_para   = resid2;
            
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to fit to the MDCs of an ARPES spectrum
        function fitStr = mdc_fitter(app, fitStr, fit_args, mdc_args)
            % fitStr = edc_fitter(fitStr, fit_args, edc_args)
            %   This is a function that will perform a fitting of a desired
            %   type over the MDC cuts of the 2D ARPES data.
            %
            %   REQ. FUNCTIONS: none.
            %
            %   IN:
            %   -   fitStr:         data structure of the ROI from the ARPES data.
            %   -   fit_args:       1x4 cell of fit properties {fitType, fitMDC, fitEDC, voigtMix}.
            %   -   mdc_args:       1x6 cell of EDC parameters {ebRange, curveType, peak, fwhm, symmetric, null}.
            %
            %   OUT:
            %   -   fitStr.mdc:     new data structure with all the mdc fit data.
            % .fit_args
            % .mdc_args
            % .nStates
            % .nCuts
            % .fit_indxs
            % .f1
            % .fdd_f1
            % .fn
            % .beta
            % .ci
            % .resnorm
            % .resid
            % .kx_pts
            % .eb_pts
            % .fpara
            % .beta_para
            % .ci_para
            % .resnorm_para
            % .resid_para
            
            disp('Fitting the MDCs...')
            wbar = waitbar(0., 'Performing MDC fits...', 'Name', 'mdc_fitter');
            
            %% Initialising and extracting parameters
            fitType     = fit_args{1};
            voigtMix    = fit_args{4};
            curveType   = mdc_args{1, 2};
            symFit      = mdc_args{1, 5};
            
            %% 1 - Finding the total number of states to be fitted
            waitbar(0.2, wbar, 'Determining how many states to be fitted...', 'Name', 'mdc_fitter');
            nStates = 0;
            for i = 1:size(mdc_args, 1)
                if isempty(mdc_args{i,1}); break;
                elseif isempty(mdc_args{i,2}); break;
                elseif isempty(mdc_args{i,3}); break;
                elseif isempty(mdc_args{i,4}); break;
                elseif isempty(mdc_args{i,5}); break;
                else; nStates = nStates + 1;
                end
            end
            
            %% 2 - Defining the functional form of the singlet states
            waitbar(0.4, wbar, 'Defining the singlet states...', 'Name', 'mdc_fitter');
            if string(curveType) == "lorentzian"
                f1 = @(x, xdat) (Lorz(app, x(1), x(2), x(3), xdat) + x(4));
            elseif string(curveType) == "gaussian"
                f1 = @(x, xdat) (Gauss(app, x(1), x(2), x(3), xdat) + x(4));
            elseif string(curveType) == "voigt"
                f1 = @(x, xdat) (Voigt(app, x(1), x(2), x(3), xdat, voigtMix) + x(4));
            end
            
            %% 3 - Defining the full functional form of the curve used to fit to the MDC cut
            waitbar(0.6, wbar, 'Defining the fit function...', 'Name', 'mdc_fitter');
            % --- Input form for sym fits is: [x1=peak, x2=fwhm, x3=pos1, x4=pos1, ...., xend=shift]
            % --- Input form for non-sym fits is: [x1=peak, x2=peak, x3=fwhm, x4=pos1, x5=pos1, ...., xend=shift]
            if nStates == 1
                if symFit == 1
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (Lorz(app, x(1), x(2), x(3), xdat) + (Lorz(app, x(1), x(2), x(4), xdat) + x(5)));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (Gauss(app, x(1), x(2), x(3), xdat) + (Gauss(app, x(1), x(2), x(4), xdat) + x(5)));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (Voigt(app, x(1), x(2), x(3), xdat, voigtMix) + (Voigt(app, x(1), x(2), x(4), xdat, voigtMix) + x(5)));
                    end
                elseif symFit == 0
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (Lorz(app, x(1), x(3), x(4), xdat) + (Lorz(app, x(2), x(3), x(5), xdat) + x(6)));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (Gauss(app, x(1), x(3), x(4), xdat) + (Gauss(app, x(2), x(3), x(5), xdat) + x(6)));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (Voigt(app, x(1), x(3), x(4), xdat, voigtMix) + Voigt(app, x(2), x(3), x(5), xdat, voigtMix) + x(6));
                    end
                end
            elseif nStates == 2
                if symFit == 1
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (   Lorz(app, x(1), x(2), x(3), xdat) + Lorz(app, x(1), x(2), x(4), xdat) + ...
                                            Lorz(app, x(6), x(7), x(8), xdat) + Lorz(app, x(6), x(7), x(9), xdat) + ...
                                            0.5*(x(5) + x(10))...
                                            );
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (   Gauss(app, x(1), x(2), x(3), xdat) + Gauss(app, x(1), x(2), x(4), xdat) + ...
                                            Gauss(app, x(6), x(7), x(8), xdat) + Gauss(app, x(6), x(7), x(9), xdat) + ...
                                            0.5*(x(5) + x(10)));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (   Voigt(app, x(1), x(2), x(3), xdat, voigtMix) + Voigt(app, x(1), x(2), x(4), xdat, voigtMix) + ...
                                            Voigt(app, x(6), x(7), x(8), xdat, voigtMix) + Voigt(app, x(6), x(7), x(9), xdat, voigtMix) + ...
                                            0.5*(x(5) + x(10)));
                    end
                elseif symFit == 0
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (   Lorz(app, x(1), x(3), x(4), xdat) + Lorz(app, x(2), x(3), x(5), xdat) + ...
                                            Lorz(app, x(7), x(9), x(10), xdat) + Lorz(app, x(8), x(9), x(11), xdat) + ...
                                            0.5*(x(6) + x(12)));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (   Gauss(app, x(1), x(3), x(4), xdat) + Gauss(app, x(2), x(3), x(5), xdat) + ...
                                            Gauss(app, x(7), x(9), x(10), xdat) + Gauss(app, x(8), x(9), x(11), xdat) + ...
                                            0.5*(x(6) + x(12)));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (   Voigt(app, x(1), x(3), x(4), xdat, voigtMix) + Voigt(app, x(2), x(3), x(5), xdat, voigtMix) + ...
                                            Voigt(app, x(7), x(9), x(10), xdat, voigtMix) + Voigt(app, x(8), x(9), x(11), xdat, voigtMix) + ...
                                            0.5*(x(6) + x(12)));
                    end
                end
            elseif nStates == 3
                if symFit == 1
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (   Lorz(app, x(1), x(2), x(3), xdat) + Lorz(app, x(1), x(2), x(4), xdat) + ...
                                            Lorz(app, x(6), x(7), x(8), xdat) + Lorz(app, x(6), x(7), x(9), xdat) + ...
                                            Lorz(app, x(11), x(12), x(13), xdat) + Lorz(app, x(11), x(12), x(14), xdat) + ...
                                            0.5*(x(5) + x(10) + x(15))...
                                            );
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (   Gauss(app, x(1), x(2), x(3), xdat) + Gauss(app, x(1), x(2), x(4), xdat) + ...
                                            Gauss(app, x(6), x(7), x(8), xdat) + Gauss(app, x(6), x(7), x(9), xdat) + ...
                                            Gauss(app, x(11), x(12), x(13), xdat) + Gauss(app, x(11), x(12), x(14), xdat) + ...
                                            0.5*(x(5) + x(10) + x(15)));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (   Voigt(app, x(1), x(2), x(3), xdat, voigtMix) + Voigt(app, x(1), x(2), x(4), xdat, voigtMix) + ...
                                            Voigt(app, x(6), x(7), x(8), xdat, voigtMix) + Voigt(app, x(6), x(7), x(9), xdat, voigtMix) + ...
                                            Voigt(app, x(11), x(12), x(13), xdat, voigtMix) + Voigt(app, x(11), x(12), x(14), xdat, voigtMix) + ...
                                            0.5*(x(5) + x(10) + x(15)));
                    end
                elseif symFit == 0
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (   Lorz(app, x(1), x(3), x(4), xdat) + Lorz(app, x(2), x(3), x(5), xdat) + ...
                                            Lorz(app, x(7), x(9), x(10), xdat) + Lorz(app, x(8), x(9), x(11), xdat) + ...
                                            Lorz(app, x(13), x(15), x(16), xdat) + Lorz(app, x(14), x(15), x(17), xdat) + ...
                                            0.5*(x(6) + x(12) + x(18)));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (   Gauss(app, x(1), x(3), x(4), xdat) + Gauss(app, x(2), x(3), x(5), xdat) + ...
                                            Gauss(app, x(7), x(9), x(10), xdat) + Gauss(app, x(8), x(9), x(11), xdat) + ...
                                            Gauss(app, x(13), x(15), x(16), xdat) + Gauss(app, x(14), x(15), x(17), xdat) + ...
                                            0.5*(x(6) + x(12) + x(18)));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (   Voigt(app, x(1), x(3), x(4), xdat, voigtMix) + Voigt(app, x(2), x(3), x(5), xdat, voigtMix) + ...
                                            Voigt(app, x(7), x(9), x(10), xdat, voigtMix) + Voigt(app, x(8), x(9), x(11), xdat, voigtMix) + ...
                                            Voigt(app, x(13), x(15), x(16), xdat, voigtMix) + Voigt(app, x(14), x(15), x(17), xdat, voigtMix) + ...
                                            0.5*(x(6) + x(12) + x(18)));
                    end
                end
            elseif nStates == 4
                if symFit == 1
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (   Lorz(app, x(1), x(2), x(3), xdat) + Lorz(app, x(1), x(2), x(4), xdat) + ...
                                            Lorz(app, x(6), x(7), x(8), xdat) + Lorz(app, x(6), x(7), x(9), xdat) + ...
                                            Lorz(app, x(11), x(12), x(13), xdat) + Lorz(app, x(11), x(12), x(14), xdat) + ...
                                            Lorz(app, x(16), x(17), x(18), xdat) + Lorz(app, x(16), x(17), x(19), xdat) + ...
                                            0.5*(x(5) + x(10) + x(15) + x(20))...
                                            );
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (   Gauss(app, x(1), x(2), x(3), xdat) + Gauss(app, x(1), x(2), x(4), xdat) + ...
                                            Gauss(app, x(6), x(7), x(8), xdat) + Gauss(app, x(6), x(7), x(9), xdat) + ...
                                            Gauss(app, x(11), x(12), x(13), xdat) + Gauss(app, x(11), x(12), x(14), xdat) + ...
                                            Gauss(app, x(16), x(17), x(18), xdat) + Gauss(app, x(16), x(17), x(19), xdat) + ...
                                            0.5*(x(5) + x(10) + x(15) + x(20)));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (   Voigt(app, x(1), x(2), x(3), xdat, voigtMix) + Voigt(app, x(1), x(2), x(4), xdat, voigtMix) + ...
                                            Voigt(app, x(6), x(7), x(8), xdat, voigtMix) + Voigt(app, x(6), x(7), x(9), xdat, voigtMix) + ...
                                            Voigt(app, x(11), x(12), x(13), xdat, voigtMix) + Voigt(app, x(11), x(12), x(14), xdat, voigtMix) + ...
                                            Voigt(app, x(16), x(17), x(18), xdat, voigtMix) + Voigt(app, x(16), x(17), x(19), xdat, voigtMix) + ...
                                            0.5*(x(5) + x(10) + x(15) + x(20)));
                    end
                elseif symFit == 0
                    if string(curveType) == "lorentzian"
                        fn = @(x, xdat) (   Lorz(app, x(1), x(3), x(4), xdat) + Lorz(app, x(2), x(3), x(5), xdat) + ...
                                            Lorz(app, x(7), x(9), x(10), xdat) + Lorz(app, x(8), x(9), x(11), xdat) + ...
                                            Lorz(app, x(13), x(15), x(16), xdat) + Lorz(app, x(14), x(15), x(17), xdat) + ...
                                            Lorz(app, x(19), x(21), x(22), xdat) + Lorz(app, x(20), x(21), x(23), xdat) + ...
                                            0.5*(x(6) + x(12) + x(18) + x(24)));
                    elseif string(curveType) == "gaussian"
                        fn = @(x, xdat) (   Gauss(app, x(1), x(3), x(4), xdat) + Gauss(app, x(2), x(3), x(5), xdat) + ...
                                            Gauss(app, x(7), x(9), x(10), xdat) + Gauss(app, x(8), x(9), x(11), xdat) + ...
                                            Gauss(app, x(13), x(15), x(16), xdat) + Gauss(app, x(14), x(15), x(17), xdat) + ...
                                            Gauss(app, x(19), x(21), x(22), xdat) + Gauss(app, x(20), x(21), x(23), xdat) + ...
                                            0.5*(x(6) + x(12) + x(18) + x(24)));
                    elseif string(curveType) == "voigt"
                        fn = @(x, xdat) (   Voigt(app, x(1), x(3), x(4), xdat, voigtMix) + Voigt(app, x(2), x(3), x(5), xdat, voigtMix) + ...
                                            Voigt(app, x(7), x(9), x(10), xdat, voigtMix) + Voigt(app, x(8), x(9), x(11), xdat, voigtMix) + ...
                                            Voigt(app, x(13), x(15), x(16), xdat, voigtMix) + Voigt(app, x(14), x(15), x(17), xdat, voigtMix) + ...
                                            Voigt(app, x(19), x(21), x(22), xdat, voigtMix) + Voigt(app, x(20), x(21), x(23), xdat, voigtMix) + ...
                                            0.5*(x(6) + x(12) + x(18) + x(24)));
                    end
                end
            end
            
            %% 4 - Determination of the initial conditions
            waitbar(0.8, wbar, 'Finding initial conditions...', 'Name', 'mdc_fitter');
            % --- Input form for sym fits is: [x1=peak, x2=fwhm, x3=pos1, x4=pos1, x5=shift, ...]
            % --- Input form for non-sym fits is: [x1=peak, x2=peak, x3=fwhm, x4=pos1, x5=pos1, x6=shift, ...]
            if symFit == 1
                ic = {nStates,5}; lb = {nStates,5}; ub = {nStates,5};
                % -- Filing through the constraints of each state to be fitted
                for n = 1:nStates
                    % --- Defining input limits
                    pos     = mdc_args{n, 1};
                    peak    = mdc_args{n, 3};
                    fwhm    = mdc_args{n, 4};
                    null    = mdc_args{n, 6};
                    % --- Extracting the peak limits
                    ic{n,1} = mean(peak);
                    lb{n,1} = peak(1);
                    ub{n,1} = peak(2);                
                    % --- Extracting the fwhm limits 
                    ic{n,2} = mean(fwhm);
                    lb{n,2} = fwhm(1);
                    ub{n,2} = fwhm(2);
                    % --- Extracting the kx limits without the parabolic trail 
                    ic{n,3} = mean(pos(:));
                    lb{n,3} = min(pos(:));
                    ub{n,3} = max(pos(:));
                    ic{n,4} = mean(pos(:));
                    lb{n,4} = min(pos(:));
                    ub{n,4} = max(pos(:));
                    % --- Allowing for a vertical shift in the fits
                    ic{n,5} = mean(null);
                    lb{n,5} = null(1);
                    ub{n,5} = null(2);
                end
            elseif symFit == 0
                ic = {nStates,6}; lb = {nStates,6}; ub = {nStates,6};
                % -- Filing through the constraints of each state to be fitted
                for n = 1:nStates
                    % --- Defining input limits
                    pos     = mdc_args{n, 1};
                    peak    = mdc_args{n, 3};
                    fwhm    = mdc_args{n, 4};
                    null    = mdc_args{n, 6};
                    % --- Extracting the peak limits
                    ic{n,1} = mean(peak);
                    lb{n,1} = peak(1);
                    ub{n,1} = peak(2);
                    ic{n,2} = mean(peak);
                    lb{n,2} = peak(1);
                    ub{n,2} = peak(2);     
                    % --- Extracting the fwhm limits 
                    ic{n,3} = mean(fwhm);
                    lb{n,3} = fwhm(1);
                    ub{n,3} = fwhm(2);
                    % --- Extracting the kx limits without the parabolic trail 
                    ic{n,4} = mean(pos(:));
                    lb{n,4} = min(pos(:));
                    ub{n,4} = max(pos(:));
                    ic{n,5} = mean(pos(:));
                    lb{n,5} = min(pos(:));
                    ub{n,5} = max(pos(:));
                    % --- Allowing for a vertical shift in the fits
                    ic{n,6} = mean(null);
                    lb{n,6} = null(1);
                    ub{n,6} = null(2);
                end
            end
            
            %% 5 - Extracting the ARPES spectrum about the fitting area
            waitbar(1.0, wbar, 'Cropping around fit limits...', 'Name', 'edc_fitter');
            % - Extracting indices for the cropping
            [~, lbInd] = min((abs(fitStr.eb_roi(:,1) - min(lb{:,3}))));
            [~, ubInd] = min((abs(fitStr.eb_roi(:,1) - max(ub{:,3}))));
            fit_indxs = [lbInd, ubInd];
            % - Cropping around the region to be fitted
            kx_crp      = fitStr.kx_roi(lbInd:ubInd,:);
            eb_crp      = fitStr.eb_roi(lbInd:ubInd,:);
            data_crp    = fitStr.data_roi(lbInd:ubInd,:);
            
            %% 6 - File through all MDCs and find the best fits=
            nCuts = size(eb_crp,1);
            cutVals = eb_crp(:,1);
            for i = 1:nCuts
                wbar_txt = sprintf("Executing MDC fit #%i...", i);
                waitbar(i/nCuts, wbar, wbar_txt, 'Name', 'mdc_fitter');
                
                %% - A - Follow this path to perform a least squares fit
                if fitType == "scan - lsqcurvefit()" || fitType == "scan - lsqnonlin()"
                    %% - A.1 - Determine the initial conditions in Kx around the parabola
                    for n = 1:nStates            
                        % --- Finding the value of eb
                        eb_pts(i,n) = mean(mean(eb_crp(i,:)));
                        % --- Finding the value of kx based on the parabola
                        [~, minInd] = min((abs(fitStr.para.eb{n} - eb_pts(i,n))));
                        kx_pts_para(i,n) = abs(fitStr.para.kx{n}(minInd));
                        % --- Extracting the kx limits
                        ic{n,3} = 1 * kx_pts_para(i,n);
                        lb{n,3} = ic{n,3} - fitStr.para.dkx{n};
                        ub{n,3} = ic{n,3} + fitStr.para.dkx{n};
                        ic{n,4} = - 1 * kx_pts_para(i,n);
                        lb{n,4} = ic{n,4} - fitStr.para.dkx{n};
                        ub{n,4} = ic{n,4} + fitStr.para.dkx{n};
                        % error estimate for the base of the parabola
                        cuspErr = fitStr.para.deb{n};
                        % ---- symmetric curves peak intensities if below predicted value of Eb
                        if symFit == 1
                            if fitStr.para.args{n,5} > 0
                                if eb_pts(i,n) < (min(fitStr.para.eb{n}) - cuspErr)
                                    ic{n,1} = 0.5*mdc_args{n,3}(1); lb{n,1} = 0; ub{n,1} = 2*mdc_args{n,3}(1);
                                else
                                    ic{n,1} = mean(mdc_args{n,3}); 
                                    lb{n,1} = mdc_args{n,3}(1);
                                    ub{n,1} = mdc_args{n,3}(2);
                                end
                            else
                                if eb_pts(i,n) > (max(fitStr.para.eb{n}) + cuspErr)
                                    ic{n,1} = 0.5*mdc_args{n,3}(1); lb{n,1} = 0; ub{n,1} = 2*mdc_args{n,3}(1); 
                                else
                                    ic{n,1} = mean(mdc_args{n,3}); 
                                    lb{n,1} = mdc_args{n,3}(1);
                                    ub{n,1} = mdc_args{n,3}(2);
                                end
                            end
                        % ---- non-symmetric curves peak intensities if below predicted value of Eb
                        elseif symFit == 0
                            if fitStr.para.args{n,5} > 0
                                if eb_pts(i,n) < (min(fitStr.para.eb{n}) - cuspErr)
                                    ic{n,1} = 0.5*mdc_args{n,3}(1); lb{n,1} = 0; ub{n,1} = 2*mdc_args{n,3}(1); 
                                    ic{n,2} = 0.5*mdc_args{n,3}(1); lb{n,2} = 0; ub{n,2} = 2*mdc_args{n,3}(1);
                                else
                                    ic{n,1} = mean(mdc_args{n,3}); 
                                    lb{n,1} = mdc_args{n,3}(1);
                                    ub{n,1} = mdc_args{n,3}(2);
                                    ic{n,2} = mean(mdc_args{n,3}); 
                                    lb{n,2} = mdc_args{n,3}(1);
                                    ub{n,2} = mdc_args{n,3}(2);
                                end
                            else
                                if eb_pts(i,n) > (max(fitStr.para.eb{n}) + cuspErr)
                                    ic{n,1} = 0.5*mdc_args{n,3}(1); lb{n,1} = 0; ub{n,1} = 2*mdc_args{n,3}(1); 
                                    ic{n,2} = 0.5*mdc_args{n,3}(1); lb{n,2} = 0; ub{n,2} = 2*mdc_args{n,3}(1);
                                else
                                    ic{n,1} = mean(mdc_args{n,3}); 
                                    lb{n,1} = mdc_args{n,3}(1);
                                    ub{n,1} = mdc_args{n,3}(2);
                                    ic{n,2} = mean(mdc_args{n,3}); 
                                    lb{n,2} = mdc_args{n,3}(1);
                                    ub{n,2} = mdc_args{n,3}(2);
                                end
                            end
                            
                        end
                    end
                    %% - A.2 - Transform the initial conditions as vectors
                    ic_vector = []; lb_vector = []; ub_vector = [];
                    for n = 1:nStates
                        % --- symmetric curves kx values
                        if symFit == 1
                            ic_vector = horzcat(ic_vector, [ic{n,1}, ic{n,2}, ic{n,3}, ic{n,4}, ic{n,5}]);
                            lb_vector = horzcat(lb_vector, [lb{n,1}, lb{n,2}, lb{n,3}, lb{n,4}, lb{n,5}]);
                            ub_vector = horzcat(ub_vector, [ub{n,1}, ub{n,2}, ub{n,3}, ub{n,4}, ub{n,5}]);
                        % -- non symmetric curves kx values
                        elseif symFit == 0
                            ic_vector = horzcat(ic_vector, [ic{n,1}, ic{n,2}, ic{n,3}, ic{n,4}, ic{n,5}, ic{n,6}]);
                            lb_vector = horzcat(lb_vector, [lb{n,1}, lb{n,2}, lb{n,3}, lb{n,4}, lb{n,5}, lb{n,6}]);
                            ub_vector = horzcat(ub_vector, [ub{n,1}, ub{n,2}, ub{n,3}, ub{n,4}, ub{n,5}, ub{n,6}]);
                        end
                    end
                    %% - A.3 - Executing the curve fitting algorithm
                    if fitType == "scan - lsqcurvefit()"
                        options = optimoptions('lsqcurvefit','Diagnostics','On', 'MaxFunctionEvaluations', 1e6, 'MaxIterations', 1e6, 'StepTolerance', 1e-13);
                        [beta1{i}, resnorm1{i}, resid1{i}, ~, ~, ~, J1] = lsqcurvefit(fn, ic_vector, kx_crp(i,:), data_crp(i,:)', lb_vector, ub_vector, options);
                    elseif fitType == "scan - lsqnonlin()"
                        options = optimoptions('lsqnonlin','Diagnostics','On', 'MaxFunctionEvaluations', 1e6, 'MaxIterations', 1e6, 'StepTolerance', 1e-13);
                        fun = @ (x) fn(x, kx_crp(i,:)) - data_crp(i,:);
                        [beta1{i}, resnorm1{i}, resid1{i}, ~, ~, ~, J1] = lsqnonlin(fun, ic_vector, lb_vector, ub_vector, options);
                    end
                    ci1{i} = nlparci(beta1{i}, resid1{i}, 'jacobian', J1);
            
                    %% - A.4 - Determination of the fitted value of Kx
                    for n = 1:nStates
                        % --- symmetric curves kx values
                        if symFit == 1
                            kx_pts_lhs(i,n) = beta1{i}(5*n-2);
                            kx_pts_rhs(i,n)   = beta1{i}(5*n-1);
                        % -- non symmetric curves kx values
                        elseif symFit == 0
                            kx_pts_lhs(i,n) = beta1{i}(6*n-2);
                            kx_pts_rhs(i,n)   = beta1{i}(6*n-1);
                        end
                    end
                end
            end
            
            %% 7 - Making the k- and eb-points consistent
            eb_pts_new = {}; kx_pts_new = {};
            % Deleting any points where the eb-points are below threshold
            for n = 1:nStates
                l = 1;
                for i = 1:size(eb_pts, 1)
                    if fitStr.para.args{n,5} > 0 && round(eb_pts(i,n), 4) >= (min(fitStr.para.eb{n}) - 2*fitStr.para.deb{n})
                        eb_pts_new{n}(l,1) = eb_pts(i,n);
                        kx_pts_new{n}(l,1) = kx_pts_lhs(i,n);
                        kx_pts_new{n}(l,2) = kx_pts_rhs(i,n);
                        l = l+1;
                    elseif fitStr.para.args{n,5} < 0 && round(eb_pts(i,n), 4) <= (max(fitStr.para.eb{n}) + 2*fitStr.para.deb{n})
                        eb_pts_new{n}(l,1) = eb_pts(i,n);
                        kx_pts_new{n}(l,1) = kx_pts_lhs(i,n);
                        kx_pts_new{n}(l,2) = kx_pts_rhs(i,n);
                        l = l+1;
                    end
                end
            end
            % Making the k- and eb-points consistent now
            eb_pts = []; kx_pts = [];
            for n = 1:nStates
                eb_pts{n} = [eb_pts_new{n}; eb_pts_new{n}];
                kx_pts{n} = [kx_pts_new{n}(:,1); kx_pts_new{n}(:,2)];
            end
            
            %% 8 - File through all k- and eb-points to fit a parabola
            %% Defining the best fit parabola
            fpara = @(x, xdat) (3.8180 ./ x(3)) * (xdat - x(1)).^2 + x(2);
            %% - Filing through all states to fit independent parabola
            for n = 1:nStates
                wbar_txt = sprintf("Executing Parabolic fit #%i...", n);
                waitbar(i/nStates, wbar, wbar_txt, 'Name', 'edc_fitter');
                % - Defining the initial conditions and bounds of the parabola
                ic_vector = [fitStr.para.args{n,1}, fitStr.para.args{n,3}, fitStr.para.args{n,5}];
                ub_vector = [fitStr.para.args{n,1} - fitStr.para.dkx{n}, fitStr.para.args{n,3} - fitStr.para.deb{n}, fitStr.para.args{n,5} - 0.05];
                lb_vector = [fitStr.para.args{n,1} + fitStr.para.dkx{n}, fitStr.para.args{n,3} + fitStr.para.deb{n}, fitStr.para.args{n,5} + 0.05];
                % -- Executing fit algorithm
                [beta2{n}, resnorm2{n}, resid2{n}, ~, ~, ~, J2] = lsqcurvefit(fpara, ic_vector, kx_pts{n}, eb_pts{n});
                ci2{n} = nlparci(beta2{n}, resid2{n}, 'jacobian', J2);
            end
            
            %% 8 - Assigning all relevant outputs
            % - Inputs from EDC fitting
            fitStr.mdc.fit_args     = fit_args;
            fitStr.mdc.mdc_args     = mdc_args;
            fitStr.mdc.nStates      = nStates;
            fitStr.mdc.nCuts        = nCuts;
            fitStr.mdc.cutVals      = cutVals;
            % - Outputs from EDC curve analysis
            fitStr.mdc.fit_indxs    = fit_indxs;
            fitStr.mdc.f1           = f1;
            fitStr.mdc.fn           = fn;
            fitStr.mdc.beta         = beta1;
            fitStr.mdc.ci           = ci1;
            fitStr.mdc.resnorm      = resnorm1;
            fitStr.mdc.resid        = resid1;
            fitStr.mdc.kx_pts       = kx_pts;
            fitStr.mdc.eb_pts       = eb_pts;
            % - Outputs from parabolic curve analysis
            fitStr.mdc.fpara        = fpara;
            fitStr.mdc.beta_para    = beta2;
            fitStr.mdc.ci_para      = ci2;
            fitStr.mdc.resnorm_para = resnorm2;
            fitStr.mdc.resid_para   = resid2;
            
            %% Close wait-bar
            close(wbar);
            
            
        end
        
        % --- Function to browse through all the EDC fit parameters
        function view_edc_table(app, edcStr)
            %% 1 - EXTRACTING ALL EDC FIT PARAMETERS
            for i = 1:size(edcStr.beta, 2)
                for n = 1:edcStr.nStates
                    peak(i,n)       = round(edcStr.beta{i}(4*n-3), 4);
                    dpeak(i,n)      = abs(round(diff(edcStr.ci{i}(4*n-3,:)), 4));
                    fwhm(i,n)       = round(edcStr.beta{i}(4*n-2), 4);
                    dfwhm(i,n)      = abs(round(diff(edcStr.ci{i}(4*n-2,:)), 4));
                    pos(i,n)        = round(edcStr.beta{i}(4*n-1), 4);
                    dpos(i,n)       = abs(round(diff(edcStr.ci{i}(4*n-1,:)), 4));
                    res(i,n)        = round(edcStr.resnorm{i}, 4);
                end
            end
            %% 2 - APPENDING DATA INTO A CELL ARRAY
            tabData{1} = peak;      tabTitle{1} = 'Peak (A)';
            tabData{2} = dpeak;     tabTitle{2} = 'Peak Error (dA)';
            tabData{3} = fwhm;      tabTitle{3} = 'FWHM (fwhm)';
            tabData{4} = dfwhm;     tabTitle{4} = 'FWHM Error (dfwhm)';
            tabData{5} = pos;       tabTitle{5} = 'Position (mu)';
            tabData{6} = dpos;      tabTitle{6} = 'Position Error (dmu)';
            tabData{7} = res;       tabTitle{7} = 'RES-NORM';
            %% 3 - PLOTTING UITABLE
            % Defining table sizes
            nCols   = length(tabData);
            sizeTab = [200, 200];
            % Plotting UI table
            fig = figure; set(fig, 'Position', [100 100 nCols*sizeTab(1) 1.2*sizeTab(1)]);
            for i = 1:nCols
                % Adding title
                tab_title(i) = uicontrol('Style', 'text', 'String', tabTitle{i});
                tab_title(i).Position = [1+200*(i-1) 210 sizeTab(1) 15];
                % Adding UI data table
                tab(i) = uitable(fig);
                tab(i).Data = tabData{i};
                tab(i).Position = [1+200*(i-1) 5 sizeTab];
            end
        end
        
        % --- Function to browse through all the MDC fit parameters
        function view_mdc_table(app, mdcStr)
            %% 1 - EXTRACTING ALL EDC FIT PARAMETERS
            for i = 1:size(mdcStr.beta, 2)
                for n = 1:mdcStr.nStates
                    if mdcStr.mdc_args{n,5} == 1
                        peak(i,n)   = round(mdcStr.beta{i}(5*n-4), 4);
                        dpeak(i,n)  = abs(round(diff(mdcStr.ci{i}(5*n-4,:)), 4));
                        fwhm(i,n)   = round(mdcStr.beta{i}(5*n-3), 4);
                        dfwhm(i,n)  = abs(round(diff(mdcStr.ci{i}(5*n-3,:)), 4));
                        pos1(i,n)   = round(mdcStr.beta{i}(5*n-2), 4);
                        dpos1(i,n)  = abs(round(diff(mdcStr.ci{i}(5*n-2,:)), 4));
                        pos2(i,n)   = round(mdcStr.beta{i}(5*n-1), 4);
                        dpos2(i,n)  = abs(round(diff(mdcStr.ci{i}(5*n-1,:)), 4));
                        res(i,n)    = round(mdcStr.resnorm{i}, 4);
                    elseif mdcStr.mdc_args{n,5} == 0
                        peak(i,n)   = round(mdcStr.beta{i}(6*n-5), 4);
                        dpeak(i,n)  = abs(round(diff(mdcStr.ci{i}(6*n-5,:)), 4));
                        fwhm(i,n)   = round(mdcStr.beta{i}(6*n-3), 4);
                        dfwhm(i,n)  = abs(round(diff(mdcStr.ci{i}(6*n-3,:)), 4));
                        pos1(i,n)   = round(mdcStr.beta{i}(6*n-2), 4);
                        dpos1(i,n)  = abs(round(diff(mdcStr.ci{i}(6*n-2,:)), 4));
                        pos2(i,n)   = round(mdcStr.beta{i}(6*n-1), 4);
                        dpos2(i,n)  = abs(round(diff(mdcStr.ci{i}(6*n-1,:)), 4));
                        res(i,n)    = round(mdcStr.resnorm{i}, 4);
                    end
                end
            end
            %% 2 - APPENDING DATA INTO A CELL ARRAY
            tabData{1} = peak;      tabTitle{1} = 'Peak (A)';
            tabData{2} = dpeak;     tabTitle{2} = 'Peak Error (dA)';
            tabData{3} = fwhm;      tabTitle{3} = 'FWHM (fwhm)';
            tabData{4} = dfwhm;     tabTitle{4} = 'FWHM Error (dfwhm)';
            tabData{5} = pos1;      tabTitle{5} = 'Position 1 (mu)';
            tabData{6} = dpos1;     tabTitle{6} = 'Position Error 1 (dmu)';
            tabData{7} = pos1;      tabTitle{7} = 'Position 2 (mu)';
            tabData{8} = dpos1;     tabTitle{8} = 'Position Error 2 (dmu)';
            tabData{9} = res;       tabTitle{9} = 'RES-NORM';
            %% 3 - PLOTTING UITABLE
            % Defining table sizes
            nCols   = length(tabData);
            sizeTab = [200, 200];
            % Plotting UI table
            fig = figure; set(fig, 'Position', [100 100 nCols*sizeTab(1) 1.2*sizeTab(1)]);
            for i = 1:nCols
                % Adding title
                tab_title(i) = uicontrol('Style', 'text', 'String', tabTitle{i});
                tab_title(i).Position = [1+200*(i-1) 210 sizeTab(1) 15];
                % Adding UI data table
                tab(i) = uitable(fig);
                tab(i).Data = tabData{i};
                tab(i).Position = [1+200*(i-1) 5 sizeTab];
            end
        end
        
        % --- Function to view a summary of both EDC and MDC plots
        function fig = view_summary(app, fitStr)
            %% Loading in plot properties
            pp = plot_props(app);
            
            %% 1 - Plotting the ARPES image
            fig = figure(); set(gcf, 'Name', 'State fitting: fitting summary');
            hold on;
            ImData(fitStr.kx_roi, fitStr.eb_roi, fitStr.data_roi);
            gca_properties(app, 'kx'); colorbar('off');
            axis([min(fitStr.kx_roi(:)), max(fitStr.kx_roi(:)),min(fitStr.eb_roi(:)), max(fitStr.eb_roi(:))]);
            %% 2 - Plotting the best fit EDC / MDC data points
            if ~isempty(fitStr.mdc)
                for n = 1:fitStr.mdc.nStates
                    plot(fitStr.mdc.kx_pts{n}, fitStr.mdc.eb_pts{n}, 'kx',...
                        'color', pp.fit.color{n}, 'linewidth', 1, 'marker', pp.fit.mstyle{n});
                end
            end
            if ~isempty(fitStr.edc)
                for n = 1:fitStr.edc.nStates
                    plot(fitStr.edc.kx_pts{n}, fitStr.edc.eb_pts{n}, 'kx',...
                        'color', pp.fit.color{n}, 'linewidth', 1, 'marker', pp.fit.mstyle{n});
                end                
            end
            %% 3 - Plotting the best fit EDC / MDC parabola
            if ~isempty(fitStr.edc) && ~isempty(fitStr.mdc) && fitStr.edc.nStates == fitStr.mdc.nStates
                for i = 1:fitStr.edc.nStates
                    X = linspace(-2, 2, 1e3);
                    Yedc = fitStr.edc.fpara(fitStr.edc.beta_para{n}, X);
                    Ymdc = fitStr.mdc.fpara(fitStr.mdc.beta_para{n}, X);
                    fill([X, fliplr(X)], [Yedc, fliplr(Ymdc)], pp.fit.color{i}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                end
            % - Plotting the parabolic trail if both EDC + MDC fits are not available
            else
                if isfield(fitStr, 'para')
                    for i = 1:size(fitStr.para.kx,2)
                        kx = fitStr.para.kx{i}; dkx = fitStr.para.dkx{i};
                        eb = fitStr.para.eb{i}; deb = fitStr.para.deb{i};
                        fill([kx, fliplr(kx)], [eb + deb, fliplr(eb - deb)], pp.fit.color{i}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                    end
                end
            end
            
        end
        
        % --- Function to view series all the EDC fits
        function view_edc_series(app, fitStr)
            %% Loading in plot properties
            pp = plot_props(app);
            %% Initialisation
            filter = {'*.*'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the EDC series...', fitStr.FileName);
            fName = string(string(save_filepath) + string(save_filename));
            % If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% Initialising the figure
            fig = figure(); set(fig, 'Position', [5,5, 800, 600]);
            
            %% 2 - Saving all EDC fits
            for i = 1:fitStr.edc.nCuts
                % - Reset the figure
                cla reset; clf reset; delete(findall(gcf,'type','annotation')); hold on;
                % - Initialising variables
                ifName = fName + "_edc" + string(i);
                cutVal = fitStr.edc.cutVals(i);
                % 2.1 - Plotting the ARPES data
                subplot(1,3,1:2); hold on;
                ImData(fitStr.kx_roi, fitStr.eb_roi, fitStr.data_roi);
                gca_properties(app, 'kx'); colorbar('off');
                axis([min(fitStr.kx_roi(:)), max(fitStr.kx_roi(:)),min(fitStr.eb_roi(:)), max(fitStr.eb_roi(:))]);
                 %% 2.2 - Plotting the parabolic trail
                if isfield(fitStr, 'para')
                    for n = 1:size(fitStr.para.kx,2)
                        kx = fitStr.para.kx{n}; dkx = fitStr.para.dkx{n};
                        eb = fitStr.para.eb{n}; deb = fitStr.para.deb{n};
                        fill([kx, fliplr(kx)], [eb + deb, fliplr(eb - deb)], pp.fit.color{n}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                    end
                end
                %% 2.3 - Plotting the best fit data points and parabola
                for n = 1:fitStr.edc.nStates
                    % - Plotting the best fit data-points
                    plot(fitStr.edc.kx_pts{n}, fitStr.edc.eb_pts{n}, 'kx',...
                        'color', pp.fit.color{n}, 'linewidth', 1, 'marker', pp.fit.mstyle{n});
                    % - Plotting the best fit parabola
                    X = linspace(-2, 2, 1e3);
                    Y = fitStr.edc.fpara(fitStr.edc.beta_para{n}, X);
                    plot(X, Y, 'k-', 'color', pp.fit.color{n}, 'linewidth', 1);
                end 
                %% 2.4 - Plotting an outline over the integrated sliced region
                line([cutVal cutVal], [-1e5, 1e5], 'Color', pp.edc.color, 'LineWidth', 2, 'Linestyle', '-');
                
                subplot(1,3,3); hold on;
                %% A - Plotting the parabolic estimate line
                if isfield(fitStr, 'para')
                    for n = 1:size(fitStr.para.kx,2)
                        deb = fitStr.para.deb{n};
                        [~, minInd] = min((abs(fitStr.para.kx{n} - cutVal)));
                        parVal = fitStr.para.eb{n}(minInd);
                        paraX = [-1e5, 1e5, 1e5, -1e5];
                        paraY = [parVal + deb, parVal + deb, parVal - deb, parVal - deb];
                        fill(paraX, paraY, pp.fit.color{n}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                    end
                end
                %% B - Plotting the best fit curves
                xx = linspace(-8, 8, 5e3)';
                fit_params = fitStr.edc.beta{i};
                % - Plotting the singlet fits
                for n = 1:fitStr.edc.nStates
                    args        = [fit_params(4*n-3), fit_params(4*n-2), fit_params(4*n-1), fit_params(4*n)];
                    yy_fdd_f1   = fitStr.edc.fdd_f1(args, xx);
                    yy_f1       = fitStr.edc.f1(args, xx);
                    % - Plotting the fit
                    if fitStr.edc.edc_args{1,5} == 1
                        fill(yy_f1, xx, pp.fit.color{n}, 'facealpha', 0.5*pp.fit.alpha, 'EdgeColor', 'none');
                        fill(yy_fdd_f1, xx, pp.fit.color{n}, 'facealpha', pp.fit.alpha, 'EdgeColor', 'none');
                    else
                        fill(yy_f1, xx, pp.fit.color{n}, 'facealpha', pp.fit.alpha, 'EdgeColor', 'none');
                    end
                end
                %% C - Extracting and plotting cut
                [XCut, DCut] = Cut(fitStr.kx_roi, fitStr.eb_roi, fitStr.data_roi, 'edc', cutVal + [-0.0001, 0.0001]);
                plot(DCut, XCut, 'k.-', 'color', pp.edc.color, 'LineWidth', pp.edc.linewidth,...
                    'marker', pp.edc.markerstyle, 'markersize', pp.edc.markersize);
                %% D - Defining the axes properties
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 12;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'out';
                % Ruler properties
                ax.XAxisLocation = 'bottom';            % 'bottom' | 'top' | 'origin'
                ax.YAxisLocation = 'right';             % 'left' | 'right' | 'origin'
                % Axis labels and limits
                xlabel('$$ \bf  Intensity $$', 'Interpreter', 'latex');
                ylabel('$$ \bf  E_{B} (eV) $$', 'Interpreter', 'latex');
                %% E - Plotting the x- and y-axes
                xl = [0, max(DCut(:))]; yl = [min(fitStr.eb_roi(:)), max(fitStr.eb_roi(:))];
                axis([xl(1), xl(2), yl(1), yl(2)]);
                line([0 0], [-1e5, 1e5], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
                line([-1e5, 1e5], [0 0], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
                %% F - Plotting the the best general functional form to the fit
                xx = linspace(-8, 8, 5e3)';
                fit_params = fitStr.edc.beta{i};
                yy_fn       = fitStr.edc.fn(fit_params, xx);
                plot(yy_fn, xx, 'k-', 'linewidth', pp.fit.linewidth);
                
                %% SAVE THE FIGURE
                saveas(fig, ifName,'png');
            end
            
        end
        
        % --- Function to view series all the EDC fits
        function view_mdc_series(app, fitStr)
            %% Loading in plot properties
            pp = plot_props(app);
            %% Initialisation
            filter = {'*.*'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the MDC series...', fitStr.FileName);
            fName = string(string(save_filepath) + string(save_filename));
            % If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% Initialising the figure
            fig = figure(); set(fig, 'Position', [5,5, 600, 800]);
            
            %% 2 - Saving all MDC fits
            for i = 1:fitStr.mdc.nCuts
                % - Reset the figure
                cla reset; clf reset; delete(findall(gcf,'type','annotation')); hold on;
                % - Initialising variables
                ifName = fName + "_mdc" + string(i);
                cutVal = fitStr.mdc.cutVals(i);
                % 2.1 - Plotting the ARPES data
                subplot(3,1,2:3); hold on;
                ImData(fitStr.kx_roi, fitStr.eb_roi, fitStr.data_roi);
                gca_properties(app, 'kx'); colorbar('off');
                axis([min(fitStr.kx_roi(:)), max(fitStr.kx_roi(:)),min(fitStr.eb_roi(:)), max(fitStr.eb_roi(:))]);
                 %% 2.2 - Plotting the parabolic trail
                if isfield(fitStr, 'para')
                    for n = 1:size(fitStr.para.kx,2)
                        kx = fitStr.para.kx{n}; dkx = fitStr.para.dkx{n};
                        eb = fitStr.para.eb{n}; deb = fitStr.para.deb{n};
                        fill([kx, fliplr(kx)], [eb + deb, fliplr(eb - deb)], pp.fit.color{n}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                    end
                end
                %% 2.3 - Plotting the best fit data points and parabola
                for n = 1:fitStr.mdc.nStates
                    % - Plotting the best fit data-points
                    plot(fitStr.mdc.kx_pts{n}, fitStr.mdc.eb_pts{n}, 'kx',...
                        'color', pp.fit.color{n}, 'linewidth', 1, 'marker', pp.fit.mstyle{n});
                    % - Plotting the best fit parabola
                    X = linspace(-2, 2, 1e3);
                    Y = fitStr.mdc.fpara(fitStr.mdc.beta_para{n}, X);
                    plot(X, Y, 'k-', 'color', pp.fit.color{n}, 'linewidth', 1);
                end 
                %% 2.4 - Plotting an outline over the integrated sliced region
                line([-1e5, 1e5], [cutVal cutVal], 'Color', pp.edc.color, 'LineWidth', 2, 'Linestyle', '-');
                
                subplot(3,1,1); hold on;
                %% A - Plotting the parabolic estimate line
                if isfield(fitStr, 'para')
                    for n = 1:size(fitStr.para.kx,2)
                        dkx = fitStr.para.dkx{n};
                        [~, minInd] = min((abs(fitStr.para.eb{n} - cutVal)));
                        parVal = fitStr.para.kx{n}(minInd);
                        paraX1 = [parVal + dkx, parVal + dkx, parVal - dkx, parVal - dkx];
                        paraX2 = -1 * [parVal + dkx, parVal + dkx, parVal - dkx, parVal - dkx];
                        paraY1 = [-1e5, 1e5, 1e5, -1e5];
                        fill(paraX1, paraY1, pp.fit.color{n}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                        fill(paraX2, paraY1, pp.fit.color{n}, 'edgecolor', 'none', 'FaceAlpha', pp.fit.alpha);
                    end
                end
                %% B - Plotting the best fit curves
                xx = linspace(-8, 8, 5e3)';
                fit_params = fitStr.mdc.beta{i};
                for n = 1:fitStr.edc.nStates
                    if fitStr.mdc.mdc_args{n, 5} == 1
                        args1 = [fit_params(5*n-4), fit_params(5*n-3), fit_params(5*n-2), fit_params(5*n)];
                        args2 = [fit_params(5*n-4), fit_params(5*n-3), fit_params(5*n-1), fit_params(5*n)];
                        yy_f1 = fitStr.mdc.f1(args1, xx);
                        yy_f2 = fitStr.mdc.f1(args2, xx);
                        fill(xx, yy_f1, pp.fit.color{n}, 'facealpha', pp.fit.alpha, 'EdgeColor', 'none');
                        fill(xx, yy_f2, pp.fit.color{n}, 'facealpha', pp.fit.alpha, 'EdgeColor', 'none');
                    else
                        args1 = [fit_params(6*n-5), fit_params(6*n-3), fit_params(6*n-2), fit_params(6*n)];
                        args2 = [fit_params(6*n-4), fit_params(6*n-3), fit_params(6*n-1), fit_params(6*n)];
                        yy_f1 = fitStr.mdc.f1(args1, xx);
                        yy_f2 = fitStr.mdc.f1(args2, xx);
                        fill(xx, yy_f1, pp.fit.color{n}, 'facealpha', pp.fit.alpha, 'EdgeColor', 'none');
                        fill(xx, yy_f2, pp.fit.color{n}, 'facealpha', pp.fit.alpha, 'EdgeColor', 'none');
                    end
                end
                
                %% C - Extracting and plotting cut
                [XCut, DCut] = Cut(fitStr.kx_roi, fitStr.eb_roi, fitStr.data_roi, 'mdc', cutVal + [-0.0001, 0.0001]);
                plot(XCut, DCut, 'k.-', 'color', pp.edc.color, 'LineWidth', pp.edc.linewidth,...
                    'marker', pp.edc.markerstyle, 'markersize', pp.edc.markersize);
                %% D - Defining the axes properties
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 12;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'out';
                % Ruler properties
                ax.XAxisLocation = 'top';               % 'bottom' | 'top' | 'origin'
                ax.YAxisLocation = 'left';              % 'left' | 'right' | 'origin'
                % Axis labels and limits
                xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                ylabel('$$ \bf  Intensity $$', 'Interpreter', 'latex');
                %% 4 - Plotting the x- and y-axes
                xl = [min(fitStr.kx_roi(:)), max(fitStr.kx_roi(:))]; yl = [0, max(DCut(:))];
                axis([xl(1), xl(2), yl(1), yl(2)]);
                line([0 0], [-1e5, 1e5], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
                line([-1e5, 1e5], [0 0], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
                %% F - Plotting the the best general functional form to the fit
                xx = linspace(-8, 8, 5e3)';
                fit_params  = fitStr.mdc.beta{i};
                yy_fn       = fitStr.mdc.fn(fit_params, xx);
                plot(xx, yy_fn, 'k-', 'linewidth', pp.fit.linewidth);
                
                %% SAVE THE FIGURE
                saveas(fig, ifName,'png')
            end
            
        end
        
    end
     
    methods (Access = private)
        % Code that executes to initialise the app once data is loaded
        function initialiseFcn(app)
            %% 1 - Updating handle text and gui elements
            % - 1.1 - Show the name, type and information of the ARPES data
            app.DataFile_Label.Text         = app.myData.FileName;
            % - 1.2 - Activating push-buttons that can be used
            % -- General buttons
            app.SAVE_Button.Enable          = 'on';
            app.TRANSFER_Button.Enable      = 'on';
            app.APPINFO_Button.Enable       = 'on';
            app.RESET_Button.Enable         = 'on';
            % -- Data manipulation UI panel activation
            children = get(app.FilterDatagloballyPanel,'Children');     set(children,'Enable','on');
            children = get(app.RealignEbscanPanel,'Children');      set(children,'Enable','on');
            children = get(app.RealignkxscanPanel,'Children');      set(children,'Enable','on');
            children = get(app.RescalekxscanPanel,'Children');      set(children,'Enable','on');
            % Data browser and ROI UI panel activation
            children = get(app.DatabrowserPanel,'Children');    set(children,'Enable','on');
            children = get(app.ROICroppingPanel,'Children');    set(children,'Enable','on');
            children = get(app.BackgroundSubtractionPanel,'Children'); set(children,'Enable','off');
            % - Deactivating all 3D processing techniques
            if app.myData.Type == "Eb(k)"
                app.ViewSeries_Button.Enable        = 'off';
                app.ScanIndex_Slider.Enable      = 'off';
            % - Activating all 3D processing techniques
            elseif app.myData.Type == "Eb(kx,ky)" || app.myData.Type == "Eb(kx,kz)"
                app.ViewSeries_Button.Enable        = 'on';
                app.ScanIndex_Slider.Enable      = 'on';
                % -- Initialising the isoSlice slider
                [~, ~, ~, dField] = find_data_fields(app, app.myData);
                app.ScanIndex_Slider.Limits = [1, size(app.myData.(dField), 3)];
                app.ScanIndex_Slider.Value = 1;
            end
            % Initialising slider values
            app.ScanIndex_Slider.Value = 1; 
            app.CutIndex_Slider.Value = 1;
            
            %% 2 - Running UI call-backs to ensure consistency
            % - Eb alignment arguments
            ebWin_EditFieldValueChanged(app);
            dEWin_EditFieldValueChanged(app);
            dESmooth_EditFieldValueChanged(app);
            feat_DropDownValueChanged(app);
            % - Filter arguments
            FilterType_DropDownValueChanged(app);
            FilterParams_EditFieldValueChanged(app);
            % - Re-align kx arguments
            kxShift_EditFieldValueChanged(app);
            % - Re-scale kx arguments
            kxScale_EditFieldValueChanged(app);
            % - Data browser arguments
            ScanIndex_SliderValueChanged(app);
            isoCutType_DropDownValueChanged(app);
            isoCutWin_EditFieldValueChanged(app);
            CutIndex_SliderValueChanged(app);
            % - ROI and BS arguments
            kLimsROI_EditFieldValueChanged(app);
            ebLimsROI_EditFieldValueChanged(app);
            kLimsBS_EditFieldValueChanged(app);
            ebLimsBS_EditFieldValueChanged(app);
            polyOrder_EditFieldValueChanged(app);
            % - Fitting arguments
            fitType_DropDownValueChanged(app);
            fitMDC_CheckBoxValueChanged(app);
            fitEDC_CheckBoxValueChanged(app);
            voigtMix_EditFieldValueChanged(app);
            % Initialising fit arguments
            % - FDD table
            app.fdd_args{1,1} = 12.00000;
            app.fdd_args{1,2} = 0.000000;
            app.fdd_args{1,3} = 40.00000;
            % - Parabolae table
            app.para_args{1,1} = 0.00000;
            app.para_args{1,2} = 0.01000;
            app.para_args{1,3} = -0.12000;
            app.para_args{1,4} = 0.01000;
            app.para_args{1,5} = 0.18000;
            for i = 2:4; for j = 1:5; app.para_args{i,j} = 0; end; end
            % -- EDC arguments initialisation
            app.edc_args{1,1} = [-0.1, 0.1];
            app.edc_args{1,2} = 'gaussian';
            app.edc_args{1,3} = [0.0, 1.0];
            app.edc_args{1,4} = [0.0, 0.2];
            app.edc_args{1,5} = true;
            app.edc_args{1,6} = [0.0,0.02];
            % -- MDC arguments initialisation
            app.mdc_args{1,1} = [-0.15, 0.05];
            app.mdc_args{1,2} = 'gaussian';
            app.mdc_args{1,3} = [0.0, 1.0];
            app.mdc_args{1,4} = [0.0, 0.2];
            app.mdc_args{1,5} = true;
            app.mdc_args{1,6} = [0.0,0.02];
            
            %% 3 - If processing has already been performed, load to most recent stage
            % If ROI has been defined
            if isfield(app.myData, 'fits')
                updateFcn(app);
                %% If fdd fits have already been made
                if isfield(app.myData.fits, 'fdd')
                    updateFddFcn(app);
                    app.fdd_UITable.Data = app.myData.fits.fdd.uitable;
                end
                %% If parabolic fits have already been made
                if isfield(app.myData.fits, 'para')
                    updateParaFcn(app);
                    app.para_UITable.Data = app.myData.fits.para.uitable;
                end
                %% If fits have already been made
                if isfield(app.myData.fits, 'edc')
                    if ~isempty(app.myData.fits.edc)
                        app.edc_UITable.Data = app.myData.fits.edc.uitable;
                        app.ViewEDCTab_Button.Enable    = 'on';
                        app.edcSeries_Button.Enable     = 'on';
                        app.ViewFitSummary_Button.Enable        = 'on';
                        app.DeleteFits_Button.Enable            = 'on';
                    end
                end
                if isfield(app.myData.fits, 'mdc')
                    if ~isempty(app.myData.fits.mdc)
                        app.mdc_UITable.Data = app.myData.fits.mdc.uitable;
                        app.ViewMDCTab_Button.Enable    = 'on';
                        app.mdcSeries_Button.Enable     = 'on';
                        app.ViewFitSummary_Button.Enable        = 'on';
                        app.DeleteFits_Button.Enable            = 'on';
                    end
                end
            end
        end
        
        % Code that executes to update dependencies after ROI extraction
        function updateFcn(app)
            % Updating UI dependencies once ROI has been extracted
            children = get(app.BackgroundSubtractionPanel,'Children');  set(children,'Enable','on');
            % Disable scan slider and update MDC cuts
            app.ScanIndex_Slider.Enable = 'off';
            if app.isocut_args{1} == "MDC"
                app.CutIndex_Slider.Limits = [1, size(app.myData.fits.kx_roi, 1)];
                app.CutIndex_Slider.Value = floor(0.5 * (size(app.myData.fits.kx_roi, 1)));
            elseif app.isocut_args{1} == "EDC"
                app.CutIndex_Slider.Limits = [1, size(app.myData.fits.kx_roi, 2)];
                app.CutIndex_Slider.Value = floor(0.5 * (size(app.myData.fits.kx_roi, 2)));
            end
            % Enable Fitting Panel
            children = get(app.FittingPanel,'Children'); set(children,'Enable','on');
            app.ViewPara_Button.Enable      = 'off';
            app.ViewFDD_Button.Enable       = 'off';
            app.ViewEDCTab_Button.Enable    = 'off';
            app.ViewMDCTab_Button.Enable    = 'off';
            app.edcSeries_Button.Enable    = 'off';
            app.mdcSeries_Button.Enable    = 'off';
            app.ViewFitSummary_Button.Enable    = 'off';
            app.DeleteFits_Button.Enable    = 'off';
            % Controlling the order of activation
            app.para_UITable.Enable = 'off';
            app.ExtractPara_Button.Enable      = 'off';
            app.edc_UITable.Enable = 'off';
            app.mdc_UITable.Enable = 'off';
            app.ExecuteFits_Button.Enable    = 'off';
        end
        
        % Code that executes to update dependencies if FDD is determined
        function updateFddFcn(app)
            %% - Updating UI dependencies
            % -- FDD UI
            app.ViewFDD_Button.Enable       = 'on';
            % -- Parabola UI
            app.para_UITable.Enable         = 'on';
            app.ExtractPara_Button.Enable   = 'on';
        end
        
        % Code that executes to update dependencies if Parabolae are determined
        function updateParaFcn(app)
            %% 1- Updating UI dependencies
            % -- Parabola UI
            app.ViewPara_Button.Enable   = 'on';
            % -- EDC Fitting UI
            app.edc_UITable.Enable       = 'on';
            app.fitEDC_CheckBox.Enable      = 'on';
            % -- MDC Fitting UI
            app.mdc_UITable.Enable       = 'on';
            app.fitMDC_CheckBox.Enable   = 'on';
            % -- Final Fitting UI
            app.fitType_DropDown.Enable = 'on';
            app.ExecuteFits_Button.Enable = 'on';
        end
    
        % Code that executes to update dependencies if Fits have been made before
        function updateFitsFcn(app)
            %% - Updating UI dependencies
            if isfield(app.myData.fits, 'edc')
                app.ViewEDCTab_Button.Enable    = 'on';
                app.edcSeries_Button.Enable     = 'on';
            end
            if isfield(app.myData.fits, 'mdc')
                app.ViewMDCTab_Button.Enable = 'on'; 
                app.mdcSeries_Button.Enable    = 'on';
            end
            app.ViewFitSummary_Button.Enable        = 'on';
            app.DeleteFits_Button.Enable            = 'on';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% 1 - Setting push-buttons to inactive, until data is loaded
            app.SAVE_Button.Enable          = 'off';
            app.TRANSFER_Button.Enable      = 'off';
            app.APPINFO_Button.Enable       = 'off';
            app.ViewSeries_Button.Enable    = 'off';
            app.RESET_Button.Enable         = 'off';
            % 2 - Data manipulation UI panel activation
            children = get(app.FilterDatagloballyPanel,'Children');     set(children,'Enable','off');
            children = get(app.RealignEbscanPanel,'Children');      set(children,'Enable','off');
            children = get(app.RealignkxscanPanel,'Children');      set(children,'Enable','off');
            children = get(app.RescalekxscanPanel,'Children');      set(children,'Enable','off');
            % 3 - Data browser and ROI UI panel activation
            children = get(app.DatabrowserPanel,'Children');    set(children,'Enable','off');
            children = get(app.BackgroundSubtractionPanel,'Children'); set(children,'Enable','off');
            children = get(app.ROICroppingPanel,'Children');    set(children,'Enable','off');
            children = get(app.FittingPanel,'Children');    set(children,'Enable','off');
            
            %% 4 - Populating UI tables with default data
            % - FDD table
            app.fdd_UITable.Data{1,1} = 12.00000;
            app.fdd_UITable.Data{1,2} = 0.000000;
            app.fdd_UITable.Data{1,3} = 40.00000;
            % - Parabolae table
            app.para_UITable.RowName = 'numbered';
            app.para_UITable.Data{1,1} = 0.00000;
            app.para_UITable.Data{1,2} = 0.01000;
            app.para_UITable.Data{1,3} = -0.120000;
            app.para_UITable.Data{1,4} = 0.01000;
            app.para_UITable.Data{1,5} = 0.18000;
            for i = 2:4; for j = 1:5; app.para_UITable.Data{i,j} = 0; end; end
            % - EDC table
            app.edc_UITable.ColumnFormat = {[], {'gaussian','lorentzian', 'voigt'},[], [], [], []};
            app.edc_UITable.RowName = 'numbered';
            app.edc_UITable.Data{1,1} = '-0.1:0.1';
            app.edc_UITable.Data{1,2} = char("gaussian");
            app.edc_UITable.Data{1,3} = '0.0:1.0';
            app.edc_UITable.Data{1,4} = '0.0:0.2';
            app.edc_UITable.Data{1,5} = true;
            app.edc_UITable.Data{1,6} = '0.0:0.02';
            for i = 2:4
                for j = 1:6
                    if j == 5; app.edc_UITable.Data{i,j} = false; 
                    else; app.edc_UITable.Data{i,j} = []; 
                    end
                end
            end
            % - MDC table
            app.mdc_UITable.ColumnFormat = {[], {'gaussian','lorentzian', 'voigt'},[], [], [], []};
            app.mdc_UITable.RowName = 'numbered';
            app.mdc_UITable.Data{1,1} = '-0.15:0.05';
            app.mdc_UITable.Data{1,2} = char("gaussian");
            app.mdc_UITable.Data{1,3} = '0.0:1.0';
            app.mdc_UITable.Data{1,4} = '0.0:0.2';
            app.mdc_UITable.Data{1,5} = true;
            app.mdc_UITable.Data{1,6} = '0.0:0.02';
            for i = 2:4
                for j = 1:6
                    if j == 5; app.mdc_UITable.Data{i,j} = false; 
                    else; app.mdc_UITable.Data{i,j} = []; 
                    end
                end
            end
        end

        % Button pushed function: ARPESGUI_Button
        function ARPESGUI_ButtonPushed(app, event)
            run ARPESGUI;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            %% 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            %% 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            %% Remove the GUI handle and restart
            close(gcbf); run ARPESfitter;
        end

        % Button pushed function: RESIZEGUI_Button
        function RESIZEGUI_ButtonPushed(app, event)
            %% Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 1200;
            screen_size(4) = 625;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'arpes_data', app.myData);
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('APP : '); disp(app);
            disp('DATA (app.myData) : '); disp(app.myData);
            if isfield(app.myData, 'fits'); disp('FITS (.myData.fits) : '); disp(app.myData.fits); end
            if isfield(app.myData.fits, 'fdd'); disp('FDD (.myData.fits.fdd) : '); disp(app.myData.fits.fdd); end
            if isfield(app.myData.fits, 'para'); disp('PARA (.myData.fits.para) : '); disp(app.myData.fits.para); end
            if isfield(app.myData.fits, 'edc'); disp('EDC (.myData.fits.edc) : '); disp(app.myData.fits.edc); end
            if isfield(app.myData.fits, 'mdc'); disp('MDC (.myData.fits.mdc) : '); disp(app.myData.fits.mdc); end
        end

        % Button pushed function: LOAD_Button
        function LOAD_ButtonPushed(app, event)
            %% 1 - Loading in the FileName and PathName of the selected data
            [FileName, PathName] = uigetfile({'*.mat'}, 'Pick a processed *.mat data file...');
            % - If Cancel is pressed, then return nothing
            if isequal(PathName,0) || isequal(FileName,0); return; end
            % - 1.1 - Load in the file selected
            app.myData = [];
            app.myData = load_arpes_data(app, FileName);
            app.myData.FileName = FileName;
            %% 2 - Reseting the app to the initial state
            runStartupFcn(app, @startupFcn);
            %% 3 - Initialising the app to the data that has been loaded
            initialiseFcn(app);
        end

        % Button pushed function: SAVE_Button
        function SAVE_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', app.myData.FileName);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStruc = app.myData; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(app.myData);
            close(wbar);
            %% 3 - Saving a figure with the data
            if isfield(app.myData, 'fits'); fig = view_final_fig(app, app.myData.fits, app.isocut_args);
            else; fig = view_data(app, app.myData, app.isoslice_args);
            end
            print(fig, char(save_fullfile(1:end-4)+"_snap.png"), '-dpng');
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: RESET_Button
        function RESET_ButtonPushed(app, event)
            %% 1 - Re-load the raw data-file
            FileName = char(string(app.myData.FileName));
            app.myData = [];
            app.myData = load_arpes_data(app, FileName);
            app.myData.FileName = FileName;
            %% 2 - Reseting the app to the initial state
            runStartupFcn(app, @startupFcn);
            %% 3 - Initialising the app to the data that has been loaded
            initialiseFcn(app);
        end

        % Button pushed function: ViewSeries_Button
        function ViewSeries_ButtonPushed(app, event)
            view_data_series(app, app.myData);
        end

        % Value changed function: Colormap_DropDown
        function Colormap_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.Colormap_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.colmap_arg = value;
            fprintf("--> Colormap: " + app.colmap_arg + " \n");
        end

        % Value changed function: FilterType_DropDown
        function FilterType_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.FilterType_DropDown.Value;
            %% 2 - Validity check on input
            % For 'Gaco2' smoothing
            if value == "Gaco2"     
                app.hwXhwYLabel.Text = 'hwX, hwY:';
            % For 'GaussFlt2' smoothing
            elseif value == "GaussFlt2"  
                app.hwXhwYLabel.Text = 'hwX, hwY:';
            % For 'LaplaceFlt2' smoothing
            elseif value == "LaplaceFlt2"
                app.hwXhwYLabel.Text = 'y2xRatio:';
            % For 'CurvatureFlt2' smoothing
            elseif value == "CurvatureFlt2"
                app.hwXhwYLabel.Text = 'CX, CY:';
            end
            %% 3 - Printing the change that has occurred
            app.filter_args{1} = value;
            fprintf("--> Filter Type: " + app.filter_args{1} + " \n");
            %% 4 - Updating dependencies
            app.FilterParams_EditField.Value = ''; 
            FilterParams_EditFieldValueChanged(app);
        end

        % Value changed function: FilterParams_EditField
        function FilterParams_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.FilterParams_EditField.Value;
            data_entry = round(abs(str2double(split(value,','))), 4);
            
            %% 2 - Validity check on input
            % For 'Gaco2' or 'CurvatureFlt2' smoothing
            if app.filter_args{1} == "Gaco2" || app.filter_args{1} == "CurvatureFlt2"
                defVal = 0.5;
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = [defVal, defVal];
                % - If a single entry is made, make a row vector that is identical
                elseif length(data_entry) == 1
                    if isnan(data_entry); data_entry = defVal; end
                    data_entry = [data_entry, data_entry];
                % - If a double entry is made, check for NaN values
                elseif length(data_entry) == 2
                    % -- Forcing to default on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = defVal; end
                    if isnan(data_entry(2)); data_entry(2) = defVal; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
            % For 'GaussFlt2' smoothing
            elseif app.filter_args{1} == "GaussFlt2"
                defVal = 1;
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = [defVal, defVal];
                % - If a single entry is made, make a row vector that is identical
                elseif length(data_entry) == 1
                    if isnan(data_entry); data_entry = defVal; end
                    data_entry = [data_entry, data_entry];
                    if data_entry(1) < 1; data_entry(1) = 1; end
                    if data_entry(2) < 1; data_entry(2) = 1; end
                % - If a double entry is made, check for NaN values
                elseif length(data_entry) == 2
                    % -- Forcing to default on the entries if NaN or < 1
                    if isnan(data_entry(1)) || data_entry(1) < 1; data_entry(1) = defVal; end
                    if isnan(data_entry(2)) || data_entry(2) < 1; data_entry(2) = defVal; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
            % For 'LaplaceFlt2' smoothing
            elseif app.filter_args{1} == "LaplaceFlt2"
                defVal = 0;
                 % - If no entry is made, default to a given value
                if isempty(data_entry) || length(data_entry) > 1
                    data_entry = defVal;
                % - If a single entry is made, check for NaN
                elseif length(data_entry) == 1
                    if isnan(data_entry); data_entry = defVal; end
                end
            end
            %% 4 - Assigning output and printing change
            app.filter_args{2} = round(abs(data_entry), 4);
            if app.filter_args{1} == "LaplaceFlt2"
                app.FilterParams_EditField.Value = string(app.filter_args{2}); 
                fprintf("--> Filter params: " + string(app.filter_args{2}) + " \n");
            else
                app.FilterParams_EditField.Value = string(app.filter_args{2}(1) + "," + app.filter_args{2}(2)); 
                fprintf("--> Filter params: : " + string(app.filter_args{2}(1) + "," + app.filter_args{2}(2)) + " \n");
            end
        end

        % Button pushed function: ExecuteFiltering_Button
        function ExecuteFiltering_ButtonPushed(app, event)
            %% 1 - Performing the filtering operation
            temp_data = filter_data(app, app.myData, app.filter_args);
            
            %% 2 - View the figure update
            figure(); hold on;
            view_scan_fig(app, app.scanIndex, temp_data);
            
            %% 3 - Ask the user if they want to keep the new filtered data
            answer = questdlg('Would you like to store the filtered data?', ...
            	'Filter data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            app.myData = temp_data;
        end

        % Value changed function: ebWin_EditField
        function ebWin_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.ebWin_EditField.Value;
            data_entry = round(str2double(split(value,':')), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            minVal = -1e5;
            maxVal = 1e5;
            %% 3 - Validity check on input
            % - If no entry is made, default to the minimum value
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = 'Auto';
            % - If a single entry is made, ensure it does not exceed the max / min limits  
            else
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry); data_entry = 'Auto'; end
                % -- Forcing the max / min limits if exceeded
                if data_entry < minVal; data_entry = minVal; end
                if data_entry > maxVal; data_entry = maxVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 4 - Assigning output and printing change
            app.align_args{1}  = data_entry;
            app.ebWin_EditField.Value = string(app.align_args{1}); 
            fprintf("--> ebWin: " + string(app.align_args{1}) + " \n");
        end

        % Value changed function: dEWin_EditField
        function dEWin_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.dEWin_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            % - If no entry is made, default to 'Auto'
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = 'Auto';
            % - If a single entry is made, ensure it does not exceed the max / min limits  
            elseif length(data_entry) == 1
                % -- For NaN, default to 'Auto'
                if isnan(data_entry); data_entry = 'Auto'; end
                data_entry = round(data_entry, 4);
            end
            %% 4 - Assigning output and printing change
            app.align_args{2}  = data_entry;
            app.dEWin_EditField.Value = string(app.align_args{2}); 
            fprintf("--> dEWin: " + string(app.align_args{2}) + " \n");
        end

        % Value changed function: dESmooth_EditField
        function dESmooth_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.dESmooth_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            % - If no entry is made, default to 'Auto'
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = 'Auto';
            % - If a single entry is made, ensure it does not exceed the max / min limits  
            elseif length(data_entry) == 1
                % -- For NaN, default to 'Auto'
                if isnan(data_entry); data_entry = 'Auto'; end
                data_entry = round(data_entry, 4);
            end
            %% 4 - Assigning output and printing change
            app.align_args{3}  = data_entry;
            app.dESmooth_EditField.Value = string(app.align_args{3}); 
            fprintf("--> dESmooth: " + string(app.align_args{3}) + " \n");
        end

        % Value changed function: feat_DropDown
        function feat_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.feat_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.align_args{4} = value;
            fprintf("--> Feat: " + app.align_args{4} + " \n");
        end

        % Button pushed function: ExecuteAlignment_Button
        function ExecuteAlignment_ButtonPushed(app, event)
            %% 1 - Executing the Eb alignment operation
            temp_data = align_energy(app, app.scanIndex, app.myData, app.align_args);
            
            %% 2 - View the figure update
            figure(); hold on;
            view_scan_fig(app, app.scanIndex, temp_data);
            
            %% 3 - Ask the user if they want to keep the new filtered data
            answer = questdlg('Would you like to store the new data?', ...
            	'Store data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            %% 4 - Updating dependencies
            app.myData = temp_data;
        end

        % Value changed function: kxShift_EditField
        function kxShift_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.kxShift_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 4));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 4)); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            defVal = 0;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 3 - Assigning output and printing change
            app.kxalign_args{1}  = data_entry;
            app.kxShift_EditField.Value = string(app.kxalign_args{1}); 
            fprintf("--> kx-shift: " + string(app.kxalign_args{1}) + " \n");
        end

        % Button pushed function: ExecuteAlignKx_Button
        function ExecuteAlignKx_ButtonPushed(app, event)
            %% 1 - Performing the kx shifting operation
            temp_data = align_kx(app, app.scanIndex, app.myData, app.kxalign_args);
            
            %% 2 - View the figure update
            figure(); hold on;
            view_scan_fig(app, app.scanIndex, temp_data);
            
            %% 3 - Ask the user if they want to keep the new filtered data
            answer = questdlg('Would you like to store the new data?', ...
            	'Store data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            app.myData = temp_data;
        end

        % Value changed function: kxScale_EditField
        function kxScale_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.kxScale_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 4));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 4)); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            defVal = 1;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 3 - Assigning output and printing change
            app.kxscale_args{1}  = data_entry;
            app.kxScale_EditField.Value = string(app.kxscale_args{1}); 
            fprintf("--> kx-scale: " + string(app.kxscale_args{1}) + " \n");
        end

        % Button pushed function: ExecuteScaleKx_Button
        function ExecuteScaleKx_ButtonPushed(app, event)
            %% 1 - Performing the kx scaling operation
            temp_data = scale_kx(app, app.scanIndex, app.myData, app.kxscale_args);
            
            %% 2 - View the figure update
            figure(); hold on;
            view_scan_fig(app, app.scanIndex, temp_data);
            
            %% 3 - Ask the user if they want to keep the new filtered data
            answer = questdlg('Would you like to store the new data?', ...
            	'Store data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            app.myData = temp_data;
        end

        % Value changed function: ScanIndex_Slider
        function ScanIndex_SliderValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.ScanIndex_Slider.Value;
            data_entry = floor(value);
            %% 2 - Defining the limits of the input
            [~, ~, ~, dField] = find_data_fields(app, app.myData);
            minVal = 1;
            maxVal = size(app.myData.(dField), 3);
            %% 3 - Validity check on input
            % - Checking max/min are not exceeded
            if data_entry < minVal; data_entry = minVal; end
            if data_entry > maxVal; data_entry = minVal; end
            %% 4 - Printing the change that has occurred
            app.scanIndex = data_entry;
            fprintf("--> Scan Index: " + app.scanIndex + " \n");
            %% 5 - Updating dependencies
            app.ScanIndex_Slider.Value = app.scanIndex;
            app.ScanIndex_EditField.Value = app.scanIndex;
            [~, ~, zField, ~] = find_data_fields(app, app.myData);
            if size(app.myData.(zField), 3) > 1
                app.ScanValue_EditField.Value = app.myData.(zField)(1,1,app.scanIndex);
            else
                app.ScanValue_EditField.Value = app.myData.(zField)(1,app.scanIndex);
            end
            %% 6 - Updating the figure
            figure(); hold on;
            view_scan_fig(app, app.scanIndex, app.myData);
        end

        % Value changed function: isoCutType_DropDown
        function isoCutType_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.isoCutType_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.isocut_args{1} = value;
            fprintf("--> IsoCut Type: " + app.isocut_args{1} + " \n");
            %% 3 - Updating dependencies
            isoCutWin_EditFieldValueChanged(app);
            %% 4 - Size of the slider cut indexes
            if isfield(app.myData, 'fits')
                if app.isocut_args{1} == "MDC"
                    app.CutIndex_Slider.Limits = [1, size(app.myData.fits.kx_roi, 1)];
                    app.CutIndex_Slider.Value = floor(0.5 * (size(app.myData.fits.kx_roi, 1)));
                elseif app.isocut_args{1} == "EDC"
                    app.CutIndex_Slider.Limits = [1, size(app.myData.fits.kx_roi, 2)];
                    app.CutIndex_Slider.Value = floor(0.5 * (size(app.myData.fits.kx_roi, 2)));
                end
            else
                [~, ~, ~, dField] = find_data_fields(app, app.myData);
                if app.isocut_args{1} == "MDC"
                    app.CutIndex_Slider.Limits = [1, size(app.myData.(dField), 1)];
                    app.CutIndex_Slider.Value = floor(0.5 * (size(app.myData.(dField), 1)));
                elseif app.isocut_args{1} == "EDC"
                    app.CutIndex_Slider.Limits = [1, size(app.myData.(dField), 2)];
                    app.CutIndex_Slider.Value = floor(0.5 * (size(app.myData.(dField), 2)));
                end
            end
        end

        % Value changed function: isoCutWin_EditField
        function isoCutWin_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.isoCutWin_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 4));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 4)); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            [xField, yField, ~, ~] = find_data_fields(app, app.myData);
            defVal = 0.01;
            minVal = 0.001;
            XmaxVal = round(max(app.myData.(xField)(:)), 4);
            YmaxVal = round(max(app.myData.(yField)(:)), 4);
            %% 3 - Validity check on input
            if app.isocut_args{1} == "MDC"
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 1
                    data_entry = defVal;
                % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
                else
                    % -- Forcing to default if NaN
                    if isnan(data_entry); data_entry = defVal; end
                    % -- Forcing the max / min limits if exceeded
                    if data_entry < minVal; data_entry = minVal; end
                    if data_entry > YmaxVal; data_entry = YmaxVal; end
                    data_entry = round(data_entry(1), 4);
                end
            elseif app.isocut_args{1} == "EDC"
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 1
                    data_entry = defVal;
                % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
                else
                    % -- Forcing to default if NaN
                    if isnan(data_entry); data_entry = defVal; end
                    % -- Forcing the max / min limits if exceeded
                    if data_entry < minVal; data_entry = minVal; end
                    if data_entry > XmaxVal; data_entry = XmaxVal; end
                    data_entry = round(data_entry(1), 4);
                end
            end
            %% 4 - Assigning output and printing change
            app.isocut_args{2}  = sort(round(data_entry, 4));
            app.isoCutWin_EditField.Value = string(app.isocut_args{2}); 
            fprintf("--> IsoCut Win: " + app.isocut_args{2} + " \n");
        end

        % Value changed function: CutIndex_Slider
        function CutIndex_SliderValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.CutIndex_Slider.Value;
            data_entry = floor(value);
            %% 2 - Defining the limits of the input
            minVal = 1;
            if isfield(app.myData, 'fits')
                if app.isocut_args{1} == "MDC"
                    maxVal = size(app.myData.fits.data_roi, 1);
                elseif app.isocut_args{1} == "EDC"
                    maxVal = size(app.myData.fits.data_roi, 2);
                end
            else
                [~, ~, ~, dField] = find_data_fields(app, app.myData);
                if app.isocut_args{1} == "MDC"
                    maxVal = size(app.myData.(dField), 1);
                elseif app.isocut_args{1} == "EDC"
                    maxVal = size(app.myData.(dField), 2);
                end
            end

            %% 3 - Validity check on input
            % - Checking max/min are not exceeded
            if data_entry < minVal; data_entry = minVal; end
            if data_entry > maxVal; data_entry = maxVal; end
            %% 4 - Printing the change that has occurred
            app.isocut_args{3} = data_entry;
            fprintf("--> Cut Index: " + app.isocut_args{3} + " \n");
            %% 5 - Updating dependencies
            app.CutIndex_Slider.Value = app.isocut_args{3};
            app.CutScanIndex_EditField.Value = app.isocut_args{3};
            %% 6 - Updating the cut index/values for the figure
            if isfield(app.myData, 'fits')
                if app.isocut_args{1} == "MDC"
                    app.CutScanValue_EditField.Value = mean(app.myData.fits.eb_roi(app.isocut_args{3},:,1));
                    app.isocut_args{4} = app.CutScanValue_EditField.Value;
                elseif app.isocut_args{1} == "EDC"
                    app.CutScanValue_EditField.Value = mean(app.myData.fits.kx_roi(:,app.isocut_args{3},1));
                    app.isocut_args{4} = app.CutScanValue_EditField.Value;
                end
                view_final_fig(app, app.myData.fits, app.isocut_args);
            else
                [xField, yField, ~, ~] = find_data_fields(app, app.myData);
                if app.isocut_args{1} == "MDC"
                    app.CutScanValue_EditField.Value = mean(app.myData.(yField)(app.isocut_args{3},:,1));
                    app.isocut_args{4} = app.CutScanValue_EditField.Value;
                elseif app.isocut_args{1} == "EDC"
                    app.CutScanValue_EditField.Value = mean(app.myData.(xField)(:,app.isocut_args{3},1));
                    app.isocut_args{4} = app.CutScanValue_EditField.Value;
                end
                view_cut_fig(app, app.scanIndex, app.myData, app.isocut_args);
            end
        end

        % Callback function
        function ViewLatest_ButtonPushed(app, event)
            view_isoCut(app, app.myData, app.isocut_args);
        end

        % Value changed function: kLimsROI_EditField
        function kLimsROI_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.kLimsROI_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            [xField, ~, ~, ~] = find_data_fields(app, app.myData);
            minVal = round(min(min(app.myData.(xField)(:,:,app.scanIndex))) + 0.01, 2);
            maxVal = round(max(max(app.myData.(xField)(:,:,app.scanIndex))) - 0.01, 2);
            defVal = [-0.35, 0.35];
            %% 3 - Validity check on input
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) > 2
                data_entry = defVal;
            % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
            elseif length(data_entry) == 1
                data_entry = defVal + data_entry;
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + defVal; end
            end
            % -- Forcing the max / min limits if exceeded
            if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
            if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            data_entry = sort(round([data_entry(1), data_entry(2)], 4));
            %% 4 - Assigning output and printing change
            app.roi_args{1}  = data_entry;
            app.kLimsROI_EditField.Value = string(app.roi_args{1}(1) + ":" + app.roi_args{1}(2)); 
            fprintf("--> (ROI) kLims: " + string(app.roi_args{1}(1) + ":" + app.roi_args{1}(2)) + " \n");
        end

        % Value changed function: ebLimsROI_EditField
        function ebLimsROI_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.ebLimsROI_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            [~, yField, ~, ~] = find_data_fields(app, app.myData);
            minVal = round(min(min(app.myData.(yField)(:,:,app.scanIndex))) + 0.01, 2);
            maxVal = round(max(max(app.myData.(yField)(:,:,app.scanIndex))) - 0.01, 2);
            defVal = [-0.75, 0.35];
            %% 3 - Validity check on input
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) > 2
                data_entry = defVal;
            % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
            elseif length(data_entry) == 1
                data_entry = defVal + data_entry;
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + defVal; end
            end
            % -- Forcing the max / min limits if exceeded
            if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
            if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            data_entry = sort(round([data_entry(1), data_entry(2)], 4));
            %% 4 - Assigning output and printing change
            app.roi_args{2}  = data_entry;
            app.ebLimsROI_EditField.Value = string(app.roi_args{2}(1) + ":" + app.roi_args{2}(2)); 
            fprintf("--> (ROI) ebLims: " + string(app.roi_args{2}(1) + ":" + app.roi_args{2}(2)) + " \n");
        end

        % Value changed function: kLimsBS_EditField
        function kLimsBS_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.kLimsBS_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            [xField, ~, ~, ~] = find_data_fields(app, app.myData);
            minVal = round(min(min(app.myData.(xField)(:,:,app.scanIndex))) + 0.01, 2);
            maxVal = round(max(max(app.myData.(xField)(:,:,app.scanIndex))) - 0.01, 2);
            % Finding the default value based on the ROI
            if mean(app.roi_args{1}) < -0.01; defVal = app.roi_args{1} + abs(diff(app.roi_args{1})) + 0.05;
            else; defVal = app.roi_args{1} - abs(diff(app.roi_args{1})) - 0.05;
            end
            %% 3 - Validity check on input
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) > 2
                data_entry = defVal;
            % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
            elseif length(data_entry) == 1
                data_entry = defVal + data_entry;
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + defVal; end
            end
            % -- Forcing the max / min limits if exceeded
            if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
            if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            %% 4 - Assigning output and printing change
            app.roi_args{3}  = data_entry;
            app.kLimsBS_EditField.Value = string(app.roi_args{3}(1) + ":" + app.roi_args{3}(2)); 
            fprintf("--> (Background) kLims: " + string(app.roi_args{3}(1) + ":" + app.roi_args{3}(2)) + " \n");
        end

        % Value changed function: ebLimsBS_EditField
        function ebLimsBS_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.ebLimsBS_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            [~, yField, ~, ~] = find_data_fields(app, app.myData);
            minVal = round(min(min(app.myData.(yField)(:,:,app.scanIndex))) + 0.01, 2);
            maxVal = round(max(max(app.myData.(yField)(:,:,app.scanIndex))) - 0.01, 2);
            defVal = app.roi_args{2};
            %% 3 - Validity check on input
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) > 2
                data_entry = defVal;
            % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
            elseif length(data_entry) == 1
                data_entry = defVal + data_entry;
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + defVal; end
            end
            % -- Forcing the max / min limits if exceeded
            if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
            if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            data_entry = sort(round([data_entry(1), data_entry(2)], 4));
            %% 4 - Assigning output and printing change
            app.roi_args{4}  = data_entry;
            app.ebLimsBS_EditField.Value = string(app.roi_args{4}(1) + ":" + app.roi_args{4}(2)); 
            fprintf("--> (Background) ebLims: " + string(app.roi_args{4}(1) + ":" + app.roi_args{4}(2)) + " \n");
        end

        % Button pushed function: ViewROI_Button
        function ViewROI_ButtonPushed(app, event)
            view_roi_fig(app, app.scanIndex, app.myData, app.roi_args);
        end

        % Button pushed function: SaveROI_Button
        function SaveROI_ButtonPushed(app, event)
            %% 1 - Executing cropping operation around ROI and BS regions
            roiStr = extract_roi(app, app.scanIndex, app.myData, app.roi_args);
            
            %% 2 - Ask the user if they want to save this slice
            answer = questdlg('Save this data?', ...
            	'Save data?', ...
            	'Yes','No','No');
            
            %% 3 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', app.myData.FileName);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            
            %% 4 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStruc = roiStr; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(roiStr);
            close(wbar);
        end

        % Button pushed function: ExtractROI_Button
        function ExtractROI_ButtonPushed(app, event)
            %% 1 - Executing cropping operation around ROI and BS regions
            temp_data = extract_roi(app, app.scanIndex, app.myData, app.roi_args);
            
            %% 2 - Determination of temporary arguments for iso-cut
            temp_args = app.isocut_args;
            if app.isocut_args{1} == "MDC"
                temp_args{3} = floor(0.5 * (size(temp_data.fits.eb_roi, 1)));
                temp_args{4} = mean(temp_data.fits.eb_roi(temp_args{3},:,1));
            elseif app.isocut_args{1} == "EDC"
                temp_args{3} = floor(0.5 * (size(temp_data.fits.kx_roi, 2)));
                temp_args{4} = mean(temp_data.fits.kx_roi(:,temp_args{3},1));
            end
            
            %% 3 - View the figure update
            view_final_fig(app, temp_data.fits, temp_args);
            
            %% 4 - Ask the user if they want to keep the new filtered data
            answer = questdlg('Store ROI and Background?', ...
            	'Store data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            
            %% 5 - Updating UI dependencies
            app.myData = temp_data;
            updateFcn(app);
        end

        % Value changed function: polyOrder_EditField
        function polyOrder_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.polyOrder_EditField.Value;
            data_entry = floor(sort(abs(str2double(split(value,':')))));
            %% 2 - Defining the limits of the input
            minVal = 1;
            defVal = 3;
            maxVal = 10;
            %% 3 - Validity check on input
            % - If no entry is made, default to the minimum value
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, ensure it does not exceed the max / min limits  
            else
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = defVal; end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < minVal; data_entry(1) = minVal; end
                if data_entry(1) > maxVal; data_entry(1) = maxVal; end
                data_entry = floor(data_entry(1));
            end
            %% 4 - Assigning output and printing change
            app.bs_args{1}  = data_entry;
            app.polyOrder_EditField.Value = string(app.bs_args{1}); 
            fprintf("--> Polynomial Order: " + string(app.bs_args{1}) + " \n");
        end

        % Button pushed function: ExecuteBS_Button
        function ExecuteBS_ButtonPushed(app, event)
            %% 1 - Execute background subtraction
            temp_data = background_subtraction(app, app.myData.fits, app.bs_args);
            
            %% 2 - Ask the user if they want to keep the new filtered data
            answer = questdlg('Keep Background Subtraction?', ...
            	'Store background subtracted data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            
            %% 3 - Updating UI dependencies
            app.myData.fits.data_roi = temp_data;
        end

        % Cell edit callback: fdd_UITable
        function fdd_UITableCellEdit(app, event)
            %% 1 - Extracting the input defined by the user
            indices = event.Indices;
            newData = event.NewData;
            data_entry = round(newData, 5);
            %% 2.1 - Validity check of column input index 1
            if indices(2) == 1
                if isnan(data_entry); data_entry = 12; end
                data_entry = abs(data_entry);
            %% 2.2 - Validity check of column input index 2
            elseif indices(2) == 2
                if isnan(data_entry); data_entry = 0; end
            %% 2.3 - Validity check of column input index 3
            elseif indices(2) == 3
                if isnan(data_entry); data_entry = 40; end
                data_entry = abs(data_entry);
            end
            %% 3 - Assigning output and printing change
            app.fdd_args{indices(1), indices(2)} = data_entry;
            app.fdd_UITable.Data{indices(1), indices(2)} = data_entry;
            fprintf("fdd_UITable.Data{" + string(indices(1)) + "," + string(indices(2)) + "}\n");
            fprintf("--> " + string(app.fdd_UITable.ColumnName{indices(2)}) + " = " + string(data_entry) + " \n\n");
        end

        % Button pushed function: ExtractFDD_Button
        function ExtractFDD_ButtonPushed(app, event)
            %% 1 - Extracting the FDD
            app.myData.fits = extract_fdd(app, app.myData.fits, app.fdd_args);
            app.myData.fits.fdd.uitable = app.fdd_UITable.Data;
            
            %% 2 - View the FDD
            view_fdd(app, app.myData.fits);
    
            %% 3 - Updating UI dependencies
            updateFddFcn(app)
        end

        % Button pushed function: ViewFDD_Button
        function ViewFDD_ButtonPushed(app, event)
            view_fdd(app, app.myData.fits);
        end

        % Cell edit callback: para_UITable
        function para_UITableCellEdit(app, event)
            %% 1 - Extracting the input defined by the user
            indices = event.Indices;
            newData = event.NewData;
            data_entry = round(newData, 5);
            %% 2.1 - Validity check of input column index 1
            if indices(2) == 1
                if isnan(data_entry); data_entry = 0.00000; end
            %% 2.2 - Validity check of input column index 2
            elseif indices(2) == 2
                if isnan(data_entry); data_entry = 0.01000; end
                data_entry = abs(data_entry);
            %% 2.3 - Validity check of input column index 3
            elseif indices(2) == 3
                if isnan(data_entry); data_entry = -0.1200; end
            %% 2.4 - Validity check of input column index 4
            elseif indices(2) == 4
                if isnan(data_entry); data_entry = 0.01000; end
                data_entry = abs(data_entry);
            %% 2.5 - Validity check of input column index 5
            elseif indices(2) == 5
                if isnan(data_entry); data_entry = 0.18000; end
            end
            %% 3 - Assigning output and printing change
            app.para_args{indices(1), indices(2)} = data_entry;
            app.para_UITable.Data{indices(1), indices(2)} = data_entry;
            fprintf("para_UITable.Data{" + string(indices(1)) + "," + string(indices(2)) + "}\n");
            fprintf("--> " + string(app.para_UITable.ColumnName{indices(2)}) + " = " + string(data_entry) + " \n\n");
        end

        % Button pushed function: ExtractPara_Button
        function ExtractPara_ButtonPushed(app, event)
            %% 1 - Extracting the parabolae
            app.myData.fits = extract_parabola(app, app.myData.fits, app.para_args);
            app.myData.fits.para.uitable = app.para_UITable.Data;
            
            %% 2 - View the parabolae
            view_para(app, app.myData.fits);
    
            %% 3 - Updating UI dependencies
            updateParaFcn(app);
        end

        % Button pushed function: ViewPara_Button
        function ViewPara_ButtonPushed(app, event)
            view_para(app, app.myData.fits);
        end

        % Cell edit callback: edc_UITable
        function edc_UITableCellEdit(app, event)
            %% 1 - Extracting the input defined by the user
            indices = event.Indices;
            newData = event.NewData;
            %% 2.1 - Validity check of input column index 1 (k-range)
            if indices(2) == 1
                % - Extracting the input defined by user
                data_entry = sort(round(str2num(strrep(newData, ':', ' ')), 5)); 
                % - Validity check of the input
                 % - If no entry is made, default to empty
                if isempty(data_entry) ||  length(data_entry) > 2 || size(data_entry, 1) > 1
                    if indices(1) == 1
                        data_entry = [-0.1, 0.1];
                        % -- Forcing the max / min limits on the entries
                        if data_entry(1) < min(app.myData.fits.kx_roi(:)) || data_entry(1) > max(app.myData.fits.kx_roi(:)); data_entry(1) = min(app.myData.fits.kx_roi(:)); end
                        if data_entry(2) < min(app.myData.fits.kx_roi(:)) || data_entry(2) > max(app.myData.fits.kx_roi(:)); data_entry(2) = max(app.myData.fits.kx_roi(:)); end
                    else
                        data_entry = [];
                    end
                 % - If a single entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 1 && size(data_entry, 1) ==1
                    data_entry = data_entry + [-0.05, 0.05];
                    % -- Forcing the max / min limits on the entries
                    if data_entry(1) < min(app.myData.fits.kx_roi(:)) || data_entry(1) > max(app.myData.fits.kx_roi(:)); data_entry(1) = min(app.myData.fits.kx_roi(:)); end
                    if data_entry(2) < min(app.myData.fits.kx_roi(:)) || data_entry(2) > max(app.myData.fits.kx_roi(:)); data_entry(2) = max(app.myData.fits.kx_roi(:)); end
                 % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries
                    if data_entry(1) < min(app.myData.fits.kx_roi(:)) || data_entry(1) > max(app.myData.fits.kx_roi(:)); data_entry(1) = min(app.myData.fits.kx_roi(:)); end
                    if data_entry(2) < min(app.myData.fits.kx_roi(:)) || data_entry(2) > max(app.myData.fits.kx_roi(:)); data_entry(2) = max(app.myData.fits.kx_roi(:)); end
                end
                data_entry = round(data_entry, 4);
                % - Printing the data entry
                fprintf("edc_UITable.Data{" + string(indices(1)) + "," + string(indices(2)) + "}\n");
                if isempty(data_entry)
                    data_char = [];
                    fprintf("--> " + string(app.edc_UITable.ColumnName{indices(2)}) + " = [] \n\n");
                else
                    data_char = char(string(data_entry(1)) + ":" + data_entry(2)); 
                    fprintf("--> " + string(app.edc_UITable.ColumnName{indices(2)}) + " = " + string(data_entry(1)) + ":" + string(data_entry(2)) + " \n\n");
                end
                % - Assigning the data entry to the uitable
                app.edc_args{indices(1), indices(2)} = data_entry;
                app.edc_UITable.Data{indices(1), indices(2)} = data_char;
                
            %% 2.2 - Validity check of input column index 2 & 5 (curve & checkbox)
            elseif indices(2) == 2 || indices(2) == 5
                 % - Printing the data entry
                fprintf("edc_UITable.Data{" + string(indices(1)) + "," + string(indices(2)) + "}\n");
                fprintf("--> " + string(app.edc_UITable.ColumnName{indices(2)}) + " = " + string(newData) + " \n\n");
                % - Assigning the data entry to the uitable
                app.edc_args{indices(1), indices(2)} = newData;
                app.edc_UITable.Data{indices(1), indices(2)} = newData;
                
            %% 2.3 - Validity check of input column index 3 & 4 (peak, fwhm & shift)
            elseif indices(2) == 3 || indices(2) == 4 || indices(2) == 6
                % - Extracting the input defined by user
                data_entry = sort(round(str2num(strrep(newData, ':', ' ')), 5)); 
                % - Validity check of the input
                 % - If no entry is made, default to empty
                if isempty(data_entry) ||  length(data_entry) > 2 || size(data_entry, 1) > 1
                    if indices(1) == 1
                        if indices(2) == 3;     data_entry = [0.0, 1.0]; 
                        elseif indices(2) == 4; data_entry = [0.05, 0.20]; 
                        elseif indices(2) == 6; data_entry = [0.0, 0.02]; 
                        end
                    else; data_entry = []; 
                    end
                 % - If a single entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 1 && size(data_entry, 1) ==1
                    data_entry = data_entry + [-0.05, 0.05];
                    % -- Forcing the max / min limits on the entries
                    if data_entry(1) < 0 || data_entry(1) > 5; data_entry(1) = 0; end
                    if data_entry(2) < 0 || data_entry(2) > 5; data_entry(2) = 5; end
                 % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries
                    if data_entry(1) < 0 || data_entry(1) > 5; data_entry(1) = 0; end
                    if data_entry(2) < 0 || data_entry(2) > 5; data_entry(2) = 5; end
                end
                data_entry = round(data_entry, 4);
                % - Printing the data entry
                fprintf("edc_UITable.Data{" + string(indices(1)) + "," + string(indices(2)) + "}\n");
                if isempty(data_entry)
                    data_char = [];
                    fprintf("--> " + string(app.edc_UITable.ColumnName{indices(2)}) + " = [] \n\n");
                else
                    data_char = char(string(data_entry(1)) + ":" + data_entry(2)); 
                    fprintf("--> " + string(app.edc_UITable.ColumnName{indices(2)}) + " = " + string(data_entry(1)) + ":" + string(data_entry(2)) + " \n\n");
                end
                % - Assigning the data entry to the uitable
                app.edc_args{indices(1), indices(2)} = data_entry;
                app.edc_UITable.Data{indices(1), indices(2)} = data_char;
            end
        end

        % Cell edit callback: mdc_UITable
        function mdc_UITableCellEdit(app, event)
            %% 1 - Extracting the input defined by the user
            indices = event.Indices;
            newData = event.NewData;
            %% 1.1 - Validity check of input column index 1 (eb-range)
            if indices(2) == 1
                % - Extracting the input defined by user
                data_entry = sort(round(str2num(strrep(newData, ':', ' ')), 5)); 
                % - Validity check of the input
                 % - If no entry is made, default to a small range around the mean
                if isempty(data_entry) ||  length(data_entry) > 2 || size(data_entry, 1) > 1
                    if indices(1) == 1
                        data_entry = [-0.15, 0.05];
                        % -- Forcing the max / min limits on the entries
                        if data_entry(1) < min(app.myData.fits.eb_roi(:)) || data_entry(1) > max(app.myData.fits.eb_roi(:)); data_entry(1) = min(app.myData.fits.eb_roi(:)); end
                        if data_entry(2) < min(app.myData.fits.eb_roi(:)) || data_entry(2) > max(app.myData.fits.eb_roi(:)); data_entry(2) = max(app.myData.fits.eb_roi(:)); end
                    else
                        data_entry = [];
                    end
                 % - If a single entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 1 && size(data_entry, 1) ==1
                    data_entry = data_entry + [-0.1, 0.1];
                    % -- Forcing the max / min limits on the entries
                    if data_entry(1) < min(app.myData.fits.eb_roi(:)) || data_entry(1) > max(app.myData.fits.eb_roi(:)); data_entry(1) = min(app.myData.fits.eb_roi(:)); end
                    if data_entry(2) < min(app.myData.fits.eb_roi(:)) || data_entry(2) > max(app.myData.fits.eb_roi(:)); data_entry(2) = max(app.myData.fits.eb_roi(:)); end
                 % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries
                    if data_entry(1) < min(app.myData.fits.eb_roi(:)) || data_entry(1) > max(app.myData.fits.eb_roi(:)); data_entry(1) = min(app.myData.fits.eb_roi(:)); end
                    if data_entry(2) < min(app.myData.fits.eb_roi(:)) || data_entry(2) > max(app.myData.fits.eb_roi(:)); data_entry(2) = max(app.myData.fits.eb_roi(:)); end
                end
                data_entry = round(data_entry, 4);
                % - Printing the data entry
                fprintf("mdc_UITable.Data{" + string(indices(1)) + "," + string(indices(2)) + "}\n");
                if isempty(data_entry)
                    data_char = [];
                    fprintf("--> " + string(app.mdc_UITable.ColumnName{indices(2)}) + " = [] \n\n");
                else
                    data_char = char(string(data_entry(1)) + ":" + data_entry(2)); 
                    fprintf("--> " + string(app.mdc_UITable.ColumnName{indices(2)}) + " = " + string(data_entry(1)) + ":" + string(data_entry(2)) + " \n\n");
                end
                % - Assigning the data entry to the uitable
                app.mdc_args{indices(1), indices(2)} = data_entry;
                app.mdc_UITable.Data{indices(1), indices(2)} = data_char;
                
            %% 1.2 - Validity check of input column index 2 & 5 (curve & checkbox)
            elseif indices(2) == 2 || indices(2) == 5
                 % - Printing the data entry
                fprintf("mdc_UITable.Data{" + string(indices(1)) + "," + string(indices(2)) + "}\n");
                fprintf("--> " + string(app.mdc_UITable.ColumnName{indices(2)}) + " = " + string(newData) + " \n\n");
                % - Assigning the data entry to the uitable
                app.mdc_args{indices(1), indices(2)} = newData;
                app.mdc_UITable.Data{indices(1), indices(2)} = newData;
                
            %% 1.3 - Validity check of input column index 3 & 4 (peak, fwhm & shift)
            elseif indices(2) == 3 || indices(2) == 4 || indices(2) == 6
                % - Extracting the input defined by user
                data_entry = sort(round(str2num(strrep(newData, ':', ' ')), 5)); 
                % - Validity check of the input
                 % - If no entry is made, default to empty
                if isempty(data_entry) ||  length(data_entry) > 2 || size(data_entry, 1) > 1
                    if indices(1) == 1
                        if indices(2) == 3;     data_entry = [0.0, 1.0]; 
                        elseif indices(2) == 4; data_entry = [0.05, 0.20]; 
                        elseif indices(2) == 6; data_entry = [0.0, 0.02]; 
                        end
                    else; data_entry = []; 
                    end
                 % - If a single entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 1 && size(data_entry, 1) ==1
                    data_entry = data_entry + [-0.05, 0.05];
                    % -- Forcing the max / min limits on the entries
                    if data_entry(1) < 0 || data_entry(1) > 5; data_entry(1) = 0; end
                    if data_entry(2) < 0 || data_entry(2) > 5; data_entry(2) = 5; end
                 % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries
                    if data_entry(1) < 0 || data_entry(1) > 5; data_entry(1) = 0; end
                    if data_entry(2) < 0 || data_entry(2) > 5; data_entry(2) = 5; end
                end
                data_entry = round(data_entry, 4);
                % - Printing the data entry
                fprintf("mdc_UITable.Data{" + string(indices(1)) + "," + string(indices(2)) + "}\n");
                if isempty(data_entry)
                    data_char = [];
                    fprintf("--> " + string(app.mdc_UITable.ColumnName{indices(2)}) + " = [] \n\n");
                else
                    data_char = char(string(data_entry(1)) + ":" + data_entry(2)); 
                    fprintf("--> " + string(app.mdc_UITable.ColumnName{indices(2)}) + " = " + string(data_entry(1)) + ":" + string(data_entry(2)) + " \n\n");
                end
                % - Assigning the data entry to the uitable
                app.mdc_args{indices(1), indices(2)} = data_entry;
                app.mdc_UITable.Data{indices(1), indices(2)} = data_char;
            end
        end

        % Value changed function: fitType_DropDown
        function fitType_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.fitType_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.fit_args{1} = value;
            fprintf("--> Fitting Algorithm Type: " + app.fit_args{1} + " \n");
        end

        % Value changed function: fitMDC_CheckBox
        function fitMDC_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.fitMDC_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.fit_args{2} = value;
            if app.fit_args{2} == 1;    fprintf("--> Fit using MDCs?: True \n");
            else;                       fprintf("--> Fit using MDCs?: False \n");
            end
        end

        % Value changed function: fitEDC_CheckBox
        function fitEDC_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.fitEDC_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.fit_args{3} = value;
            if app.fit_args{3} == 1;    fprintf("--> Fit using EDCs?: True \n");
            else;                       fprintf("--> Fit using EDCs?: False \n");
            end
        end

        % Value changed function: voigtMix_EditField
        function voigtMix_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.voigtMix_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.fit_args{4} = value;
            fprintf("--> Voigt Mixing (per cent Gaussian): " + app.fit_args{4} + " \n");
        end

        % Button pushed function: ExecuteFits_Button
        function ExecuteFits_ButtonPushed(app, event)
            %% 1 - Applying the fitting algorithm
            app.myData.fits = fit_algorithm(app, app.myData.fits, app.fit_args, app.edc_args, app.mdc_args);
            
            %% 2 - View the fitting data
            view_final_fig(app, app.myData.fits, app.isocut_args);
    
            %% 3 - Updating UI dependencies
            updateFitsFcn(app);
        end

        % Button pushed function: ViewEDCTab_Button
        function ViewEDCTab_ButtonPushed(app, event)
            view_edc_table(app, app.myData.fits.edc);
        end

        % Button pushed function: ViewMDCTab_Button
        function ViewMDCTab_ButtonPushed(app, event)
            view_mdc_table(app, app.myData.fits.mdc);
        end

        % Button pushed function: ViewFitSummary_Button
        function ViewFitSummary_ButtonPushed(app, event)
            view_summary(app, app.myData.fits);
        end

        % Button pushed function: SaveFitParams_Button
        function SaveFitParams_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', app.myData.FileName);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % Creates a data-structures with all UI table information
            ui_info.fdd_args    = app.myData.fits.fdd.uitable;
            ui_info.para_args   = app.myData.fits.para.uitable;
            ui_info.edc_args    = app.myData.fits.edc.uitable;
            ui_info.mdc_args    = app.myData.fits.mdc.uitable;
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStruc = ui_info; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(ui_info);
            close(wbar);
            waitbar(1,'Save complete!');
        end

        % Button pushed function: LoadFitParams_Button
        function LoadFitParams_ButtonPushed(app, event)
            %% 1 - Loading in the FileName and PathName of the selected data
            [FileName, PathName] = uigetfile({'*.mat'}, 'Choose a file of UI table parameters...');
            % - If Cancel is pressed, then return nothing
            if isequal(PathName,0) || isequal(FileName,0); return; end
            % - 1.1 - Load in the file selected
            ui_info = load(FileName); ui_info = ui_info.dataStruc;
            if ~isfield(ui_info, 'fdd_args'); error('Error loading file (wrong format)'); end
            %% 2 - Append UI info to UI tables
            app.fdd_UITable.Data = ui_info.fdd_args;
            app.para_UITable.Data = ui_info.para_args;
            app.edc_UITable.Data = ui_info.edc_args;
            app.mdc_UITable.Data = ui_info.mdc_args;
        end

        % Button pushed function: DeleteFits_Button
        function DeleteFits_ButtonPushed(app, event)
            %% 1 - Ask the user if they want to keep the new filtered data
            answer = questdlg('Are you sur eyou want to delete all fits?', ...
            	'Delete fits?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            %% 2 - Deleting all EDC fits
            if isfield(app.myData.fits, 'edc'); app.myData.fits.edc = []; end            
            %% 3 - Deleting all MDC fits
            if isfield(app.myData.fits, 'mdc'); app.myData.fits.mdc = []; end
            %% 4 - Updating UI dependencies
            %% - Updating UI dependencies
            if isempty(app.myData.fits.edc)
                app.ViewEDCTab_Button.Enable = 'off'; 
                app.edcSeries_Button.Enable    = 'off';
            end
            if isempty(app.myData.fits.mdc)
                app.ViewMDCTab_Button.Enable = 'off'; 
                app.mdcSeries_Button.Enable    = 'off';
            end
            app.ViewFitSummary_Button.Enable        = 'off';
            app.DeleteFits_Button.Enable            = 'off';
        end

        % Button pushed function: edcSeries_Button
        function edcSeries_ButtonPushed(app, event)
            view_edc_series(app, app.myData.fits);
            waitbar(1,'Save complete!');
        end

        % Button pushed function: mdcSeries_Button
        function mdcSeries_ButtonPushed(app, event)
            view_mdc_series(app, app.myData.fits);
            waitbar(1,'Save complete!');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CLOSEFIGS_Button = uibutton(app.UIFigure, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Closes all open figures, but leaves the current one open.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.UIFigure, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Restarts the app to its default state.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEGUI_Button = uibutton(app.UIFigure, 'push');
            app.RESIZEGUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEGUI_ButtonPushed, true);
            app.RESIZEGUI_Button.Tooltip = {'Resizes the app to its default size.'};
            app.RESIZEGUI_Button.Text = 'RESIZE GUI';
            app.ARPESGUI_Button = uibutton(app.UIFigure, 'push');
            app.ARPESGUI_Button.ButtonPushedFcn = createCallbackFcn(app, @ARPESGUI_ButtonPushed, true);
            app.ARPESGUI_Button.Tooltip = {'Back to the main ARPESGUI menu.'};
            app.ARPESGUI_Button.Text = 'ARPESGUI (main)';
            app.SAVE_Button = uibutton(app.UIFigure, 'push');
            app.SAVE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_ButtonPushed, true);
            app.SAVE_Button.Tooltip = {'Save the data from the app as a MATLAB structure file.'};
            app.SAVE_Button.Text = 'SAVE';
            app.TRANSFER_Button = uibutton(app.UIFigure, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.APPINFO_Button = uibutton(app.UIFigure, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.LOAD_Button = uibutton(app.UIFigure, 'push');
            app.LOAD_Button.ButtonPushedFcn = createCallbackFcn(app, @LOAD_ButtonPushed, true);
            app.LOAD_Button.Tooltip = {'Load in the ARPES data. Must be a single *.mat file that has been parsed through the ''data processing'' UI.'};
            app.LOAD_Button.Text = {'LOAD'; ''};
            app.AppTitle = uilabel(app.UIFigure);
            app.AppTitle.Text = 'Eb(k) state fitting';
            app.ViewSeries_Button = uibutton(app.UIFigure, 'push');
            app.ViewSeries_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewSeries_ButtonPushed, true);
            app.ViewSeries_Button.Tooltip = {'View series of the most recent data processing.'};
            app.ViewSeries_Button.Text = 'View Series';
            app.RESET_Button = uibutton(app.UIFigure, 'push');
            app.RESET_Button.ButtonPushedFcn = createCallbackFcn(app, @RESET_ButtonPushed, true);
            app.RESET_Button.Tooltip = {'Reset all data processing and re-load the initial data.'};
            app.RESET_Button.Text = 'RESET';
            app.DataManipulationPanel = uipanel(app.UIFigure);
            app.DataManipulationPanel.Title = 'Data manipulation';
            app.FilterDatagloballyPanel = uipanel(app.DataManipulationPanel);
            app.FilterDatagloballyPanel.Title = 'Filter Data (globally)';
            app.ExecuteFiltering_Button = uibutton(app.FilterDatagloballyPanel, 'push');
            app.ExecuteFiltering_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteFiltering_ButtonPushed, true);
            app.ExecuteFiltering_Button.Tooltip = {''};
            app.ExecuteFiltering_Button.Text = 'Execute Filtering';
            app.FilterTypeDropDownLabel = uilabel(app.FilterDatagloballyPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type:';
            app.FilterType_DropDown = uidropdown(app.FilterDatagloballyPanel);
            app.FilterType_DropDown.Items = {'Gaco2', 'GaussFlt2', 'LaplaceFlt2', 'CurvatureFlt2'};
            app.FilterType_DropDown.ValueChangedFcn = createCallbackFcn(app, @FilterType_DropDownValueChanged, true);
            app.FilterType_DropDown.Value = 'Gaco2';
            app.hwXhwYLabel = uilabel(app.FilterDatagloballyPanel);
            app.hwXhwYLabel.Text = 'hwX, hwY:';
            app.FilterParams_EditField = uieditfield(app.FilterDatagloballyPanel, 'text');
            app.FilterParams_EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParams_EditFieldValueChanged, true);
            app.FilterParams_EditField.Tooltip = {'Filter variables. Either a list of x,y smooth parameters or a single value for Laplace.'};
            app.FilterParams_EditField.Value = '0.5,0.5';
            app.ColormapLabel = uilabel(app.DataManipulationPanel);
            app.ColormapLabel.Text = 'Colormap:';
            app.Colormap_DropDown = uidropdown(app.DataManipulationPanel);
            app.Colormap_DropDown.Items = {'hot', 'parula', 'jet', 'gray', 'bone', 'hsv', 'cool', 'spring', 'summer', 'autumn', 'winter', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'flag'};
            app.Colormap_DropDown.ValueChangedFcn = createCallbackFcn(app, @Colormap_DropDownValueChanged, true);
            app.Colormap_DropDown.Tooltip = {'Select colormap of the ARPES data.'};
            app.Colormap_DropDown.Value = 'hot';
            app.RealignEbscanPanel = uipanel(app.DataManipulationPanel);
            app.RealignEbscanPanel.Title = 'Re-align Eb (scan)';
            app.ExecuteAlignment_Button = uibutton(app.RealignEbscanPanel, 'push');
            app.ExecuteAlignment_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteAlignment_ButtonPushed, true);
            app.ExecuteAlignment_Button.Tooltip = {''};
            app.ExecuteAlignment_Button.Text = 'Execute Alignment';
            app.ebWinEditFieldLabel = uilabel(app.RealignEbscanPanel);
            app.ebWinEditFieldLabel.Text = 'ebWin:';
            app.ebWin_EditField = uieditfield(app.RealignEbscanPanel, 'text');
            app.ebWin_EditField.ValueChangedFcn = createCallbackFcn(app, @ebWin_EditFieldValueChanged, true);
            app.ebWin_EditField.Tooltip = {'Approximate EF position.'};
            app.ebWin_EditField.Value = 'Auto';
            app.dEWinLabel = uilabel(app.RealignEbscanPanel);
            app.dEWinLabel.Text = 'dEWin:';
            app.dEWin_EditField = uieditfield(app.RealignEbscanPanel, 'text');
            app.dEWin_EditField.ValueChangedFcn = createCallbackFcn(app, @dEWin_EditFieldValueChanged, true);
            app.dEWin_EditField.Value = 'Auto';
            app.dESmoothEditFieldLabel = uilabel(app.RealignEbscanPanel);
            app.dESmoothEditFieldLabel.Text = 'dESmooth:';
            app.dESmooth_EditField = uieditfield(app.RealignEbscanPanel, 'text');
            app.dESmooth_EditField.ValueChangedFcn = createCallbackFcn(app, @dESmooth_EditFieldValueChanged, true);
            app.dESmooth_EditField.Value = 'Auto';
            app.featDropDownLabel = uilabel(app.RealignEbscanPanel);
            app.featDropDownLabel.Text = 'feat:';
            app.feat_DropDown = uidropdown(app.RealignEbscanPanel);
            app.feat_DropDown.Items = {'edge', 'peak'};
            app.feat_DropDown.ValueChangedFcn = createCallbackFcn(app, @feat_DropDownValueChanged, true);
            app.feat_DropDown.Tooltip = {'Defines the feature to align as a Fermi-edge or peak. '};
            app.feat_DropDown.Value = 'edge';
            app.RealignkxscanPanel = uipanel(app.DataManipulationPanel);
            app.RealignkxscanPanel.Title = 'Re-align kx (scan)';
            app.ExecuteAlignKx_Button = uibutton(app.RealignkxscanPanel, 'push');
            app.ExecuteAlignKx_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteAlignKx_ButtonPushed, true);
            app.ExecuteAlignKx_Button.Tooltip = {''};
            app.ExecuteAlignKx_Button.Text = 'Execute kx shift';
            app.kxShiftLabel = uilabel(app.RealignkxscanPanel);
            app.kxShiftLabel.Text = 'kxShift:';
            app.kxShift_EditField = uieditfield(app.RealignkxscanPanel, 'text');
            app.kxShift_EditField.ValueChangedFcn = createCallbackFcn(app, @kxShift_EditFieldValueChanged, true);
            app.kxShift_EditField.Tooltip = {'Offset value to shift the kx-axis origin by.'};
            app.kxShift_EditField.Value = '0';
            app.RescalekxscanPanel = uipanel(app.DataManipulationPanel);
            app.RescalekxscanPanel.Title = 'Re-scale kx (scan)';
            app.ExecuteScaleKx_Button = uibutton(app.RescalekxscanPanel, 'push');
            app.ExecuteScaleKx_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteScaleKx_ButtonPushed, true);
            app.ExecuteScaleKx_Button.Tooltip = {''};
            app.ExecuteScaleKx_Button.Text = 'Execute kx scaling';
            app.kxScaleLabel = uilabel(app.RescalekxscanPanel);
            app.kxScaleLabel.Text = 'kxScale:';
            app.kxScale_EditField = uieditfield(app.RescalekxscanPanel, 'text');
            app.kxScale_EditField.ValueChangedFcn = createCallbackFcn(app, @kxScale_EditFieldValueChanged, true);
            app.kxScale_EditField.Tooltip = {'Value to scale the kx-axis by.'};
            app.kxScale_EditField.Value = '1';
            app.ROIandBSPanel = uipanel(app.UIFigure);
            app.ROIandBSPanel.Title = '(1) Region of Interest & Background subtraction';
            app.BackgroundSubtractionPanel = uipanel(app.ROIandBSPanel);
            app.BackgroundSubtractionPanel.Title = 'Background Subtraction';
            app.polyOrderLabel = uilabel(app.BackgroundSubtractionPanel);
            app.polyOrderLabel.Text = 'polyOrder:';
            app.polyOrder_EditField = uieditfield(app.BackgroundSubtractionPanel, 'text');
            app.polyOrder_EditField.ValueChangedFcn = createCallbackFcn(app, @polyOrder_EditFieldValueChanged, true);
            app.polyOrder_EditField.Tooltip = {'Polynomial fit order for the background.'};
            app.polyOrder_EditField.Value = '3';
            app.ExecuteBS_Button = uibutton(app.BackgroundSubtractionPanel, 'push');
            app.ExecuteBS_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteBS_ButtonPushed, true);
            app.ExecuteBS_Button.Tooltip = {''};
            app.ExecuteBS_Button.Text = 'Execute BS';
            app.ROICroppingPanel = uipanel(app.ROIandBSPanel);
            app.ROICroppingPanel.Title = 'ROI Cropping';
            app.kLimsLabel = uilabel(app.ROICroppingPanel);
            app.kLimsLabel.Text = 'kLims:';
            app.kLimsROI_EditField = uieditfield(app.ROICroppingPanel, 'text');
            app.kLimsROI_EditField.ValueChangedFcn = createCallbackFcn(app, @kLimsROI_EditFieldValueChanged, true);
            app.kLimsROI_EditField.Tooltip = {'Limits of kx-axis for the ROI, defined over the range xmin:xmax.'};
            app.kLimsROI_EditField.Value = '[]';
            app.ebLimsLabel = uilabel(app.ROICroppingPanel);
            app.ebLimsLabel.Text = 'ebLims:';
            app.ebLimsROI_EditField = uieditfield(app.ROICroppingPanel, 'text');
            app.ebLimsROI_EditField.ValueChangedFcn = createCallbackFcn(app, @ebLimsROI_EditFieldValueChanged, true);
            app.ebLimsROI_EditField.Tooltip = {'Limits of eb-axis for the ROI, defined over the range ymin:ymax.'};
            app.ebLimsROI_EditField.Value = '[]';
            app.kLimsLabel_2 = uilabel(app.ROICroppingPanel);
            app.kLimsLabel_2.Text = 'kLims:';
            app.kLimsBS_EditField = uieditfield(app.ROICroppingPanel, 'text');
            app.kLimsBS_EditField.ValueChangedFcn = createCallbackFcn(app, @kLimsBS_EditFieldValueChanged, true);
            app.kLimsBS_EditField.Tooltip = {'Limits of kx-axis for the background, defined over the range xmin:xmax.'};
            app.kLimsBS_EditField.Value = '[]';
            app.ebLimsLabel_2 = uilabel(app.ROICroppingPanel);
            app.ebLimsLabel_2.Text = 'ebLims:';
            app.ebLimsBS_EditField = uieditfield(app.ROICroppingPanel, 'text');
            app.ebLimsBS_EditField.ValueChangedFcn = createCallbackFcn(app, @ebLimsBS_EditFieldValueChanged, true);
            app.ebLimsBS_EditField.Tooltip = {'Limits of eb-axis for the background, defined over the range ymin:ymax.'};
            app.ebLimsBS_EditField.Value = '[]';
            app.ROI_Label = uilabel(app.ROICroppingPanel);
            app.ROI_Label.Text = 'ROI';
            app.BACKGROUND_Label = uilabel(app.ROICroppingPanel);
            app.BACKGROUND_Label.Text = 'BACKGROUND';
            app.ExtractROI_Button = uibutton(app.ROICroppingPanel, 'push');
            app.ExtractROI_Button.ButtonPushedFcn = createCallbackFcn(app, @ExtractROI_ButtonPushed, true);
            app.ExtractROI_Button.Tooltip = {''};
            app.ExtractROI_Button.Text = 'Extract ROI & Background';
            app.ViewROI_Button = uibutton(app.ROICroppingPanel, 'push');
            app.ViewROI_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewROI_ButtonPushed, true);
            app.ViewROI_Button.Tooltip = {'View the ROI and background areas.'; ''};
            app.ViewROI_Button.Text = 'View ROI';
            app.SaveROI_Button = uibutton(app.ROICroppingPanel, 'push');
            app.SaveROI_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveROI_ButtonPushed, true);
            app.SaveROI_Button.Tooltip = {'Save only the data associated with the ROI shown here.'};
            app.SaveROI_Button.Text = 'Save ROI';
            app.DataFile_Label = uilabel(app.UIFigure);
            app.DataFile_Label.Text = '<<   select a processed *.mat ARPES data file';
            app.DatabrowserPanel = uipanel(app.UIFigure);
            app.DatabrowserPanel.Title = 'Data browser';
            app.CutIndexLabel_2 = uilabel(app.DatabrowserPanel);
            app.CutIndexLabel_2.Text = 'Cut Index:';
            app.CutScanIndex_EditField = uieditfield(app.DatabrowserPanel, 'numeric');
            app.CutScanIndex_EditField.Limits = [1 Inf];
            app.CutScanIndex_EditField.ValueDisplayFormat = '%.0f';
            app.CutScanIndex_EditField.Editable = 'off';
            app.CutScanIndex_EditField.Value = 1;
            app.CutValueLabel = uilabel(app.DatabrowserPanel);
            app.CutValueLabel.Text = 'Cut Value:';
            app.CutScanValue_EditField = uieditfield(app.DatabrowserPanel, 'numeric');
            app.CutScanValue_EditField.ValueDisplayFormat = '%.2f';
            app.CutScanValue_EditField.Editable = 'off';
            app.isoCutTypeDropDownLabel = uilabel(app.DatabrowserPanel);
            app.isoCutTypeDropDownLabel.Text = 'isoCut Type:';
            app.isoCutType_DropDown = uidropdown(app.DatabrowserPanel);
            app.isoCutType_DropDown.Items = {'EDC', 'MDC', ''};
            app.isoCutType_DropDown.ValueChangedFcn = createCallbackFcn(app, @isoCutType_DropDownValueChanged, true);
            app.isoCutType_DropDown.Tooltip = {'EDC (vertical cut) or MDC (horizontal cut) through ARPES data.'};
            app.isoCutType_DropDown.Value = 'EDC';
            app.CutIndexLabel = uilabel(app.DatabrowserPanel);
            app.CutIndexLabel.Text = 'Cut Index:';
            app.CutIndex_Slider = uislider(app.DatabrowserPanel);
            app.CutIndex_Slider.Limits = [1 100];
            app.CutIndex_Slider.ValueChangedFcn = createCallbackFcn(app, @CutIndex_SliderValueChanged, true);
            app.CutIndex_Slider.MinorTicks = [];
            app.CutIndex_Slider.Tooltip = {'Cut index for viewing the data and fits.'};
            app.CutIndex_Slider.Value = 1;
            app.isoCutWinLabel = uilabel(app.DatabrowserPanel);
            app.isoCutWinLabel.Text = 'isoCut Win (+/-):';
            app.isoCutWin_EditField = uieditfield(app.DatabrowserPanel, 'text');
            app.isoCutWin_EditField.ValueChangedFcn = createCallbackFcn(app, @isoCutWin_EditFieldValueChanged, true);
            app.isoCutWin_EditField.Tooltip = {'Cut window for the EDC / MDC at the cut value shown below.'};
            app.isoCutWin_EditField.Value = '0.01';
            app.ScanIndexLabel = uilabel(app.DatabrowserPanel);
            app.ScanIndexLabel.Text = 'Scan Index:';
            app.ScanIndex_EditField = uieditfield(app.DatabrowserPanel, 'numeric');
            app.ScanIndex_EditField.Limits = [1 Inf];
            app.ScanIndex_EditField.ValueDisplayFormat = '%.0f';
            app.ScanIndex_EditField.Editable = 'off';
            app.ScanIndex_EditField.Value = 1;
            app.ScanValueLabel = uilabel(app.DatabrowserPanel);
            app.ScanValueLabel.Text = 'Scan Value:';
            app.ScanValue_EditField = uieditfield(app.DatabrowserPanel, 'numeric');
            app.ScanValue_EditField.ValueDisplayFormat = '%.2f';
            app.ScanValue_EditField.Editable = 'off';
            app.ScanIndexSliderLabel = uilabel(app.DatabrowserPanel);
            app.ScanIndexSliderLabel.Text = 'Scan Index:';
            app.ScanIndex_Slider = uislider(app.DatabrowserPanel);
            app.ScanIndex_Slider.Limits = [1 100];
            app.ScanIndex_Slider.ValueChangedFcn = createCallbackFcn(app, @ScanIndex_SliderValueChanged, true);
            app.ScanIndex_Slider.MinorTicks = [];
            app.ScanIndex_Slider.Tooltip = {'Scan index for data browsing.'};
            app.ScanIndex_Slider.Value = 1;
            app.FittingPanel = uipanel(app.UIFigure);
            app.FittingPanel.Title = '(2) Executing the fitting alogrithm over the ROI';
            app.fdd_UITable = uitable(app.FittingPanel);
            app.fdd_UITable.ColumnName = {'T[K]'; 'Ef[eV]'; 'dEb[meV]'};
            app.fdd_UITable.RowName = {};
            app.fdd_UITable.ColumnEditable = [true true true];
            app.fdd_UITable.CellEditCallback = createCallbackFcn(app, @fdd_UITableCellEdit, true);
            app.fdd_UITable.Tooltip = {'UITable defining the Fermi-Dirac Distribution.'};
            app.para_UITable = uitable(app.FittingPanel);
            app.para_UITable.ColumnName = {'k0'; 'dk0'; 'eb0'; 'deb0'; 'm*'};
            app.para_UITable.RowName = {};
            app.para_UITable.ColumnEditable = [true true true true true];
            app.para_UITable.CellEditCallback = createCallbackFcn(app, @para_UITableCellEdit, true);
            app.para_UITable.Tooltip = {'UITable defining the initial estimate to the parabolic dispersion of N states.'};
            app.ExtractPara_Button = uibutton(app.FittingPanel, 'push');
            app.ExtractPara_Button.ButtonPushedFcn = createCallbackFcn(app, @ExtractPara_ButtonPushed, true);
            app.ExtractPara_Button.Tooltip = {''};
            app.ExtractPara_Button.Text = 'Extract Parabolae';
            app.ViewPara_Button = uibutton(app.FittingPanel, 'push');
            app.ViewPara_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewPara_ButtonPushed, true);
            app.ViewPara_Button.Tooltip = {'View the current initial estimate of the parabolic dispersions used in fitting.'};
            app.ViewPara_Button.Text = 'View Parabolae';
            app.ExtractFDD_Button = uibutton(app.FittingPanel, 'push');
            app.ExtractFDD_Button.ButtonPushedFcn = createCallbackFcn(app, @ExtractFDD_ButtonPushed, true);
            app.ExtractFDD_Button.Tooltip = {''};
            app.ExtractFDD_Button.Text = 'Extract FDD';
            app.ViewFDD_Button = uibutton(app.FittingPanel, 'push');
            app.ViewFDD_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewFDD_ButtonPushed, true);
            app.ViewFDD_Button.Tooltip = {'View the current FDD used in fitting.'; ''};
            app.ViewFDD_Button.Text = 'View FDD';
            app.edc_UITable = uitable(app.FittingPanel);
            app.edc_UITable.ColumnName = {'k-range (EDC)'; 'curve-type'; 'peak'; 'fwhm'; 'FDD?'; 'null shift'};
            app.edc_UITable.RowName = {};
            app.edc_UITable.ColumnEditable = [true true true true true true];
            app.edc_UITable.CellEditCallback = createCallbackFcn(app, @edc_UITableCellEdit, true);
            app.edc_UITable.Tooltip = {'UITable defining the EDC fit type / constraints for N states.'};
            app.mdc_UITable = uitable(app.FittingPanel);
            app.mdc_UITable.ColumnName = {'eb-range (MDC)'; 'curve-type'; 'peak'; 'fwhm'; 'symmetric?'; 'null shift'};
            app.mdc_UITable.RowName = {};
            app.mdc_UITable.ColumnEditable = [true true true true true true];
            app.mdc_UITable.CellEditCallback = createCallbackFcn(app, @mdc_UITableCellEdit, true);
            app.mdc_UITable.Tooltip = {'UITable defining the MDC fit type / constraints for N states.'};
            app.FittingalgorithmLabel = uilabel(app.FittingPanel);
            app.FittingalgorithmLabel.Text = 'Fitting algorithm:';
            app.fitType_DropDown = uidropdown(app.FittingPanel);
            app.fitType_DropDown.Items = {'scan - lsqcurvefit()', 'scan - lsqnonlin()'};
            app.fitType_DropDown.ValueChangedFcn = createCallbackFcn(app, @fitType_DropDownValueChanged, true);
            app.fitType_DropDown.Tooltip = {'Fitting algorithm to be employed.'};
            app.fitType_DropDown.Value = 'scan - lsqcurvefit()';
            app.fitMDC_CheckBox = uicheckbox(app.FittingPanel);
            app.fitMDC_CheckBox.ValueChangedFcn = createCallbackFcn(app, @fitMDC_CheckBoxValueChanged, true);
            app.fitMDC_CheckBox.Tooltip = {'Fit along the MDC direction.'};
            app.fitMDC_CheckBox.Text = 'Fit MDCs?';
            app.fitEDC_CheckBox = uicheckbox(app.FittingPanel);
            app.fitEDC_CheckBox.ValueChangedFcn = createCallbackFcn(app, @fitEDC_CheckBoxValueChanged, true);
            app.fitEDC_CheckBox.Tooltip = {'Fit along the EDC direction.'};
            app.fitEDC_CheckBox.Text = 'Fit EDCs?';
            app.VoigtMixingGEditFieldLabel = uilabel(app.FittingPanel);
            app.VoigtMixingGEditFieldLabel.Text = 'Voigt Mixing (%G):';
            app.voigtMix_EditField = uieditfield(app.FittingPanel, 'numeric');
            app.voigtMix_EditField.Limits = [0 1];
            app.voigtMix_EditField.ValueChangedFcn = createCallbackFcn(app, @voigtMix_EditFieldValueChanged, true);
            app.voigtMix_EditField.Tooltip = {'Voigt mixing parameter, which gives the % Gaussian contribution.'};
            app.voigtMix_EditField.Value = 0.5;
            app.SaveFitParams_Button = uibutton(app.FittingPanel, 'push');
            app.SaveFitParams_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveFitParams_ButtonPushed, true);
            app.SaveFitParams_Button.Tooltip = {'Save the FDD, parabolic, EDC and MDC fitting constraints defined in the UI tables above.'};
            app.SaveFitParams_Button.Text = 'Save fit constraints...';
            app.LoadFitParams_Button = uibutton(app.FittingPanel, 'push');
            app.LoadFitParams_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadFitParams_ButtonPushed, true);
            app.LoadFitParams_Button.Tooltip = {'Load in previously saved FDD, parabolic, EDC and MDC fitting constraints.'};
            app.LoadFitParams_Button.Text = 'Load fit constraints...';
            app.ViewEDCTab_Button = uibutton(app.FittingPanel, 'push');
            app.ViewEDCTab_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewEDCTab_ButtonPushed, true);
            app.ViewEDCTab_Button.Tooltip = {'View a table summarising all the EDC fit parameters.'; ''};
            app.ViewEDCTab_Button.Text = 'View EDC Fit Table';
            app.ViewMDCTab_Button = uibutton(app.FittingPanel, 'push');
            app.ViewMDCTab_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewMDCTab_ButtonPushed, true);
            app.ViewMDCTab_Button.Tooltip = {'View a table summarising all the MDC fit parameters.'};
            app.ViewMDCTab_Button.Text = 'View MDC Fit Table';
            app.ViewFitSummary_Button = uibutton(app.FittingPanel, 'push');
            app.ViewFitSummary_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewFitSummary_ButtonPushed, true);
            app.ViewFitSummary_Button.Tooltip = {'View a summary of both the EDC and MDC fits.'};
            app.ViewFitSummary_Button.Text = 'View Fit Summary';
            app.ExecuteFits_Button = uibutton(app.FittingPanel, 'push');
            app.ExecuteFits_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteFits_ButtonPushed, true);
            app.ExecuteFits_Button.Tooltip = {''; ''};
            app.ExecuteFits_Button.Text = 'Execute Fitting';
            app.DeleteFits_Button = uibutton(app.FittingPanel, 'push');
            app.DeleteFits_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteFits_ButtonPushed, true);
            app.DeleteFits_Button.Tooltip = {'Delete all fits.'};
            app.DeleteFits_Button.Text = 'DELETE FITS';
            app.edcSeries_Button = uibutton(app.FittingPanel, 'push');
            app.edcSeries_Button.ButtonPushedFcn = createCallbackFcn(app, @edcSeries_ButtonPushed, true);
            app.edcSeries_Button.Tooltip = {'Save a ''.png'' image series of the EDC fits as a function of kx.'};
            app.edcSeries_Button.Text = 'Save EDC Fit Series';
            app.mdcSeries_Button = uibutton(app.FittingPanel, 'push');
            app.mdcSeries_Button.ButtonPushedFcn = createCallbackFcn(app, @mdcSeries_ButtonPushed, true);
            app.mdcSeries_Button.Tooltip = {'Save a ''.png'' image series of the MDC fits as a function of eb.'};
            app.mdcSeries_Button.Text = 'Save MDC Fit Series';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARPESfitter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end