classdef ARPESisoe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CLOSEFIGS_Button              matlab.ui.control.Button
        RESTART_Button                matlab.ui.control.Button
        RESIZEGUI_Button              matlab.ui.control.Button
        ARPESGUI_Button               matlab.ui.control.Button
        SAVE_Button                   matlab.ui.control.Button
        TRANSFER_Button               matlab.ui.control.Button
        APPINFO_Button                matlab.ui.control.Button
        LOAD_Button                   matlab.ui.control.Button
        AppTitle                      matlab.ui.control.Label
        RESET_Button                  matlab.ui.control.Button
        IsoEDataExtractionPanel       matlab.ui.container.Panel
        ExtractIsoE_Button            matlab.ui.control.Button
        isoSliceRemap_CheckBox        matlab.ui.control.CheckBox
        isoSliceWinLabel_3            matlab.ui.control.Label
        isoSliceWin_EditField         matlab.ui.control.EditField
        IsoSliceTypeDropDownLabel     matlab.ui.control.Label
        isoSliceType_DropDown         matlab.ui.control.DropDown
        IsoEPlotterPanel              matlab.ui.container.Panel
        intLimsLabel                  matlab.ui.control.Label
        intLims_EditField             matlab.ui.control.EditField
        xLimsLabel                    matlab.ui.control.Label
        xLims_EditField               matlab.ui.control.EditField
        yLimsLabel                    matlab.ui.control.Label
        yLims_EditField               matlab.ui.control.EditField
        axColLabel                    matlab.ui.control.Label
        axCol_EditField               matlab.ui.control.EditField
        bzColLabel                    matlab.ui.control.Label
        bzCol_EditField               matlab.ui.control.EditField
        bgrndColLabel                 matlab.ui.control.Label
        bgrndCol_EditField            matlab.ui.control.EditField
        showAxes_CheckBox             matlab.ui.control.CheckBox
        showScan_CheckBox             matlab.ui.control.CheckBox
        showGrid_CheckBox             matlab.ui.control.CheckBox
        showBZ_CheckBox               matlab.ui.control.CheckBox
        showInterp_CheckBox           matlab.ui.control.CheckBox
        bzWidthLabel                  matlab.ui.control.Label
        bzWidth_EditField             matlab.ui.control.EditField
        ViewIsoEFig_Button            matlab.ui.control.Button
        SaveIsoEOnly_Button           matlab.ui.control.Button
        ColormapLabel                 matlab.ui.control.Label
        Colormap_DropDown             matlab.ui.control.DropDown
        DataFile_Label                matlab.ui.control.Label
        IsoCutBrowser                 matlab.ui.container.TabGroup
        LineProfilesTab               matlab.ui.container.Tab
        LineProfileDirectionLabel     matlab.ui.control.Label
        lpType_DropDown               matlab.ui.control.DropDown
        CutValueLabel                 matlab.ui.control.Label
        lpCutVal_EditField            matlab.ui.control.EditField
        CutWindowLabel                matlab.ui.control.Label
        lpWin_EditField               matlab.ui.control.EditField
        lpViewAll_Button              matlab.ui.control.Button
        lpAppend_Button               matlab.ui.control.Button
        lpClearAll_Button             matlab.ui.control.Button
        lpExtract_Button              matlab.ui.control.Button
        IsoContoursTab                matlab.ui.container.Tab
        icViewAll_Button              matlab.ui.control.Button
        icAppend_Button               matlab.ui.control.Button
        icClearAll_Button             matlab.ui.control.Button
        icExtract_Button              matlab.ui.control.Button
        IsoContourValueLabel          matlab.ui.control.Label
        icVal_EditField               matlab.ui.control.EditField
        preSmoothLabel                matlab.ui.control.Label
        icSmooth_EditField            matlab.ui.control.EditField
        MinimumAreaThresholdLabel     matlab.ui.control.Label
        icMinArea_EditField           matlab.ui.control.EditField
        IsoEVideoTab                  matlab.ui.container.Tab
        IsoELimitseVLabel             matlab.ui.control.Label
        vidLims_EditField             matlab.ui.control.EditField
        IsoEIntWindowLabel            matlab.ui.control.Label
        vidWin_EditField              matlab.ui.control.EditField
        SaveVideo_Button              matlab.ui.control.Button
        TotalnumberofframesLabel      matlab.ui.control.Label
        nFrames_EditField             matlab.ui.control.NumericEditField
        SaveSeries_Button             matlab.ui.control.Button
        BrilluoinZoneExtractionPanel  matlab.ui.container.Panel
        Extract2DBZ_Button            matlab.ui.control.Button
        CrystalDropDownLabel          matlab.ui.control.Label
        Crystal_DropDown              matlab.ui.control.DropDown
        PlaneSlicehklDropDownLabel    matlab.ui.control.Label
        PlaneSlice_DropDown           matlab.ui.control.DropDown
        aAngLabel                     matlab.ui.control.Label
        a_EditField                   matlab.ui.control.NumericEditField
        bAngLabel                     matlab.ui.control.Label
        b_EditField                   matlab.ui.control.NumericEditField
        cAngLabel                     matlab.ui.control.Label
        c_EditField                   matlab.ui.control.NumericEditField
        alphadegLabel                 matlab.ui.control.Label
        alpha_EditField               matlab.ui.control.NumericEditField
        betadegLabel                  matlab.ui.control.Label
        beta_EditField                matlab.ui.control.NumericEditField
        gammadegLabel                 matlab.ui.control.Label
        gamma_EditField               matlab.ui.control.NumericEditField
        IsoEDataCorrectionsPanel      matlab.ui.container.Panel
        FilterDataPanel               matlab.ui.container.Panel
        ExecuteFiltering_Button       matlab.ui.control.Button
        FilterTypeDropDownLabel       matlab.ui.control.Label
        FilterType_DropDown           matlab.ui.control.DropDown
        hwXhwYLabel                   matlab.ui.control.Label
        FilterParams_EditField        matlab.ui.control.EditField
        ShearCorrectionPanel          matlab.ui.container.Panel
        xslopeLabel                   matlab.ui.control.Label
        xSlope_EditField              matlab.ui.control.EditField
        xinterceptLabel               matlab.ui.control.Label
        xIntercept_EditField          matlab.ui.control.EditField
        yslopeLabel                   matlab.ui.control.Label
        ySlope_EditField              matlab.ui.control.EditField
        yinterceptLabel               matlab.ui.control.Label
        yIntercept_EditField          matlab.ui.control.EditField
        ExecuteSC_Button              matlab.ui.control.Button
        FileindexsLabel               matlab.ui.control.Label
        fileIndex_EditField           matlab.ui.control.EditField
        BackgroundSubtractionPanel    matlab.ui.container.Panel
        ExecuteBS_Button              matlab.ui.control.Button
        xWinLabel                     matlab.ui.control.Label
        xWinBS_EditField              matlab.ui.control.EditField
        OriginCorrectionsPanel        matlab.ui.container.Panel
        xshiftLabel                   matlab.ui.control.Label
        xShift_EditField              matlab.ui.control.EditField
        yshiftLabel                   matlab.ui.control.Label
        yShift_EditField              matlab.ui.control.EditField
        ExecuteOC_Button              matlab.ui.control.Button
        ScaleCorrectionPanel          matlab.ui.container.Panel
        xscaleLabel                   matlab.ui.control.Label
        xScale_EditField              matlab.ui.control.EditField
        yscaleLabel                   matlab.ui.control.Label
        yScale_EditField              matlab.ui.control.EditField
        ExecuteScale_Button           matlab.ui.control.Button
    end

    % Properties that correspond to public variables within the app
    properties (Access = public)
        % Defining the argument structures
        crystal_args    = cell(1,4);
        % Defining IsoE argument structures
        isoslice_args   = cell(1,3);
        isoe_plot_args  = cell(1,13);
        % Defining IsoE correction arguments
        fileIndex       = 1;
        filter_args     = cell(1,2);
        bsub_args       = cell(1,1);
        origin_args     = cell(1,2);
        shear_args      = cell(1,4);
        scale_args      = cell(1,2);
        % Defining the IsoE video arguments
        isoVid_args     = cell(1,2);
        % Other arguments
        lp_args         = cell(1,3);
        ic_args         = cell(1,3);
        % Defining the main data structure
        myData = struct();
        tempLP = struct();
        tempIC = struct();
    end
    
    methods (Access = public)
        % ---  Function to load in a single ARPES data-file that is unprocessed or previously processed
        function dataStr = load_isoe_data(app, FileNames)
            % dataStr = load_arpes_data(FileName)
            %   This function loads in the HDataF5 H5files of ARPES data from 
            %   the ADRESS beamline at the SLS. The output is a MATLAB
            %   data-structure that yields all the data and information.
            %
            %   REQ. FUNCTIONS:
            %
            %   IN:
            %   -   FileNames:           cell array of the .mat FileNames to load.
            %
            %   OUT:
            %   dataStr - MATLAB data structure containing all data below.
            %   -   .(FileName):        string of the FileName.
            %   -   .(H5file):          string of the raw .H5 filename of the data.
            %	-   .(Type):            string "Eb(k)", "Eb(kx,ky)" or "Eb(kx,kz)".
            %	-   .(meta.info):       [1xN] char array of scan information.
            %	-   .(meta.ep):         scalar of the Pass energy.
            %	-   .(raw_tht):         [1×nAngle] row vector of angles.
            % 	-   .(raw_eb):          [nEnergyx1] column vector of energy.
            %	-   .(deb):             scalar of the Analyser resolution.
            %   -   .(tltM):            scalar or [1×nScan] row vector (if Scan parameter for 3Data data).
            %	-   .(hv):              scalar or [1×nScan] row vector (if Scan parameter for 3Data data).
            %	-   .(dhv):             scalar of the Beamline resolution.
            %	-   .(raw_data):        2Data [nEnergy x nAngle] or 3Data [nEnergy x nAngle xnScan] data array.
            %	-   .(thtM):            scalar of the manipular Theta angle.
            %	-   .(Temp):            scalar of the sample temperature.
            % -- after processing (1) Eb alignment
            %	-   .(tht):             aligned 2D or 3D array of theta.
            %	-   .(eb):              aligned 2D or 3D array of energy.
            % -- after processing (2) intensity normalisation
            %	-   .(data):            2D or 3D array of the normalised data.
            %   -   .(surfNormX):       double or vector of surface normal vector.
            % -- after processing (3) k conversions
            %   -   .(kx):              2D or 3D array of kx from the Theta angle.
            %	-   .(ky):              1D, 2D or 3D array of kx from the Tilt angle.
            %	-   .(kz):              1D, 2D or 3D array of kx from the Photon Energy.
            % -- general slices / cuts of the data
            %	-   .(slices):          MATLAB data structure containing 2D slices through data.
            %	-   .(cuts):            MATLAB data structure containing 1D cuts through data.
            % -- after analysing kf
            %	-   .(kf):              MATLAB data structure containing all kf analysis.
            % -- after analysing isoe
            %	-   .(isoe):            MATLAB cell structure containing all isoe analysis.
            %	-   .(bz):              MATLAB data structure Brilluoin Zone information.
            % -- after executing state-fitting
            %	-   .(fits):            MATLAB data structure containing all fitting analysis.
            
            disp('Loading processed ARPES data...')
            wbar = waitbar(0, 'Loading in ARPES data...', 'Name', 'load_arpes_data');
            wbar.Children.Title.Interpreter = 'none';

            %% 1 - Filing through each of the file-names to extract data
            FileNames = cellstr(FileNames);
            % - Iterating through all the FileNames to extract the ARPES data
            for i = 1:length(FileNames)
                waitbar(i/length(FileNames), wbar, sprintf("Loading '%s'...", FileNames{i}), 'Name', 'load_isoe_data', 'interpreter', 'none');
                arpes_data = load(FileNames{i}); data{i} = arpes_data.dataStruc;
            end
            
            %% 2 - Checking if the data has already been processed
            % -- If previous analysis has been made on a single file
            if isfield(data{1}, 'isoe') && length(data) == 1
                dataStr = data{1};
                
            % -- If no previous analysis has been made
            else
                %% A - Checking that all scans are of the same type, otherwise error
                if length(data) > 1
                    % - Checking that all scans are of the same type
                    for j = 1:length(data); ScanType(j) = data{j}.Type; end
                    ScanType(ScanType ~= ScanType(1)) = 0;
                    for j = 1:length(ScanType)
                        if ScanType(j) == '0'
                            error('Only a single type of Eb(kx,ky) or Eb(kx,kz) can be loaded at once...');
                        end
                    end
                    % - Checking that the stage of analysis is consistent across all data
                    for j = 1:length(data)
                        [x, y, z, d] = find_data_fields(app, data{j});
                        xField(j) = string(x);
                        yField(j) = string(y);
                        zField(j) = string(z);
                        dField(j) = string(d);
                    end
                    xField(xField ~= xField(1)) = 0;
                    yField(yField ~= yField(1)) = 0;
                    zField(zField ~= zField(1)) = 0;
                    dField(dField ~= dField(1)) = 0;
                    for j = 1:length(xField)
                        if xField(j) == '0' || yField(j) == '0' || zField(j) == '0' || dField(j) == '0'
                            error('ARPES processing is not consistent...');
                        end
                    end
                end
                
                %% B - Assigning the data into its data structure form
                % - Extracting the fields to be used with most recent processing
                [xField, yField, zField, dField] = find_data_fields(app, data{1});
                for i = 1:length(data)
                    if i == 1
                        dataStr = data{i};
                        dataStr.isoe_FileNames = FileNames;
                        dataStr.isoe = [];
                        dataStr.isoe{i}.FileName    = string(FileNames{i});
                        dataStr.isoe{i}.Type        = data{i}.Type;
                        if data{i}.Type == "Eb(kx,ky)"
                            dataStr.isoe{i}.scanAxis = data{i}.tltM;
                        elseif data{i}.Type == "Eb(kx,kz)"
                            dataStr.isoe{i}.scanAxis = data{i}.hv;
                        end
                        dataStr.isoe{i}.(xField)    = data{i}.(xField);
                        dataStr.isoe{i}.(yField)    = data{i}.(yField);
                        dataStr.isoe{i}.(zField)    = data{i}.(zField);
                        dataStr.isoe{i}.(dField)    = data{i}.(dField);
                    else
                        dataStr.isoe{i}.FileName    = string(FileNames{i});
                        dataStr.isoe{i}.Type        = data{i}.Type;
                        if data{i}.Type == "Eb(kx,ky)"
                            dataStr.isoe{i}.scanAxis = data{i}.tltM;
                        elseif data{i}.Type == "Eb(kx,kz)"
                            dataStr.isoe{i}.scanAxis = data{i}.hv;
                        end
                        dataStr.isoe{i}.(xField)    = data{i}.(xField);
                        dataStr.isoe{i}.(yField)    = data{i}.(yField);
                        dataStr.isoe{i}.(zField)    = data{i}.(zField);
                        dataStr.isoe{i}.(dField)    = data{i}.(dField);
                    end
                end
            end


            %% Close wait-bar
            close(wbar);
        end
        
        % --- General function to find the stage of the processing
        function [xField, yField, zField, dField] = find_data_fields(~, dataStr)
            % [xField, yField, zField, dField] = find_data_fields(dataStr)
            %   This function determines the most recent processed 
            %   fields of the ARPES data-structure so that the correct
            %   post-processing can be performed in any order. The
            %   fields can be used to explicitly call a field within dataStr
            %   by using dataStr.(xField) for example.
            %
            %   REQ. FUNCTIONS: none
            %
            %   IN:
            %   -   dataStr              loaded MATLAB data structure.
            %
            %   OUT:
            %   -   xField:            char of the most recent x-field ('raw_tht', 'tht', 'kx')
            %	-   yField:            char of the most recent y-field ('raw_eb', 'eb')
            %   -   zField:            char of the most recent z-field ('hv / tltM', 'kz / ky')
            %	-   dField:            char of the most recent d-field ('raw_data', 'data')
            
            % 1 - EbAlign->Normalise->kConvert fields
            if isfield(dataStr, 'kx')
                xField = 'kx'; yField = 'eb'; dField = 'data';
                if dataStr.Type == "Eb(k)"; zField = 'hv';
                elseif dataStr.Type == "Eb(kx,ky)"; zField = 'ky';
                elseif dataStr.Type == "Eb(kx,kz)"; zField = 'kz';
                end
            % 2 - EbAlign->Normalise fields
            elseif isfield(dataStr, 'data')
                xField = 'tht'; yField = 'eb'; dField = 'data';
                if dataStr.Type == "Eb(k)"; zField = 'hv';
                elseif dataStr.Type == "Eb(kx,ky)"; zField = 'tltM';
                elseif dataStr.Type == "Eb(kx,kz)"; zField = 'hv';
                end
            % 3 - EbAlign fields
            elseif isfield(dataStr, 'eb')
                xField = 'tht'; yField = 'eb'; dField = 'raw_data';
                if dataStr.Type == "Eb(k)"; zField = 'hv';
                elseif dataStr.Type == "Eb(kx,ky)"; zField = 'tltM';
                elseif dataStr.Type == "Eb(kx,kz)"; zField = 'hv';
                end
            % 4 - Raw, unprocessed data fields
            else
                xField = 'raw_tht'; yField = 'raw_eb'; dField = 'raw_data';
                if dataStr.Type == "Eb(k)"; zField = 'hv';
                elseif dataStr.Type == "Eb(kx,ky)"; zField = 'tltM';
                elseif dataStr.Type == "Eb(kx,kz)"; zField = 'hv';
                end
            end
        end
        
        % ---  Function to extract all real- and reciprocal-space vectors based on crystallographic input
        function [realStr, reciStr] = extract_lattice(app, crystal_args, plotFig)
            % crystStr = extract_lattice(crystal_args)
            %   This is a function that extracts the crystallographic vectors
            %   of a general crystal structure in both real- and reciprocal-
            %   space based on the inputs. All 14 Bravais lattices can be 
            %   defined, including primitive-, base-, face- and body-centered
            %   lattices. The outputs are two MATLAB structures that contain
            %   all of the real- and reciprocal-space data.
            %
            %   IN:
            %   (1)   crystal_args:   1x4 cell of {crystal, (hkl), (a, b, c), (alpha, beta, gamma)} described below;
            %    -crystalType;          "CUB-Oh", "BCC-Oh", "FCC-Oh", "HEX-D6h", "RHL-D3d", "TET-D4h", "BCT-D4h", "ORC-D2h", 
            %                           "ORCC-D2h", "ORCI-D2h", "ORCF-D2h", "MCL-C2h", "MCLC-C2h",  "TRI-Ci" .
            %    -(a, b, c);            1x3 row-vector of the side lengths of the crystal systems unit cell (Angstroms).
            %    -(alpha, beta, gamma); 1x3 row vector the opposite angles of the crystal systems unit cell (degrees).
            %   (2) plotFig:            if 1, will plot figure summary, otherwise it wont.
            %
            %   OUT:
            %   realStr: MATLAB data structure containing all real-space information below.
            %   -   .(crystal):     string of the crystal type.
            %   -   .(T1):          a1 primitive translation vector.
            %	-   .(T2):          a2 primitive translation vector.
            %   -   .(T3):          a3 primitive translation vector.
            %   -   .(Tb0):         atomic basis vectors.
            %	-   .(Tvol):        volume of the real-space unit cell.
            %	-   .(T):           atomic positions over multiple primitive-translations to show crystal structure.
            %	-   .(Tlims):       axes limits in 3D for the lattice structure T.
            %	-   .(Tnn):         linked lines between nearest neighbour atomsdisplayed in .(T).
            %	-   .(Tws):         Wigner-Seitz cell in real-space.
            %   reciStr: MATLAB data structure containing all reciprocal-space information below.
            %   -   .(crystal):     string of the crystal type.
            %   -   .(G1):          b1 primitive reciprocal vector.
            %	-   .(G2):          b2 primitive reciprocal vector.
            %   -   .(G3):          b3 primitive reciprocal vector.
            %   -   .(Gb0):         atomic basis vectors in reciprocal space.
            %	-   .(Gvol):        volume of the reciprocal-space unit cell.
            %	-   .(G):           reciprocal lattice over multiple translations.
            %	-   .(Glims):       axes limits in 3D for the lattice structure G.
            %	-   .(Gnn):         linked lines between nearest neighbour reciprocal-points displayed in .(G).
            %	-   .(Gbz):         First Brilluoin zone in reciprocal-space.
            
            %% Default parameters
            if nargin < 3; plotFig = 0; end
            if isempty(plotFig); plotFig = 0;  end
            disp('-> Extracting 3D BZ...')
            wbar = waitbar(0, 'Extracting crystallographic variables...', 'Name', 'extract_lattice');
            
            %% Initialising the input variables
            crystal     = crystal_args{1};
            a           = crystal_args{3}(1);
            b           = crystal_args{3}(2);
            c           = crystal_args{3}(3);
            alpha       = crystal_args{4}(1) * pi/180;
            beta        = crystal_args{4}(2) * pi/180;
            gamma       = crystal_args{4}(3) * pi/180;
            
            %% 1 - Defining the real-space unit cell from a general triclinic geometry
            % -- Extracting the triclinic coefficients for real-space unit vectors
            w1 = cos(alpha) - cos(beta) * cos(gamma)/(sin(beta) * sin(gamma));
            w2 = sin(gamma)^2 - cos(beta)^2 - cos(alpha)^2 + 2*cos(alpha) *cos(beta) * cos(gamma);
            w2 = sqrt(w2) / (sin(beta) * sin(gamma));
            % -- Defining the real-space unit vectors
            T1 = [a, 0, 0];
            T2 = [b * cos(gamma), b	* sin(gamma), 0];
            T3 = [c * cos(beta), c * w1*sin(beta), c*w2*sin(beta)];
            % -- Extracting the volume of the real-space unit cell
            Tvol = cross(T1, T2)*T3';
            %% 2 - Defining the basis of the Bravais Lattice out of the 14 possible choices
            % - 2.1 - The 7 Primitive (Simple) Bravais lattices
            if crystal == "CUB-Oh" || crystal == "HEX-D6h" || crystal == "RHL-D3d" ||...
                    crystal == "TET-D4h" || crystal == "ORC-D2h" || crystal == "MCL-C2h" ||...
                    crystal == "TRI-Ci"
                Tb0 = [0, 0, 0];
            % - 2.2 - The 2 Base-Centered Bravais Lattices
            elseif crystal == "ORCC-D2h" || crystal == "MCLC-C2h"
                Tb0 = [0, 0, 0;...
                    0.5*(T1+T2)];
            % - 2.3 - The 3 Body-Centered Bravais Lattices
            elseif crystal == "BCC-Oh" || crystal == "BCT-D4h" || crystal == "ORCI-D2h"
                Tb0 = [0, 0, 0;...
                    0.5*(T1+T2+T3)];
            % - 2.4 - The 2 Face-Centered Bravais Lattices
            elseif crystal == "FCC-Oh" || crystal == "ORCF-D2h"
                Tb0 = [0, 0, 0;...
                    0.5*(T1+T2);...
                    0.5*(T1+T3);...
                    0.5*(T2+T3)];
            end
            %% 3 - Extracting the real-space lattice over multiple translations of T
            l=1;
            for i=-1:1
                for j=0:2
                    for k=-1:1
                        for n = 1:size(Tb0,1)
                            T(l,:)=i*T1+j*T2+k*T3+Tb0(n,:);
                            l=l+1;
                        end
                    end
                end
            end
            % - 3.1 - Finding the axes limited for a 2x2 super structure as in T
            if crystal == "CUB-Oh" || crystal == "HEX-D6h" || crystal == "RHL-D3d" ||...
                    crystal == "TET-D4h" || crystal == "ORC-D2h" || crystal == "MCL-C2h" ||...
                    crystal == "TRI-Ci"
                Tlims = 1.04*[min(T(:,1)), max(T(:,1)), min(T(:,2)), max(T(:,2)), min(T(:,3)), max(T(:,3))];
            elseif crystal == "ORCC-D2h" || crystal == "MCLC-C2h" || crystal == "BCC-Oh" ||...
                    crystal == "BCT-D4h" || crystal == "ORCI-D2h" || crystal == "FCC-Oh" ||...
                    crystal == "ORCF-D2h" 
                Tlims = 1.04*[-1*norm(T1), norm(T1), 0, 2*norm(T2),-1*norm(T3), norm(T3)];
            end
            %% 4 - Finding all vector paths to link all nearest neighbour points
            Tnn = [];
            % - Filing through all possible points given in the T-matrix
            for i = 1:length(T)-1
                waitbar(i/length(T)-1, wbar, 'Extracting real-space lattice...', 'Name', 'extract_lattice');
                for j = i+1:length(T)
                    % -- Finding the Euclidean distance between the two lattice points
                    dist  = sqrt((T(i,1)-T(j,1)).^2+(T(i,2)-T(j,2)).^2+(T(i,3)-T(j,3)).^2);
                    %% 4.1 - Applying geometrical constraints for nearest neighbour points
                    
                    % --- For the 7 Primitive (Simple) Bravais lattices
                    % Points are linked if their distance is identical to the
                    % length of T1, T2 or T3.
                    if crystal_args{1} == "CUB-Oh" || crystal_args{1} == "HEX-D6h" || crystal_args{1} == "RHL-D3d" ||...
                            crystal_args{1} == "TET-D4h" || crystal_args{1} == "ORC-D2h" || crystal_args{1} == "MCL-C2h" ||...
                            crystal_args{1} == "TRI-Ci"
                        if (abs(dist-norm(T1))) <= 0.0001 || (abs(dist-norm(T2))) <= 0.0001 || (abs(dist-norm(T3))) <= 0.0001                
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        end
                        
                    % --- For the 2 Base-Centered Bravais Lattices
                    % Points are linked if their distance is equal to the length
                    % of the BCC basis vector. Also, if their length is equal to 
                    % T1, T2 or T3, with the condition that it is not divisible by
                    % the BCC basis vector (as the base-centered points are only
                    % joined within the horizontal plane of the vertices).
                    elseif crystal_args{1} == "ORCC-D2h" || crystal_args{1} == "MCLC-C2h" 
                        if abs(dist-norm(Tb0(2,:))) <= 0.0001
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        elseif (mod(T(i,2), 2*Tb0(2,2)) == 0 ||  abs(T(i,2)) <= 0.0001 ) && ((abs(dist-norm(T1))) <= 0.0001 || (abs(dist-norm(T2))) <= 0.0001 || (abs(dist-norm(T3))) <= 0.0001)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        end
                        
                    % --- For the 3 Body-Centered Bravais Lattices
                    % Points are linked if their distance is equal to the length
                    % of the BCC basis vector. Also, if their length is equal to 
                    % T1, T2 or T3, with the condition that it is not divisible by
                    % the BCC basis vector (as the body-centered points are only
                    % joined radially outwards to vertices of unit cell).
                    elseif crystal_args{1} == "BCC-Oh" || crystal_args{1} == "BCT-D4h" || crystal_args{1} == "ORCI-D2h"
                        if abs(dist-norm(Tb0(2,:))) <= 0.0001
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        elseif mod(T(i,3), 2*Tb0(2,3)) == 0 && ((abs(dist-norm(T1))) <= 0.0001 || (abs(dist-norm(T2))) <= 0.0001 || (abs(dist-norm(T3))) <= 0.0001)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        end
                        
                    % --- For the 2 Face-Centered Bravais Lattices
                    % Points are linked if their distance is equal to the length
                    % of the FCC basis vector, with the proviso that the two points
                    % are coplanar and not an FCC point. This is applied along each
                    % dimension. Furthermore, if their length is equal to T1, T2 or T3, 
                    % with the condition that it is not divisible by the FCC basis vector
                    % (as the face-centered points are only joined radially outwards to
                    % vertices of unit cell, not to other FCC points).
                    elseif crystal_args{1} == "FCC-Oh" || crystal_args{1} == "ORCF-D2h"
                        if abs(dist-norm(Tb0(2,:))) <= 0.001 && abs(T(i,1)-T(j,1)) <= 0.001 && (abs(T(i,1)) <= 0.001 || mod(T(i,1), 2*Tb0(2,1)) == 0)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        elseif abs(dist-norm(Tb0(2,:))) <= 0.001 && abs(T(i,2)-T(j,2)) <= 0.001 && (abs(T(i,2)) <= 0.001 || mod(T(i,2), 2*Tb0(2,2)) == 0)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        elseif abs(dist-norm(Tb0(3,:))) <= 0.001 && abs(T(i,3)-T(j,3)) <= 0.001 && (abs(T(i,3)) <= 0.001 || mod(T(i,3), 2*Tb0(3,3)) == 0)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        elseif (abs(dist-norm(T1))) <= 0.0001 && abs(T(i,1)-T(j,1)) <= 0.001 && (abs(T(i,1)) <= 0.001 || mod(T(i,1), 2*Tb0(2,1)) == 0) && (abs(T(i,3)) <= 0.001 || mod(T(i,3), 2*Tb0(3,3)) == 0)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        elseif (abs(dist-norm(T2))) <= 0.0001 && abs(T(i,2)-T(j,2)) <= 0.001 && (abs(T(i,2)) <= 0.001 || mod(T(i,2), 2*Tb0(2,2)) == 0) && (abs(T(i,3)) <= 0.001 || mod(T(i,3), 2*Tb0(3,3)) == 0)
                            Tnn = vertcat(Tnn, [[T(i,1);T(j,1)], [T(i,2);T(j,2)], [T(i,3);T(j,3)]]);
                        end
                        
                    end
                end
            end
            %% 5 - Computing the Voronoi diagram, which is identical to the Wigner-Seitz cell
            % - Calculating Voronoi diagram
            [T_c, T_v] = voronoin(T); 
            T_nx = T_c(T_v{floor(l/2)},:);
            T_cell = convhulln(T_nx);
            % - Defining the T_ws variable and shifting Wigner-Seitz cell to +T2
            for i = 1:size(T_cell,1)
                Tws{i,1} = T_nx(T_cell(i,:),1)-T(floor(l/2),1)+T2(1);
                Tws{i,2} = T_nx(T_cell(i,:),2)-T(floor(l/2),2)+T2(2);
                Tws{i,3} = T_nx(T_cell(i,:),3)-T(floor(l/2),3)+T2(3);
            end
            
            
            %% A - Defining the reciprocal-space unit cell
            % - A.1 - For simple and base-centered lattices, use T-vectors only
            if crystal_args{1} == "CUB-Oh" || crystal_args{1} == "HEX-D6h" || crystal_args{1} == "RHL-D3d" ||...
                    crystal_args{1} == "TET-D4h" || crystal_args{1} == "ORC-D2h" || crystal_args{1} == "MCL-C2h" ||...
                    crystal_args{1} == "TRI-Ci" || crystal_args{1} == "ORCC-D2h" || crystal_args{1} == "MCLC-C2h"
                G1 = 2*pi*cross(T2,T3)/Tvol;
                G2 = 2*pi*cross(T3,T1)/Tvol;
                G3 = 2*pi*cross(T1,T2)/Tvol;
            % - A.2 - For body- and face-centered lattices, scale by a factor of 2
            elseif crystal_args{1} == "BCC-Oh" || crystal_args{1} == "BCT-D4h" || crystal_args{1} == "ORCI-D2h" ||...
                    crystal_args{1} == "FCC-Oh" || crystal_args{1} == "ORCF-D2h"
                G1 = 4*pi*cross(T2,T3)/Tvol;
                G2 = 4*pi*cross(T3,T1)/Tvol;
                G3 = 4*pi*cross(T1,T2)/Tvol;
            end
            % -- Extracting the volume of reciprocal unit cell
            Gvol = cross(G1,G2)*G3';
            %% B - Extracting the corresponding Brilluoin Zone in reciprocal-space
            % - B.1 - The 7 Primitive (Simple) Bravais lattices yield no basis
            if crystal_args{1} == "CUB-Oh" || crystal_args{1} == "HEX-D6h" || crystal_args{1} == "RHL-D3d" ||...
                    crystal_args{1} == "TET-D4h" || crystal_args{1} == "ORC-D2h" || crystal_args{1} == "MCL-C2h" ||...
                    crystal_args{1} == "TRI-Ci"
                Gb0 = [0, 0, 0];
            % - B.2 - The 2 Base-Centered Bravais Lattices turn into base-centered in reciprocal space
            elseif crystal_args{1} == "ORCC-D2h" || crystal_args{1} == "MCLC-C2h"
                Gb0 = [0, 0, 0;...
                    0.5*(G1+G2)];
            % - B.3 - The 3 Body-Centered Bravais Lattice turns to FCC in reciprocal space
            elseif crystal_args{1} == "BCC-Oh" || crystal_args{1} == "BCT-D4h" || crystal_args{1} == "ORCI-D2h"
                Gb0 = [0, 0, 0;...
                    0.5*(G1+G2);...
                    0.5*(G1+G3);...
                    0.5*(G2+G3)];
            % - B.4 - The 2 Face-Centered Bravais Lattices turns to BCC in reciprocal space
            elseif crystal_args{1} == "FCC-Oh" || crystal_args{1} == "ORCF-D2h"
                Gb0 = [0, 0, 0;...
                    0.5*(G1+G2+G3)];
            end
            %% C - Extracting the reciprocal-space lattice over multiple translations of G
            l=1;
            for i=-1:1
                for j=0:2
                    for k=-1:1
                        for n = 1:size(Gb0,1)
                            G(l,:)=i*G1+j*G2+k*G3+Gb0(n,:);
                            l=l+1;
                        end
                    end
                end
            end
            % - C.1 - Finding the axes limited for a 2x2 super structure as in G
            if crystal == "CUB-Oh" || crystal == "HEX-D6h" || crystal == "RHL-D3d" ||...
                    crystal == "TET-D4h" || crystal == "ORC-D2h" || crystal == "MCL-C2h" ||...
                    crystal == "TRI-Ci"
                Glims = 1.04*[min(G(:,1)), max(G(:,1)), min(G(:,2)), max(G(:,2)), min(G(:,3)), max(G(:,3))];
            elseif crystal == "ORCC-D2h" || crystal == "MCLC-C2h" || crystal == "BCC-Oh" ||...
                    crystal == "BCT-D4h" || crystal == "ORCI-D2h" || crystal == "FCC-Oh" ||...
                    crystal == "ORCF-D2h"
                Glims = 1.04*[-1*norm(G1), norm(G1), 0, 2*norm(G2),-1*norm(G3), norm(G3)];
            end
            
            %% D - Finding all vector paths to link all nearest neighbour points
            Gnn = [];
            % - Filing through all possible points given in the G-matrix
            for i = 1:length(G)-1
                waitbar(i/length(G)-1, wbar, 'Extracting reciprocal-space lattice...', 'Name', 'extract_lattice');
                for j = i+1:length(G)
                    % -- Finding the Euclidean distance between the two lattice points
                    dist  = sqrt((G(i,1)-G(j,1)).^2+(G(i,2)-G(j,2)).^2+(G(i,3)-G(j,3)).^2);
                    %% 4.1 - Applying geometrical constraints for nearest neighbour points
                    
                    % --- For the 7 Primitive (Simple) Reciprocal lattices
                    % Points are linked if their distance is identical to the
                    % length of G1, G2 or G3.
                    if crystal_args{1} == "CUB-Oh" || crystal_args{1} == "HEX-D6h" || crystal_args{1} == "RHL-D3d" ||...
                            crystal_args{1} == "TET-D4h" || crystal_args{1} == "ORC-D2h" || crystal_args{1} == "MCL-C2h" ||...
                            crystal_args{1} == "TRI-Ci"
                        if (abs(dist-norm(G1))) <= 0.0001 || (abs(dist-norm(G2))) <= 0.0001 || (abs(dist-norm(G3))) <= 0.0001                
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        end
                        
                    % --- For the 2 Base-Centered Reciprocal Lattices
                    % Points are linked if their distance is equal to the length
                    % of the BCC basis vector. Also, if their length is equal to 
                    % G1, G2 or G3, with the condition that it is not divisible by
                    % the BCC basis vector (as the base-centered points are only
                    % joined within the horizontal plane of the vertices).
                    elseif crystal_args{1} == "ORCC-D2h" || crystal_args{1} == "MCLC-C2h" 
                        if abs(dist-norm(Gb0(2,:))) <= 0.0001
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif (mod(G(i,2), 2*Gb0(2,2)) == 0 ||  abs(G(i,2)) <= 0.0001 ) && ((abs(dist-norm(G1))) <= 0.0001 || (abs(dist-norm(G2))) <= 0.0001 || (abs(dist-norm(G3))) <= 0.0001)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        end
                        
                    % --- For the 2 Body-Centered Reciprocal Lattices
                    % Points are linked if their distance is equal to the length
                    % of the BCC basis vector. Also, if their length is equal to 
                    % G1, G2 or G3, with the condition that it is not divisible by
                    % the BCC basis vector (as the body-centered points are only
                    % joined radially outwards to vertices of unit cell).
                    elseif crystal_args{1} == "FCC-Oh" || crystal_args{1} == "ORCF-D2h"
                        if abs(dist-norm(Gb0(2,:))) <= 0.0001
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif (mod(G(i,3), 2*Gb0(2,3)) == 0 || abs(G(i,3)) <= 0.001) && ((abs(dist-norm(G1))) <= 0.0001 || (abs(dist-norm(G2))) <= 0.0001 || (abs(dist-norm(G3))) <= 0.0001)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif (mod(G(i,2), 2*Gb0(2,2)) == 0 || abs(G(i,2)) <= 0.001) && ((abs(dist-norm(G1))) <= 0.0001 || (abs(dist-norm(G2))) <= 0.0001 || (abs(dist-norm(G3))) <= 0.0001)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        end
                        
                    % --- For the 3 Face-Centered Reciprocal Lattices
                    % Points are linked if their distance is equal to the length
                    % of the FCC basis vector, with the proviso that the two points
                    % are coplanar and not an FCC point. This is applied along each
                    % dimension. Furthermore, if their length is equal to G1, G2 or G3, 
                    % with the condition that it is not divisible by the FCC basis vector
                    % (as the face-centered points are only joined radially outwards to
                    % vertices of unit cell, not to other FCC points).
                    elseif crystal_args{1} == "BCC-Oh" || crystal_args{1} == "BCT-D4h" || crystal_args{1} == "ORCI-D2h"
                        if abs(dist-norm(Gb0(2,:))) <= 0.001 && abs(G(i,1)-G(j,1)) <= 0.001 && (abs(G(i,1)) <= 0.001 || mod(G(i,1), 2*Gb0(2,1)) == 0)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif abs(dist-norm(Gb0(2,:))) <= 0.001 && abs(G(i,2)-G(j,2)) <= 0.001 && (abs(G(i,2)) <= 0.001 || mod(G(i,2), 2*Gb0(2,2)) == 0)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif abs(dist-norm(Gb0(3,:))) <= 0.001 && abs(G(i,3)-G(j,3)) <= 0.001 && (abs(G(i,3)) <= 0.001 || mod(G(i,3), 2*Gb0(3,3)) == 0)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif (abs(dist-norm(G1))) <= 0.0001 && abs(G(i,1)-G(j,1)) <= 0.001 && (abs(G(i,1)) <= 0.001 || mod(G(i,1), 2*Gb0(2,1)) == 0) && (abs(G(i,3)) <= 0.001 || mod(G(i,3), 2*Gb0(3,3)) == 0)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        elseif (abs(dist-norm(G2))) <= 0.0001 && abs(G(i,2)-G(j,2)) <= 0.001 && (abs(G(i,2)) <= 0.001 || mod(G(i,2), 2*Gb0(2,2)) == 0) && (abs(G(i,3)) <= 0.001 || mod(G(i,3), 2*Gb0(3,3)) == 0)
                            Gnn = vertcat(Gnn, [[G(i,1);G(j,1)], [G(i,2);G(j,2)], [G(i,3);G(j,3)]]);
                        end
                    end
                end
            end
            %% E - Computing the Voronoi diagram, which is identical to the First Brilluoin Zone
            % - Calculating Voronoi diagram
            [G_c, G_v] = voronoin(G); 
            G_nx = G_c(G_v{floor(l/2)},:);
            G_cell = convhulln(G_nx);
            % - Defining the G_bz variable and shifting Brilluoin zone to +G2
            for i = 1:size(G_cell,1)
                Gbz{i,1} = G_nx(G_cell(i,:),1)-G(floor(l/2),1)+G2(1);
                Gbz{i,2} = G_nx(G_cell(i,:),2)-G(floor(l/2),2)+G2(2);
                Gbz{i,3} = G_nx(G_cell(i,:),3)-G(floor(l/2),3)+G2(3);
            end
            
            %% Assigning the real- and reciprocal-information to MATLAB data structure
            waitbar(1, wbar, 'Assigning real- and reciprocal-variables...', 'Name', 'extract_lattice');
            % - Real-space information
            realStr.crystal = crystal;
            realStr.T1 = T1;
            realStr.T2 = T2;
            realStr.T3 = T3;
            realStr.Tb0 = Tb0;
            realStr.Tvol = Tvol;
            realStr.T = T;
            realStr.Tlims = Tlims;
            realStr.Tnn = Tnn;
            realStr.Tws = Tws;
            % - Reciprocal-space information
            reciStr.crystal = crystal;
            reciStr.G1 = G1;
            reciStr.G2 = G2;
            reciStr.G3 = G3;
            reciStr.Gb0 = Gb0;
            reciStr.Gvol = Gvol;
            reciStr.G = G;
            reciStr.Glims = Glims;
            reciStr.Gnn = Gnn;
            reciStr.Gbz = Gbz;
            %% Close wait-bar
            close(wbar);
            
            %% Figure summary of the real- and reciprocal-space structures
            if plotFig == 1
                fig = figure(); set(fig, 'position', [1,1,950,650]);
            
                % - REAL-SPACE FIGURE
                subplot(1,2,1); hold on;
                % -- Plotting the axes lines
                line([min(T(:,1)), max(T(:,1))], [0 0], [0,0],  'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                line([0 0], [min(T(:,2)), max(T(:,2))], [0, 0], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                line([0 0], [0, 0], [min(T(:,3)), max(T(:,3))], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                % -- Plotting the translational real-space lattice vectors
                plot3([0,T1(1)],[0,T1(2)],[0,T1(3)], '-', 'linewidth', 3, 'color', [0.8, 0.3, 0.3]);
                plot3([0,T2(1)],[0,T2(2)],[0,T2(3)], '-', 'linewidth', 3, 'color', [0.8, 0.3, 0.3]);
                plot3([0,T3(1)],[0,T3(2)],[0,T3(3)], '-', 'linewidth', 3, 'color', [0.8, 0.3, 0.3]);
                % -- Plotting the multiple translations of the real-space vectors
                plot3(T(:,1),T(:,2),T(:,3),'b.','markersize',20);
                % -- Plotting all the nearest neighbour points
                for i = 1:2:size(Tnn,1)
                    plot3([Tnn(i,1), Tnn(i+1,1)], [Tnn(i,2), Tnn(i+1,2)], [Tnn(i,3), Tnn(i+1,3)], 'k-', 'linewidth', 1);
                end
                % -- Plotting the Wigner-Seitz cell
                for i = 1:size(Tws,1)
                    patch(Tws{i,1}, Tws{i,2}, Tws{i,3},[0.8 0.3 0.3], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                end
                % - Figure formatting
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 14;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'both';
                % Box Styling properties
                ax.LineWidth = 1.2;
                % Axis labels, limits and ticks
                xlabel('$$ \bf  x (\AA) $$', 'Interpreter', 'latex');
                ylabel('$$ \bf  y (\AA) $$', 'Interpreter', 'latex');
                zlabel('$$ \bf  z (\AA) $$', 'Interpreter', 'latex');
                xticks(round(-10*norm(T1):norm(T1):10*norm(T1),2));
                yticks(round(-10*norm(T2):norm(T2):10*norm(T2),2));
                zticks(round(-10*norm(T3):norm(T3):10*norm(T3),2));
                % -- Modify the view
                view(3); camlight(-30,24);
                title_txt1 = sprintf("%s; Real; Wigner-Seitz", crystal);
                title(title_txt1);
                axis tight equal vis3d; rotate3d on;
                pbaspect([1,1,1]);
                axis(Tlims);
            
                % - RECIPROCAL-SPACE FIGURE
                subplot(1,2,2); hold on;
                % -- Plotting the axes lines
                line([min(G(:,1)), max(G(:,1))], [0 0], [0,0],  'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                line([0 0], [min(G(:,2)), max(G(:,2))], [0, 0], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                line([0 0], [0, 0], [min(G(:,3)), max(G(:,3))], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                % -- Plotting the translational real-space lattice vectors
                plot3([0,G1(1)],[0,G1(2)],[0,G1(3)], '-', 'linewidth', 3, 'color', [0.3, 0.8, 0.3]);
                plot3([0,G2(1)],[0,G2(2)],[0,G2(3)], '-', 'linewidth', 3, 'color', [0.3, 0.8, 0.3]);
                plot3([0,G3(1)],[0,G3(2)],[0,G3(3)], '-', 'linewidth', 3, 'color', [0.3, 0.8, 0.3]);
                % -- Plotting the multiple translations of the real-space vectors
                plot3(G(:,1),G(:,2),G(:,3),'b.','markersize',20);
                % -- Plotting all the nearest neighbour points
                for i = 1:2:size(Gnn,1)
                    plot3([Gnn(i,1), Gnn(i+1,1)], [Gnn(i,2), Gnn(i+1,2)], [Gnn(i,3), Gnn(i+1,3)], 'k-', 'linewidth', 1);
                end
                % -- Plotting the first Brilluoin Zone
                for i = 1:size(Gbz,1)
                    patch(Gbz{i,1}, Gbz{i,2}, Gbz{i,3},[0.3 0.8 0.3], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                end
                % - Figure formatting
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 14;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'both';
                % Box Styling properties
                ax.LineWidth = 1.2;
                % Axis labels, limits and ticks
                xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                ylabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex');
                zlabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                xticks(round(-10*norm(G1):norm(G1):10*norm(G1),2));
                yticks(round(-10*norm(G2):norm(G2):10*norm(G2),2));
                zticks(round(-10*norm(G3):norm(G3):10*norm(G3),2));
                % -- Modify the view
                view(3); camlight(-30,24);
                title_txt2 = sprintf("%s; Reciprocal; Brilluoin Zone", crystal);
                title(title_txt2);
                axis tight  equal vis3d; rotate3d on;
                pbaspect([1,1,1]);
                axis(Glims);
            end
        end
        
        % ---  Function to extract the Brilluoin zone overlay of the given crystal plane slice
        function planeStr = extract_plane(app, reciStr, crystal_plane, plotFig)
            % planeStr = extract_plane(reciStr, crystal_plane, plotFig)
            %   This is a function that extracts the planar Brilluoin zone 
            %   along the defined crystal plane (h,k,l) in miller indices notation.
            %   This Brilluoin zone plane then represents the planar slice that
            %   that is probed through via ARPES by changing the scan parameter.
            %
            %   IN:
            %   -  reciStr:         MATLAB data structure containing all reciprocal-space information below;
            %   -  crystal_plane:   string of the crystal plane to extract in "(hkl)" format.
            %   -  plotFig:         if 1, will plot figure summary, otherwise it wont.
            %
            %   OUT:
            %   planeStr          MATLAB data structure containing all BZ plane information below;
            %   - .(crystal):           string of the crystal type.
            %   - .(crystal_plane):     string of the (h,k,l) plane extracted.
            %	- .(area):              area of the planar BZ unit cell.
            %   - .(X):                 cell array of the x vertices of the planar BZ cell slice.
            %   - .(Y):                 cell array of the y vertices of the planar BZ cell slice.
            %	- .(gX):                double of the reciprocal-tesselation vector in x.
            %	- .(gY):                double of the reciprocal-tesselation vector in y.
            
            %% Default parameters
            if nargin < 4; plotFig = 0; end
            if isempty(plotFig); plotFig = 0;  end
            disp('-> Extracting plane slice of BZ...')
            wbar = waitbar(0, 'Extracting BZ overlay...', 'Name', 'extract_plane');
            
            %% Initialising the input variables
            crystal = reciStr.crystal;
            G1 =  reciStr.G1;
            G2 =  reciStr.G2;
            G3 =  reciStr.G3;
            Gbz = reciStr.Gbz;
            %% 1 - Extracting the First Brilluoin Zone boundary
            % - Extracting the point cloud for the First Brilluoin Zone
            x_pts = cell2mat(Gbz(:,1));
            y_pts = cell2mat(Gbz(:,2));
            z_pts = cell2mat(Gbz(:,3));
            cloud_pts = [x_pts, y_pts, z_pts];
            % - Projecting the point cloud onto the desired planar slice
            % --- For the (100) plane
            if crystal_plane == "(100)"
                proj_vec = [0,1,1];
                proj_pts = cloud_pts .* proj_vec;
                xpts1 = proj_pts(:,2);
                ypts1 = proj_pts(:,3);
            % --- For the (010) plane
            elseif crystal_plane == "(010)"
                proj_vec = [1,0,1];
                proj_pts = cloud_pts .* proj_vec;
                xpts1 = proj_pts(:,1);
                ypts1 = proj_pts(:,3);
            % --- For the (001) plane
            elseif crystal_plane == "(001)"
                proj_vec = [1,1,0];
                proj_pts = cloud_pts .* proj_vec;
                xpts1 = proj_pts(:,1);
                ypts1 = proj_pts(:,2);
            % --- For the (110) plane
            elseif crystal_plane == "(110)"
                proj_vec = [1,0,1];
                R = [cosd(45) -sind(45) 0; sind(45) cosd(45) 0; 0 0 1];
                cloud_pts = cloud_pts * R;
                proj_pts = cloud_pts .* proj_vec;
                xpts1 = proj_pts(:,1);
                ypts1 = proj_pts(:,3);
            end
            % - Shifting the boundary points to the origin
            xpts1 = xpts1 - (max(xpts1(:))+min(xpts1(:)))/2;
            ypts1 = ypts1 - (max(ypts1(:))+min(ypts1(:)))/2;
            
            %% 2 - Creating the boundary Brilluoin Zone polygon and finding the area of a single zone
            [b_indx, area] = boundary(xpts1, ypts1, 0.1);
            xpts1 = xpts1(b_indx);
            ypts1 = ypts1(b_indx);
            
            %% 3 - Tessalating the polygons to get the overlay
            l=1;
            % Iterating over all (or most of!) reciprocal space
            for i = -20:20
                for j = -20:20
                    % -- For the (100) plane
                    if crystal_plane == "(100)"
                        % -- Applying the tessalated shifts
                        X{l} = xpts1 + i*G2(2) + j*G3(2);
                        Y{l} = ypts1 + i*G2(3) + j*G3(3);
                    % -- For the (010) plane
                    elseif crystal_plane == "(010)"
                        % -- Applying the tessalated shifts
                        X{l} = xpts1 + i*G1(1) + j*G3(1);
                        Y{l} = ypts1 + i*G1(3) + j*G3(3);
                    % -- For the (001) plane
                    elseif crystal_plane == "(001)"
                        % -- Applying the tessalated shifts
                        X{l} = xpts1 + i*G1(1) + j*G2(1);
                        Y{l} = ypts1 + i*G1(2) + j*G2(2);
                    % -- For the (110) plane
                    elseif crystal_plane == "(110)"
                        % --- Extracting the shift vectors
                        xshift = norm(0.5*G1 + 0.5*G2);
                        yshift = norm(0.5*G3);
                        % -- Applying the tessalated shifts
                        if mod(i,2) == 1
                            X{l} = xpts1 + i*xshift;
                            Y{l} = ypts1 + yshift +  j*2*max(yshift);
                        else
                            X{l} = xpts1 + i*xshift;
                            Y{l} = ypts1 + j*2*max(yshift);
                        end
                    end
                    l = l + 1;  
                end
            end
            %% Assigning the overlay structure to a MATLAB structure
            waitbar(1, wbar, 'Assigning the BZ overlay variables...', 'Name', 'extract_lattice');
            % - Real-space information
            planeStr.crystal = crystal;
            planeStr.crystal_plane = crystal_plane;
            planeStr.area = area;
            planeStr.X = X;
            planeStr.Y = Y;
            if crystal_plane == "(100)"
                planeStr.gX = norm(G2);
                planeStr.gY = norm(G3);
            elseif crystal_plane == "(010)"
                planeStr.gX = norm(G1);
                planeStr.gY = norm(G3);
            elseif crystal_plane == "(001)"
                planeStr.gX = norm(G1);
                planeStr.gY = norm(G2);
            elseif crystal_plane == "(110)"
                planeStr.gX = norm(G1 + G2);
                planeStr.gY = norm(G3);
            end
            %% Close wait-bar
            close(wbar);
            %% Figure summary of the real- and reciprocal-space structures
            if plotFig == 1
                
                fig = figure(); set(fig, 'position', [1,1,950,650]);
                
                %% - RECIPROCAL-SPACE FIGURE
                subplot(1,2,1); hold on;
                % -- Plotting the axes lines
                line([min(reciStr.G(:,1)), max(reciStr.G(:,1))], [0 0], [0,0],  'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                line([0 0], [min(reciStr.G(:,2)), max(reciStr.G(:,2))], [0, 0], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                line([0 0], [0, 0], [min(reciStr.G(:,3)), max(reciStr.G(:,3))], 'Color', [0 0 0], 'LineWidth', 0.75, 'Linestyle', '--');
                % -- Plotting the translational real-space lattice vectors
                plot3([0,reciStr.G1(1)],[0,reciStr.G1(2)],[0,reciStr.G1(3)], '-', 'linewidth', 3, 'color', [0.3, 0.8, 0.3]);
                plot3([0,reciStr.G2(1)],[0,reciStr.G2(2)],[0,reciStr.G2(3)], '-', 'linewidth', 3, 'color', [0.3, 0.8, 0.3]);
                plot3([0,reciStr.G3(1)],[0,reciStr.G3(2)],[0,reciStr.G3(3)], '-', 'linewidth', 3, 'color', [0.3, 0.8, 0.3]);
                % -- Plotting the multiple translations of the real-space vectors
                plot3(reciStr.G(:,1),reciStr.G(:,2),reciStr.G(:,3),'b.','markersize',20);
                % -- Plotting all the nearest neighbour points
                for i = 1:2:size(reciStr.Gnn,1)
                    plot3([reciStr.Gnn(i,1), reciStr.Gnn(i+1,1)], [reciStr.Gnn(i,2), reciStr.Gnn(i+1,2)], [reciStr.Gnn(i,3), reciStr.Gnn(i+1,3)], 'k-', 'linewidth', 1);
                end
                % -- Plotting the first Brilluoin Zone
                for i = 1:size(reciStr.Gbz,1)
                    patch(reciStr.Gbz{i,1}, reciStr.Gbz{i,2}, reciStr.Gbz{i,3},[0.3 0.8 0.3], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                end
                % -- Plotting a path of the brilluoin zone slice
                xP = [-1e3, 1e3, 1e3, -1e3, -1e3];
                yP = [1e3, 1e3, -1e3, -1e3, 1e3];
                zP = [0, 0, 0, 0, 0];
                if crystal_plane == "(100)"
                    patch(zP, xP, yP, [0.7, 0.7, 0.7], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                elseif crystal_plane == "(010)"
                    patch(xP, zP+reciStr.G2(2), yP, [0.7, 0.7, 0.7], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                elseif crystal_plane == "(001)"
                    patch(xP, yP, zP, [0.7, 0.7, 0.7], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                elseif crystal_plane == "(110)"
                    xP = [1e3, 1e3, -1e3, -1e3, 1e3];
                    yP = [1e3, 1e3, -1e3, -1e3, 1e3]+reciStr.G2(2);
                    zP = [-1e3, 1e3, 1e3, -1e3, -1e3];
                    patch(xP, yP, zP, [0.7, 0.7, 0.7], 'FaceAlpha',0.75, 'EdgeColor', 'none');
                end
                % -- Defining the axes properties
                % - Figure formatting
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 14;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'both';
                % Box Styling properties
                ax.LineWidth = 1.2;
                % Axis labels, limits and ticks
                xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                ylabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex');
                zlabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                xticks(round(-10*norm(G1):norm(G1):10*norm(G1),2));
                yticks(round(-10*norm(G2):norm(G2):10*norm(G2),2));
                zticks(round(-10*norm(G3):norm(G3):10*norm(G3),2));
                % -- Modify the view
                view(3); camlight(-30,24);
                title_txt2 = sprintf("%s; Reciprocal; Brilluoin Zone", crystal);
                title(title_txt2);
                axis tight equal; rotate3d on;
                pbaspect([1,1,1]);
                axis(reciStr.Glims);
                
                %% - BRILLUOIN ZONE PLANAR SLICE
                subplot(1,2,2); hold on;
                for i = 1:size(X, 2)
                    plot(X{i}, Y{i}, 'k-','linewidth', 2);
                end
                % -- Defining the axes properties
                % - Figure formatting
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 14;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'both';
                % Box Styling properties
                ax.LineWidth = 1.2;
                % Axis labels, limits and ticks
                title_txt = sprintf("%s; BZNavi through %s", planeStr.crystal, planeStr.crystal_plane);
                title(title_txt);
                xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                ylabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                xticks(round(-100*norm(planeStr.gX):0.5*norm(planeStr.gX):100*norm(planeStr.gX),2));
                yticks(round(-100*norm(planeStr.gY):0.5*norm(planeStr.gY):100*norm(planeStr.gY),2));
                axis(1.5*[-norm(planeStr.gX), norm(planeStr.gX), -norm(planeStr.gY),norm(planeStr.gY)]);
                axis equal;
                % Axis labels, limits and ticks
                title_txt = sprintf("BZ plane; %s", crystal_plane);
                title(title_txt);
                axis equal;
                if crystal_plane == "(100)"
                    xlabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex');
                    ylabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                elseif crystal_plane == "(010)"
                    xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                    ylabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                elseif crystal_plane == "(001)"
                    xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                    ylabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex');
                elseif crystal_plane == "(110)"
                    xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                    ylabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                end
                xticks(round(-10*planeStr.gX:0.5*planeStr.gX:10*planeStr.gX,2));
                yticks(round(-10*planeStr.gY:0.5*planeStr.gY:10*planeStr.gY,2));
                axis([-planeStr.gX, planeStr.gX, -planeStr.gY, planeStr.gY]*1.05);
            end

        end
        
        % ---  Function to extract the Iso-surface given the slice range
        function isoeStr = extract_slice(app, isoeStr, isoslice_args, plotFig)
            % isoeStr = extract_slice(dataStr, fig_args)
            %   This function extracts an isok or isoe slice through all files that
            %   have been loaded into the dataStr{} cell-array data-structure. All
            %   slices are taken and put in the form of cell-arrays that consist
            %   of the x- and y-isoe domains and the data intensity.
            %
            %   REQ. FUNCTIONS:
            %   -   [h=] ImData(X,Y,Z[,style])
            %   -   [XR,YR,DataR] = Remap(XM,YM,Data)
            %   -   [DSlice,XSlice] = Slice(ACorr,ECorr,Data,xMode,Win) 
            %
            %   IN:
            %   dataStr - loaded MATLAB data structure.
            %   -   isoeStr{i}:      1xN cell array that contains the data for N files to be sliced.
            %   -   isoslice_args:   1x3 cell of {isoType, isoWin, remap}.
            %
            %   OUT:
            %   -   isoeStr{i}.XSlice:          2D arrays holding the x-domain of isoe slices for all files loaded.
            %   -   isoeStr{i}.YSlice:          2D arrays holding the y-domain of isoe slices for all files loaded.
            %   -   isoeStr{i}.DSlice:          2D arrays holding the data intensity of isoe slices for all files loaded.
            %   -   figure output of the slice operation.
            
            %% Default parameters
            if nargin < 4; plotFig = 0; end
            if isempty(plotFig); plotFig = 0;  end
            %% Initialising variables for the figure
            isoType     = isoslice_args{1};
            isoWin      = isoslice_args{2};
            remap       = isoslice_args{3};
            % - Extracting the fields to be used with most recent processing
            [xField, yField, zField, dField] = find_data_fields(app, isoeStr{1});
            
            %% 1 - Extracting all of the iso-slices
            for i = 1:length(isoeStr)
                % - Capping the window to the max/min of the ARPES data
                if isempty(isoWin); WinLims = [-0.1, 0.1];
                else; WinLims = sort(isoWin);
                end
                step_size = 0.05;
                if isoType == "IsoE"
                    if WinLims(1) < min(isoeStr{i}.(yField)(:)); WinLims(1) = min(isoeStr{i}.(yField)(:)) + step_size; end
                    if WinLims(2) > max(isoeStr{i}.(yField)(:)); WinLims(2) = max(isoeStr{i}.(yField)(:)) - step_size; end
                elseif isoType == "IsoK"
                    if WinLims(1) < min(isoeStr{i}.(xField)(:)); WinLims(1) = min(isoeStr{i}.(xField)(:)) + step_size; end
                    if WinLims(2) > max(isoeStr{i}.(xField)(:)); WinLims(2) = max(isoeStr{i}.(xField)(:)) - step_size; end
                end
                %% 1.1 - Extracting the Iso slices
                if isoType == "IsoE"
                    [DSlice, XSlice] = Slice(isoeStr{i}.(xField), isoeStr{i}.(yField), isoeStr{i}.(dField), 'IsoE', WinLims);
                    % - Extracting the scan parameter variables
                    if isfield(isoeStr{i}, 'kx'); init = ceil(0.5*size(isoeStr{i}.(dField), 2)); YSlice = squeeze(isoeStr{i}.(zField)(init,:,:))';
                    else; YSlice = squeeze(isoeStr{i}.(zField))';
                    end
                elseif isoType== "IsoK"    
                    [DSlice, YSlice] = Slice(isoeStr{i}.(xField), isoeStr{i}.(yField), isoeStr{i}.(dField), 'IsoK', WinLims);
                    % - Extracting the scan parameter variables
                    if isfield(isoeStr{i}, 'kx'); init = ceil(0.5*size(isoeStr{i}.(dField), 2)); XSlice = squeeze(isoeStr{i}.(zField)(:,init,:));
                    else; XSlice = squeeze(isoeStr{i}.(zField))';
                    end
                end
                %% 1.2 - Re-mapping the Iso slices onto a square grid if required
                if remap == 1
                     [XSlice, YSlice, DSlice] = Remap(XSlice, YSlice, DSlice);
                     YSlice = YSlice';
                     DSlice(isnan(DSlice)) = 0;
                end
                %% 1.3 - Finding the min/max values of the data
                colLims(i,:)    = [min(DSlice(:)), max(DSlice(:))];
                xLims(i,:)      = [min(XSlice(:)), max(XSlice(:))];
                yLims(i,:)      = [min(YSlice(:)), max(YSlice(:))];
                %% 1.4 - Appending the data into the isoStr data-structure
                isoeStr{i}.XSlice = XSlice;
                isoeStr{i}.YSlice = YSlice;
                isoeStr{i}.DSlice = DSlice;
            end
            
            %% 2 - Figure summary of the slices
            if plotFig == 1
                fig = figure(); hold on;
                if isoType == "IsoE";       set(fig, 'Name', 'Iso-Slice', 'Position', [1,1,600,600]); pbaspect([1,1,1]);
                elseif isoType == "IsoK";   set(fig, 'Name', 'Iso-Slice', 'Position', [1,1,1000,500]);
                end
                % - Plotting the Iso slices
                for i = 1:length(isoeStr); ImData(isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice); end
                % -- Formatting the axes
                caxis([min(colLims(:)), max(colLims(:))]);
                axis([min(xLims(:)), max(xLims(:)), min(yLims(:)), max(yLims(:))]);
                line([0 0], [-1e5, 1e5], 'Color', [1 1 1 0.5], 'LineWidth', 0.75, 'Linestyle', '--');
                line([-1e5, 1e5], [0 0], 'Color', [1 1 1 0.5], 'LineWidth', 0.75, 'Linestyle', '--');
                % -- Re-labelling the axes depending on what slice is taken
                if isoType== "IsoE"
                    if isoeStr{1}.Type == "Eb(kx,ky)"
                        xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex'); 
                        ylabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex'); 
                    elseif isoeStr{1}.Type == "Eb(kx,kz)"
                        xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex'); 
                        ylabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex'); 
                    end
                elseif isoType== "IsoK"
                    if isoeStr{1}.Type == "Eb(kx,ky)"
                        xlabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex'); 
                        ylabel('$$ \bf  E_B (eV) $$', 'Interpreter', 'latex'); 
                    elseif isoeStr{1}.Type == "Eb(kx,kz)"
                        xlabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                        ylabel('$$ \bf  E_B (eV) $$', 'Interpreter', 'latex'); 
                    end
                end
                % -- Axes properties
                ax = gca;
                % --- Font properties
                ax.FontName = 'Helvetica'; ax.FontWeight = 'normal'; ax.FontSize = 15;
                % --- Tick properties
                ax.TickLabelInterpreter = 'latex';
                % --- Box Styling properties
                ax.Color = [0, 0, 0];
                ax.LineWidth = 1.5;
                ax.Box = 'off'; ax.Layer = 'Top';
                % -- Adding title to the figure
                title(sprintf(string(isoeStr{1}.FileName) + "; %s; [%.2f,%.2f]", isoType, isoWin(1),isoWin(2)), 'interpreter', 'none', 'fontsize', 12);
                % -- Colormap properties
                colormap hot;
            end
        end
        
        % ---  Function to plot the final 2D figure of the isoe data
        function view_final(app, isoeStr, bzStr, isoe_plot_args)
            % view_final(isoeStr, bzStr, final_fig_args)
            %   This function plots the final ARPES isoe surface, with numerous
            %   constraints placed on the figure and axes properties, so a paper
            %   quality figure can be extracted.
            %
            %   REQ. FUNCTIONS:
            %   -   [h=] ImData(X,Y,Z[,style])
            %
            %   IN:
            %   -   isoeStr:         data structure of the isoe ARPES data.
            %   -   bzStr:           data structure of the Brilluoin Zone.
            %   -   isoe_plot_args:	 1x8 cell of {cMap, cLims,axLims, axCol, backCol, BZoutline, BZwidth, BZon}.
            %
            %   OUT:
            %   -   figure output
            
            %% Initialising variables
            cMap        = isoe_plot_args{1};
            cLims       = isoe_plot_args{2};
            xLims       = isoe_plot_args{3};
            yLims       = isoe_plot_args{4};
            axCol       = isoe_plot_args{5};
            backCol     = isoe_plot_args{6};
            bzCol       = isoe_plot_args{7};
            bzWidth     = isoe_plot_args{8};
            axisOn      = isoe_plot_args{9};
            scanOn      = isoe_plot_args{10};
            gridOn      = isoe_plot_args{11};
            bzOn        = isoe_plot_args{12};
            interpData  = isoe_plot_args{13};
            %% Hold figure object
            hold on;
            
            %% 1 - Plotting the IsoE slices taken
            if interpData == 1
                for i = 1:length(isoeStr); ImData(isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice, 'interp'); end
            else
                for i = 1:length(isoeStr); ImData(isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice); end
            end
            
            %% 2 - Plotting the Brilluoin Zone overlay
            if bzOn == 1 && ~isempty(bzStr)
                for i = 1:size(bzStr.X, 2)
                    plot(bzStr.X{i}, bzStr.Y{i}, '-', 'color', bzCol, 'linewidth', bzWidth);
                end
                xticks(round(-20*bzStr.gX:bzStr.gX/2:20*bzStr.gX,2));
                yticks(round(-20*bzStr.gY:bzStr.gY/2:20*bzStr.gY,2));
            end
            
            %% 3 - Applying color and axes limits, grid- and axes-lines
            axis equal;
            colormap(cMap); caxis(cLims); axis([xLims, yLims]);
            if axisOn == 1 
                line([0 0], [-1e5, 1e5], 'Color', axCol, 'LineWidth', 1, 'Linestyle', '--');
                line([-1e5, 1e5], [0 0], 'Color', axCol, 'LineWidth', 1, 'Linestyle', '--');
            end
            if gridOn == 1; grid on; end
            
            %% 4 - Other general formatting
            % -- Defining the axes properties
            ax = gca;
            % Font properties
            ax.FontName = 'Helvetica'; ax.FontWeight = 'normal'; ax.FontSize = 15;
            % Tick properties
            ax.TickLabelInterpreter = 'latex';
            ax.TickDir = 'out';
            ax.TickLength = [0.01 0.025];
            ax.XColor = [0 0 0]; ax.YColor = [0 0 0];
            % Ruler properties
            ax.XAxisLocation = 'bottom';            % 'bottom' | 'top' | 'origin'
            ax.YAxisLocation = 'left';              % 'left' | 'right' | 'origin'
            % Box Styling properties
            ax.Color = backCol;
            ax.LineWidth = 1.0;
            ax.Box = 'off'; ax.Layer = 'Top';
            % Labelling the axes depending on what scan parameter is is taken
            xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
            if isoeStr{1}.Type == "Eb(kx,ky)";     ylabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex'); 
            elseif isoeStr{1}.Type == "Eb(kx,kz)"; ylabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex'); 
            end
            fig = gcf;
            % Window properties
            fig.Color = [1 1 1]; fig.InvertHardcopy = 'off';

            %% 5 - Adding additional y-axis for the scan parameter
            if scanOn == 1
                scanAxis = [];
                for i = 1:length(isoeStr); scanAxis = horzcat(scanAxis, isoeStr{i}.scanAxis); end
                scanAxis = [min(scanAxis(:)), max(scanAxis(:))];
                yyaxis right;
                ylim([scanAxis(1), scanAxis(2)]);
                if isoeStr{1}.Type == "Eb(kx,ky)"
                    ylabel('$$ \bf  \tau (^{\circ}) $$', 'Interpreter', 'latex'); 
                    yticks(round(scanAxis(1),-1):1:2*scanAxis(2));
                elseif isoeStr{1}.Type == "Eb(kx,kz)"
                    ylabel('$$ \bf  hv (eV) $$', 'Interpreter', 'latex'); 
                    yticks(round(scanAxis(1),-1):100:2*scanAxis(2));
                end
                ax = gca;
                ax.YColor = [0.2 0.2 0.7]; ax.YMinorTick = 'on';
            end
            
            %% 6 - Colorbar properties
            cb = colorbar; 
            % --- Colorbar font properties
            cb.FontName = 'Helvetica'; cb.FontWeight = 'normal'; cb.FontSize = 12;
            % --- Colorbar tick properties
            cb.TickLabelInterpreter = 'latex';
            cb.TickLength = 0.04; cb.TickDirection = 'out';
            % --- Colorbar position properties
            cb.Position = [0.93 0.83 0.02 0.07];
            % --- Colorbar box properties
            cb.Color = [0 0 0]; cb.Box = 'on'; cb.LineWidth = 1.2;
        end
        
        % --- Function to filter the IsoE data
        function isoeStr = filter_data(app, isoeStr, fileIndex, filter_args)
            % isoeStr = filter_data(isoeStr, fileIndex, filter_args)
            %   This function filters the ARPES isoe data given the filter arguments, 
            %   which includes the filter type ("Gaco2", "GaussFlt2", "CurvatureFlt2" 
            %   or "LaplaceFlt2") and filter parameters.
            %
            %   REQ. FUNCTIONS:
            %   -   AA = Gaco2(A,hwX,hwY [,hsX] [,hsY])
            %   -   AA = GaussFlt2(Img,hwX,hwY,hsX,hsY)
            %   -   AA = CurvatureFlt2(Img [,order] [,CX] [,CY]) 
            %   -   AA = LaplaceFlt2(Img [,y2xRatio] [,order])
            %
            %   IN:
            %   -   isoeStr{i}:     1xN cell array that contains the data for N files to be corrected.
            %   -   fileIndex:      1D vector contaning the file index/s to correct.
            %   -   filter_args:    1x2 cell of {filterType, filterVal}
            %
            %   OUT: modified isoeStr{i}
            %   -   isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice.
            
            disp('Data filtering...')
            wbar = waitbar(0, 'Executing data filtering...', 'Name', 'filter_data');
            
            %% Initialising variables for the figure
            filterType      = filter_args{1};
            filterVal       = filter_args{2};
            %% 1 - Performing the filtering operation over chosen files
            for i = fileIndex
                waitbar(i/length(fileIndex), wbar, 'Filtering ARPES data...', 'Name', 'filter_data');
                if filterType == "Gaco2"
                    filtered_data = Gaco2(isoeStr{i}.DSlice, filterVal(1), filterVal(2)); 
                elseif filterType == "GaussFlt2"
                    filtered_data = GaussFlt2(isoeStr{i}.DSlice, filterVal(1), filterVal(2),filterVal(1), filterVal(2)); 
                elseif filterType == "LaplaceFlt2"
                    filtered_data = GaussFlt2(isoeStr{i}.DSlice, 5, 5, 40, 40);
                    filtered_data = SetContrast(filtered_data, 0.4, 0.999, 1.5);
                    filtered_data = LaplaceFlt2(filtered_data, filterVal(1));
                elseif filterType == "CurvatureFlt2"
                    filtered_data =GaussFlt2(isoeStr{i}.DSlice, 100, 100, 500, 500);
                    filtered_data = SetContrast(filtered_data, 0.4, 0.999, 1.5);
                    filtered_data =CurvatureFlt2(filtered_data, '2D', filterVal(1), filterVal(2));
                    filtered_data = GaussFlt2(filtered_data, 1, 1, 10, 10);
                    filtered_data = SetContrast(filtered_data, 0.2, 0.999);
                end
                % -- Saving the filtered data slice
                isoeStr{i}.DSlice = filtered_data;
                % -- Setting NaN values to zero
                isoeStr{i}.DSlice(isnan(isoeStr{i}.DSlice)) = 0;
            end
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function for background subtraction
        function isoeStr = background_subtraction(app, isoeStr, fileIndex, bsub_args)
            % isoeStr = background_subtraction(isoeStr, fileIndex, bsub_args)
            %   This function background subtracts isoe ARPES data, given a specific
            %   window [minKx, maxKx] over which to cut the isoe surface. The entire
            %   isoe surface is then divided by this region, to normalised the
            %   background to be uniform across all isoe scans.
            %
            %   REQ. FUNCTIONS:
            %   -   AA = Gaco2(A,hwX,hwY [,hsX] [,hsY])
            %   -   [XCut,DCut] = Cut(ACorr,ECorr,Data,xMode,Win)
            %
            %   IN:
            %   -   isoeStr{i}:     1xN cell array that contains the data for N files to be corrected.
            %   -   fileIndex:      1D vector contaning the file index/s to correct.
            %   -   bsub_args:      1x1 cell of {xWin}
            %
            %   OUT: modified isoeStr{i}
            %   -   isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice.
            
            disp('Background subtraction...')
            wbar = waitbar(0, 'Executing background subtraction...', 'Name', 'background_subtraction');

            %% Initialising variables for the figure
            xWin    = bsub_args{1};
            
            %% 1 - Performing the background subtraction operation over chosen files
            for i = fileIndex
                waitbar(i/length(fileIndex), wbar, 'Background subtracting ARPES data...', 'Name', 'background_subtraction');
                % - Extracting the line profile cut over the defined window
                [~, DCut] = Cut(isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice,'edc', xWin);
                DCut(isnan(DCut)) = 0;
                DCut = Gaco2(DCut, 5, 5);
                % - Dividing by the background
                for j = 1:size(isoeStr{i}.DSlice, 2)
                    isoeStr{i}.DSlice(:,j) = isoeStr{i}.DSlice(:,j) ./ DCut;
                end
                % - Subtracting to make the background zero
                isoeStr{i}.DSlice = isoeStr{i}.DSlice - min(isoeStr{i}.DSlice(:));
            end
            %% 2 - Normalising maximum to initial maximum of data
            for i = 1:length(isoeStr); norm_const(i) = max(isoeStr{i}.DSlice(:)); end
            norm_const = max(norm_const(:));
            for i = 1:length(isoeStr)
                isoeStr{i}.DSlice = isoeStr{i}.DSlice ./ norm_const;
            end
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to apply origin corrections
        function isoeStr = origin_correction(app, isoeStr, fileIndex, origin_args)
            % isoeStr = origin_correction(dataStr, fileIndex, isocorr_args)
            %   This function corrects the origin position of an isoe surface by
            %   applying a shift in the x- and y-dimensions that is defined by the user.
            %
            %   REQ. FUNCTIONS: none
            %
            %   IN:
            %   -   isoeStr{i}:     1xN cell array that contains the data for N files to be corrected.
            %   -   fileIndex:      1D vector contaning the file index/s to correct.
            %   -   origin_args:    1x2 cell of {xShift, yShift}
            %
            %   OUT: modified isoeStr{i}
            %   -   isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice.
            
            disp('Origin corrections...')
            wbar = waitbar(0, 'Executing origin correction...', 'Name', 'origin_correction');

            %% Initialising variables for the figure
            xShift    = origin_args{1};
            yShift    = origin_args{2};
            
            %% 1 - Performing the origin correction operation over chosen files
            for i = fileIndex
                waitbar(i/length(fileIndex), wbar, 'Origin shifting ARPES data...', 'Name', 'origin_correction');
                isoeStr{i}.XSlice = isoeStr{i}.XSlice - xShift;
                isoeStr{i}.YSlice = isoeStr{i}.YSlice - yShift;
            end
            %% Close wait-bar
            close(wbar);
            
        end
        
        % ---  Function to shear correct data
        function isoeStr = shear_correction(app, isoeStr, fileIndex, shear_args)
            % isoeStr = shear_correction(isoeStr, fileIndex, shear_args)
            %   This function shear corrects the isoe surface in both the x- or
            %   y-dimension. By defining the slope and intersept of a striaght line, x-
            %   and y-shifts can be applied to shear the x- and y-variables of the
            %   ARPES isoe surface.
            %
            %   REQ. FUNCTIONS: none
            %
            %   IN:
            %   -   isoeStr{i}:     1xN cell array that contains the data for N files to be corrected.
            %   -   fileIndex:      1D vector contaning the file index/s to correct.
            %   -   shear_args:    1x4 cell of {xSlope, xIntercept, ySlope, yIntercept}
            %
            %   OUT: modified isoeStr{i}
            %   -   isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice.
            
            disp('Shear corrections...')
            wbar = waitbar(0, 'Executing shear correction...', 'Name', 'shear_correction');

            %% Initialising variables for the figure
            xSlope      = shear_args{1};
            xIntercept  = shear_args{2};
            ySlope      = shear_args{3};
            yIntercept  = shear_args{4};
            
            %% Defining the shear function
            shear_corr = @ (slope, intersept, y) (y - intersept)/slope;
            %% 1 - Performing the shearing correction operation over chosen files
            for i = fileIndex
                waitbar(i/length(fileIndex), wbar, 'Shear correcting ARPES data...', 'Name', 'shear_correction');
                % - Applying x-shear corrections
                if xSlope == 0; x_shifts = zeros(size(isoeStr{i}.YSlice));
                else; x_shifts = shear_corr(xSlope, xIntercept, isoeStr{i}.YSlice);
                end
                isoeStr{i}.XSlice = isoeStr{i}.XSlice + x_shifts;
                % - Applying y-shear corrections
                if ySlope == 0; y_shifts = zeros(size(isoeStr{i}.YSlice));
                else; y_shifts = shear_corr(ySlope, yIntercept, isoeStr{i}.XSlice);
                end
                isoeStr{i}.YSlice = isoeStr{i}.YSlice + y_shifts;
            end
            %% Close wait-bar
            close(wbar);
            
        end
        
        % ---  Function to scale correct data
        function isoeStr = scale_correction(app, isoeStr, fileIndex, scale_args)
            % isoeStr = scale_correction(isoeStr, fileIndex, scale_args)
            %   This function scale corrects the isoe surface in both the x- or
            %   y-dimension. A simple scaling factor multiples the x- and y-
            %   dimensions.
            %
            %   REQ. FUNCTIONS: none
            %
            %   IN:
            %   -   isoeStr{i}:     1xN cell array that contains the data for N files to be corrected.
            %   -   fileIndex:      1D vector contaning the file index/s to correct.
            %   -   scale_args:     1x2 cell of {xScale, yScale}
            %
            %   OUT: modified isoeStr{i}
            %   -   isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice.
            
            disp('Scale corrections...')
            wbar = waitbar(0, 'Executing scale correction...', 'Name', 'scale_correction');

            %% Initialising variables for the figure
            xScale      = scale_args{1};
            yScale      = scale_args{2};
            
            %% 1 - Performing the scale correction operation over chosen files
            for i = fileIndex
                waitbar(i/length(fileIndex), wbar, 'Scale correcting ARPES data...', 'Name', 'scale_correction');
                % - Applying x scaling
                isoeStr{i}.XSlice = (isoeStr{i}.XSlice-mean(isoeStr{i}.XSlice(:)))*xScale + mean(isoeStr{i}.XSlice(:));
                % - Applying y scaling
                isoeStr{i}.YSlice = (isoeStr{i}.YSlice-mean(isoeStr{i}.YSlice(:)))*yScale + mean(isoeStr{i}.YSlice(:));
            end
            %% Close wait-bar
            close(wbar);
        end
      
        % ---  Function to plot the isoe video of the ARPES data
        function view_video(app, isoeStr, bzStr, isoe_plot_args, isoVid_args)
            % view_video(isoeStr, bzStr, isoe_plot_args, isoVid_args)
            %   This function plots the final ARPES isoe surface as a
            %   video.
            %
            %   REQ. FUNCTIONS:
            %   -   [h=] ImData(X,Y,Z[,style])
            %
            %   IN:
            %   -   isoeStr{i}:     1xN cell array that contains the data for N files to be corrected.
            %   -   bzStr:           data structure of the Brilluoin Zone.
            %   -   isoe_plot_args:	 1x8 cell of {cMap, cLims, xLims, yLims, axCol, 
            %                                       backCol, bzCol, bzWidth, axisOn, 
            %                                           scanOn, gridOn, bzOn, interpData}.
            %   -   isoVid_args:	 1x2 cell of {isoeLims, isoeWin}.
            %
            %   OUT:
            %   -   figure output
            
            %% Initialising variables
            % - Video variables
            isoeLims    = isoVid_args{1};
            isoeWin     = isoVid_args{2};
            %% Initialisation to save the video as a .avi file
            filter = {'*.avi'};
            [save_filename, save_filepath] = uiputfile(filter);
            fname = char(string(save_filepath) + string(save_filename));
            % If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            % Else proceed with the video plotting
            disp('-> Plotting IsoE video...')
            
            %% 1 - Initialising variables for slice video
            % - Initialising window parameters
            WinLims = sort(isoeLims);
            stepsize = isoeWin;
            iWin = WinLims(2) + [-0.5, 0.5] * stepsize; 
            % - Initialising video parameters
            nFrames = round((WinLims(2) - WinLims(1))/(2*stepsize), 0)+1;
            vidObj = VideoWriter(fname);
            vidObj.FrameRate = 10;
            open(vidObj);
            % - Open and freeze the figure axes
            fig = figure(); set(fig, 'Name', 'IsoSlice Video', 'Position', [1,1,700,750]);
    
            %% 2 - Running the slice video frame by frame
            for f = 1:nFrames 
                %% 2.1 INITIALISING FIGURE AND SLICE WINDOW
                % - Finding the next iteration of the slice window
                Win = iWin - (f-1) * 2 * stepsize;
                % - Reset the figure
                cla reset; clf reset; delete(findall(gcf,'type','annotation'))
                % - Extracting the mean slice range as a string
                scanVal =  sprintf('$$ \\bf E_B = %.2f eV $$', mean(Win));
                %% 2.2 EXTRACTING THE ISOE SLICES
                isoeStr = extract_slice(app, isoeStr, {"IsoE", Win, 0});
                view_final(app, isoeStr, bzStr, isoe_plot_args);
                %% 2.3 - RENORMALISING INTENSITY BETWEEN 0 AND 1
                for i = 1:length(isoeStr)
                    minCol(i) = min(isoeStr{i}.DSlice(:));
                    maxCol(i) = max(isoeStr{i}.DSlice(:));
                end
                caxis([min(minCol), max(maxCol)]);
                %% 2.4 - ADD ANNOTATION FOR SCAN PARAMETER
                text_pos = [0.42 0.925 0.22 0.06];
                annotation('textbox', text_pos, 'String',scanVal, 'FitBoxToText','on',...
                    'color', [0 0 0], 'fontsize', 16, 'backgroundcolor', [1 1 1], 'facealpha', 0.8,...
                    'linewidth', 1, 'horizontalalignment', 'center', 'verticalalignment', 'middle',...
                    'interpreter', 'latex');
                %% Adding the current figure as a frame to the video dataObject
                mov = getframe(gcf);        %add current figure as frame
                writeVideo(vidObj,mov);     %write frame to vidObj
            end
            close(vidObj);
            disp('-> Finished plotting IsoE video...')
        end
        
        
        % ---  Function to plot the isoe image series of the ARPES data
        function view_isoSeries(app, isoeStr, bzStr, isoe_plot_args, isoVid_args)
            % view_video(isoeStr, bzStr, isoe_plot_args, isoVid_args)
            %   This function plots the final ARPES isoe surface as a
            %   video.
            %
            %   REQ. FUNCTIONS:
            %   -   [h=] ImData(X,Y,Z[,style])
            %
            %   IN:
            %   -   isoeStr{i}:     1xN cell array that contains the data for N files to be corrected.
            %   -   bzStr:           data structure of the Brilluoin Zone.
            %   -   isoe_plot_args:	 1x8 cell of {cMap, cLims, xLims, yLims, axCol, 
            %                                       backCol, bzCol, bzWidth, axisOn, 
            %                                           scanOn, gridOn, bzOn, interpData}.
            %   -   isoVid_args:	 1x2 cell of {isoeLims, isoeWin}.
            %
            %   OUT:
            %   -   figure output
            
            %% Initialising variables
            % - Video variables
            isoeLims    = isoVid_args{1};
            isoeWin     = isoVid_args{2};
            %% Initialisation
            filter = {'*.*'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the image series...');
            fName = string(string(save_filepath) + string(save_filename));
            % If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            
            %% 1 - Initialising variables for slice video
            % - Initialising window parameters
            WinLims = sort(isoeLims);
            stepsize = isoeWin;
            iWin = WinLims(2) + [-0.5, 0.5] * stepsize; 
            % - Initialising video parameters
            nFrames = round((WinLims(2) - WinLims(1))/(2*stepsize), 0) + 1;
            % - Open and freeze the figure axes
            fig = figure(); set(fig, 'Name', 'IsoSlice Video', 'Position', [1,1,700,750]);
            
            %% 2 - Running the slice video frame by frame
            for f = 1:nFrames 
                %% 2.1 INITIALISING FIGURE AND SLICE WINDOW
                % - Finding the next iteration of the slice window
                Win = iWin - (f-1) * 2 * stepsize;
                % - Reset the figure
                cla reset; clf reset; delete(findall(gcf,'type','annotation'))
                % - Initialising variables
                ifName = fName + "_" + string(f);
                % - Extracting the mean slice range as a string
                scanVal =  sprintf('$$ \\bf E_B = %.2f eV $$', mean(Win));
                %% 2.2 EXTRACTING THE ISOE SLICES
                isoeStr = extract_slice(app, isoeStr, {"IsoE", Win, 0});
                view_final(app, isoeStr, bzStr, isoe_plot_args);
                %% 2.3 - RENORMALISING INTENSITY BETWEEN 0 AND 1
                for i = 1:length(isoeStr)
                    minCol(i) = min(isoeStr{i}.DSlice(:));
                    maxCol(i) = max(isoeStr{i}.DSlice(:));
                end
                caxis([min(minCol), max(maxCol)]);
                %% 2.4 - ADD ANNOTATION FOR SCAN PARAMETER
                text_pos = [0.42 0.925 0.22 0.06];
                annotation('textbox', text_pos, 'String',scanVal, 'FitBoxToText','on',...
                    'color', [0 0 0], 'fontsize', 16, 'backgroundcolor', [1 1 1], 'facealpha', 0.8,...
                    'linewidth', 1, 'horizontalalignment', 'center', 'verticalalignment', 'middle',...
                    'interpreter', 'latex');
                %% SAVE THE FIGURE
                saveas(fig, ifName,'png');
            end
    
        end
        
        % ---  Function to extract a line-profile through an IsoE slice
        function [XCut, DCut] = extract_line_profile(app, isoeStr, lp_args)
            % [XCut, DCut] = extract_cut(dataStr, lp_args)
            %   This function extracts a horizontal or vertical cut through all files that
            %   have been loaded into the dataStr{} cell-array data-structure. All
            %   slices are taken and put in the form of cell-arrays that consist
            %   of the x- and y-isoe domains and the data intensity.
            %
            %   REQ. FUNCTIONS:
            %   -   [XCut,DCut]=Cut(ACorr,ECorr,Data,xMode,Win)
            %
            %   IN:
            %   dataStr - loaded MATLAB data structure.
            %   -   isoeStr{i}:     1xN cell array that contains the data for N files to be corrected.
            %   -   lp_args:        1x3 cell of {lpType, lpCutVal, lpWin}.
            %
            %   OUT:
            %   -   XCut:           nD array holding the x-domain of isoe cuts for all files loaded.
            %   -   DCut:           nD array holding the data of isoe cuts for all files loaded.

            %% Initialising variables for the figure
            lpType      = lp_args{1};
            lpCutVal    = lp_args{2};
            lpWin       = lp_args{3};
            %% 1 - Determine the window for the line profile integral
            Win = lpCutVal + [-1, 1] * lpWin;
            %% 2 - Extracting the line profiles
            for i = 1:length(isoeStr)
                if length(length(isoeStr)) == 1
                    if lpType == "horizontal"
                        [XCut, DCut] = Cut(isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice,'mdc',Win);
                    elseif lpType == "vertical"
                        [XCut, DCut] = Cut(isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice,'edc',Win);
                    end
                else
                    if lpType == "horizontal"
                        [XCut(:,i), DCut(:,i)] = Cut(isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice,'mdc',Win);
                    elseif lpType == "vertical"
                        [XCut(:,i), DCut(:,i)] = Cut(isoeStr{i}.XSlice, isoeStr{i}.YSlice, isoeStr{i}.DSlice,'edc',Win);
                    end
                end
            end
            %% 3 - Verifying line profiles
            DCut(isnan(DCut)) = 0;
            if isempty(XCut)
                XCut = [-0.1, 0.1]; DCut = [-0.1, 0.1];
            end
        end
        

        % ---  Function to plot a line-profiles
        function fig = view_LP(app, lpStr, isoeStr, bzStr, isoe_plot_args)
            % Determine the x-axis limits for the type of profile to image
            Hactive = 0; Vactive = 0;
            for i = 1:length(lpStr)
                lpType      = lpStr{i}.lp_args{1};
                % horizontal limits
                if lpType == "horizontal"
                    Hactive = 1;
                    HminVals(i) = min(lpStr{i}.XCut(:));
                    HmaxVals(i) = max(lpStr{i}.XCut(:));
                % vertical limits
                elseif lpType == "vertical"
                    Vactive = 1;
                    VminVals(i) = min(lpStr{i}.XCut(:));
                    VmaxVals(i) = max(lpStr{i}.XCut(:));
                end
            end
            if Hactive == 1;    HaxLims = [min(HminVals(:)), max(HmaxVals(:))]; end
            if Vactive == 1;    VaxLims = [min(VminVals(:)), max(VmaxVals(:))];end
            % Set random color for the line profile
            col = rand(length(lpStr),3);
            
            %% 1 - Plotting the ARPES IsoE slice (if required)
            if isempty(findobj('type','figure','number',3327))
                fig = figure(3327); 
                set(fig, 'Name', 'Line Profile Determination', 'Position', [1,1,800,450]);
                subplot(121); hold on;
                view_final(app, isoeStr, bzStr, isoe_plot_args);    
                % - Re-formatting the figure
                colorbar off;
                title('IsoE slice');
            end
            
            %% 2 - Plotting all line profile over the ARPES IsoE slice
            fig = figure(3327);
            subplot(121);
            for i = 1:length(lpStr)
                %% Initialising variables
                lpType      = lpStr{i}.lp_args{1};
                lpCutVal    = lpStr{i}.lp_args{2};
                lpWin       = lpStr{i}.lp_args{3};
                Win = lpCutVal + [-1, 1] * lpWin;
                if lpType == "horizontal" && length(lpStr{i}.XCut(:)) > 2
                    % Finding the x- and y-values of the line profile
                    x = [HaxLims(1), HaxLims(2), HaxLims(2), HaxLims(1)];
                    y = [Win(1), Win(1), Win(2), Win(2)];
                    % -- Plotting the line profile patch
                    patch(x, y, col(i,:), 'edgecolor', 'none', 'facealpha', 0.2);
                    plot(x, y, '-', 'linewidth', 0.5, 'color', col(i,:));
                    % -- Adding text to identify the line
                    text(mean(x(:)), mean(y(:)), sprintf("%.3f ± %.3f", lpCutVal, lpWin),...
                        'color', col(i,:), 'Fontsize', 12, 'horizontalalignment', 'center');
                elseif lpType == "vertical" && length(lpStr{i}.XCut(:)) > 2
                    % Finding the x- and y-values of the line profile
                    x = [Win(1), Win(1), Win(2), Win(2)];
                    y = [VaxLims(1), VaxLims(2), VaxLims(2), VaxLims(1)];
                    % -- Plotting the line profile patch
                    patch(x, y, col(i,:), 'edgecolor', 'none', 'facealpha', 0.2);
                    plot(x, y, '-', 'linewidth', 0.5, 'color', col(i,:));
                    % -- Adding text to identify the line
                    text(mean(x(:)), mean(y(:)), sprintf("%.3f ± %.3f", lpCutVal, lpWin),...
                        'color', col(i,:), 'Fontsize', 12, 'horizontalalignment', 'center');
                end
            end
            
            %% 3 - Plotting the line profile to be appended
            for i = 1:length(lpStr)
                %% Initialising variables
                lpType      = lpStr{i}.lp_args{1};
                if lpType == "horizontal" && length(lpStr{i}.XCut(:)) > 2
                    subplot(222); hold on;
                    plot(lpStr{i}.XCut, lpStr{i}.DCut, '.-', 'color', col(i,:), 'linewidth', 1.5);
                    title('horizontal cuts'); xlim(HaxLims);
                elseif lpType == "vertical" && length(lpStr{i}.XCut(:)) > 2
                    subplot(224); hold on;
                    plot(lpStr{i}.XCut, lpStr{i}.DCut, '.-', 'color', col(i,:), 'linewidth', 1.5);
                    title('vertical cuts'); xlim(VaxLims);
                end
            end
        end
        
        % ---  Function to extract a iso-contours through an IsoE slice
        function [XCont, YCont, ACont] = extract_iso_contour(app, isoeStr, ic_args)
            % icStr = extract_iso_contour(app, isoeStr, ic_args)
            %   This function determines the area over some threshold that is defined
            %   by the user, which can be used to sub-sequentially determine the
            %   Luttinger Area for number density calculations.
            %
            %   REQ. FUNCTIONS: none
            %
            %   IN:
            %   dataStr - loaded MATLAB data structure.
            %   -   isoeStr{i}:     1xN cell array that contains the data for N files to be corrected.
            %   -   ic_args:        {1x3} cell-array of {icMinArea, icVal, icSmooth}
            %
            %   OUT:
            %   -   XCont:           cell array holding the x-domain of the iso contours.
            %   -   YCont:           cell array holding the y-domain of the iso contours.
            %   -   ACont:           cell array holding the areas of the iso contours.
            
            disp('Iso Contour Extraction...')
            %% Initialising variables for the figure
            icMinArea   = ic_args{1};
            icVal       = ic_args{2};
            icSmooth    = ic_args{3};
            %% 1 - Determination of the maximum value of the intensity over all isoe scans
            for i = 1:length(isoeStr); max_d(i) = max(isoeStr{i}.DSlice(:)); end
            max_d = max(max_d(:));
            
            %% 2 - Iterating through all the data files
            areaCont = [];
            xCont = {};
            yCont = {};
            for i = 1:length(isoeStr)
                % -- Defining the x-, y- and z-matrices
                x = isoeStr{i}.XSlice;
                y = isoeStr{i}.YSlice;
                d = isoeStr{i}.DSlice;
                % -- Pre smooth the data if required
                d = Gaco2(d, icSmooth(1), icSmooth(2)); 
                
                %% 2.1 - Calculating the total area of the ARPES scan
                % -- Determination of the total area of the ARPES scan
                x_vect = x(:);
                y_vect = y(:);
                [k, area] = boundary(x_vect, y_vect, 0.5);
%                 plot(x_vect(k),y_vect(k), '-', 'color', [0,0.8,0.8,0.8], 'linewidth', 2);
                % -- Assigning the values to the data-structure
                xScan = x_vect(k);
                yScan = y_vect(k);
                areaScan = area;
                
                %% 2.2 - Calculating the contour threshold of the IsoE surface
                % - Extracting and plotting the filled contour plot
                [CMatrix, ~] = contour(x, y, d, [1, 1]*icVal*max_d, 'linewidth', 2, 'linestyle', '-');
                % - Finding the areas contained within all contours
                n = 0; ii = 1;
                sz = size(CMatrix, 2);
                if ~isempty(CMatrix)
                    nn(1) = CMatrix(2,1);
                    xx{1} = CMatrix(1,2:nn(1)+1);
                    yy{1} = CMatrix(2,2:nn(1)+1);
                    areaThresh(1) = polyarea(xx{1},yy{1});
                    while n+nn(ii)+ii < sz
                        n = n + nn(ii);
                        ii = ii + 1;
                        nn(ii) = CMatrix(2,n+ii);
                        xx{ii} = CMatrix(1,n+ii+1:n+nn(ii)+ii);
                        yy{ii} = CMatrix(2,n+ii+1:n+nn(ii)+ii);
                        areaThresh(ii) = polyarea(xx{ii}, yy{ii});
                    end
                else
                    xx{1} = 0;
                    yy{1} = 0;
                    areaThresh = 0;
                end
                
                %% 3 - Appling the limitations from the minimum area
                indx = find(areaThresh > icMinArea);
                j = 1;
                for n = indx
                    xCont{j} = xx{n};
                    yCont{j} = yy{n};
                    areaCont(j) = areaThresh(n);
                    j = j + 1;
                end
                areaThresh = sum(areaCont(:));
                %% 4 - Plotting the contours that are used
%                 for n = 1:length(areaCont)
%                     plot(xCont{n}, yCont{n}, 'color', [0.1,0.7,0.1], 'linewidth', 2, 'linestyle', '-');
%                 end
                %% 5 - Assigning to data structure
%                 % - Assigning values to the total area of the scans
%                 xScan{i} = xScan;
%                 yScan{i} = yScan;
%                 areaScan{i} = areaScan;
                % - Assigning values to the isocontours and their areas
                XCont{i} = xCont;
                YCont{i} = yCont;
                ACont{i} = areaCont;
            end
            
        end
    
        % ---  Function to plot iso-contours
        function fig = view_IC(app, icStr, isoeStr, bzStr, isoe_plot_args)
            %% 1 - Plotting the ARPES IsoE slice (if required)
            if isempty(findobj('type','figure','number',3333))
                fig = figure(3333); set(fig, 'Name', 'Iso-Contours', 'Position', [1,1,700,750]);
                view_final(app, isoeStr, bzStr, isoe_plot_args);    
                % - Re-formatting the figure
                title('Iso Contours');
                colorbar off; colorbar;
            end
            %% 2 - Plotting the iso-contours
            for i = 1:length(icStr)
                for j = 1:length(isoeStr)
                    for n = 1:length(icStr{i}.ACont{j})
                        % Set random color
                        col = rand(1,3);
                        % Plotting the iso-contours
                        hold on;
                        plot(icStr{i}.XCont{j}{n}, icStr{i}.YCont{j}{n}, 'color', col, 'linewidth', 2, 'linestyle', '-');
                        text(max(icStr{i}.XCont{j}{n}(:)), max(icStr{i}.YCont{j}{n}(:)), char(sprintf('%i,%i,%i', i, j, n)),...
                            'horizontalalignment', 'center', 'fontsize', 14, 'color', col);
                    end
                end
            end
        end
        
    end
     
    methods (Access = private)
        % Code that executes to initialise the app once data is loaded
        function initialiseFcn(app)
            %% 1 - Updating handle text and gui elements
            % - 1.1 - Show the name, type and information of the ARPES data
            app.DataFile_Label.Text         = app.myData.isoe_FileNames;
            % - 1.2 - Activating push-buttons that can be used
            % -- General buttons
            app.SAVE_Button.Enable          = 'off';
            app.TRANSFER_Button.Enable      = 'on';
            app.APPINFO_Button.Enable       = 'on';
            app.RESET_Button.Enable         = 'on';
            % -- General UI panel activation
            children = get(app.IsoEDataExtractionPanel,'Children'); set(children,'Enable','on');
            % - Iso-slice arguments
            isoSliceType_DropDownValueChanged(app);
            isoSliceWin_EditFieldValueChanged(app);
            isoSliceRemap_CheckBoxValueChanged(app);
            %% 2 - If processing has already been performed, load to most recent stage
            if isfield(app.myData.isoe{1}, 'XSlice')
                updateFcn(app);
            end
        end
        
        % Code that executes to update UI once IsoE data is extracted
        function updateFcn(app)
            %% Enable the save button
            app.SAVE_Button.Enable          = 'on';
            %% 1 - Setting all children in data-plotter panel to active
            children = get(app.IsoEPlotterPanel,'Children');set(children,'Enable','on');
            %% 2 - Setting all children to tab panels to active
            children = get(app.LineProfilesTab,'Children'); set(children,'Enable','on');
            children = get(app.IsoContoursTab,'Children'); set(children,'Enable','on');
            children = get(app.IsoEVideoTab,'Children');    set(children,'Enable','on');
            if ~isempty(app.myData.BZ);  app.showBZ_CheckBox.Enable  = 'on'; 
            else;                           app.showBZ_CheckBox.Enable  = 'off'; end
            %% 3 - Setting all children to IsoE corrections panels to active
            app.fileIndex_EditField.Enable          = 'on';
            children = get(app.FilterDataPanel,'Children');             set(children,'Enable','on');
            children = get(app.ShearCorrectionPanel,'Children');        set(children,'Enable','on');
            children = get(app.BackgroundSubtractionPanel,'Children');  set(children,'Enable','on');
            children = get(app.OriginCorrectionsPanel,'Children');      set(children,'Enable','on');
            children = get(app.ScaleCorrectionPanel,'Children');        set(children,'Enable','on');
            %% 4 - Update UI dependencies (IsoE plotter)
            Colormap_DropDownValueChanged(app);
            intLims_EditFieldValueChanged(app);
            xLims_EditFieldValueChanged(app);
            yLims_EditFieldValueChanged(app);
            axCol_EditFieldValueChanged(app);
            bgrndCol_EditFieldValueChanged(app);
            bzCol_EditFieldValueChanged(app);
            bzWidth_EditFieldValueChanged(app);
            showAxes_CheckBoxValueChanged(app);
            showScan_CheckBoxValueChanged(app);
            showGrid_CheckBoxValueChanged(app);
            showBZ_CheckBoxValueChanged(app);
            showInterp_CheckBoxValueChanged(app);
            %% 5 - Update UI dependencies (IsoE corrections)
            fileIndex_EditFieldValueChanged(app);
            FilterType_DropDownValueChanged(app);
            FilterParams_EditFieldValueChanged(app);
            xWinBS_EditFieldValueChanged(app);
            xShift_EditFieldValueChanged(app);
            yShift_EditFieldValueChanged(app);
            xSlope_EditFieldValueChanged(app);
            xIntercept_EditFieldValueChanged(app);
            ySlope_EditFieldValueChanged(app);
            yIntercept_EditFieldValueChanged(app);
            xScale_EditFieldValueChanged(app);
            yScale_EditFieldValueChanged(app);
            %% 6 - Update UI dependencies (IsoE Video)
            app.isoVid_args{1} = [-0.1, 0.1]; app.isoVid_args{2} = 0.1;
            vidWin_EditFieldValueChanged(app);
            vidLims_EditFieldValueChanged(app);
            %% 7 - Update UI dependencies (Line profiles)
            lpType_DropDownValueChanged(app);
            lpCutVal_EditFieldValueChanged(app);
            lpWin_EditFieldValueChanged(app);
            %% 8 - Update UI dependencies (Iso Contours)
            icMinArea_EditFieldValueChanged(app);
            icVal_EditFieldValueChanged(app);
            icSmooth_EditFieldValueChanged(app);
            %% - Updating UI if line-profiles or iso-contours exist
            if ~isempty(app.myData.LP); app.lpViewAll_Button.Enable  = 'on';
            else;                       app.lpViewAll_Button.Enable  = 'off';
            end
            if ~isempty(app.myData.IC); app.icViewAll_Button.Enable  = 'on';
            else;                       app.icViewAll_Button.Enable  = 'off';
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% 1 - Setting push-buttons to inactive, until data is loaded
            app.SAVE_Button.Enable          = 'off';
            app.TRANSFER_Button.Enable      = 'off';
            app.APPINFO_Button.Enable       = 'off';
            app.RESET_Button.Enable         = 'off';
            %% 2 - Setting all children to data-processing panels to inactive, until data is loaded
            children = get(app.IsoEDataExtractionPanel,'Children'); set(children,'Enable','off');
            children = get(app.IsoEPlotterPanel,'Children');        set(children,'Enable','off');
            %% 3 - Setting all children to tab panels to inactive, until data is loaded
            children = get(app.LineProfilesTab,'Children'); set(children,'Enable','off');
            children = get(app.IsoContoursTab,'Children'); set(children,'Enable','off');
            children = get(app.IsoEVideoTab,'Children');    set(children,'Enable','off');
            %% 4 - Setting all children to IsoE corrections panels to active
            app.fileIndex_EditField.Enable          = 'off';
            children = get(app.FilterDataPanel,'Children');             set(children,'Enable','off');
            children = get(app.ShearCorrectionPanel,'Children');        set(children,'Enable','off');
            children = get(app.BackgroundSubtractionPanel,'Children');  set(children,'Enable','off');
            children = get(app.OriginCorrectionsPanel,'Children');      set(children,'Enable','off');
            children = get(app.ScaleCorrectionPanel,'Children');        set(children,'Enable','off');
            %% 5 - Initialisation of variables
            % - Crystal arguments
            app.crystal_args{1}         = app.Crystal_DropDown.Value;
            app.crystal_args{2}         = app.PlaneSlice_DropDown.Value;
            app.crystal_args{3}(1)      = app.a_EditField.Value;
            app.crystal_args{3}(2)      = app.b_EditField.Value;
            app.crystal_args{3}(3)      = app.c_EditField.Value;
            app.crystal_args{4}(1)      = app.alpha_EditField.Value;
            app.crystal_args{4}(2)      = app.beta_EditField.Value;
            app.crystal_args{4}(3)      = app.gamma_EditField.Value;
        end

        % Button pushed function: ARPESGUI_Button
        function ARPESGUI_ButtonPushed(app, event)
            run ARPESGUI;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            %% 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            %% 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            %% Remove the GUI handle and restart
            close(gcbf); run ARPESisoe;
        end

        % Button pushed function: RESIZEGUI_Button
        function RESIZEGUI_ButtonPushed(app, event)
            %% Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 720;
            screen_size(4) = 530;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'arpes_data', app.myData);
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('APP : '); disp(app);
            disp('DATA (app.myData) : '); disp(app.myData);
            if isfield(app.myData, 'isoe'); disp('ISOE (.myData.isoe{1}) : '); disp(app.myData.isoe{1}); end
            if ~isempty(app.myData.BZ); disp('BZ (.myData.BZ) : '); disp(app.myData.BZ); end
            if ~isempty(app.myData.LP); disp('LP (.myData.LP) : '); disp(app.myData.LP); end
            if ~isempty(app.myData.IC); disp('IC (.myData.IC) : '); disp(app.myData.IC); end
        end

        % Button pushed function: LOAD_Button
        function LOAD_ButtonPushed(app, event)
            %% 1 - Loading in the FileNames and PathNames of the selected data
            [FileNames, PathNames] = uigetfile({'*.mat'}, 'Pick a processed *.mat data file...', 'MultiSelect', 'on');
            % - If Cancel is pressed, then return nothing
            if isequal(PathNames, 0) || isequal(FileNames, 0); return; end
            % - 1.1 - Load in the file selected
            if isfield(app.myData, 'BZ'); tempBZ = app.myData.BZ; else; tempBZ = []; end
            app.myData = [];
            app.myData = load_isoe_data(app, FileNames);
            app.myData.BZ = tempBZ;
            if ~isfield(app.myData, 'LP'); app.myData.LP = {}; end
            if ~isfield(app.myData, 'IC'); app.myData.IC = {}; end
            %% 2 - Reseting the app to the initial state
            runStartupFcn(app, @startupFcn);
            %% 3 - Initialising the app to the data that has been loaded
            initialiseFcn(app);
        end

        % Button pushed function: SAVE_Button
        function SAVE_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', app.myData.isoe{1}.FileName);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStruc = app.myData; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(app.myData);
            close(wbar);
            %% 3 - Saving a figure with the data
            fig = figure(); set(fig, 'Name', 'Iso-Slice', 'Position', [1,1,700,750]);
            view_final(app, app.myData.isoe, app.myData.BZ, app.isoe_plot_args);
            print(fig, char(save_fullfile(1:end-4)+"_isoe.png"), '-dpng');
            %% 4 - Saving a figure with the Line Profiles
            if ~isempty(app.myData.LP)
                fig = view_LP(app, app.myData.LP, app.myData.isoe, app.myData.BZ, app.isoe_plot_args);
                print(fig, char(save_fullfile(1:end-4)+"_lp.png"), '-dpng');
            end
            %% 4 - Saving a figure with the Line Profiles
            if ~isempty(app.myData.IC)
                fig = view_IC(app, app.myData.IC, app.myData.isoe, app.myData.BZ, app.isoe_plot_args);
                print(fig, char(save_fullfile(1:end-4)+"_ic.png"), '-dpng');
                savefig(fig, char(save_fullfile(1:end-4)+"_ic"));
            end
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: RESET_Button
        function RESET_ButtonPushed(app, event)
            %% 1 - Re-load the data-files
            FileNames = app.myData.isoe_FileNames;
            app.myData = [];
            app.myData = load_isoe_data(app, FileNames);
            app.myData.BZ = [];
            %% 2 - Reseting the app to the initial state
            runStartupFcn(app, @startupFcn);
            %% 3 - Initialising the app to the data that has been loaded
            initialiseFcn(app);
        end

        % Value changed function: Crystal_DropDown
        function Crystal_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.Crystal_DropDown.Value;
            
            %% 2 - Changing the GUI elements based on the selection
            % For 'CUB', 'BCC' and 'FCC' crystal
            if value == "CUB-Oh" || value == "BCC-Oh" || value == "FCC-Oh"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'off';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'HEX' crystal
            elseif value == "HEX-D6h"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'RHL' crystal
            elseif value == "RHL-D3d"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'off';
                app.alpha_EditField.Enable = 'on';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'TET' and 'BCT' crystal
            elseif value == "TET-D4h" || value == "BCT-D4h"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'off';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif value == "ORC-D2h" || value == "ORCC-D2h" || value == "ORCI-D2h" || value == "ORCF-D2h"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'off';
                app.gamma_EditField.Enable = 'off';
            % For 'MCL' and 'MCLC' crystal
            elseif value == "MCL-C2h" || value == "MCLC-C2h"
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'off';
                app.beta_EditField.Enable = 'on';
                app.gamma_EditField.Enable = 'off';
            % For 'TRI' crystal
            elseif value == "TRI-Ci" 
                % - Forcing symmetry by deactivating degenerate UI elements
                app.a_EditField.Enable = 'on';
                app.b_EditField.Enable = 'on';
                app.c_EditField.Enable = 'on';
                app.alpha_EditField.Enable = 'on';
                app.beta_EditField.Enable = 'on';
                app.gamma_EditField.Enable = 'on';
            end
            % - Setting the value of the argument
            app.crystal_args{1} = value;
            
            %% 3 - Run through all crystallographic inputs to update their values
            a_EditFieldValueChanged(app);
            b_EditFieldValueChanged(app);
            c_EditFieldValueChanged(app);
            alpha_EditFieldValueChanged(app);
            beta_EditFieldValueChanged(app);
            gamma_EditFieldValueChanged(app);
            
            %% 4 - Printing the change that has occurred
            fprintf("--> Crystal: " + app.crystal_args{1} + " \n");
        end

        % Value changed function: PlaneSlice_DropDown
        function PlaneSlice_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.PlaneSlice_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.crystal_args{2} = value;
            fprintf("--> Plane Slice: " + app.crystal_args{2} + " \n");
        end

        % Value changed function: a_EditField
        function a_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.a_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-Oh" || app.crystal_args{1} == "BCC-Oh" || app.crystal_args{1} == "FCC-Oh"
                % - a == b & c
                if value ~= app.crystal_args{3}(2); app.crystal_args{3}(2) = value; end
                if value ~= app.crystal_args{3}(3); app.crystal_args{3}(3) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-D6h"
                % - a == b ONLY
                if value ~= app.crystal_args{3}(2); app.crystal_args{3}(2) = value; end
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-D3d"
                % - a == b & c
                if value ~= app.crystal_args{3}(2); app.crystal_args{3}(2) = value; end
                if value ~= app.crystal_args{3}(3); app.crystal_args{3}(3) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-D4h" || app.crystal_args{1} == "BCT-D4h"
                % - a == b AND a =/= c
                if value == app.crystal_args{3}(3); value = value - 0.001; end
                if value ~= app.crystal_args{3}(2); app.crystal_args{3}(2) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-D2h" || app.crystal_args{1} == "ORCC-D2h" || app.crystal_args{1} == "ORCI-D2h" || app.crystal_args{1} == "ORCF-D2h"
                % - a =/= b AND a =/= c
                if value == app.crystal_args{3}(2); value = value - 0.001; end
                if value == app.crystal_args{3}(3); value = value - 0.001; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-C2h" || app.crystal_args{1} == "MCLC-C2h"
                % - a =/= c ONLY
                if value == app.crystal_args{3}(3); value = value - 0.001; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-Ci"
                % - No conditions for the 'TRI' crystal for a
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{3}(1) = value;
            fprintf("--> a (Ang): " + app.crystal_args{3}(1) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.a_EditField.Value = app.crystal_args{3}(1);
            app.b_EditField.Value = app.crystal_args{3}(2);
            app.c_EditField.Value = app.crystal_args{3}(3);
        end

        % Value changed function: b_EditField
        function b_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.b_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-Oh" || app.crystal_args{1} == "BCC-Oh" || app.crystal_args{1} == "FCC-Oh"
                % - b == a & c
                if value ~= app.crystal_args{3}(1); app.crystal_args{3}(1) = value; end
                if value ~= app.crystal_args{3}(3); app.crystal_args{3}(3) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-D6h"
                % - b == a ONLY
                if value ~= app.crystal_args{3}(1); app.crystal_args{3}(1) = value; end
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-D3d"
                % - b == a & c
                if value ~= app.crystal_args{3}(1); app.crystal_args{3}(1) = value; end
                if value ~= app.crystal_args{3}(3); app.crystal_args{3}(3) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-D4h" || app.crystal_args{1} == "BCT-D4h"
                % - b == a AND b =/= c
                if value == app.crystal_args{3}(3); value = value-0.001; end
                if value ~= app.crystal_args{3}(1); app.crystal_args{3}(1) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-D2h" || app.crystal_args{1} == "ORCC-D2h" || app.crystal_args{1} == "ORCI-D2h" || app.crystal_args{1} == "ORCF-D2h"
                % - b =/= a AND b =/= c
                if value == app.crystal_args{3}(1); value = value-0.001; end
                if value == app.crystal_args{3}(3); value = value-0.001; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-C2h" || app.crystal_args{1} == "MCLC-C2h"
                % - No conditions for the 'MCL' or 'MCLC' crystal for b
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-Ci"
                 % - No conditions for the 'TRI' crystal for b
            end
            %% 3 - Printing the change that has occurred
            app.crystal_args{3}(2) = value;
            fprintf("--> b (Ang): " + app.crystal_args{3}(2) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.a_EditField.Value = app.crystal_args{3}(1);
            app.b_EditField.Value = app.crystal_args{3}(2);
            app.c_EditField.Value = app.crystal_args{3}(3);
        end

        % Value changed function: c_EditField
        function c_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.c_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-Oh" || app.crystal_args{1} == "BCC-Oh" || app.crystal_args{1} == "FCC-Oh"
                % - c == a & b
                if value ~= app.crystal_args{2}(1); app.crystal_args{2}(1) = value; end
                if value ~= app.crystal_args{2}(2); app.crystal_args{2}(2) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-D6h"
                % - No conditions for the 'HEX' crystal for c(nm)
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-D3d"
                % - c == a & b
                if value ~= app.crystal_args{2}(1); app.crystal_args{2}(1) = value; end
                if value ~= app.crystal_args{2}(2); app.crystal_args{2}(2) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-D4h" || app.crystal_args{1} == "BCT-D4h"
                % - c =/= a
                if value == app.crystal_args{2}(1); value = value-0.001; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-D2h" || app.crystal_args{1} == "ORCC-D2h" || app.crystal_args{1} == "ORCI-D2h" || app.crystal_args{1} == "ORCF-D2h"
                % - c =/= a
                if value == app.crystal_args{3}(1); value = value-0.001; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-C2h" || app.crystal_args{1} == "MCLC-C2h"
                % - c =/= a
                if value == app.crystal_args{3}(1); value = value-0.001; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-Ci"
                % - No conditions for the 'TRI' crystal for c(nm)
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{3}(3) = value;
            fprintf("--> c (Ang): " + app.crystal_args{3}(3) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.a_EditField.Value = app.crystal_args{3}(1);
            app.b_EditField.Value = app.crystal_args{3}(2);
            app.c_EditField.Value = app.crystal_args{3}(3);
        end

        % Value changed function: alpha_EditField
        function alpha_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.alpha_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-Oh" || app.crystal_args{1} == "BCC-Oh" || app.crystal_args{1} == "FCC-Oh"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-D6h"
                % - Applying condition that alpha must be 90degrees and equal to beta
                value = 90;
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-D3d"
                % - Applying condition that alpha must not be 90degrees, but equal to beta and gamma
                if value == 90; value = value - 1; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-D4h" || app.crystal_args{1} == "BCT-D4h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-D2h" || app.crystal_args{1} == "ORCC-D2h" || app.crystal_args{1} == "ORCI-D2h" || app.crystal_args{1} == "ORCF-D2h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-C2h" || app.crystal_args{1} == "MCLC-C2h"
                % - Applying condition that alpha must be 90degrees and equal to gamma
                value = 90;
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-Ci"
                % - No conditions for the 'TRI' crystal for alpha
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{4}(1) = value;
            fprintf("--> alpha (deg): " + app.crystal_args{4}(1) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.alpha_EditField.Value = app.crystal_args{4}(1);
            app.beta_EditField.Value = app.crystal_args{4}(2);
            app.gamma_EditField.Value = app.crystal_args{4}(3);
        end

        % Value changed function: beta_EditField
        function beta_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.beta_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-Oh" || app.crystal_args{1} == "BCC-Oh" || app.crystal_args{1} == "FCC-Oh"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-D6h"
                % - Applying condition that alpha must be 90degrees and equal to beta
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-D3d"
                % - Applying condition that alpha must not be 90degrees, but equal to beta and gamma
                if value == 90; value = value - 1; end
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-D4h" || app.crystal_args{1} == "BCT-D4h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-D2h" || app.crystal_args{1} == "ORCC-D2h" || app.crystal_args{1} == "ORCI-D2h" || app.crystal_args{1} == "ORCF-D2h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(3); app.crystal_args{4}(3) = value; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-C2h" || app.crystal_args{1} == "MCLC-C2h"
                % - Applying condition that beta must not be 90 degrees
                if value == 90; value = value - 1; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-Ci"
                % - No conditions for the 'TRI' crystal for beta
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{4}(2) = value;
            fprintf("--> beta (deg): " + app.crystal_args{4}(2) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.alpha_EditField.Value = app.crystal_args{4}(1);
            app.beta_EditField.Value = app.crystal_args{4}(2);
            app.gamma_EditField.Value = app.crystal_args{4}(3);
        end

        % Value changed function: gamma_EditField
        function gamma_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.gamma_EditField.Value;
            
            %% 2 - Validity check on the symmetry arguments
            % For 'CUB', 'BCC' and 'FCC' crystal
            if app.crystal_args{1} == "CUB-Oh" || app.crystal_args{1} == "BCC-Oh" || app.crystal_args{1} == "FCC-Oh"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'HEX' crystal
            elseif app.crystal_args{1} == "HEX-D6h"
                % - Applying condition that gamma must be equal to 120 degrees
                value = 120;
            % For 'RHL' crystal
            elseif app.crystal_args{1} == "RHL-D3d"
                % - Applying condition that alpha must not be 90degrees, but equal to beta and gamma
                if value == 90; value = value - 1; end
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'TET' and 'BCT' crystal
            elseif app.crystal_args{1} == "TET-D4h" || app.crystal_args{1} == "BCT-D4h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'ORC', 'ORCC', 'ORCI' and 'ORCF' crystal
            elseif app.crystal_args{1} == "ORC-D2h" || app.crystal_args{1} == "ORCC-D2h" || app.crystal_args{1} == "ORCI-D2h" || app.crystal_args{1} == "ORCF-D2h"
                % - Applying condition that alpha must be 90degrees and equal to beta and gamma
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
                if value ~= app.crystal_args{4}(2); app.crystal_args{4}(2) = value; end
            % For 'MCL' and 'MCLC' crystal
            elseif app.crystal_args{1} == "MCL-C2h" || app.crystal_args{1} == "MCLC-C2h"
                % - Applying condition that alpha must be equal to gamma and 90 degrees
                value = 90;
                if value ~= app.crystal_args{4}(1); app.crystal_args{4}(1) = value; end
            % For 'TRI' crystal
            elseif app.crystal_args{1} == "TRI-Ci"
                % - No conditions for the 'TRI' crystal for beta
            end
            
            %% 3 - Printing the change that has occurred
            app.crystal_args{4}(3) = value;
            fprintf("--> gamma (deg): " + app.crystal_args{4}(3) + " \n");
            
            %% 4 - Updating all other relevant fields
            app.alpha_EditField.Value = app.crystal_args{4}(1);
            app.beta_EditField.Value = app.crystal_args{4}(2);
            app.gamma_EditField.Value = app.crystal_args{4}(3);
        end

        % Button pushed function: Extract2DBZ_Button
        function Extract2DBZ_ButtonPushed(app, event)
            %% 1 - Extracting the 3D Crystal and Brilluoin Zone
            [~, reciStr] = extract_lattice(app, app.crystal_args, 0);
            %% 2 - Extracting the 2D Brilluoin Zone planar slice
            app.myData.BZ = extract_plane(app, reciStr, app.crystal_args{2}, 1);
            %% 3 - Updating UI dependencies
            app.showBZ_CheckBox.Enable  = 'on';
        end

        % Value changed function: isoSliceType_DropDown
        function isoSliceType_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.isoSliceType_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.isoslice_args{1} = value;
            fprintf("--> IsoSlice Type: " + app.isoslice_args{1} + " \n");
            %% 3 - Updating dependencies
            isoSliceWin_EditFieldValueChanged(app);
        end

        % Value changed function: isoSliceWin_EditField
        function isoSliceWin_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.isoSliceWin_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            [xField, yField, ~, ~] = find_data_fields(app, app.myData);
            XminVal = round(min(app.myData.(xField)(:)) + 0.1, 2);
            XmaxVal = round(max(app.myData.(xField)(:)) - 0.1, 2);
            YminVal = round(min(app.myData.(yField)(:)) + 0.1, 2);
            YmaxVal = round(max(app.myData.(yField)(:)) - 0.1, 2);
            %% 3 - Validity check on input
            if app.isoslice_args{1} == "IsoE"
                defVal = [YminVal, YmaxVal];
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = defVal;
                % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
                elseif length(data_entry) == 1
                    data_entry = [-0.1, 0.1] + data_entry; 
                % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = YminVal; end
                    if isnan(data_entry(2)); data_entry(2) = YmaxVal; end
                    % -- If the values are identical, make them different
                    if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < YminVal || data_entry(1) > YmaxVal; data_entry(1) = YminVal; end
                if data_entry(2) < YminVal || data_entry(2) > YmaxVal; data_entry(2) = YmaxVal; end
            elseif app.isoslice_args{1} == "IsoK"
                defVal = [XminVal, XmaxVal];
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = defVal;
                % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
                elseif length(data_entry) == 1
                    data_entry = [-0.1, 0.1] + data_entry;
                % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = XminVal; end
                    if isnan(data_entry(2)); data_entry(2) = XmaxVal; end
                    % -- If the values are identical, make them different
                    if data_entry(1) == data_entry(2); data_entry = data_entry + [-0.1, 0.1]; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < XminVal || data_entry(1) > XmaxVal; data_entry(1) = XminVal; end
                if data_entry(2) < XminVal || data_entry(2) > XmaxVal; data_entry(2) = XmaxVal; end
            end
            %% 4 - Assigning output and printing change
            app.isoslice_args{2}  = sort(round(data_entry, 4));
            app.isoSliceWin_EditField.Value = string(app.isoslice_args{2}(1) + ":" + app.isoslice_args{2}(2)); 
            fprintf("--> IsoSlice Win: " + string(app.isoslice_args{2}(1) + ":" + app.isoslice_args{2}(2)) + " \n");
            %% 5 - Plotting the iso-slice
            extract_slice(app, app.myData.isoe, app.isoslice_args, 1);
        end

        % Value changed function: isoSliceRemap_CheckBox
        function isoSliceRemap_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.isoSliceRemap_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.isoslice_args{3} = value;
            if app.isoslice_args{3} == 1;   fprintf("--> isoSlice Remap: True \n");
            else;                           fprintf("--> isoSlice Remap: False \n");
            end
        end

        % Button pushed function: ExtractIsoE_Button
        function ExtractIsoE_ButtonPushed(app, event)
            %% 1 - Performing the IsoE slice operation
            temp_args = app.isoslice_args; temp_args{1} = "IsoE";
            app.myData.isoe = extract_slice(app, app.myData.isoe, temp_args, 1);
            %% 2 - Update UI dependencies
            updateFcn(app);
        end

        % Value changed function: Colormap_DropDown
        function Colormap_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.Colormap_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.isoe_plot_args{1} = value;
            fprintf("--> Colormap: " + app.isoe_plot_args{1} + " \n");
        end

        % Value changed function: intLims_EditField
        function intLims_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.intLims_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            %% 2 - Defining the limits of the input
            for i = 1:length(app.myData.isoe)
                minVals(i) = min(app.myData.isoe{i}.DSlice(:));
                maxVals(i) = max(app.myData.isoe{i}.DSlice(:));
            end
            minVal = round(min(minVals) + 0.01, 2);
            maxVal = round(max(maxVals) - 0.01, 2);
            %% 3 - Validity check on input
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) == 1 || length(data_entry) > 2
                data_entry = [minVal, maxVal];
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
            end
            data_entry = sort(round([data_entry(1), data_entry(2)], 4));
            %% 4 - Assigning output and printing change
            app.isoe_plot_args{2}  = data_entry;
            app.intLims_EditField.Value = string(app.isoe_plot_args{2}(1) + ":" + app.isoe_plot_args{2}(2)); 
            fprintf("--> intLims: " + string(app.isoe_plot_args{2}(1) + ":" + app.isoe_plot_args{2}(2)) + " \n");
        end

        % Value changed function: xLims_EditField
        function xLims_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.xLims_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            %% 2 - Defining the limits of the input
            for i = 1:length(app.myData.isoe)
                minVals(i) = min(app.myData.isoe{i}.XSlice(:));
                maxVals(i) = max(app.myData.isoe{i}.XSlice(:));
            end
            minVal = round(min(minVals) + 0.01, 2);
            maxVal = round(max(maxVals) - 0.01, 2);
            %% 3 - Validity check on input
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) == 1 || length(data_entry) > 2
                data_entry = [minVal, maxVal];
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
                if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            end
            data_entry = sort(round([data_entry(1), data_entry(2)], 4));
            %% 4 - Assigning output and printing change
            app.isoe_plot_args{3}  = data_entry;
            app.xLims_EditField.Value = string(app.isoe_plot_args{3}(1) + ":" + app.isoe_plot_args{3}(2)); 
            fprintf("--> xLims: " + string(app.isoe_plot_args{3}(1) + ":" + app.isoe_plot_args{3}(2)) + " \n");
        end

        % Value changed function: yLims_EditField
        function yLims_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.yLims_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            %% 2 - Defining the limits of the input
            for i = 1:length(app.myData.isoe)
                minVals(i) = min(app.myData.isoe{i}.YSlice(:));
                maxVals(i) = max(app.myData.isoe{i}.YSlice(:));
            end
            minVal = round(min(minVals) + 0.01, 2);
            maxVal = round(max(maxVals) - 0.01, 2);
            %% 3 - Validity check on input
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) == 1 || length(data_entry) > 2
                data_entry = [minVal, maxVal];
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
                if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            end
            data_entry = sort(round([data_entry(1), data_entry(2)], 4));
            %% 4 - Assigning output and printing change
            app.isoe_plot_args{4}  = data_entry;
            app.yLims_EditField.Value = string(app.isoe_plot_args{4}(1) + ":" + app.isoe_plot_args{4}(2)); 
            fprintf("--> yLims: " + string(app.isoe_plot_args{4}(1) + ":" + app.isoe_plot_args{4}(2)) + " \n");
        end

        % Value changed function: axCol_EditField
        function axCol_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.axCol_EditField.Value;
            data_entry = round(abs(str2double(split(value,','))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            minVal = 0;
            maxVal = 1;
            defVal = [1, 1, 1];
            %% 3 - Validity check on input
            % - If no entry is made, go to default
            if isempty(data_entry) || length(data_entry) ~= 3
                data_entry = defVal;
            % - If a double entry is made, check for NaN values
            else
                % -- Forcing to minimum on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = minVal; end
                if isnan(data_entry(3)); data_entry(3) = minVal; end
                % -- Check that they do not exceed max / min limites
                for i = 1:3
                    if data_entry(i) < minVal; data_entry(i) = minVal; end
                    if data_entry(i) > maxVal; data_entry(i) = maxVal; end
                end
                data_entry = [data_entry(1), data_entry(2), data_entry(3)];
            end
            data_entry = round(data_entry, 2);
            %% 4 - Assigning output and printing change
            app.isoe_plot_args{5} = data_entry;
            % - Defining comma-delimited string
            arg_str = sprintf('%.1f,' , app.isoe_plot_args{5});
            arg_str = arg_str(1:end-1);
            app.axCol_EditField.Value = arg_str; 
            fprintf("--> Axes Colour: " + arg_str + " \n");
        end

        % Value changed function: bgrndCol_EditField
        function bgrndCol_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.bgrndCol_EditField.Value;
            data_entry = round(abs(str2double(split(value,','))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            minVal = 0;
            maxVal = 1;
            defVal = [0, 0, 0];
            %% 3 - Validity check on input
            % - If no entry is made, go to default
            if isempty(data_entry) || length(data_entry) ~= 3
                data_entry = defVal;
            % - If a double entry is made, check for NaN values
            else
                % -- Forcing to minimum on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = minVal; end
                if isnan(data_entry(3)); data_entry(3) = minVal; end
                % -- Check that they do not exceed max / min limites
                for i = 1:3
                    if data_entry(i) < minVal; data_entry(i) = minVal; end
                    if data_entry(i) > maxVal; data_entry(i) = maxVal; end
                end
                data_entry = [data_entry(1), data_entry(2), data_entry(3)];
            end
            data_entry = round(data_entry, 2);
            %% 4 - Assigning output and printing change
            app.isoe_plot_args{6} = data_entry;
            % - Defining comma-delimited string
            arg_str = sprintf('%.1f,' , app.isoe_plot_args{6});
            arg_str = arg_str(1:end-1);
            app.bgrndCol_EditField.Value = arg_str; 
            fprintf("--> Background Colour: " + arg_str + " \n");
        end

        % Value changed function: bzCol_EditField
        function bzCol_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.bzCol_EditField.Value;
            data_entry = round(abs(str2double(split(value,','))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            minVal = 0;
            maxVal = 1;
            defVal = [1, 1, 1];
            %% 3 - Validity check on input
            % - If no entry is made, go to default
            if isempty(data_entry) || length(data_entry) ~= 3
                data_entry = defVal;
            % - If a double entry is made, check for NaN values
            else
                % -- Forcing to minimum on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = minVal; end
                if isnan(data_entry(3)); data_entry(3) = minVal; end
                % -- Check that they do not exceed max / min limites
                for i = 1:3
                    if data_entry(i) < minVal; data_entry(i) = minVal; end
                    if data_entry(i) > maxVal; data_entry(i) = maxVal; end
                end
                data_entry = [data_entry(1), data_entry(2), data_entry(3)];
            end
            data_entry = round(data_entry, 2);
            %% 4 - Assigning output and printing change
            app.isoe_plot_args{7} = data_entry;
            % - Defining comma-delimited string
            arg_str = sprintf('%.1f,' , app.isoe_plot_args{7});
            arg_str = arg_str(1:end-1);
            app.bzCol_EditField.Value = arg_str; 
            fprintf("--> BZ Outline Color: " + arg_str + " \n");
        end

        % Value changed function: bzWidth_EditField
        function bzWidth_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.bzWidth_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 3));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 3)); end
            data_entry = abs(data_entry(1));
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            defVal = 1.0;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 3 - Assigning output and printing change
            app.isoe_plot_args{8}  = data_entry;
            app.bzWidth_EditField.Value = string(app.isoe_plot_args{8}); 
            fprintf("--> BZ Line Width: " + string(app.isoe_plot_args{8}) + " \n");
        end

        % Value changed function: showAxes_CheckBox
        function showAxes_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.showAxes_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.isoe_plot_args{9} = value;
            if app.isoe_plot_args{9} == 1;  fprintf("--> Show axes lines: True \n");
            else;                           fprintf("--> Show axes lines: False \n");
            end
        end

        % Value changed function: showScan_CheckBox
        function showScan_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.showScan_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.isoe_plot_args{10} = value;
            if app.isoe_plot_args{10} == 1; fprintf("--> Show scan axis: True \n");
            else;                           fprintf("--> Show scan axis: False \n");
            end
        end

        % Value changed function: showGrid_CheckBox
        function showGrid_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.showGrid_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.isoe_plot_args{11} = value;
            if app.isoe_plot_args{11} == 1; fprintf("--> Show grid lines: True \n");
            else;                           fprintf("--> Show grid lines: False \n");
            end
        end

        % Value changed function: showBZ_CheckBox
        function showBZ_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.showBZ_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.isoe_plot_args{12} = value;
            if app.isoe_plot_args{12} == 1; fprintf("--> Show BZ overlay: True \n");
            else;                           fprintf("--> Show BZ overlay: False \n");
            end
        end

        % Value changed function: showInterp_CheckBox
        function showInterp_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.showInterp_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.isoe_plot_args{13} = value;
            if app.isoe_plot_args{13} == 1; fprintf("--> Interpolate data: True \n");
            else;                           fprintf("--> Interpolate data: False \n");
            end
        end

        % Button pushed function: ViewIsoEFig_Button
        function ViewIsoEFig_ButtonPushed(app, event)
            fig = figure(); set(fig, 'Name', 'Iso-Slice', 'Position', [1,1,700,750]);
            view_final(app, app.myData.isoe, app.myData.BZ, app.isoe_plot_args);
        end

        % Button pushed function: SaveIsoEOnly_Button
        function SaveIsoEOnly_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...');
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            isoeStr = app.myData.isoe; 
            save(char(save_fullfile), 'isoeStr', '-v7.3');
            disp('-> saved data : '); display(isoeStr);
            close(wbar);
            %% 5 - Saving a figure with the data
            fig = figure(); set(fig, 'Name', 'Iso-Slice', 'Position', [1,1,700,750]);
            view_final(app, app.myData.isoe, app.myData.BZ, app.isoe_plot_args);
            print(fig, char(save_fullfile(1:end-4)+"_slice.png"), '-dpng');
        end

        % Value changed function: fileIndex_EditField
        function fileIndex_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.fileIndex_EditField.Value;
            data_entry = sort(floor(abs(str2double(split(value,',')))))';
            if isnan(data_entry); data_entry = sort(floor(abs(str2double(split(value,':'))))); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            minVal = 1;
            maxVal = length(app.myData.isoe);
            %% 3 - Validity check on input
            % - If no entry is made, default to minimum
            if isempty(data_entry)
                data_entry = minVal:1:maxVal;
            % Using ':' separator for a range of scans
            elseif size(data_entry, 1) > 1
                data_entry(isnan(data_entry)) = minVal;
                data_entry = data_entry(1):1:data_entry(end);
            % Using ',' separator for certain scans
            elseif size(data_entry, 2) > 1
                % - Deleting any NaN values
                data_entry(isnan(data_entry)) = minVal;
            end
            % - Checking max/min are not exceeded
            data_entry(data_entry < minVal) = minVal;
            data_entry(data_entry > maxVal) = maxVal;
            % - Checking that there are not duplicate numbers
            data_entry = unique(data_entry);
            %% 4 - Assigning output and printing change
            app.fileIndex = data_entry;
            % - Defining comma-delimited string
            arg_str = sprintf('%.0f,' , app.fileIndex);
            arg_str = arg_str(1:end-1);
            app.fileIndex_EditField.Value = arg_str; 
            fprintf("--> File index/s: " + arg_str + " \n");
        end

        % Value changed function: FilterType_DropDown
        function FilterType_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.FilterType_DropDown.Value;
            %% 2 - Validity check on input
            % For 'Gaco2' smoothing
            if value == "Gaco2"     
                app.hwXhwYLabel.Text = 'hwX, hwY:';
            % For 'GaussFlt2' smoothing
            elseif value == "GaussFlt2"  
                app.hwXhwYLabel.Text = 'hwX, hwY:';
            % For 'LaplaceFlt2' smoothing
            elseif value == "LaplaceFlt2"
                app.hwXhwYLabel.Text = 'y2xRatio:';
            % For 'CurvatureFlt2' smoothing
            elseif value == "CurvatureFlt2"
                app.hwXhwYLabel.Text = 'CX, CY:';
            end
            %% 3 - Printing the change that has occurred
            app.filter_args{1} = value;
            fprintf("--> Filter Type: " + app.filter_args{1} + " \n");
            %% 4 - Updating dependencies
            app.FilterParams_EditField.Value = ''; 
            FilterParams_EditFieldValueChanged(app);
        end

        % Value changed function: FilterParams_EditField
        function FilterParams_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.FilterParams_EditField.Value;
            data_entry = round(abs(str2double(split(value,','))), 4);
            
            %% 2 - Validity check on input
            % For 'Gaco2' or 'CurvatureFlt2' smoothing
            if app.filter_args{1} == "Gaco2" || app.filter_args{1} == "CurvatureFlt2"
                defVal = 0.5;
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = [defVal, defVal];
                % - If a single entry is made, make a row vector that is identical
                elseif length(data_entry) == 1
                    if isnan(data_entry); data_entry = defVal; end
                    data_entry = [data_entry, data_entry];
                % - If a double entry is made, check for NaN values
                elseif length(data_entry) == 2
                    % -- Forcing to default on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = defVal; end
                    if isnan(data_entry(2)); data_entry(2) = defVal; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
            % For 'GaussFlt2' smoothing
            elseif app.filter_args{1} == "GaussFlt2"
                defVal = 1;
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = [defVal, defVal];
                % - If a single entry is made, make a row vector that is identical
                elseif length(data_entry) == 1
                    if isnan(data_entry); data_entry = defVal; end
                    data_entry = [data_entry, data_entry];
                    if data_entry(1) < 1; data_entry(1) = 1; end
                    if data_entry(2) < 1; data_entry(2) = 1; end
                % - If a double entry is made, check for NaN values
                elseif length(data_entry) == 2
                    % -- Forcing to default on the entries if NaN or < 1
                    if isnan(data_entry(1)) || data_entry(1) < 1; data_entry(1) = defVal; end
                    if isnan(data_entry(2)) || data_entry(2) < 1; data_entry(2) = defVal; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
            % For 'LaplaceFlt2' smoothing
            elseif app.filter_args{1} == "LaplaceFlt2"
                defVal = 0;
                 % - If no entry is made, default to a given value
                if isempty(data_entry) || length(data_entry) > 1
                    data_entry = defVal;
                % - If a single entry is made, check for NaN
                elseif length(data_entry) == 1
                    if isnan(data_entry); data_entry = defVal; end
                end
            end
            %% 4 - Assigning output and printing change
            app.filter_args{2} = round(abs(data_entry), 4);
            if app.filter_args{1} == "LaplaceFlt2"
                app.FilterParams_EditField.Value = string(app.filter_args{2}); 
                fprintf("--> Filter params: " + string(app.filter_args{2}) + " \n");
            else
                app.FilterParams_EditField.Value = string(app.filter_args{2}(1) + "," + app.filter_args{2}(2)); 
                fprintf("--> Filter params: : " + string(app.filter_args{2}(1) + "," + app.filter_args{2}(2)) + " \n");
            end
        end

        % Button pushed function: ExecuteFiltering_Button
        function ExecuteFiltering_ButtonPushed(app, event)
            %% 1 - Performing the filtering operation
            temp_data = filter_data(app, app.myData.isoe, app.fileIndex, app.filter_args);
            
            %% 2 - View the figure update
            fig = figure(); set(fig, 'Name', 'Iso-Slice', 'Position', [1,1,700,750]);
            view_final(app, temp_data, app.myData.BZ, app.isoe_plot_args);
            
            %% 3 - Ask the user if they want to keep the new data
            answer = questdlg('Would you like to store the filtered data?', ...
            	'Filter data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            % - Otherwise, store the data
            app.myData.isoe = temp_data;
        end

        % Value changed function: xWinBS_EditField
        function xWinBS_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.xWinBS_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            %% 2 - Defining the limits of the input
            for i = 1:length(app.myData.isoe)
                minVals(i) = min(app.myData.isoe{i}.XSlice(:));
                maxVals(i) = max(app.myData.isoe{i}.XSlice(:));
            end
            minVal = round(min(minVals) + 0.01, 2);
            maxVal = round(max(maxVals) - 0.01, 2);
            %% 3 - Validity check on input
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) == 1 || length(data_entry) > 2
                data_entry = [minVal, maxVal];
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
                if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            end
            data_entry = sort(round([data_entry(1), data_entry(2)], 4));
            %% 4 - Assigning output and printing change
            app.bsub_args{1}  = data_entry;
            app.xWinBS_EditField.Value = string(app.bsub_args{1}(1) + ":" + app.bsub_args{1}(2)); 
            fprintf("--> xWin (Back. Sub.): " + string(app.bsub_args{1}(1) + ":" + app.bsub_args{1}(2)) + " \n");
            
        end

        % Button pushed function: ExecuteBS_Button
        function ExecuteBS_ButtonPushed(app, event)
            %% 1 - Performing the filtering operation
            temp_data = background_subtraction(app, app.myData.isoe, app.fileIndex, app.bsub_args);
            
            %% 2 - View the figure update
            fig = figure(); set(fig, 'Name', 'Iso-Slice', 'Position', [1,1,700,750]);
            view_final(app, temp_data, app.myData.BZ, app.isoe_plot_args);
            
            %% 3 - Ask the user if they want to keep the new data
            answer = questdlg('Would you like to store the background subtracted data?', ...
            	'Background subtraction?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            % - Otherwise, store the data
            app.myData.isoe = temp_data;
        end

        % Value changed function: xShift_EditField
        function xShift_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.xShift_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 4));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 4)); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            defVal = 0;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 3 - Assigning output and printing change
            app.origin_args{1}  = data_entry;
            app.xShift_EditField.Value = string(app.origin_args{1}); 
            fprintf("--> x-shift: " + string(app.origin_args{1}) + " \n");
        end

        % Value changed function: yShift_EditField
        function yShift_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.yShift_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 4));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 4)); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            defVal = 0;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 3 - Assigning output and printing change
            app.origin_args{2}  = data_entry;
            app.yShift_EditField.Value = string(app.origin_args{2}); 
            fprintf("--> y-shift: " + string(app.origin_args{2}) + " \n");
        end

        % Button pushed function: ExecuteOC_Button
        function ExecuteOC_ButtonPushed(app, event)
            %% 1 - Performing the filtering operation
            temp_data = origin_correction(app, app.myData.isoe, app.fileIndex, app.origin_args);
            
            %% 2 - View the figure update
            fig = figure(); set(fig, 'Name', 'Iso-Slice', 'Position', [1,1,700,750]);
            view_final(app, temp_data, app.myData.BZ, app.isoe_plot_args);
            
            %% 3 - Ask the user if they want to keep the new data
            answer = questdlg('Would you like to store the origin corrected data?', ...
            	'Origin Correction?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            % - Otherwise, store the data
            app.myData.isoe = temp_data;
        end

        % Value changed function: xSlope_EditField
        function xSlope_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.xSlope_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 4));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 4)); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            defVal = 0;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 3 - Assigning output and printing change
            app.shear_args{1}  = data_entry;
            app.xSlope_EditField.Value = string(app.shear_args{1}); 
            fprintf("--> x-slope: " + string(app.shear_args{1}) + " \n");
        end

        % Value changed function: xIntercept_EditField
        function xIntercept_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.xIntercept_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 4));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 4)); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            defVal = 0;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 3 - Assigning output and printing change
            app.shear_args{2}  = data_entry;
            app.xIntercept_EditField.Value = string(app.shear_args{2}); 
            fprintf("--> x-intercept: " + string(app.shear_args{2}) + " \n");
        end

        % Value changed function: ySlope_EditField
        function ySlope_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.ySlope_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 4));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 4)); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            defVal = 0;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 3 - Assigning output and printing change
            app.shear_args{3}  = data_entry;
            app.ySlope_EditField.Value = string(app.shear_args{3}); 
            fprintf("--> y-slope: " + string(app.shear_args{3}) + " \n");
        end

        % Value changed function: yIntercept_EditField
        function yIntercept_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.yIntercept_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 4));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 4)); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            defVal = 0;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 3 - Assigning output and printing change
            app.shear_args{4}  = data_entry;
            app.yIntercept_EditField.Value = string(app.shear_args{4}); 
            fprintf("--> y-intercept: " + string(app.shear_args{4}) + " \n");
        end

        % Button pushed function: ExecuteSC_Button
        function ExecuteSC_ButtonPushed(app, event)
            %% 1 - Performing the filtering operation
            temp_data = shear_correction(app, app.myData.isoe, app.fileIndex, app.shear_args);
            
            %% 2 - View the figure update
            fig = figure(); set(fig, 'Name', 'Iso-Slice', 'Position', [1,1,700,750]);
            view_final(app, temp_data, app.myData.BZ, app.isoe_plot_args);
            
            %% 3 - Ask the user if they want to keep the new data
            answer = questdlg('Would you like to store the shear corrected data?', ...
            	'Shear Correction?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            % - Otherwise, store the data
            app.myData.isoe = temp_data;
        end

        % Value changed function: xScale_EditField
        function xScale_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.xScale_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 4));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 4)); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            defVal = 0;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 3 - Assigning output and printing change
            app.scale_args{1}  = data_entry;
            app.xScale_EditField.Value = string(app.scale_args{1}); 
            fprintf("--> x-scale: " + string(app.scale_args{1}) + " \n");
        end

        % Value changed function: yScale_EditField
        function yScale_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.yScale_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 4));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 4)); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            defVal = 0;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 3 - Assigning output and printing change
            app.scale_args{2}  = data_entry;
            app.yScale_EditField.Value = string(app.scale_args{2}); 
            fprintf("--> y-scale: " + string(app.scale_args{2}) + " \n");
        end

        % Button pushed function: ExecuteScale_Button
        function ExecuteScale_ButtonPushed(app, event)
            %% 1 - Performing the filtering operation
            temp_data = scale_correction(app, app.myData.isoe, app.fileIndex, app.scale_args);
            
            %% 2 - View the figure update
            fig = figure(); set(fig, 'Name', 'Iso-Slice', 'Position', [1,1,700,750]);
            view_final(app, temp_data, app.myData.BZ, app.isoe_plot_args);
            
            %% 3 - Ask the user if they want to keep the new data
            answer = questdlg('Would you like to store the scale corrected data?', ...
            	'Scale Correction?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            % - Otherwise, store the data
            app.myData.isoe = temp_data;
        end

        % Value changed function: vidLims_EditField
        function vidLims_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.vidLims_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            [~, yField, ~, ~] = find_data_fields(app, app.myData);
            YminVal = round(min(app.myData.(yField)(:)) + 0.1, 2);
            YmaxVal = round(max(app.myData.(yField)(:)) - 0.1, 2);
            %% 3 - Validity check on input
            defVal = [YminVal, YmaxVal];
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) > 2
                data_entry = defVal;
            % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
            elseif length(data_entry) == 1
                data_entry = [-0.1, 0.1] + data_entry; 
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = YminVal; end
                if isnan(data_entry(2)); data_entry(2) = YmaxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                data_entry = [data_entry(1), data_entry(2)];
            end
            % -- Forcing the max / min limits if exceeded
            if data_entry(1) < YminVal || data_entry(1) > YmaxVal; data_entry(1) = YminVal; end
            if data_entry(2) < YminVal || data_entry(2) > YmaxVal; data_entry(2) = YmaxVal; end
            %% 4 - Assigning output and printing change
            app.isoVid_args{1}  = sort(round(data_entry, 4));
            app.vidLims_EditField.Value = string(app.isoVid_args{1}(1) + ":" + app.isoVid_args{1}(2)); 
            fprintf("--> IsoE Limits: " + string(app.isoVid_args{1}(1) + ":" + app.isoVid_args{1}(2)) + " \n");
            %% 5 - Updating UI dependencies
            nFrames = length(app.isoVid_args{1}(1):app.isoVid_args{2}:app.isoVid_args{1}(2));
            app.nFrames_EditField.Value = nFrames;
        end

        % Value changed function: vidWin_EditField
        function vidWin_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.vidWin_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 3));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 3)); end
            data_entry = abs(data_entry(1));
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            defVal = 0.10;
            minVal = 0.01;
            maxVal = 0.5 * abs(app.isoVid_args{1}(2) - app.isoVid_args{1}(1));
            %% 3 - Validity check on input
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                % -- Forcing the max / min limits if exceeded
                if data_entry < minVal; data_entry = minVal; end
                if data_entry > maxVal; data_entry = maxVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 4 - Assigning output and printing change
            app.isoVid_args{2}  = data_entry;
            app.vidWin_EditField.Value = string(app.isoVid_args{2}); 
            fprintf("--> IsoE Integration Window: " + string(app.isoVid_args{2}) + " \n");
            %% 5 - Updating UI dependencies
            nFrames = length(app.isoVid_args{1}(1):app.isoVid_args{2}:app.isoVid_args{1}(2));
            app.nFrames_EditField.Value = nFrames;
        end

        % Button pushed function: SaveVideo_Button
        function SaveVideo_ButtonPushed(app, event)
            view_video(app, app.myData.isoe, app.myData.BZ, app.isoe_plot_args, app.isoVid_args);
            waitbar(1,'Save complete!');
        end

        % Button pushed function: SaveSeries_Button
        function SaveSeries_ButtonPushed(app, event)
            view_isoSeries(app, app.myData.isoe, app.myData.BZ, app.isoe_plot_args, app.isoVid_args);
            waitbar(1,'Save complete!');
        end

        % Value changed function: lpType_DropDown
        function lpType_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.lpType_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.lp_args{1} = value;
            fprintf("--> Line Profile Type: " + app.lp_args{1} + " \n");
            %% 3 - Updating dependencies
            app.lpCutVal_EditField.Value = '';  lpCutVal_EditFieldValueChanged(app);
            app.lpWin_EditField.Value = '';     lpWin_EditFieldValueChanged(app);
        end

        % Value changed function: lpCutVal_EditField
        function lpCutVal_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.lpCutVal_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 3));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 3)); end
            data_entry = (data_entry(1));
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            for i = 1:length(app.myData.isoe)
                XminVals(i) = min(app.myData.isoe{i}.XSlice(:));
                XavgVals(i) = mean(app.myData.isoe{i}.XSlice(:));
                XmaxVals(i) = max(app.myData.isoe{i}.XSlice(:));
                YminVals(i) = min(app.myData.isoe{i}.YSlice(:));
                YavgVals(i) = mean(app.myData.isoe{i}.YSlice(:));
                YmaxVals(i) = max(app.myData.isoe{i}.YSlice(:));
            end
            XminVal = round(min(XminVals) + 0.01, 2);
            XavgVal = round(min(XavgVals), 2);
            XmaxVal = round(max(XmaxVals) - 0.01, 2);
            YminVal = round(min(YminVals) + 0.01, 2);
            YavgVal = round(min(YavgVals), 2);
            YmaxVal = round(max(YmaxVals) - 0.01, 2);
            %% 3 - Validity check on input
            if app.lp_args{1} == "horizontal"
                % - If no entry is made, default to minimum
                if isempty(data_entry) || length(data_entry) > 1
                    data_entry = YavgVal;
                % - If a single entry is made, check min/max values
                else
                    % -- Forcing to default if NaN
                    if isnan(data_entry); data_entry = YavgVal; end
                    % -- Forcing the max / min limits if exceeded
                    if data_entry < YminVal; data_entry = YminVal; end
                    if data_entry > YmaxVal; data_entry = YmaxVal; end
                    data_entry = round(data_entry(1), 4);
                end
            elseif app.lp_args{1} == "vertical"
                % - If no entry is made, default to minimum
                if isempty(data_entry) || length(data_entry) > 1
                    data_entry = XavgVal;
                % - If a single entry is made, check min/max values
                else
                    % -- Forcing to default if NaN
                    if isnan(data_entry); data_entry = XavgVal; end
                    % -- Forcing the max / min limits if exceeded
                    if data_entry < XminVal; data_entry = XminVal; end
                    if data_entry > XmaxVal; data_entry = XmaxVal; end
                    data_entry = round(data_entry(1), 4);
                end
            end
            %% 4 - Assigning output and printing change
            app.lp_args{2} = data_entry;
            app.lpCutVal_EditField.Value = string(app.lp_args{2}); 
            fprintf("--> Line Profile - Cut Value: " + app.lp_args{2} + " \n");
        end

        % Value changed function: lpWin_EditField
        function lpWin_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.lpWin_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 3));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 3)); end
            data_entry = abs(data_entry(1));
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            for i = 1:length(app.myData.isoe)
                XminVals(i) = min(app.myData.isoe{i}.XSlice(:));
                XmaxVals(i) = max(app.myData.isoe{i}.XSlice(:));
                YminVals(i) = min(app.myData.isoe{i}.YSlice(:));
                YmaxVals(i) = max(app.myData.isoe{i}.YSlice(:));
            end
            defVal = 0.1;
            minVal = 0.01;
            XmaxVal = 0.4 * abs(max(XmaxVals) - min(XminVals));
            YmaxVal = 0.4 * abs(max(YmaxVals) - min(YminVals));
            %% 3 - Validity check on input
            if app.lp_args{1} == "horizontal"
                % - If no entry is made, default to minimum
                if isempty(data_entry) || length(data_entry) > 1
                    data_entry = defVal;
                % - If a single entry is made, check min/max values
                else
                    % -- Forcing to default if NaN
                    if isnan(data_entry); data_entry = defVal; end
                    % -- Forcing the max / min limits if exceeded
                    if data_entry < minVal; data_entry = minVal; end
                    if data_entry > YmaxVal; data_entry = YmaxVal; end
                    data_entry = round(data_entry(1), 4);
                end
            elseif app.lp_args{1} == "vertical"
                % - If no entry is made, default to minimum
                if isempty(data_entry) || length(data_entry) > 1
                    data_entry = defVal;
                % - If a single entry is made, check min/max values
                else
                    % -- Forcing to default if NaN
                    if isnan(data_entry); data_entry = defVal; end
                    % -- Forcing the max / min limits if exceeded
                    if data_entry < minVal; data_entry = minVal; end
                    if data_entry > XmaxVal; data_entry = XmaxVal; end
                    data_entry = round(data_entry(1), 4);
                    data_entry = round(data_entry(1), 4);
                end
            end
            %% 4 - Assigning output and printing change
            app.lp_args{3} = data_entry;
            app.lpWin_EditField.Value = string(app.lp_args{3}); 
            fprintf("--> Line Profile - Cut Window: " + app.lp_args{3} + " \n");
        end

        % Button pushed function: lpExtract_Button
        function lpExtract_ButtonPushed(app, event)
            %% 1 - Extracting the line profiles
            app.tempLP = {};
            app.tempLP{1}.lp_args = app.lp_args;
            [app.tempLP{1}.XCut, app.tempLP{1}.DCut] = extract_line_profile(app, app.myData.isoe, app.lp_args);
            
            %% 2 - View the figure update
            view_LP(app, app.tempLP, app.myData.isoe, app.myData.BZ, app.isoe_plot_args);
        end

        % Button pushed function: lpAppend_Button
        function lpAppend_ButtonPushed(app, event)
            %% 1 - Appending the line-profile to the data structure
            % If there are no LPs stored, append the LP
            if isempty(app.myData.LP)
                app.myData.LP{end+1} = app.tempLP{1};
                fprintf("-> LP appended - unique...\n");
                app.lpViewAll_Button.Enable  = 'on';
            else
               % Checking for any identical LPs that are already stores
                break_point = 0;
                for i = 1:length(app.myData.LP)
                    if strcmp(app.myData.LP{i}.lp_args{1}, app.tempLP{1}.lp_args{1}) && app.myData.LP{i}.lp_args{2} == app.tempLP{1}.lp_args{2} && app.myData.LP{i}.lp_args{3} == app.tempLP{1}.lp_args{3}
                        break_point = 1;
                    end
                end    
                % If it is a unique line-profile, append it
                if break_point == 0
                    app.myData.LP{end+1} = app.tempLP{1};
                    fprintf("-> LP appended - unique...\n");
                 % If the same line-profile already exists in the LP cell, do not append
                elseif break_point == 1
                    fprintf("-> LP not appended - already stored...\n");
                end
            end
        end

        % Button pushed function: lpViewAll_Button
        function lpViewAll_ButtonPushed(app, event)
            view_LP(app, app.myData.LP, app.myData.isoe, app.myData.BZ, app.isoe_plot_args);
        end

        % Button pushed function: lpClearAll_Button
        function lpClearAll_ButtonPushed(app, event)
            %% 1 - Ask the user if they want to clear all the line-profile
            answer = questdlg('Are you sure you want to clear all appended line-profiles?', ...
            	'Clear all?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            %% 2 - Deleting the line-profiles and button
            if ~isempty(app.myData.LP)
                app.myData.LP = {}; 
                app.lpViewAll_Button.Enable  = 'off';
            end
        end

        % Value changed function: icMinArea_EditField
        function icMinArea_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.icMinArea_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 3));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 3)); end
            data_entry = abs(data_entry(1));
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            defVal = 0.005;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = defVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 3 - Assigning output and printing change
            app.ic_args{1}  = data_entry;
            app.icMinArea_EditField.Value = string(app.ic_args{1}); 
            fprintf("--> Iso Contour - Minimum Area Threshold: " + string(app.ic_args{1}) + " \n");
        end

        % Value changed function: icVal_EditField
        function icVal_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.icVal_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 3));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 3)); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            for i = 1:length(app.myData.isoe)
                minVals(i) = min(app.myData.isoe{i}.DSlice(:));
                avgVals(i) = mean(app.myData.isoe{i}.DSlice(:));
                maxVals(i) = max(app.myData.isoe{i}.DSlice(:));
            end
            minVal = round(min(minVals), 2);
            avgVal = round(min(avgVals), 2);
            maxVal = round(max(maxVals), 2);
            %% 3 - Validity check on input
            % - If no entry is made, default
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = maxVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing to default if NaN
                if isnan(data_entry); data_entry = maxVal; end
                % -- Forcing the max / min limits if exceeded
                if data_entry < minVal; data_entry = minVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 4 - Assigning output and printing change
            app.ic_args{2} = data_entry;
            app.icVal_EditField.Value = string(app.ic_args{2}); 
            fprintf("--> Iso Contours - Value: " + app.ic_args{2} + " \n");
        end

        % Value changed function: icSmooth_EditField
        function icSmooth_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.icSmooth_EditField.Value;
            data_entry = round(abs(str2double(split(value,','))), 4);
            %% 2 - Validity check on input
            % For 'Gaco2' smoothing
            defVal = 0.5;
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) > 2
                data_entry = [defVal, defVal];
            % - If a single entry is made, make a row vector that is identical
            elseif length(data_entry) == 1
                if isnan(data_entry); data_entry = defVal; end
                data_entry = [data_entry, data_entry];
            % - If a double entry is made, check for NaN values
            elseif length(data_entry) == 2
                % -- Forcing to default on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = defVal; end
                if isnan(data_entry(2)); data_entry(2) = defVal; end
                data_entry = [data_entry(1), data_entry(2)];
            end
            %% 4 - Assigning output and printing change
            app.ic_args{3} = round(abs(data_entry), 4);
            app.icSmooth_EditField.Value = string(app.ic_args{3}(1) + "," + app.ic_args{3}(2)); 
            fprintf("--> Iso Contour - pre-smooth: : " + string(app.ic_args{3}(1) + "," + app.ic_args{3}(2)) + " \n");
        end

        % Button pushed function: icExtract_Button
        function icExtract_ButtonPushed(app, event)
            %% 1 - Extracting the iso contours
            app.tempIC = {};
            app.tempIC{1}.ic_args = app.ic_args;
            [app.tempIC{1}.XCont, app.tempIC{1}.YCont, app.tempIC{1}.ACont] = extract_iso_contour(app, app.myData.isoe, app.ic_args);
            
            %% 2 - View the figure update
            view_IC(app, app.tempIC, app.myData.isoe, app.myData.BZ, app.isoe_plot_args);
        end

        % Button pushed function: icAppend_Button
        function icAppend_ButtonPushed(app, event)
            %% 1 - Appending the iso contour to the data structure
            % If there are no ICs stored, append the IC
            if isempty(app.myData.IC)
                app.myData.IC{end+1} = app.tempIC{1};
                fprintf("-> IC appended - unique...\n");
                app.icViewAll_Button.Enable  = 'on';
            else
               % Checking for any identical LPs that are already stores
                break_point = 0;
                for i = 1:length(app.myData.IC)
                    if app.myData.IC{i}.ic_args{1} == app.tempIC{1}.ic_args{1} && app.myData.IC{i}.ic_args{2} == app.tempIC{1}.ic_args{2} && app.myData.IC{i}.ic_args{3} == app.tempIC{1}.ic_args{3}
                        break_point = 1;
                    end
                end
                % If it is a unique line-profile, append it
                if break_point == 0
                    app.myData.IC{end+1} = app.tempIC{1};
                    fprintf("-> IC appended - unique...\n");
                 % If the same line-profile already exists in the LP cell, do not append
                elseif break_point == 1
                    fprintf("-> IC not appended - already stored...\n");
                end
            end
        end

        % Button pushed function: icViewAll_Button
        function icViewAll_ButtonPushed(app, event)
            view_IC(app, app.myData.IC, app.myData.isoe, app.myData.BZ, app.isoe_plot_args);
        end

        % Button pushed function: icClearAll_Button
        function icClearAll_ButtonPushed(app, event)
            %% 1 - Ask the user if they want to clear all the line-profile
            answer = questdlg('Are you sure you want to clear all appended iso-contours?', ...
            	'Clear all?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            %% 2 - Deleting the line-profiles and button
            if ~isempty(app.myData.IC)
                app.myData.IC = {}; 
                app.icViewAll_Button.Enable  = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CLOSEFIGS_Button = uibutton(app.UIFigure, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Closes all open figures, but leaves the current one open.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.UIFigure, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Restarts the app to its default state.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEGUI_Button = uibutton(app.UIFigure, 'push');
            app.RESIZEGUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEGUI_ButtonPushed, true);
            app.RESIZEGUI_Button.Tooltip = {'Resizes the app to its default size.'};
            app.RESIZEGUI_Button.Text = 'RESIZE GUI';
            app.ARPESGUI_Button = uibutton(app.UIFigure, 'push');
            app.ARPESGUI_Button.ButtonPushedFcn = createCallbackFcn(app, @ARPESGUI_ButtonPushed, true);
            app.ARPESGUI_Button.Tooltip = {'Back to the main ARPESGUI menu.'};
            app.ARPESGUI_Button.Text = 'ARPESGUI (main)';
            app.SAVE_Button = uibutton(app.UIFigure, 'push');
            app.SAVE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_ButtonPushed, true);
            app.SAVE_Button.Tooltip = {'Save the data from the app as a MATLAB structure file.'};
            app.SAVE_Button.Text = 'SAVE';
            app.TRANSFER_Button = uibutton(app.UIFigure, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.APPINFO_Button = uibutton(app.UIFigure, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.LOAD_Button = uibutton(app.UIFigure, 'push');
            app.LOAD_Button.ButtonPushedFcn = createCallbackFcn(app, @LOAD_ButtonPushed, true);
            app.LOAD_Button.Tooltip = {'Load in the ARPES data. Must be single/multiple *.mat files that have been parsed through the ''data processing'' UI.'};
            app.LOAD_Button.Text = {'LOAD'; ''};
            app.AppTitle = uilabel(app.UIFigure);
            app.AppTitle.Text = 'IsoE Analysis';
            app.RESET_Button = uibutton(app.UIFigure, 'push');
            app.RESET_Button.ButtonPushedFcn = createCallbackFcn(app, @RESET_ButtonPushed, true);
            app.RESET_Button.Tooltip = {'Reset all data processing and re-load the initial data.'};
            app.RESET_Button.Text = 'RESET';
            app.IsoEDataExtractionPanel = uipanel(app.UIFigure);
            app.IsoEDataExtractionPanel.Title = '(1) IsoE Data Extraction';
            app.ExtractIsoE_Button = uibutton(app.IsoEDataExtractionPanel, 'push');
            app.ExtractIsoE_Button.ButtonPushedFcn = createCallbackFcn(app, @ExtractIsoE_ButtonPushed, true);
            app.ExtractIsoE_Button.Tooltip = {''; ''};
            app.ExtractIsoE_Button.Text = 'Extract IsoE Slice';
            app.isoSliceRemap_CheckBox = uicheckbox(app.IsoEDataExtractionPanel);
            app.isoSliceRemap_CheckBox.ValueChangedFcn = createCallbackFcn(app, @isoSliceRemap_CheckBoxValueChanged, true);
            app.isoSliceRemap_CheckBox.Tooltip = {'Remap slice onto a rectangular grid.'};
            app.isoSliceRemap_CheckBox.Text = 'Remap?';
            app.isoSliceWinLabel_3 = uilabel(app.IsoEDataExtractionPanel);
            app.isoSliceWinLabel_3.Text = 'isoSlice Win:';
            app.isoSliceWin_EditField = uieditfield(app.IsoEDataExtractionPanel, 'text');
            app.isoSliceWin_EditField.ValueChangedFcn = createCallbackFcn(app, @isoSliceWin_EditFieldValueChanged, true);
            app.isoSliceWin_EditField.Tooltip = {'Limits of slice window, defined over the range min:max.'};
            app.isoSliceWin_EditField.Value = '-0.1:0.1';
            app.IsoSliceTypeDropDownLabel = uilabel(app.IsoEDataExtractionPanel);
            app.IsoSliceTypeDropDownLabel.Text = 'IsoSlice Type:';
            app.isoSliceType_DropDown = uidropdown(app.IsoEDataExtractionPanel);
            app.isoSliceType_DropDown.Items = {'IsoK', 'IsoE', '', ''};
            app.isoSliceType_DropDown.ValueChangedFcn = createCallbackFcn(app, @isoSliceType_DropDownValueChanged, true);
            app.isoSliceType_DropDown.Tooltip = {'Iso-slice for browsing data prior to IsoE extraction. '};
            app.isoSliceType_DropDown.Value = 'IsoE';
            app.IsoEPlotterPanel = uipanel(app.UIFigure);
            app.IsoEPlotterPanel.Title = '(2) IsoE Plotter';
            app.intLimsLabel = uilabel(app.IsoEPlotterPanel);
            app.intLimsLabel.Text = 'intLims:';
            app.intLims_EditField = uieditfield(app.IsoEPlotterPanel, 'text');
            app.intLims_EditField.ValueChangedFcn = createCallbackFcn(app, @intLims_EditFieldValueChanged, true);
            app.intLims_EditField.Tooltip = {'Limits of the intensity, defined over the range min:max.'};
            app.intLims_EditField.Value = '[]';
            app.xLimsLabel = uilabel(app.IsoEPlotterPanel);
            app.xLimsLabel.Text = 'xLims:';
            app.xLims_EditField = uieditfield(app.IsoEPlotterPanel, 'text');
            app.xLims_EditField.ValueChangedFcn = createCallbackFcn(app, @xLims_EditFieldValueChanged, true);
            app.xLims_EditField.Tooltip = {'Limits of x-axis, defined over the range xmin:xmax.'};
            app.xLims_EditField.Value = '[]';
            app.yLimsLabel = uilabel(app.IsoEPlotterPanel);
            app.yLimsLabel.Text = 'yLims:';
            app.yLims_EditField = uieditfield(app.IsoEPlotterPanel, 'text');
            app.yLims_EditField.ValueChangedFcn = createCallbackFcn(app, @yLims_EditFieldValueChanged, true);
            app.yLims_EditField.Tooltip = {'Limits of y-axis, defined over the range ymin:ymax.'};
            app.yLims_EditField.Value = '[]';
            app.axColLabel = uilabel(app.IsoEPlotterPanel);
            app.axColLabel.Text = 'axCol:';
            app.axCol_EditField = uieditfield(app.IsoEPlotterPanel, 'text');
            app.axCol_EditField.ValueChangedFcn = createCallbackFcn(app, @axCol_EditFieldValueChanged, true);
            app.axCol_EditField.Tooltip = {'Colour of the x- and y-axis, defined as a row-vector.'};
            app.axCol_EditField.Value = '[]';
            app.bzColLabel = uilabel(app.IsoEPlotterPanel);
            app.bzColLabel.Text = 'bzCol:';
            app.bzCol_EditField = uieditfield(app.IsoEPlotterPanel, 'text');
            app.bzCol_EditField.ValueChangedFcn = createCallbackFcn(app, @bzCol_EditFieldValueChanged, true);
            app.bzCol_EditField.Tooltip = {'Colour of the Brilluoin Zone overlay, defined as a row-vector.'};
            app.bzCol_EditField.Value = '[]';
            app.bgrndColLabel = uilabel(app.IsoEPlotterPanel);
            app.bgrndColLabel.Text = 'bgrndCol:';
            app.bgrndCol_EditField = uieditfield(app.IsoEPlotterPanel, 'text');
            app.bgrndCol_EditField.ValueChangedFcn = createCallbackFcn(app, @bgrndCol_EditFieldValueChanged, true);
            app.bgrndCol_EditField.Tooltip = {'Colour of the axis background, defined as a row-vector.'};
            app.bgrndCol_EditField.Value = '[]';
            app.showAxes_CheckBox = uicheckbox(app.IsoEPlotterPanel);
            app.showAxes_CheckBox.ValueChangedFcn = createCallbackFcn(app, @showAxes_CheckBoxValueChanged, true);
            app.showAxes_CheckBox.Tooltip = {'Show the horizontal and vertical axes lines.'};
            app.showAxes_CheckBox.Text = 'Show axes lines?';
            app.showScan_CheckBox = uicheckbox(app.IsoEPlotterPanel);
            app.showScan_CheckBox.ValueChangedFcn = createCallbackFcn(app, @showScan_CheckBoxValueChanged, true);
            app.showScan_CheckBox.Tooltip = {'Show the scan parameter axis (hv for kz, tilt for ky).'};
            app.showScan_CheckBox.Text = 'Show scan axis?';
            app.showGrid_CheckBox = uicheckbox(app.IsoEPlotterPanel);
            app.showGrid_CheckBox.ValueChangedFcn = createCallbackFcn(app, @showGrid_CheckBoxValueChanged, true);
            app.showGrid_CheckBox.Tooltip = {'Show grid-lines.'};
            app.showGrid_CheckBox.Text = 'Show grid lines?';
            app.showBZ_CheckBox = uicheckbox(app.IsoEPlotterPanel);
            app.showBZ_CheckBox.ValueChangedFcn = createCallbackFcn(app, @showBZ_CheckBoxValueChanged, true);
            app.showBZ_CheckBox.Tooltip = {'Show a tessellated overlay of the extended Brilluoin Zone.'};
            app.showBZ_CheckBox.Text = 'Show BZ overlay?';
            app.showInterp_CheckBox = uicheckbox(app.IsoEPlotterPanel);
            app.showInterp_CheckBox.ValueChangedFcn = createCallbackFcn(app, @showInterp_CheckBoxValueChanged, true);
            app.showInterp_CheckBox.Tooltip = {'Interpolate the data.'};
            app.showInterp_CheckBox.Text = 'Interpolate data?';
            app.bzWidthLabel = uilabel(app.IsoEPlotterPanel);
            app.bzWidthLabel.Text = 'bzWidth:';
            app.bzWidth_EditField = uieditfield(app.IsoEPlotterPanel, 'text');
            app.bzWidth_EditField.ValueChangedFcn = createCallbackFcn(app, @bzWidth_EditFieldValueChanged, true);
            app.bzWidth_EditField.Tooltip = {'Linewidth of the Brilluoin Zone overlay.'};
            app.bzWidth_EditField.Value = '[]';
            app.ViewIsoEFig_Button = uibutton(app.IsoEPlotterPanel, 'push');
            app.ViewIsoEFig_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewIsoEFig_ButtonPushed, true);
            app.ViewIsoEFig_Button.Tooltip = {'View the final ARPES IsoE figure.'};
            app.ViewIsoEFig_Button.Text = 'View ARPES IsoE Figure';
            app.SaveIsoEOnly_Button = uibutton(app.IsoEPlotterPanel, 'push');
            app.SaveIsoEOnly_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveIsoEOnly_ButtonPushed, true);
            app.SaveIsoEOnly_Button.Tooltip = {'Save only the IsoE data.'};
            app.SaveIsoEOnly_Button.Text = 'Save IsoE Data Only';
            app.ColormapLabel = uilabel(app.IsoEPlotterPanel);
            app.ColormapLabel.Text = 'Colormap:';
            app.Colormap_DropDown = uidropdown(app.IsoEPlotterPanel);
            app.Colormap_DropDown.Items = {'hot', 'parula', 'jet', 'gray', 'bone', 'hsv', 'cool', 'spring', 'summer', 'autumn', 'winter', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'flag'};
            app.Colormap_DropDown.ValueChangedFcn = createCallbackFcn(app, @Colormap_DropDownValueChanged, true);
            app.Colormap_DropDown.Tooltip = {'Select colormap of the ARPES data.'};
            app.Colormap_DropDown.Value = 'hot';
            app.DataFile_Label = uilabel(app.UIFigure);
            app.DataFile_Label.Text = '<<   select one or multiple Eb(kx,ky) / Eb(kx,kz) processed *.mat ARPES data file';
            app.IsoCutBrowser = uitabgroup(app.UIFigure);
            app.LineProfilesTab = uitab(app.IsoCutBrowser);
            app.LineProfilesTab.Title = 'Line Profiles';
            app.LineProfileDirectionLabel = uilabel(app.LineProfilesTab);
            app.LineProfileDirectionLabel.Text = 'Line Profile Direction:';
            app.lpType_DropDown = uidropdown(app.LineProfilesTab);
            app.lpType_DropDown.Items = {'horizontal', 'vertical'};
            app.lpType_DropDown.ValueChangedFcn = createCallbackFcn(app, @lpType_DropDownValueChanged, true);
            app.lpType_DropDown.Value = 'horizontal';
            app.CutValueLabel = uilabel(app.LineProfilesTab);
            app.CutValueLabel.Text = 'Cut Value:';
            app.lpCutVal_EditField = uieditfield(app.LineProfilesTab, 'text');
            app.lpCutVal_EditField.ValueChangedFcn = createCallbackFcn(app, @lpCutVal_EditFieldValueChanged, true);
            app.lpCutVal_EditField.Tooltip = {'Cut value for the IsoE line profile extraction.'};
            app.lpCutVal_EditField.Value = '0';
            app.CutWindowLabel = uilabel(app.LineProfilesTab);
            app.CutWindowLabel.Text = 'Cut Window (+/-):';
            app.lpWin_EditField = uieditfield(app.LineProfilesTab, 'text');
            app.lpWin_EditField.ValueChangedFcn = createCallbackFcn(app, @lpWin_EditFieldValueChanged, true);
            app.lpWin_EditField.Tooltip = {'Cut window for the IsoE line profile extraction.'};
            app.lpWin_EditField.Value = '0';
            app.lpViewAll_Button = uibutton(app.LineProfilesTab, 'push');
            app.lpViewAll_Button.ButtonPushedFcn = createCallbackFcn(app, @lpViewAll_ButtonPushed, true);
            app.lpViewAll_Button.Tooltip = {'View all appended line-profiles.'};
            app.lpViewAll_Button.Text = 'View All';
            app.lpAppend_Button = uibutton(app.LineProfilesTab, 'push');
            app.lpAppend_Button.ButtonPushedFcn = createCallbackFcn(app, @lpAppend_ButtonPushed, true);
            app.lpAppend_Button.Tooltip = {'Append this line profile to be included in the final data set when saving.'};
            app.lpAppend_Button.Text = 'Append';
            app.lpClearAll_Button = uibutton(app.LineProfilesTab, 'push');
            app.lpClearAll_Button.ButtonPushedFcn = createCallbackFcn(app, @lpClearAll_ButtonPushed, true);
            app.lpClearAll_Button.Tooltip = {'Clear all appended line-profiles.'; ''};
            app.lpClearAll_Button.Text = 'Clear all LPs';
            app.lpExtract_Button = uibutton(app.LineProfilesTab, 'push');
            app.lpExtract_Button.ButtonPushedFcn = createCallbackFcn(app, @lpExtract_ButtonPushed, true);
            app.lpExtract_Button.Tooltip = {'Extract the 2D slice through the Brilluoin Zone of the crystal structure defined through the plane slice (hkl).'};
            app.lpExtract_Button.Text = 'Extract Line Profile';
            app.IsoContoursTab = uitab(app.IsoCutBrowser);
            app.IsoContoursTab.Title = 'Iso Contours';
            app.icViewAll_Button = uibutton(app.IsoContoursTab, 'push');
            app.icViewAll_Button.ButtonPushedFcn = createCallbackFcn(app, @icViewAll_ButtonPushed, true);
            app.icViewAll_Button.Tooltip = {'View all appended iso-contours.'};
            app.icViewAll_Button.Text = 'View All';
            app.icAppend_Button = uibutton(app.IsoContoursTab, 'push');
            app.icAppend_Button.ButtonPushedFcn = createCallbackFcn(app, @icAppend_ButtonPushed, true);
            app.icAppend_Button.Tooltip = {'Append these iso-contours to be included in the final data set when saving.'; ''};
            app.icAppend_Button.Text = 'Append';
            app.icClearAll_Button = uibutton(app.IsoContoursTab, 'push');
            app.icClearAll_Button.ButtonPushedFcn = createCallbackFcn(app, @icClearAll_ButtonPushed, true);
            app.icClearAll_Button.Tooltip = {'Clear all appended iso-contours.'; ''};
            app.icClearAll_Button.Text = 'Clear all ICs';
            app.icExtract_Button = uibutton(app.IsoContoursTab, 'push');
            app.icExtract_Button.ButtonPushedFcn = createCallbackFcn(app, @icExtract_ButtonPushed, true);
            app.icExtract_Button.Tooltip = {'Extract the 2D slice through the Brilluoin Zone of the crystal structure defined through the plane slice (hkl).'};
            app.icExtract_Button.Text = 'Extract Iso Contour';
            app.IsoContourValueLabel = uilabel(app.IsoContoursTab);
            app.IsoContourValueLabel.Text = 'Iso Contour Value:';
            app.icVal_EditField = uieditfield(app.IsoContoursTab, 'text');
            app.icVal_EditField.ValueChangedFcn = createCallbackFcn(app, @icVal_EditFieldValueChanged, true);
            app.icVal_EditField.Tooltip = {'Value of the iso-contour to be extracted.'};
            app.icVal_EditField.Value = '0';
            app.preSmoothLabel = uilabel(app.IsoContoursTab);
            app.preSmoothLabel.Text = 'preSmooth:';
            app.icSmooth_EditField = uieditfield(app.IsoContoursTab, 'text');
            app.icSmooth_EditField.ValueChangedFcn = createCallbackFcn(app, @icSmooth_EditFieldValueChanged, true);
            app.icSmooth_EditField.Tooltip = {'Pre-smoothing along x, y that is applied to IsoE data when finding the iso-contours. '};
            app.icSmooth_EditField.Value = '0.5, 0.5';
            app.MinimumAreaThresholdLabel = uilabel(app.IsoContoursTab);
            app.MinimumAreaThresholdLabel.Text = 'Minimum Area Threshold:';
            app.icMinArea_EditField = uieditfield(app.IsoContoursTab, 'text');
            app.icMinArea_EditField.ValueChangedFcn = createCallbackFcn(app, @icMinArea_EditFieldValueChanged, true);
            app.icMinArea_EditField.Tooltip = {'Minimum rea threshold for iso-contours. Anything below this will be omitted.'};
            app.icMinArea_EditField.Value = '0';
            app.IsoEVideoTab = uitab(app.IsoCutBrowser);
            app.IsoEVideoTab.Title = 'IsoE Video';
            app.IsoELimitseVLabel = uilabel(app.IsoEVideoTab);
            app.IsoELimitseVLabel.Text = 'IsoE Limits (eV):';
            app.vidLims_EditField = uieditfield(app.IsoEVideoTab, 'text');
            app.vidLims_EditField.ValueChangedFcn = createCallbackFcn(app, @vidLims_EditFieldValueChanged, true);
            app.vidLims_EditField.Tooltip = {'Limits of IsoE window for the video, defined over the range min:max.'};
            app.vidLims_EditField.Value = '-0.1:0.1';
            app.IsoEIntWindowLabel = uilabel(app.IsoEVideoTab);
            app.IsoEIntWindowLabel.Text = 'IsoE IntWindow:';
            app.vidWin_EditField = uieditfield(app.IsoEVideoTab, 'text');
            app.vidWin_EditField.ValueChangedFcn = createCallbackFcn(app, @vidWin_EditFieldValueChanged, true);
            app.vidWin_EditField.Tooltip = {'Integration window for each IsoE slice.'};
            app.vidWin_EditField.Value = '0.05';
            app.SaveVideo_Button = uibutton(app.IsoEVideoTab, 'push');
            app.SaveVideo_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveVideo_ButtonPushed, true);
            app.SaveVideo_Button.Tooltip = {'Save a ''.avi'' video file of the IsoE slices vs binding energy.'};
            app.SaveVideo_Button.Text = 'Save IsoE Video';
            app.TotalnumberofframesLabel = uilabel(app.IsoEVideoTab);
            app.TotalnumberofframesLabel.Text = 'Total number of frames:';
            app.nFrames_EditField = uieditfield(app.IsoEVideoTab, 'numeric');
            app.nFrames_EditField.Limits = [1 Inf];
            app.nFrames_EditField.ValueDisplayFormat = '%.0f';
            app.nFrames_EditField.Editable = 'off';
            app.nFrames_EditField.Value = 1;
            app.SaveSeries_Button = uibutton(app.IsoEVideoTab, 'push');
            app.SaveSeries_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveSeries_ButtonPushed, true);
            app.SaveSeries_Button.Tooltip = {'Save a ''.png'' image series of the IsoE slices vs binding energy.'};
            app.SaveSeries_Button.Text = 'Save IsoE Image Series';
            app.BrilluoinZoneExtractionPanel = uipanel(app.UIFigure);
            app.BrilluoinZoneExtractionPanel.Title = 'Brilluoin Zone Extraction';
            app.Extract2DBZ_Button = uibutton(app.BrilluoinZoneExtractionPanel, 'push');
            app.Extract2DBZ_Button.ButtonPushedFcn = createCallbackFcn(app, @Extract2DBZ_ButtonPushed, true);
            app.Extract2DBZ_Button.Tooltip = {'Extract the 2D slice through the Brilluoin Zone of the crystal structure defined through the plane slice (hkl).'};
            app.Extract2DBZ_Button.Text = 'Extract / View 2D BZ';
            app.CrystalDropDownLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.CrystalDropDownLabel.Text = 'Crystal:';
            app.Crystal_DropDown = uidropdown(app.BrilluoinZoneExtractionPanel);
            app.Crystal_DropDown.Items = {'CUB-Oh', 'BCC-Oh', 'FCC-Oh', 'HEX-D6h', 'RHL-D3d', 'TET-D4h', 'BCT-D4h', 'ORC-D2h', 'ORCC-D2h', 'ORCI-D2h', 'ORCF-D2h', 'MCL-C2h', 'MCLC-C2h', 'TRI-Ci'};
            app.Crystal_DropDown.ValueChangedFcn = createCallbackFcn(app, @Crystal_DropDownValueChanged, true);
            app.Crystal_DropDown.Tooltip = {'Real-space crystal structure.'};
            app.Crystal_DropDown.Value = 'FCC-Oh';
            app.PlaneSlicehklDropDownLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.PlaneSlicehklDropDownLabel.Text = 'Plane Slice (hkl):';
            app.PlaneSlice_DropDown = uidropdown(app.BrilluoinZoneExtractionPanel);
            app.PlaneSlice_DropDown.Items = {'(001)', '(010)', '(100)', '(110)'};
            app.PlaneSlice_DropDown.ValueChangedFcn = createCallbackFcn(app, @PlaneSlice_DropDownValueChanged, true);
            app.PlaneSlice_DropDown.Tooltip = {'Miller place slice through the real-space crystal.'};
            app.PlaneSlice_DropDown.Value = '(001)';
            app.aAngLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.aAngLabel.Text = 'a (Ang.):';
            app.a_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.a_EditField.Limits = [0 Inf];
            app.a_EditField.ValueDisplayFormat = '%.4f';
            app.a_EditField.ValueChangedFcn = createCallbackFcn(app, @a_EditFieldValueChanged, true);
            app.a_EditField.Tooltip = {'Lattice constant in Angstroms along the x-axis (or crystal vector a).'};
            app.a_EditField.Value = 5.431;
            app.bAngLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.bAngLabel.Text = 'b (Ang.):';
            app.b_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.b_EditField.Limits = [0 Inf];
            app.b_EditField.ValueDisplayFormat = '%.4f';
            app.b_EditField.ValueChangedFcn = createCallbackFcn(app, @b_EditFieldValueChanged, true);
            app.b_EditField.Enable = 'off';
            app.b_EditField.Tooltip = {'Lattice constant in Angstroms along the y-axis (or crystal vector b).'};
            app.b_EditField.Value = 5.431;
            app.cAngLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.cAngLabel.Text = 'c(Ang.):';
            app.c_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.c_EditField.Limits = [0 Inf];
            app.c_EditField.ValueDisplayFormat = '%.4f';
            app.c_EditField.ValueChangedFcn = createCallbackFcn(app, @c_EditFieldValueChanged, true);
            app.c_EditField.Enable = 'off';
            app.c_EditField.Tooltip = {'Lattice constant in Angstroms along the z-axis (or crystal vector c).'};
            app.c_EditField.Value = 5.431;
            app.alphadegLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.alphadegLabel.Text = 'alpha (deg.):';
            app.alpha_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.alpha_EditField.Limits = [-180 180];
            app.alpha_EditField.ValueDisplayFormat = '%.4f';
            app.alpha_EditField.ValueChangedFcn = createCallbackFcn(app, @alpha_EditFieldValueChanged, true);
            app.alpha_EditField.Enable = 'off';
            app.alpha_EditField.Tooltip = {'Angle cosine in degrees of the crystal vector a, relative to the x-axis.'};
            app.alpha_EditField.Value = 90;
            app.betadegLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.betadegLabel.Text = 'beta (deg.):';
            app.beta_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.beta_EditField.Limits = [-180 180];
            app.beta_EditField.ValueDisplayFormat = '%.4f';
            app.beta_EditField.ValueChangedFcn = createCallbackFcn(app, @beta_EditFieldValueChanged, true);
            app.beta_EditField.Enable = 'off';
            app.beta_EditField.Tooltip = {'Angle cosine in degrees of the crystal vector b, relative to the y-axis.'};
            app.beta_EditField.Value = 90;
            app.gammadegLabel = uilabel(app.BrilluoinZoneExtractionPanel);
            app.gammadegLabel.Text = 'gamma (deg.):';
            app.gamma_EditField = uieditfield(app.BrilluoinZoneExtractionPanel, 'numeric');
            app.gamma_EditField.Limits = [-180 180];
            app.gamma_EditField.ValueDisplayFormat = '%.4f';
            app.gamma_EditField.ValueChangedFcn = createCallbackFcn(app, @gamma_EditFieldValueChanged, true);
            app.gamma_EditField.Enable = 'off';
            app.gamma_EditField.Tooltip = {'Angle cosine in degrees of the crystal vector c, relative to the z-axis.'};
            app.gamma_EditField.Value = 90;
            app.IsoEDataCorrectionsPanel = uipanel(app.UIFigure);
            app.IsoEDataCorrectionsPanel.Title = '(3) IsoE Data Corrections';
            app.FilterDataPanel = uipanel(app.IsoEDataCorrectionsPanel);
            app.FilterDataPanel.Title = 'Filter Data';
            app.ExecuteFiltering_Button = uibutton(app.FilterDataPanel, 'push');
            app.ExecuteFiltering_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteFiltering_ButtonPushed, true);
            app.ExecuteFiltering_Button.Tooltip = {''};
            app.ExecuteFiltering_Button.Text = 'Execture Filtering';
            app.FilterTypeDropDownLabel = uilabel(app.FilterDataPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type:';
            app.FilterType_DropDown = uidropdown(app.FilterDataPanel);
            app.FilterType_DropDown.Items = {'Gaco2', 'GaussFlt2', 'LaplaceFlt2', 'CurvatureFlt2'};
            app.FilterType_DropDown.ValueChangedFcn = createCallbackFcn(app, @FilterType_DropDownValueChanged, true);
            app.FilterType_DropDown.Tooltip = {'Data filtering type.'};
            app.FilterType_DropDown.Value = 'Gaco2';
            app.hwXhwYLabel = uilabel(app.FilterDataPanel);
            app.hwXhwYLabel.Text = 'hwX, hwY:';
            app.FilterParams_EditField = uieditfield(app.FilterDataPanel, 'text');
            app.FilterParams_EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParams_EditFieldValueChanged, true);
            app.FilterParams_EditField.Tooltip = {'Filter variables. Either a list of x,y smooth parameters or a single value for Laplace.'};
            app.FilterParams_EditField.Value = '0.5,0.5';
            app.ShearCorrectionPanel = uipanel(app.IsoEDataCorrectionsPanel);
            app.ShearCorrectionPanel.Title = 'Shear Correction';
            app.xslopeLabel = uilabel(app.ShearCorrectionPanel);
            app.xslopeLabel.Text = 'x-slope:';
            app.xSlope_EditField = uieditfield(app.ShearCorrectionPanel, 'text');
            app.xSlope_EditField.ValueChangedFcn = createCallbackFcn(app, @xSlope_EditFieldValueChanged, true);
            app.xSlope_EditField.Value = '0';
            app.xinterceptLabel = uilabel(app.ShearCorrectionPanel);
            app.xinterceptLabel.Text = 'x-intercept:';
            app.xIntercept_EditField = uieditfield(app.ShearCorrectionPanel, 'text');
            app.xIntercept_EditField.ValueChangedFcn = createCallbackFcn(app, @xIntercept_EditFieldValueChanged, true);
            app.xIntercept_EditField.Value = '0';
            app.yslopeLabel = uilabel(app.ShearCorrectionPanel);
            app.yslopeLabel.Text = 'y-slope:';
            app.ySlope_EditField = uieditfield(app.ShearCorrectionPanel, 'text');
            app.ySlope_EditField.ValueChangedFcn = createCallbackFcn(app, @ySlope_EditFieldValueChanged, true);
            app.ySlope_EditField.Value = '0';
            app.yinterceptLabel = uilabel(app.ShearCorrectionPanel);
            app.yinterceptLabel.Text = 'y-intercept:';
            app.yIntercept_EditField = uieditfield(app.ShearCorrectionPanel, 'text');
            app.yIntercept_EditField.ValueChangedFcn = createCallbackFcn(app, @yIntercept_EditFieldValueChanged, true);
            app.yIntercept_EditField.Value = '0';
            app.ExecuteSC_Button = uibutton(app.ShearCorrectionPanel, 'push');
            app.ExecuteSC_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteSC_ButtonPushed, true);
            app.ExecuteSC_Button.Tooltip = {''};
            app.ExecuteSC_Button.Text = 'Execture Shear Correction';
            app.FileindexsLabel = uilabel(app.IsoEDataCorrectionsPanel);
            app.FileindexsLabel.Text = 'File index/s:';
            app.fileIndex_EditField = uieditfield(app.IsoEDataCorrectionsPanel, 'text');
            app.fileIndex_EditField.ValueChangedFcn = createCallbackFcn(app, @fileIndex_EditFieldValueChanged, true);
            app.fileIndex_EditField.Tooltip = {'List of file indices to be IsoE corrected. Can be defined as a list a,b,c,... or range a:c.'};
            app.fileIndex_EditField.Value = '[]';
            app.BackgroundSubtractionPanel = uipanel(app.IsoEDataCorrectionsPanel);
            app.BackgroundSubtractionPanel.Title = 'Background Subtraction';
            app.ExecuteBS_Button = uibutton(app.BackgroundSubtractionPanel, 'push');
            app.ExecuteBS_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteBS_ButtonPushed, true);
            app.ExecuteBS_Button.Tooltip = {''};
            app.ExecuteBS_Button.Text = 'Execute Back. Sub.';
            app.xWinLabel = uilabel(app.BackgroundSubtractionPanel);
            app.xWinLabel.Text = 'xWin:';
            app.xWinBS_EditField = uieditfield(app.BackgroundSubtractionPanel, 'text');
            app.xWinBS_EditField.ValueChangedFcn = createCallbackFcn(app, @xWinBS_EditFieldValueChanged, true);
            app.xWinBS_EditField.Tooltip = {'Limits of the region defined as the background, defined over the range min:max.'};
            app.xWinBS_EditField.Value = '-0.65:-0.35';
            app.OriginCorrectionsPanel = uipanel(app.IsoEDataCorrectionsPanel);
            app.OriginCorrectionsPanel.Title = 'Origin Corrections';
            app.xshiftLabel = uilabel(app.OriginCorrectionsPanel);
            app.xshiftLabel.Text = 'x-shift:';
            app.xShift_EditField = uieditfield(app.OriginCorrectionsPanel, 'text');
            app.xShift_EditField.ValueChangedFcn = createCallbackFcn(app, @xShift_EditFieldValueChanged, true);
            app.xShift_EditField.Tooltip = {'Offset value to shift the x-axis origin by.'};
            app.xShift_EditField.Value = '0';
            app.yshiftLabel = uilabel(app.OriginCorrectionsPanel);
            app.yshiftLabel.Text = 'y-shift:';
            app.yShift_EditField = uieditfield(app.OriginCorrectionsPanel, 'text');
            app.yShift_EditField.ValueChangedFcn = createCallbackFcn(app, @yShift_EditFieldValueChanged, true);
            app.yShift_EditField.Tooltip = {'Offset value to shift the y-axis origin by.'};
            app.yShift_EditField.Value = '0';
            app.ExecuteOC_Button = uibutton(app.OriginCorrectionsPanel, 'push');
            app.ExecuteOC_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteOC_ButtonPushed, true);
            app.ExecuteOC_Button.Tooltip = {''};
            app.ExecuteOC_Button.Text = 'Execute Origin Shift';
            app.ScaleCorrectionPanel = uipanel(app.IsoEDataCorrectionsPanel);
            app.ScaleCorrectionPanel.Title = 'Scale Correction';
            app.xscaleLabel = uilabel(app.ScaleCorrectionPanel);
            app.xscaleLabel.Text = 'x-scale:';
            app.xScale_EditField = uieditfield(app.ScaleCorrectionPanel, 'text');
            app.xScale_EditField.ValueChangedFcn = createCallbackFcn(app, @xScale_EditFieldValueChanged, true);
            app.xScale_EditField.Tooltip = {'x-axis scale factor to stretch the IsoE data.'};
            app.xScale_EditField.Value = '0';
            app.yscaleLabel = uilabel(app.ScaleCorrectionPanel);
            app.yscaleLabel.Text = 'y-scale:';
            app.yScale_EditField = uieditfield(app.ScaleCorrectionPanel, 'text');
            app.yScale_EditField.ValueChangedFcn = createCallbackFcn(app, @yScale_EditFieldValueChanged, true);
            app.yScale_EditField.Tooltip = {'y-axis scale factor to stretch the IsoE data.'};
            app.yScale_EditField.Value = '0';
            app.ExecuteScale_Button = uibutton(app.ScaleCorrectionPanel, 'push');
            app.ExecuteScale_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteScale_ButtonPushed, true);
            app.ExecuteScale_Button.Tooltip = {''};
            app.ExecuteScale_Button.Text = 'Execute Scale Corr.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARPESisoe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end