classdef ARPESprocess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CLOSEFIGS_Button             matlab.ui.control.Button
        RESTART_Button               matlab.ui.control.Button
        RESIZEGUI_Button             matlab.ui.control.Button
        ARPESGUI_Button              matlab.ui.control.Button
        SAVE_Button                  matlab.ui.control.Button
        TRANSFER_Button              matlab.ui.control.Button
        APPINFO_Button               matlab.ui.control.Button
        LOAD_Button                  matlab.ui.control.Button
        AppTitle                     matlab.ui.control.Label
        ViewLatest_Button            matlab.ui.control.Button
        ViewSeries_Button            matlab.ui.control.Button
        RESET_Button                 matlab.ui.control.Button
        ScanInformationPanel         matlab.ui.container.Panel
        DataTypeEditFieldLabel       matlab.ui.control.Label
        DataType_EditField           matlab.ui.control.EditField
        ScanInfo_Label               matlab.ui.control.Label
        DataManipulationPanel        matlab.ui.container.Panel
        CropDataPanel                matlab.ui.container.Panel
        xLimsthtEditFieldLabel       matlab.ui.control.Label
        xLims_EditField              matlab.ui.control.EditField
        yLimsebEditFieldLabel        matlab.ui.control.Label
        yLims_EditField              matlab.ui.control.EditField
        zLimsscanEditFieldLabel      matlab.ui.control.Label
        zLims_EditField              matlab.ui.control.EditField
        ExecuteDataCrop_Button       matlab.ui.control.Button
        FilterDataPanel              matlab.ui.container.Panel
        ExecuteFiltering_Button      matlab.ui.control.Button
        FilterTypeDropDownLabel      matlab.ui.control.Label
        FilterType_DropDown          matlab.ui.control.DropDown
        hwXhwYLabel                  matlab.ui.control.Label
        FilterParams_EditField       matlab.ui.control.EditField
        ScanCorrectionsPanel         matlab.ui.container.Panel
        scanindexsLabel              matlab.ui.control.Label
        ScanIndxs_EditField          matlab.ui.control.EditField
        DeleteScans_Button           matlab.ui.control.Button
        SumScans_Button              matlab.ui.control.Button
        ColormapLabel                matlab.ui.control.Label
        Colormap_DropDown            matlab.ui.control.DropDown
        EbAlignmentPanel             matlab.ui.container.Panel
        AlignTypeDropDownLabel       matlab.ui.control.Label
        AlignType_DropDown           matlab.ui.control.DropDown
        ebWinEditFieldLabel          matlab.ui.control.Label
        ebWin_EditField              matlab.ui.control.EditField
        dEWinLabel                   matlab.ui.control.Label
        dEWin_EditField              matlab.ui.control.EditField
        dESmoothEditFieldLabel       matlab.ui.control.Label
        dESmooth_EditField           matlab.ui.control.EditField
        featDropDownLabel            matlab.ui.control.Label
        feat_DropDown                matlab.ui.control.DropDown
        scanindexsEditFieldLabel     matlab.ui.control.Label
        scanindexs_EditField         matlab.ui.control.EditField
        ExecuteAlignment_Button      matlab.ui.control.Button
        IntensityNormalisationPanel  matlab.ui.container.Panel
        NormTypeLabel                matlab.ui.control.Label
        NormType_DropDown            matlab.ui.control.DropDown
        intWinLabel                  matlab.ui.control.Label
        intWin_EditField             matlab.ui.control.EditField
        intScaleEditFieldLabel       matlab.ui.control.Label
        intScale_EditField           matlab.ui.control.NumericEditField
        ExecuteNormalisation_Button  matlab.ui.control.Button
        NormScansLabel               matlab.ui.control.Label
        NormScans_DropDown           matlab.ui.control.DropDown
        WavevectorConversionsPanel   matlab.ui.container.Panel
        eb_refEditFieldLabel         matlab.ui.control.Label
        eb_ref_EditField             matlab.ui.control.NumericEditField
        kx_refEditFieldLabel         matlab.ui.control.Label
        kx_refEditField              matlab.ui.control.NumericEditField
        thtA_refEditFieldLabel       matlab.ui.control.Label
        thtA_refEditField            matlab.ui.control.NumericEditField
        V000EditFieldLabel           matlab.ui.control.Label
        V000_EditField               matlab.ui.control.NumericEditField
        ExecuteConversion_Button     matlab.ui.control.Button
        DataFile_Label               matlab.ui.control.Label
        IsoSliceBrowser              matlab.ui.container.TabGroup
        IsoSliceBrowserTab           matlab.ui.container.Tab
        SaveSlice_Button             matlab.ui.control.Button
        ScanIndexLabel_2             matlab.ui.control.Label
        ScanIndex_EditField          matlab.ui.control.NumericEditField
        ScanValueLabel               matlab.ui.control.Label
        ScanValue_EditField          matlab.ui.control.NumericEditField
        isoSliceTypeLabel            matlab.ui.control.Label
        isoSliceType_DropDown        matlab.ui.control.DropDown
        isoSliceWinLabel_2           matlab.ui.control.Label
        isoSliceWin_EditField        matlab.ui.control.EditField
        ScanIndexSliderLabel         matlab.ui.control.Label
        ScanIndex_Slider             matlab.ui.control.Slider
        Remap_CheckBox               matlab.ui.control.CheckBox
        ScanVideosTab                matlab.ui.container.Tab
        zLimsscanEditFieldLabel_2    matlab.ui.control.Label
        zLimsVid_EditField           matlab.ui.control.EditField
        InterpolateVid_CheckBox      matlab.ui.control.CheckBox
        SaveVideo_Button             matlab.ui.control.Button
        ROI_CheckBox                 matlab.ui.control.CheckBox
        xLimsthtEditFieldLabel_2     matlab.ui.control.Label
        xLimsROI_EditField           matlab.ui.control.EditField
        yLimsebEditFieldLabel_2      matlab.ui.control.Label
        yLimsROI_EditField           matlab.ui.control.EditField
        SaveSeries_Button            matlab.ui.control.Button
        DataCubeTab                  matlab.ui.container.Tab
        Interpolate_CheckBox         matlab.ui.control.CheckBox
        MakeSquare_CheckBox          matlab.ui.control.CheckBox
        View3Ddata_Button            matlab.ui.control.Button
        Save3Ddata_Button            matlab.ui.control.Button
        ThresholdLabel               matlab.ui.control.Label
        Threshold_EditField          matlab.ui.control.EditField
        IsoCutBrowser                matlab.ui.container.TabGroup
        IsoCutBrowserTab             matlab.ui.container.Tab
        ScanIndexLabel_4             matlab.ui.control.Label
        CutScanIndex_EditField       matlab.ui.control.NumericEditField
        ScanValueLabel_2             matlab.ui.control.Label
        CutScanValue_EditField       matlab.ui.control.NumericEditField
        isoCutTypeDropDownLabel      matlab.ui.control.Label
        isoCutType_DropDown          matlab.ui.control.DropDown
        ViewCut_Button               matlab.ui.control.Button
        SaveCut_Button               matlab.ui.control.Button
        ScanIndexLabel_3             matlab.ui.control.Label
        CutScanIndex_Slider          matlab.ui.control.Slider
        isoCutWinLabel               matlab.ui.control.Label
        isoCutWin_EditField          matlab.ui.control.EditField
    end

    % Properties that correspond to public variables within the app
    properties (Access = public)
        % Defining the argument structures
        colmap_arg      = 'hot';
        crop_args       = cell(1,3);
        filter_args     = cell(1,2);
        scancorr_args   = cell(1,1);
        align_args      = cell(1,6);
        norm_args       = cell(1,4);
        conv_args       = cell(1,4);
        isocut_args     = cell(1,3);
        isoslice_args   = cell(1,4);
        % Defining other 3D plotting arguments
        view3D_args     = cell(1,3);
        viewVideo_args  = cell(1,5);
        % Defining the main data structure
        myData = struct();
    end
    
    methods (Access = public)
        % ---  Function to load in a single ARPES data-file that is unprocessed or previously processed
        function dataStr = load_arpes_data(~, FileName)
            % dataStr = load_arpes_data(FileName)
            %   This function loads in the HDataF5 H5files of ARPES data from 
            %   the ADRESS beamline at the SLS. The output is a MATLAB
            %   data-structure that yields all the data and information.
            %
            %   REQ. FUNCTIONS:
            %   -   [data, axes, note] = ReaderHDF5(fname);
            %   -   [DataXC,OffsE] = SumScanXC(Energy,Data,maxLagE[,WinE]);
            %
            %   IN:
            %   -   FileName:           char of the input .h5 or .mat file-name.
            %
            %   OUT:
            %   dataStr - MATLAB data structure containing all data below.
            %   -   .(H5file):          string of the raw .H5 filename of the data.
            %	-   .(Type):            string "Eb(k)", "Eb(kx,ky)" or "Eb(kx,kz)".
            %	-   .(meta.info):       [1xN] char array of scan information.
            %	-   .(meta.ep):         scalar of the Pass energy.
            %	-   .(raw_tht):         [1×nAngle] row vector of angles.
            % 	-   .(raw_eb):          [nEnergyx1] column vector of energy.
            %	-   .(deb):             scalar of the Analyser resolution.
            %   -   .(tltM):            scalar or [1×nScan] row vector (if Scan parameter for 3Data data).
            %	-   .(hv):              scalar or [1×nScan] row vector (if Scan parameter for 3Data data).
            %	-   .(dhv):             scalar of the Beamline resolution.
            %	-   .(raw_data):        2Data [nEnergy x nAngle] or 3Data [nEnergy x nAngle xnScan] data array.
            %	-   .(thtM):            scalar of the manipular Theta angle.
            %	-   .(Temp):            scalar of the sample temperature.
            % -- after processing (1) Eb alignment
            %	-   .(tht):             aligned 2D or 3D array of theta.
            %	-   .(eb):              aligned 2D or 3D array of energy.
            % -- after processing (2) intensity normalisation
            %	-   .(data):            2D or 3D array of the normalised data.
            %   -   .(surfNormX):       double or vector of surface normal vector.
            % -- after processing (3) k conversions
            %   -   .(kx):              2D or 3D array of kx from the Theta angle.
            %	-   .(ky):              1D, 2D or 3D array of kx from the Tilt angle.
            %	-   .(kz):              1D, 2D or 3D array of kx from the Photon Energy.
            % -- general slices / cuts of the data
            %	-   .(slices):          MATLAB data structure containing 2D slices through data.
            %	-   .(cuts):            MATLAB data structure containing 1D cuts through data.
            % -- after analysing kf
            %	-   .(kf):              MATLAB data structure containing all kf analysis.
            % -- after analysing isoe
            %	-   .(isoe):            MATLAB data structure containing all isoe analysis.
            %	-   .(bz):              MATLAB data structure containing all Brilluoin Zone analysis.
            % -- after executing state-fitting
            %	-   .(fits):            MATLAB data structure containing all fitting analysis.
            
            disp('Loading ARPES data...')
            wbar = waitbar(0, 'Loading in ARPES data...', 'Name', 'load_arpes_data');
            wbar.Children.Title.Interpreter = 'none';
            
            %% 1 - Reading in the HDataF5 data
            % - Verify that a .h5 file has been parsed and load the variables
            if FileName(end-2:end) == '.h5'
                waitbar(0.1, wbar, sprintf('Loading %s...', FileName), 'Name', 'load_arpes_data');
                % -- Defining the MATLAB data-structure
                dataStr = struct;
                dataStr.FileName = FileName;
                dataStr.H5file = char(FileName(1:end-3));
                % -- Extracting all of the data variables
                [Data, Axes, Note] = ReaderHDF5(FileName);
                Angle = Axes{1};
                Energy = (Axes{2})';
                if size(Axes,1)==3; Scan = Axes{3}; else Scan = []; end
                if ndims(Data)==3; Data = double(permute(Data,[2 1 3])); else Data=double(Data'); end
                % -- Identifying the type of scan that has been performed
                % --- If no Scan parameter is initially defined, it is an Eb(k) scan
                if size(Scan, 1) == 0
                    dataStr.Type = "Eb(k)";
                    % -- Assigning the photon energy (hv)
                    tmpPos = strfind(Note,'hv      =');
                    for i = 0:1:3
                        hv = str2double(Note(tmpPos+9:tmpPos+13-i));
                        if ~isnan(hv); break; end
                    end
                    if isnan(hv); error('hv in load_data not assigned.'); end
                    % -- Assigning the tilt angle (Tilt)
                    tmpPos = strfind(Note, 'Tilt    =');
                    Tilt = str2double(Note(tmpPos+9:tmpPos+17));   
                    for i = 0:1:7
                        Tilt = str2double(Note(tmpPos+9:tmpPos+17-i));   
                        if ~isnan(Tilt); break;end
                    end
                    if isnan(Tilt);error('Tilt in load_data not assigned.'); end
                % --- If the first and last Scan parameter are identical, multiple Eb(k) scans have been performed
                elseif Scan(1) - Scan(end) == 0
                    dataStr.Type = "Eb(k)";
                    % -- Assigning the photon energy (hv)
                    tmpPos = strfind(Note,'hv      =');
                    for i = 0:1:3
                        hv = str2double(Note(tmpPos+9:tmpPos+13-i));
                        if ~isnan(hv); break; end
                    end
                    if isnan(hv); error('hv in load_data not assigned.'); end
                    % -- Assigning the tilt angle (Tilt)
                    tmpPos = strfind(Note, 'Tilt    =');
                    Tilt = str2double(Note(tmpPos+9:tmpPos+17));   
                    for i = 0:1:7
                        Tilt = str2double(Note(tmpPos+9:tmpPos+17-i));   
                        if ~isnan(Tilt); break;end
                    end
                    if isnan(Tilt);error('Tilt in load_data not assigned.'); end
                % --- If the maximum value of the Scan parameter is > 50, it must be Eb(kx,kzs)
                elseif max(Scan(:)) > 50
                    dataStr.Type = "Eb(kx,kz)";
                    % -- Assigning the photon energy (hv) as the scan parameter
                    hv = Scan;
                    % -- Assigning the tilt angle (Tilt)
                    tmpPos = strfind(Note, 'Tilt    =');
                    Tilt = str2double(Note(tmpPos+9:tmpPos+17));   
                    for i = 0:1:7
                        Tilt = str2double(Note(tmpPos+9:tmpPos+17-i));   
                        if ~isnan(Tilt); break;end
                    end
                    if isnan(Tilt);error('Tilt in load_data not assigned.'); end
                % --- Else the final type remaining is the Eb(kx,ky) scan
                else
                    dataStr.Type = "Eb(kx,ky)";
                    % -- Assigning the photon energy (hv)
                    tmpPos = strfind(Note,'hv      =');
                    for i = 0:1:3
                        hv = str2double(Note(tmpPos+9:tmpPos+13-i));
                        if ~isnan(hv); break; end
                    end
                    if isnan(hv); error('hv in load_data not assigned.'); end
                    % -- Assigning the tilt angle (Tilt) as the scan parameter
                    Tilt = Scan;
                end
                %% 1.2 - Extracting the information variables from the Notes
                waitbar(0.25, wbar, 'Extracting information variables...', 'Name', 'load_arpes_data');
                % -- Pass energy (ep) and uncertainty (dE) evaluations
                tmpPos = strfind(Note, 'Epass   =');
                for i = 0:1:3
                    ep = str2double(Note(tmpPos+9:tmpPos+12-i));
                    if ~isnan(ep); break; end
                end
                if isnan(ep); error('ep in load_data not assigned.'); end
                dHv = 75e-3;
                dEnergy = 0.5*ep/1000;
                % -- Theta Manipulator (Theta) evaluation
                tmpPos = strfind(Note, 'Theta   =');
                for i = 0:1:7
                    Theta = str2double(Note(tmpPos+9:tmpPos+17-i));
                    if ~isnan(Theta); break; end
                end
                if isnan(Theta); error('Theta in load_data not assigned.'); end
                % -- Temperature (Temp) evaluation
                tmpPos = strfind(Note, 'Temp     =');
                for i = 0:1:5
                    Temp = str2double(Note(tmpPos+10:tmpPos+14-i));
                    if ~isnan(Temp); break; end
                end
                %% 1.3 - For an Eb(k) scan with multiple scans, cross-correlate them
                if dataStr.Type == "Eb(k)" && size(Data, 3) > 1
                    waitbar(0.50, wbar, 'Cross-correlating repeated scans...', 'Name', 'load_arpes_data');
                    [xc_Data, ~] =  SumScanXC(Energy, Data, 0.5); 
                    xc_Data(isnan(xc_Data)) = 0;
                    Data = xc_Data;
                end
                %% 1.4 - Assigning the data to the MATLAB structure
                waitbar(0.75, wbar, 'Assigning ARPES data to MATLAB structure...', 'Name', 'load_arpes_data');
                % - Assigning the meta data
                dataStr.meta.info = Note;
                dataStr.meta.ep = ep;
                % - Assinging the main experimental variables
                dataStr.raw_data = Data;
                dataStr.raw_tht = Angle;
                dataStr.raw_eb = Energy;
                dataStr.deb = dEnergy;
                dataStr.hv = hv;
                dataStr.dhv = dHv;
                dataStr.tltM = Tilt;
                % - Assinging all other experimental variables
                dataStr.thtM = Theta;
                dataStr.Temp = Temp;
                
            %% 2 - Reading in the .mat data that has been processed
            elseif FileName(end-3:end) == '.mat'
                waitbar(0.5, wbar, sprintf('Loading %s...', FileName), 'Name', 'load_arpes_data');
                arpes_data = load(FileName);
                % Loading data to a MATLAB structure
                dataStr = arpes_data.dataStruc;
                dataStr.FileName = FileName;
            end
            
            %% 3 - Removing all other processing that may have been performed in other apps
            % -- Kf analysis
            if isfield(dataStr, 'kf'); dataStr = rmfield(dataStr, 'kf'); end
            % -- Isoe analysis
            if isfield(dataStr, 'isoe'); dataStr = rmfield(dataStr, 'isoe'); end
            if isfield(dataStr, 'bz'); dataStr = rmfield(dataStr, 'bz'); end
            % -- State-fitting analysis
            if isfield(dataStr, 'fits'); dataStr = rmfield(dataStr, 'fits'); end
            
            %% Close wait-bar
            close(wbar);
        end
        
        % --- General function to find the stage of the processing
        function [xField, yField, zField, dField] = find_data_fields(~, dataStr)
            % [xField, yField, zField, dField] = find_data_fields(dataStr)
            %   This function determines the most recent processed 
            %   fields of the ARPES data-structure so that the correct
            %   post-processing can be performed in any order. The
            %   fields can be used to explicitly call a field within dataStr
            %   by using dataStr.(xField) for example.
            %
            %   REQ. FUNCTIONS: none
            %
            %   IN:
            %   -   dataStr              loaded MATLAB data structure.
            %
            %   OUT:
            %   -   xField:            char of the most recent x-field ('raw_tht', 'tht', 'kx')
            %	-   yField:            char of the most recent y-field ('raw_eb', 'eb')
            %   -   zField:            char of the most recent z-field ('hv / tltM', 'kz / ky')
            %	-   dField:            char of the most recent d-field ('raw_data', 'data')
            
            % 1 - EbAlign->Normalise->kConvert fields
            if isfield(dataStr, 'kx')
                xField = 'kx'; yField = 'eb'; dField = 'data';
                if dataStr.Type == "Eb(k)"; zField = 'hv';
                elseif dataStr.Type == "Eb(kx,ky)"; zField = 'ky';
                elseif dataStr.Type == "Eb(kx,kz)"; zField = 'kz';
                end
            % 2 - EbAlign->Normalise fields
            elseif isfield(dataStr, 'data')
                xField = 'tht'; yField = 'eb'; dField = 'data';
                if dataStr.Type == "Eb(k)"; zField = 'hv';
                elseif dataStr.Type == "Eb(kx,ky)"; zField = 'tltM';
                elseif dataStr.Type == "Eb(kx,kz)"; zField = 'hv';
                end
            % 3 - EbAlign fields
            elseif isfield(dataStr, 'eb')
                xField = 'tht'; yField = 'eb'; dField = 'raw_data';
                if dataStr.Type == "Eb(k)"; zField = 'hv';
                elseif dataStr.Type == "Eb(kx,ky)"; zField = 'tltM';
                elseif dataStr.Type == "Eb(kx,kz)"; zField = 'hv';
                end
            % 4 - Raw, unprocessed data fields
            else
                xField = 'raw_tht'; yField = 'raw_eb'; dField = 'raw_data';
                if dataStr.Type == "Eb(k)"; zField = 'hv';
                elseif dataStr.Type == "Eb(kx,ky)"; zField = 'tltM';
                elseif dataStr.Type == "Eb(kx,kz)"; zField = 'hv';
                end
            end
        end
        
        % --- Function to crop the ARPES data along any dimension
        function dataStr = crop_data(app, dataStr, xField_lims, yField_lims, zField_lims)
            % dataStr = crop_data(dataStr, xField_lims, yField_lims, zField_lims)
            %   This function crops the ARPES x-, y- and z-independent variables over
            %   a given range. The function crops the most recently processed data and
            %   ensures consistency across both the independent variables and data
            %   matrix.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %
            %   IN:
            %   -   dataStr:        loaded MATLAB data structure.
            % 	-   xField_lims:    [1 x 2] row vector of xField ('raw_tht', 'tht', 'kx') crop limits.
            % 	-   yField_lims:    [1 x 2] row vector of yField ('raw_eb', 'eb') crop limits.
            %	-   zField_lims:    [1 x 2] row vector of zField ('hv / tltM', 'kz / ky') crop limits.
            %
            %   OUT:
            %   -   dataStr                       modified and cropped ARPES data structure.
            
            disp('Data cropping...')
            wbar = waitbar(0.1, 'Extracting cropping indices...', 'Name', 'crop_data');
            
            %% Default parameters
            maxLim = 1e4;
            if nargin < 2; xField_lims=[-1 1]*maxLim; yField_lims=[-1 1]*maxLim; zField_lims=[-1 1]*maxLim; end
            if nargin < 3; yField_lims=[-1 1]*maxLim; zField_lims=[-1 1]*maxLim; end
            if nargin < 4; zField_lims=[-1 1]*maxLim; end
            if isempty(xField_lims); xField_lims=[-1 1]*maxLim;  end
            if isempty(yField_lims); yField_lims=[-1 1]*maxLim;  end
            if isempty(zField_lims); zField_lims=[-1 1]*maxLim;  end
            if length(xField_lims) < 2; xField_lims=[-1 1]*maxLim;  end
            if length(yField_lims) < 2; yField_lims=[-1 1]*maxLim;  end
            if length(zField_lims) < 2; zField_lims=[-1 1]*maxLim;  end
            % - Sorting the cropping limits in ascending order
            xField_lims = sort(xField_lims);
            yField_lims = sort(yField_lims);
            zField_lims = sort(zField_lims);
            % - Extracting the fields to be used with most recent processing
            [xField, yField, zField, dField] = find_data_fields(app, dataStr);
            %% 1 - First determine the zField indices of the crop over scan parameter
            if string(zField) == "hv" || string(zField) == "tltM"
                [~, zIndxL] = min(abs(dataStr.(zField)(1,:) - zField_lims(1)));
                [~, zIndxU] = min(abs(dataStr.(zField)(1,:) - zField_lims(2)));
                zField_indx = [zIndxL zIndxU];
            else
                thtIndx = ceil(size(dataStr.(dField), 2)/2);
                ebIndx = ceil(size(dataStr.(dField), 1)/2);
                [~, zIndxL] = min(abs(dataStr.(zField)(ebIndx,thtIndx,:) - zField_lims(1)));
                [~, zIndxU] = min(abs(dataStr.(zField)(ebIndx,thtIndx,:) - zField_lims(2)));
                zField_indx = [zIndxL zIndxU];
            end
            %% 2 - Next determine the xField and yField indices across each scan
            for i = 1:diff(zField_indx)+1
                z_index = i  - 1 + min(zField_indx);
                waitbar(i/diff(zField_indx)+1, wbar, 'Extracting cropping indices...', 'Name', 'crop_data');
                %% 2.1 - xField cropping indices
                if string(xField) == "raw_tht"
                    [~, xIndx_L] = min(abs(dataStr.(xField)(1,:) - xField_lims(1)));
                    [~, xIndx_U] = min(abs(dataStr.(xField)(1,:) - xField_lims(2)));
                    xField_indx{i} = [xIndx_L xIndx_U];
                else
                    ebIndx = ceil(size(dataStr.(dField), 1)/2);
                    [~, xIndx_L] = min(abs(dataStr.(xField)(ebIndx,:,z_index) - xField_lims(1)));
                    [~, xIndx_U] = min(abs(dataStr.(xField)(ebIndx,:,z_index) - xField_lims(2)));
                    xField_indx{i} = [xIndx_L xIndx_U];
                end
                %% - 2.2 - yField cropping indices
                if string(yField) == "raw_eb"
                    [~, yIndx_L] = min(abs(dataStr.(yField)(:,1) - yField_lims(1)));
                    [~, yIndx_U] = min(abs(dataStr.(yField)(:,1) - yField_lims(2)));
                    yField_indx{i} = [yIndx_L yIndx_U];
                else
                    thtIndx = ceil(size(dataStr.(dField), 2)/2);
                    [~, yIndx_L] = min(abs(dataStr.(yField)(:,thtIndx,z_index) - yField_lims(1)));
                    [~, yIndx_U] = min(abs(dataStr.(yField)(:,thtIndx,z_index) - yField_lims(2)));
                    yField_indx{i} = [yIndx_L yIndx_U];
                end
            end
            %% 3 - Validity check that the cropping indices for x- and y- are consistent
            % x-field consistency checks
            xField_diff = diff(cat(1, xField_indx{:}),[], 2);
            for i = 1:length(xField_diff)
                waitbar(i/length(xField_diff), wbar, 'Validity checks on x-field indices...', 'Name', 'crop_data');
                if xField_diff(i) ~= min(xField_diff)
                    xField_indx{i}(1) = xField_indx{i}(1) - floor((min(xField_diff)-xField_diff(i))/2);
                    xField_indx{i}(2) = xField_indx{i}(2) + ceil((min(xField_diff)-xField_diff(i))/2);   
                end
            end
            %y-field consistency checks
            yField_diff = diff(cat(1, yField_indx{:}),[], 2);
            for i = 1:length(yField_diff)
                waitbar(i/length(yField_diff), wbar, 'Validity checks on y-field indices...', 'Name', 'crop_data');
                if yField_diff(i) ~= min(yField_diff) && yField_diff(i) > min(yField_diff)
                    yField_indx{i}(2) = yField_indx{i}(2) - abs(yField_diff(i) - min(yField_diff));        
                elseif yField_diff(i) ~= min(yField_diff) && yField_diff(i)< min(yfield_diff)
                    yField_indx{i}(2) = yField_indx{i}(2) + abs(yField_diff(i) - min(yField_diff));        
                end
            end
            %% 4 - Filing through each scan parameter and applying a subsequent crop to x and y variables
            for i = 1:diff(zField_indx)+1
                z_index = i -1 + min(zField_indx);
                waitbar(i/diff(zField_indx)+1, wbar, 'Cropping ARPES data...', 'Name', 'crop_data');
                % - Applying cropping operations
                if string(xField) == "kx"
                    crp_xField{i} = dataStr.(xField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                    crp_yField{i} = dataStr.(yField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                    crp_dField{i} = dataStr.(dField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                    if dataStr.Type == "Eb(k)"; crp_zField = dataStr.(zField)(1, zField_indx(1):zField_indx(2));
                    elseif dataStr.Type == "Eb(kx,ky)" || dataStr.Type == "Eb(kx,kz)"; crp_zField{i} = dataStr.(zField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index); 
                    end
                    % Cropping other fields for consistency
                    crp_thtField{i} = dataStr.tht(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                elseif string(yField) == "eb"
                    crp_xField{i} = dataStr.(xField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                    crp_yField{i} = dataStr.(yField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                    if i == diff(zField_indx)+1; crp_zField = dataStr.(zField)(1, zField_indx(1):zField_indx(2)); end
                    crp_dField{i} = dataStr.(dField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                elseif string(xField) == "raw_tht"
                    if i == diff(zField_indx)+1
                        crp_xField = dataStr.(xField)(1, xField_indx{1}(1):xField_indx{i}(2));
                        crp_yField = dataStr.(yField)(yField_indx{1}(1):yField_indx{i}(2), 1);
                        crp_zField = dataStr.(zField)(1, zField_indx(1):zField_indx(2));
                    end
                    crp_dField{i} = dataStr.(dField)(yField_indx{i}(1):yField_indx{i}(2), xField_indx{i}(1):xField_indx{i}(2), z_index);
                end
            end
            %% 5 - Converting cell arrays into 3D matrices of data
            if iscell(crp_xField); crp_xField = cat(3, crp_xField{:});
            else; crp_xField = crp_xField; end
            if iscell(crp_yField); crp_yField = cat(3, crp_yField{:});
            else; crp_yField = crp_yField; end
            if iscell(crp_zField); crp_zField = cat(3, crp_zField{:});
            else; crp_zField = crp_zField; end
            if iscell(crp_dField); crp_dField = cat(3, crp_dField{:});
            else; crp_dField = crp_dField; end
            %% 6 - Assigning the cropped variables / data to new matrices
            % - Assigning the first set of variables
            dataStr.(xField) = []; dataStr.(yField) = []; dataStr.(zField) = []; dataStr.(dField) = [];
            dataStr.(xField) = crp_xField;
            dataStr.(yField) = crp_yField;
            dataStr.(zField) = crp_zField;
            dataStr.(dField) = crp_dField;
            if exist('crp_thtField', 'var'); crp_thtField = cat(3, crp_thtField{:}); dataStr.tht = crp_thtField; end
            % - Saving the crop to the meta data
            dataStr.meta.crp_lims = [];
            dataStr.meta.crp_lims = [xField_lims, yField_lims, zField_lims];
            dataStr.meta.crp_xIndx = xField_indx;
            dataStr.meta.crp_yIndx = yField_indx;
            dataStr.meta.crp_zIndx = zField_indx;
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to filter the ARPES data
        function dataStr = filter_data(app, dataStr, filter_args)
            % dataStr = filter_data(dataStr, filter_args)
            %   This function filters the ARPES data given the filter arguments, which
            %   includes the filter type ("Gaco2", "GaussFlt2", "CurvatureFlt2" or
            %   "LaplaceFlt2") and filter parameters.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   AA = Gaco2(A,hwX,hwY [,hsX] [,hsY])
            %   -   AA = GaussFlt2(Img,hwX,hwY,hsX,hsY)
            %   -   AA = CurvatureFlt2(Img [,order] [,CX] [,CY]) 
            %   -   AA = LaplaceFlt2(Img [,y2xRatio] [,order])
            %
            %   IN:
            %   -   dataStr                      loaded MATLAB data structure.
            % 	-   filter_args:                1x2 cell of {filter_type, filter_val}.
            %
            %   OUT:
            %   -   dataStr                       modified and filtered ARPES data structure.
            
            disp('Data filtering...')
            wbar = waitbar(0, 'Executing data filtering...', 'Name', 'filter_data');
            
            %% 1 - Initialising the filter parameters
            dataStr.meta.filter_args = filter_args;
            % - Extracting the fields to be used with most recent processing
            [~, ~, ~, dField] = find_data_fields(app, dataStr);
            % - Extracting filter parameters
            filter_type = filter_args{1};
            filter_val =  filter_args{2};
            %% 2 - Performing the filtering operation over all scans
            for i = 1:size(dataStr.(dField), 3)
                waitbar(i/size(dataStr.(dField), 3), wbar, 'Filtering ARPES data...', 'Name', 'filter_data');
                if filter_type == "Gaco2"
                    filtered_data = Gaco2(dataStr.(dField)(:,:,i), filter_val(1), filter_val(2)); 
                elseif filter_type == "GaussFlt2"
                    filtered_data = GaussFlt2(dataStr.(dField)(:,:,i), filter_val(1), filter_val(2), 40, 40); 
                elseif filter_type == "LaplaceFlt2"
                    filtered_data = GaussFlt2(dataStr.(dField)(:,:,i), 5, 5, 40, 40);
                    filtered_data = SetContrast(filtered_data, 0.4, 0.999, 1.5);
                    filtered_data = LaplaceFlt2(filtered_data, filter_val(1));
                elseif filter_type == "CurvatureFlt2"
                    filtered_data=GaussFlt2(dataStr.(dField)(:,:,i), 100, 100, 500, 500);
                    filtered_data = SetContrast(filtered_data, 0.4, 0.999, 1.5);
                    filtered_data=CurvatureFlt2(filtered_data, '2D', filter_val(1), filter_val(2));
                    filtered_data = GaussFlt2(filtered_data, 1, 1, 10, 10);
                    filtered_data = SetContrast(filtered_data, 0.2, 0.999);
                end
                dataStr.(dField)(:,:,i) = filtered_data;
            end
            %% 3 - Setting NaN values to zero
            dataStr.(dField)(isnan(dataStr.(dField))) = 0;
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to delete a scan - *Eb(kx,ky) or Eb(kx,kz) scans only*
        function dataStr = delete_scans(app, dataStr, scancorr_args)
            % dataStr = delete_scans(dataStr, scancorr_args)
            %   This function deletes a single or linear range of scans that contain
            %   anomalies. The given input is a vector of all the scan indices of the
            %   ARPES scans to be deleted.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %
            %   IN:
            %   -   dataStr:        loaded MATLAB data structure.
            % 	-   scancorr_args:  1x1 cell of {scan_lims}.
            %
            %   OUT:
            %   -   dataStr:                     ARPES data structure after deleting desired scans.
            
            disp('Deleting ARPES scans...')
            wbar = waitbar(0.5, 'Deleting scans...', 'Name', 'delete_scans');
            
            %% Default parameters
            dataStr.meta.scancorr_args = scancorr_args;
            % - Extracting the fields to be used with most recent processing
            [xField, yField, zField, dField] = find_data_fields(app, dataStr);
            % - Sorting the cropping limits in ascending order
            scan_lims = sort(scancorr_args{1});
            if length(scan_lims) == 1; scan_lims = [scan_lims(1), scan_lims(1)];
            end
            %% - 1 - EbAlign->Normalise->kConvert fields
            if isfield(dataStr, 'kx')
                dataStr.(xField)(:,:,scan_lims) = [];
                dataStr.tht(:,:,scan_lims) = [];
                dataStr.(yField)(:,:,scan_lims) = [];
                dataStr.(zField)(:,:,scan_lims) = [];
                dataStr.(dField)(:,:,scan_lims) = [];
            %% - 2 - EbAlign->Normalise fields
            elseif isfield(dataStr, 'data')
                dataStr.(xField)(:,:,scan_lims) = [];
                dataStr.(yField)(:,:,scan_lims) = [];
                dataStr.(zField)(scan_lims) = [];
                dataStr.(dField)(:,:,scan_lims) = [];
            %% - 3 - EbAlign fields
            elseif isfield(dataStr, 'eb')
                dataStr.(xField)(:,:,scan_lims) = [];
                dataStr.(yField)(:,:,scan_lims) = [];
                dataStr.(zField)(scan_lims) = [];
                dataStr.(dField)(:,:,scan_lims) = [];
            %% - 4 - Raw, unprocessed data fields
            else
                dataStr.(zField)(scan_lims) = [];
                dataStr.(dField)(:,:,scan_lims) = [];
            end
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to xcorr sum over scan parameter - *Eb(kx,ky) or Eb(kx,kz) scans only*
        function dataStr = xcorr_scans(app, dataStr, scancorr_args)
            % dataStr = xcorr_scans(dataStr, scancorr_args)
            %   This function cross-correlates the scans over the defined 
            %   scan index and converts it from a 3D data set, into a 2D
            %   Eb(k) dispersion.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   [DataXC,OffsE] = SumScanXC(Energy,Data,maxLagE[,WinE]);
            %
            %   IN:
            %   -   dataStr:        data structure of the ARPES data.
            % 	-   scancorr_args:  1x1 cell of {scan_lims}.
            %
            %   OUT:
            %   -   dataStr:            ARPES data structure after the cross-correlation of scans.
            
            disp('Data cross-correlation and Eb(k) conversion...')
            wbar = waitbar(0.5, 'Executing cross-correlation...', 'Name', 'xcorr_scans');
            
            %% Default parameters
            dataStr.meta.scancorr_args = scancorr_args;
            % - Extracting the fields to be used with most recent processing
            [xField, yField, zField, dField] = find_data_fields(app, dataStr);
            % - Sorting the cropping limits in ascending order
            scan_lims = sort(scancorr_args{1});
            if length(scan_lims) == 1; scan_lims = [scan_lims(1), scan_lims(1)]; end
            mean_scan_indx = ceil(mean(scan_lims));
            xcorr_dE = 0.4;
            %% 1 - EbAlign->Normalise->kConvert fields
            if isfield(dataStr, 'kx')
                % - Finding the indices to squeeze the 3D arrays into 2D
                ebIndx = ceil(size(dataStr.(dField), 1)/2); 
                thtIndx = ceil(size(dataStr.(dField), 2)/2); 
                % - Cross-correlating the ARPES data
                Y = squeeze(dataStr.(yField)(:,thtIndx,mean_scan_indx));
                D = dataStr.(dField)(:,:,scan_lims);
                [DataXC, ~] = SumScanXC(Y, D, xcorr_dE);
                DataXC(isnan(DataXC)) = 0;
                dataStr.(dField) = []; dataStr.(dField) = DataXC;
                % - Squeezing the variable arrays
                dataStr.(xField) = squeeze(dataStr.(xField)(:,:,mean_scan_indx));
                dataStr.tht = squeeze(dataStr.tht(:,:,mean_scan_indx));
                dataStr.(yField) = squeeze(dataStr.(yField)(:,:,mean_scan_indx));
                dataStr.(zField) = squeeze(dataStr.(zField)(ebIndx,thtIndx,mean_scan_indx));
                % - Converting the wave-vector into a single field
                dataStr.ky = mean(dataStr.ky(:));
                dataStr.kz = mean(dataStr.kz(:));
            %% 2 - EbAlign->Normalise fields
            elseif isfield(dataStr, 'data')
                % - Finding the indices to squeeze the 3D arrays into 2D
                ebIndx = ceil(size(dataStr.(dField), 1)/2); 
                thtIndx = ceil(size(dataStr.(dField), 2)/2); 
                % - Cross-correlating the ARPES data
                Y =squeeze(dataStr.(yField)(:,thtIndx,mean_scan_indx));
                D = dataStr.(dField)(:,:,scan_lims);
                [DataXC, ~] = SumScanXC(Y, D, xcorr_dE);
                DataXC(isnan(DataXC)) = 0;
                dataStr.(dField) = []; dataStr.(dField) = DataXC;
                % - Squeezing the variable arrays
                dataStr.(xField) = squeeze(dataStr.(xField)(:,:,mean_scan_indx));
                dataStr.(yField) = squeeze(dataStr.(yField)(:,:,mean_scan_indx));
                dataStr.(zField) = dataStr.(zField)(mean_scan_indx);
            %% 3 - EbAlign fields
            elseif isfield(dataStr, 'eb')
                % - Finding the indices to squeeze the 3D arrays into 2D
                ebIndx = ceil(size(dataStr.(dField), 1)/2); 
                thtIndx = ceil(size(dataStr.(dField), 2)/2); 
                % - Cross-correlating the ARPES data
                Y = squeeze(dataStr.(yField)(:,thtIndx,mean_scan_indx));
                D = dataStr.(dField)(:,:,scan_lims);
                [DataXC, ~] = SumScanXC(Y, D, xcorr_dE);
                DataXC(isnan(DataXC)) = 0;
                dataStr.(dField) = []; dataStr.(dField) = DataXC;
                % - Squeezing the variable arrays
                dataStr.(xField) = squeeze(dataStr.(xField)(ebIndx,:,mean_scan_indx));
                dataStr.(yField) = squeeze(dataStr.(yField)(:,thtIndx,mean_scan_indx));
                dataStr.(zField) = dataStr.(zField)(mean_scan_indx);
            %% 4 - Raw, unprocessed data fields
            else
                % - Squeezing the variable arrays
                dataStr.(zField) = dataStr.(zField)(mean_scan_indx);
                % - Cross-correlating the ARPES data
                [DataXC, ~] = SumScanXC(dataStr.(yField), dataStr.(dField)(:,:,scan_lims), xcorr_dE);
                DataXC(isnan(DataXC)) = 0;
                dataStr.(dField) = []; dataStr.(dField) = DataXC;
            end
            %% 5 - Setting the identifier to Eb(k) now after XCorr
            dataStr.Type = "Eb(k)";
            dataStr.tltM = mean(dataStr.tltM);
            dataStr.hv = mean(dataStr.hv);
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to perform stage 1 - Eb alignment
        function dataStr = align_energy(app, dataStr, align_args)
            % dataStr = align_energy(dataStr, align_args)
            %   This is a function that will align the dataStr ARPES data 
            %   to the Fermi-level, VBM or a CB state. It operates using the
            %   AlignEF function conditions.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   [EAlign, EF, Fail] = AlignEF(Data, ECorr [,eWin] [,dEWin] [,dESmooth] [,feat]);
            %
            %   IN:
            %   -   dataStr:            data structure of the ARPES data.
            %   -   align_args:         1x6 cell of {Type, Scans, eWin, dEWin, dESmooth, feat}.
            %
            %   OUT:
            %   dataStr - MATLAB data structure with new additional fields below;
            %   -   .meta.align_args:  1x6 cell of input arguments of alignment.
            %	-   .(tht):         aligned 2D or 3D array of theta.
            %	-   .(eb):          aligned 2D or 3D array of energy.
            
            disp('(1) Eb alignment...')
            wbar = waitbar(0., 'Executing eb alignment...', 'Name', 'align_energy');
            
            %% Default parameters
            % - Extracting the fields to be used with most recent processing
            [~, yField, ~, dField] = find_data_fields(app, dataStr);
            
            %% - 1 - Initialising the alignment parameters
            % - Extracting alignment alignType parameters
            alignType   = align_args{1};
            Scans       = align_args{2};
            % - Extracting alignment parameters
            eWin        = align_args{3}; if ischar(eWin); eWin = []; end
            dEWin       = align_args{4}; if ischar(dEWin); dEWin = []; end
            dESmooth    = align_args{5}; if ischar(dESmooth); dESmooth = []; end
            feat        = align_args{6};
            % - Defining the scan values over the energy alignment for-loop
            if alignType == "global"
                scanIndx = 1:size(dataStr.(dField), 3);
            elseif alignType == "scan index"
                if length(Scans) == 1;      scanIndx = Scans;
                elseif length(Scans) == 2;  scanIndx = Scans(1):1:Scans(2);
                end
            end
            % - Defining the alignment variables
            aligned_energy = [];
            % - Defining a linear fit over eWin if it has a length larger than 1
            if length(eWin) > 1
                if alignType == "global"
                    eWin = linspace(eWin(1), eWin(2), size(dataStr.(dField), 3));
                elseif alignType == "scan index"
                    eWin = linspace(eWin(1), eWin(2), length(scanIndx));
                end
            end
            
            %% - 2 - Binding energy alignment over all scans
            eIndx = 0;
            for i = scanIndx
                waitbar(i/size(scanIndx, 2), wbar, 'Aligning ARPES data...', 'Name', 'align_energy');
                % - 2.1 Global Eb alignment
                if alignType == "global"
                    % For a single value of eWin to be used
                    if isempty(eWin) || length(eWin) == 1
                        % If the alignment was previously performed, apply again
                        if string(yField) == "eb"
                            [aligned_energy(:,1,i), ~, ~] = AlignEF(dataStr.(dField)(:,:,i), dataStr.eb(:,1,i), eWin, dEWin,dESmooth, feat);
                        % If no alignment has been performed, do it for the first time
                        elseif string(yField) == "raw_eb"
                            [aligned_energy(:,1,i), ~, ~] = AlignEF(dataStr.(dField)(:,:,i), dataStr.raw_eb, eWin, dEWin,dESmooth, feat);
                        end
                    % For an eWin value at each scan between eWin(1) -> eWin(2)
                    else
                        % If the alignment was previously performed, apply again
                        if string(yField) == "eb"
                            [aligned_energy(:,1,i), ~, ~] = AlignEF(dataStr.(dField)(:,:,i), dataStr.eb(:,1,i), eWin(i), dEWin,dESmooth, feat);
                        % If no alignment has been performed, do it for the first time
                        elseif string(yField) == "raw_eb"
                            [aligned_energy(:,1,i), ~, ~] = AlignEF(dataStr.(dField)(:,:,i), dataStr.raw_eb, eWin(i), dEWin,dESmooth, feat);
                        end
                    end
                % - 2.2 Eb alignment over defined scans
                elseif alignType == "scan index"
                    % For a single value of eWin to be used
                    if isempty(eWin) || length(eWin) == 1
                        [~, eb_shift, ~] = AlignEF(dataStr.(dField)(:,:,i), dataStr.eb(:,1,i), eWin, dEWin,dESmooth, feat);
                        dataStr.eb(:,:,i) = dataStr.eb(:,:,i) - eb_shift;
                    % For an eWin value at each scan between eWin(1) -> eWin(2)
                    else
                        eIndx = eIndx + 1;
                        [~, eb_shift, ~] = AlignEF(dataStr.(dField)(:,:,i), dataStr.eb(:,1,i), eWin(eIndx), dEWin,dESmooth, feat);
                        dataStr.eb(:,:,i) = dataStr.eb(:,:,i) - eb_shift;
                    end
                end
            end
            % - Appending new data to the data object
            if ~isempty(aligned_energy); dataStr.eb = aligned_energy; end
            dataStr.meta.align_args = align_args;
            
            %% - 3 - Remapping matrices into 2D or 3D consistent forms
            % - 3.1 Remapping to 2D
            % - Remapping the dataStr.eb domain to be 2D
            if size(dataStr.eb,1) == 1 || size(dataStr.eb,2) == 1
               dataStr.eb=repmat(dataStr.eb,[1, size(dataStr.(dField),2)]);
            end
            
            % - 3.2 Remapping to 3D
            % - Remapping the dataStr.eb domain to be 3D
            if size(dataStr.eb, 3) == 1
               dataStr.eb = repmat(dataStr.eb,[1, 1, size(dataStr.(dField),3)]);
            end
            
            % - 3.3 Remap the tht matrix
            if ~isfield(dataStr, 'tht')
                dataStr.tht = dataStr.raw_tht;
                % - 3.1 Remapping to 2D
                % - Remapping the dataStr.tht / K domain to be 2D
                if size(dataStr.tht,1) == 1 || size(dataStr.tht,2) == 1
                   dataStr.tht=repmat(dataStr.tht,[size(dataStr.(dField),1), 1]);
                end
                % - 3.2 Remapping to 3D
                % - Remapping the dataStr.tht / K domain to be 3D
                if size(dataStr.tht, 3) == 1
                   dataStr.tht = repmat(dataStr.tht,[1, 1, size(dataStr.(dField),3)]);
                end
            elseif size(dataStr.tht, 1) == 1
                dataStr.tht = dataStr.raw_tht;
                % - 3.1 Remapping to 2D
                % - Remapping the dataStr.tht / K domain to be 2D
                if size(dataStr.tht,1) == 1 || size(dataStr.tht,2) == 1
                   dataStr.tht=repmat(dataStr.tht,[size(dataStr.(dField),1), 1]);
                end
                % - 3.2 Remapping to 3D
                % - Remapping the dataStr.tht / K domain to be 3D
                if size(dataStr.tht, 3) == 1
                   dataStr.tht = repmat(dataStr.tht,[1, 1, size(dataStr.(dField),3)]);
                end
            end
            %% Close wait-bar
            close(wbar);

        end
        
        % --- Function to perform stage 2 - Intensity Normalisation
        function dataStr = normalise_data(app, dataStr, norm_args)
            % dataStr = normalise_data(dataStr, norm_args)
            %   This is a function that will normalise the ARPES data using several
            %   different approaches over a given window.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   DataC=SetContrast(Data,minFrac,maxFrac [,gamma]);
            %
            %   IN:
            %   -   dataStr:        data structure of the ARPES data.
            %   -   norm_args:      1x4 cell of {NormType, intWin, intScale, NormScans}.
            %
            %   OUT:
            %   dataStr - MATLAB data structure with new additional fields below;
            %   -   .meta.norm_args:        1x4 cell of input arguments.
            %	-   .(data):                2D or 3D array of the normalised data.
            
            disp('(2) Intensity normalisation...')
            wbar = waitbar(0., 'Executing intensity normalisation...', 'Name', 'normalise_data');
            
            %% Default parameters
            % - Extracting the fields to be used with most recent processing
            [xField, yField, ~, dField] = find_data_fields(app, dataStr);
            
            %% - 1 - Initialising the normalisation parameters
            % - Extracting normalisation parameters
            NormType   = norm_args{1};
            intWin      = norm_args{2};
            intScale    = norm_args{3};
            NormScans   = norm_args{4};
            % - Extracting the window of integration
            Win = [intWin(1), intWin(2)];
            
            %% - 2 -Data normalisation over all scans
            for i = 1:size(dataStr.(dField), 3)
                waitbar(i/size(dataStr.(dField), 3), wbar, 'Normalising ARPES data...', 'Name', 'normalise_data');
                %% 2.1 - Extracting the integrated cut
                if NormType == "angle/kx-int"
                    [~, ISubtr] = Cut(dataStr.(xField)(:,:,i), dataStr.(yField)(:,:,i), dataStr.(dField)(:,:,i), 'edc', Win);
                    % - HWHM adjusted to suppress uneven sensitivity of the CCD channels
                    ISubtr = Gaco2(ISubtr, 10, 0);
                elseif NormType == "eb-int"
                    [~, ISubtr] = Cut(dataStr.(xField)(:,:,i), dataStr.(yField)(:,:,i), dataStr.(dField)(:,:,i), 'mdc', Win);
                    % - HWHM adjusted to suppress uneven sensitivity of the CCD channels
                    ISubtr = Gaco2(ISubtr, 10, 0);
                elseif NormType == "none"
                    ISubtr = 0;
                end
                %% 2.2 - Subtracting the data by the scaled integrated cut
                norm_data(:,:,i) = dataStr.(dField)(:,:,i) - intScale * ISubtr;
                %% 2.3 - Renormalising the intensity
                if NormScans == "max"
                    norm_data(:,:,i) = norm_data(:,:,i) - min(min(norm_data(:,:,i)));
                    norm_data(:,:,i) = norm_data(:,:,i) / max(max(norm_data(:,:,i)));
                elseif NormScans == "average"
                    norm_data(:,:,i) = norm_data(:,:,i) - min(min(norm_data(:,:,i)));
                    norm_data(:,:,i) = norm_data(:,:,i) / mean(mean(norm_data(:,:,i)));
                elseif NormScans == "none"
                    norm_data(:,:,i) = norm_data(:,:,i) / 1;
                end
            end
            %% 3 - Assigning to a data structure
            dataStr.data = norm_data;
            % - Forcing maximum value across all scans to be unity
            dataStr.data = dataStr.data / max(max(max(dataStr.data)));
            % - Appending new data to the data object
            dataStr.meta.norm_args = norm_args;
            % - Setting NaN values to zero
            dataStr.data(isnan(dataStr.data)) = 0;
            % - Setting the contrast
            for i = 1:size(dataStr.(dField), 3)
                dataStr.data(:,:,i) = SetContrast(dataStr.data(:,:,i),0.05,1.);
            end
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Function to perform stage 3 - wavevector conversions
        function dataStr = convert_to_k(~, dataStr, kconv_args)
            % dataStr = convert_to_k(dataStr, kconv_args)
            %   This is a function that will convert the angles thtA into kx and the
            %   scan parameters into either ky (for tltM) or kz (for hv). 
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   surfNormX = SurfNormX(hv, eB_ref, kx_ref, thtM, thtA_ref) calculates the surface normal angle in the MP.
            %   -   Kxx = Kxx(HV, Eb, thtM, ThtA, surfNormX) calculates k// in the MP.
            %   -   Kyy = Kyy(HV, Eb, thtM, TltM, surfNormX) calculates k// in the MP.
            %   -   Kzz = Kzz(HV, Eb, thtM, ThtA, TltM, v000, surfNormX) calculates kz along the surface normal.
            %
            %   IN:
            %   -   dataStr:        data structure of the ARPES data.
            %   -   kconv_args:     1x4 cell of {eB_ref, kx_ref, thtA_ref, v000}.
            %
            %   OUT:
            %   dataStr - MATLAB data structure with new additional fields below;
            %   -   .(surfNormX):   double or vector of surface normal vector.
            %   -   .(kx):          2D or 3D array of kx from the Theta angle.
            %	-   .(ky):          2D or 3D array of kx from the Tilt angle.
            %	-   .(kz):          2D or 3D array of kx from the Photon Energy.
            
            disp('(3) Wave-vector conversions...')
            wbar = waitbar(0., 'Executing wave-vector conversions...', 'Name', 'convert_to_k');
            
            %% - 1 - Initialising the k conversion parameters
            dataStr.meta.kconv_args = kconv_args;
            % - Extracting conversion parameters
            eB_ref      = kconv_args{1};
            kx_ref      = kconv_args{2};
            thtA_ref    = kconv_args{3};
            v000        = kconv_args{4};
            %% - 2 -Wave-vector conversions over all scans
            % - 2.1 Wave-vector conversions for Eb(k)
            if dataStr.Type == "Eb(k)"
                if length(thtA_ref) == 2; thtA_ref = thtA_ref(1); end
                waitbar(0.5, wbar, 'Executing Eb(k) wave-vector conversions...', 'Name', 'convert_to_k');
                dataStr.surfNormX = SurfNormX(dataStr.hv, eB_ref, kx_ref, dataStr.thtM, thtA_ref);
                dataStr.kx = Kxx(dataStr.hv, dataStr.eb, dataStr.thtM, dataStr.tht, dataStr.surfNormX);
                dataStr.ky = Kyy(dataStr.hv, dataStr.eb, dataStr.tht, dataStr.thtM, dataStr.tltM, dataStr.surfNormX);
                dataStr.kz = Kzz(dataStr.hv, dataStr.eb, dataStr.thtM, dataStr.tht, dataStr.tltM, v000, dataStr.surfNormX);
                % Finding the mean value of ky and kz
                dataStr.ky = mean(dataStr.ky(:));
                dataStr.kz = mean(dataStr.kz(:));
            
            % - 2.2 Wave-vector conversions for Eb(kx,ky)
            elseif dataStr.Type == "Eb(kx,ky)"
                if length(thtA_ref) == 2; thtA_ref = linspace(thtA_ref(1), thtA_ref(2), size(dataStr.tltM, 2));
                else; thtA_ref = ones(size(dataStr.tltM, 2))*thtA_ref(1); 
                end
                for i = 1:size(dataStr.tltM, 2)
                    waitbar(i/size(dataStr.tltM, 2), wbar, 'Executing Eb(kx,ky) wave-vector conversions...', 'Name', 'convert_to_k');
                    dataStr.surfNormX(i) = SurfNormX(dataStr.hv, eB_ref, kx_ref, dataStr.thtM, thtA_ref(i));
                    dataStr.kx(:,:,i) = Kxx(dataStr.hv, dataStr.eb(:,:,i), dataStr.thtM, dataStr.tht(:,:,i), dataStr.surfNormX(i));
                    dataStr.ky(:,:,i) = Kyy(dataStr.hv, dataStr.eb(:,:,i), dataStr.tht(:,:,i), dataStr.thtM, dataStr.tltM(i), dataStr.surfNormX(i));
                    dataStr.kz(:,:,i) = Kzz(dataStr.hv, dataStr.eb(:,:,i), dataStr.thtM, dataStr.tht(:,:,i), dataStr.tltM(i), v000, dataStr.surfNormX(i));
                end
            
            % - 2.3 Wave-vector conversions for Eb(kx,kz)
            elseif dataStr.Type == "Eb(kx,kz)"
                if length(thtA_ref) == 2; thtA_ref = linspace(thtA_ref(1), thtA_ref(2), size(dataStr.hv, 2)); 
                else; thtA_ref = ones(size(dataStr.hv, 2))*thtA_ref(1); 
                end
                for i = 1:size(dataStr.hv, 2)
                    waitbar(i/size(dataStr.hv, 2), wbar, 'Executing Eb(kx,kz) wave-vector conversions...', 'Name', 'convert_to_k');
                    dataStr.surfNormX(i) = SurfNormX(dataStr.hv(i), eB_ref, kx_ref, dataStr.thtM, thtA_ref(i));
                    dataStr.kx(:,:,i) = Kxx(dataStr.hv(i), dataStr.eb(:,:,i), dataStr.thtM, dataStr.tht(:,:,i), dataStr.surfNormX(i));
                    dataStr.ky(:,:,i) = Kyy(dataStr.hv(i), dataStr.eb(:,:,i), dataStr.tht(:,:,i), dataStr.thtM, dataStr.tltM, dataStr.surfNormX(i));
                    dataStr.kz(:,:,i) = Kzz(dataStr.hv(i), dataStr.eb(:,:,i), dataStr.thtM, dataStr.tht(:,:,i), dataStr.tltM, v000, dataStr.surfNormX(i));
                end
            end
            %% Close wait-bar
            close(wbar);
        end
        
        % --- Constant, global formatting for the figures
        function gca_properties(app, type)
            % gca_properties(type)
            %   This function outlines the consistent axes and figure
            %   parameters to be used. Each field can be edited to what
            %   the user desires. Specific inputs are used to isolate the
            %   different types of figures that are plotted.
            %
            % IN:
            %   -   type:	string of either "theta" or "kx" for the x-axis label
            
            %% Default parameters
            if nargin < 2; type="raw_tht"; end
            if isempty(type); type="raw_tht";  end
            
            %% 1 - Defining the axes properties
            ax = gca;
            % Font properties
            ax.FontName = 'Helvetica'; ax.FontWeight = 'normal'; ax.FontSize = 15;
            % Tick properties
            ax.TickLabelInterpreter = 'latex';
            ax.XMinorTick = 'off'; ax.YMinorTick = 'off';
            ax.TickDir = 'out';
            ax.TickLength = [0.01 0.025];
            ax.XColor = [0 0 0]; ax.YColor = [0 0 0];
            % Ruler properties
            ax.XAxisLocation = 'bottom';            % 'bottom' | 'top' | 'origin'
            ax.YAxisLocation = 'left';              % 'left' | 'right' | 'origin'
            % Box Styling properties
            ax.Color = [0, 0, 0];
            ax.LineWidth = 1.5;
            ax.Box = 'off'; ax.Layer = 'Top';
            % Axis labels and limits
            if type == "raw_tht" || type == "tht"
                xlabel('$$ \bf  \theta (^{\circ}) $$', 'Interpreter', 'latex');
            elseif type == "kx"
                xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
            end
            ylabel('$$ \bf  E_B (eV) $$', 'Interpreter', 'latex');
            %% 2 - Defining the figure properties
            fig = gcf; fig.Color = [1 1 1]; fig.InvertHardcopy = 'off';
            %% 3 - Colorbar properties
            colormap(app.colmap_arg);
            cb = colorbar; 
            % Colorbar font properties
            cb.FontName = 'Helvetica'; cb.FontWeight = 'normal'; cb.FontSize = 10;
            cb.TickLabelInterpreter = 'latex';
            cb.TickLength = 0.04; cb.TickDirection = 'out';
            % Colorbar position properties
            pos = get(cb, 'Position'); cb.Position = [1.09*pos(1) 7.0*pos(2) 0.03 0.1];
            % Colorbar box properties
            cb.Color = [0 0 0]; cb.Box = 'on'; cb.LineWidth = 1.2;
            %% 4 - Plotting the x- and y-axes
            xl = xlim; yl = ylim;
            axis([xl(1), xl(2), yl(1), yl(2)]);
            line([0 0], [-1e5, 1e5], 'Color', [1 1 1 0.5], 'LineWidth', 0.75, 'Linestyle', '--');
            line([-1e5, 1e5], [0 0], 'Color', [1 1 1 0.5], 'LineWidth', 0.75, 'Linestyle', '--');
        end
        
        % --- General function to view all ARPES data
        function fig = view_data(app, dataStr, isoslice_args)
            % fig = view_data(dataStr, isoslice_args)
            %   This function plots the ARPES data in the form of D(X,Y)
            %   for 2D data, or D(X,Y,Z) for 3D data. The 2D data takes the
            %   forms of an image in a single figure, whereas the 3D data
            %   is plotted in a dedicated MATLAB GUI that allows the user to
            %   browse through all the dimensions easily. This function ensures
            %   that the most recent processing is shown in the figure.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   gca_properties(type)
            %   -   [h=] ImData(X,Y,Z[,style])
            %   -   view_2D_data(dataStr)
            %   -   view_isoSlice(dataStr, isoslice_args)
            %
            %   IN:
            %   -   dataStr:        data structure of the ARPES data.
            %   -   isoslice_args:  empty for 2D or 1x4 cell of {isoType, isoWin, scanIndex, remap} for 3D.
            %
            %   OUT:
            %   -   fig:            MATLAB figure object with the ARPES data plotted.
            
            %% Default parameters
            if nargin < 3; isoslice_args = cell(1,4); end
            if isempty(isoslice_args); isoslice_args = cell(1,4);  end
            
            %% 1 - Plotting ARPES data
            fig = figure();
            
            % - For an Eb(k) scan type
            if dataStr.Type == "Eb(k)"
                % -- Plotting the Eb(k) figure object
                if isfield(dataStr, 'kx'); set(fig, 'Name', 'Eb(k): A, N, K');
                elseif isfield(dataStr, 'data'); set(fig, 'Name', 'Eb(k): A, N');
                elseif isfield(dataStr, 'eb'); set(fig, 'Name', 'Eb(k): A');
                else; set(fig, 'Name', 'Eb(k): Raw');
                end
                % -- Plotting the 2D ARPES data
                view_2D_data(app, dataStr);
                
            % - For a 3D Eb(kx,ky) scan type
            elseif dataStr.Type == "Eb(kx,ky)"
                % -- Plotting the Eb(kx,ky) figure object
                if isfield(dataStr, 'kx'); set(fig, 'Name', 'Eb(kx,ky): A, N, K');
                elseif isfield(dataStr, 'data'); set(fig, 'Name', 'Eb(kx,ky): A, N');
                elseif isfield(dataStr, 'eb'); set(fig, 'Name', 'Eb(kx,ky): A');
                else; set(fig, 'Name', 'Eb(kx,ky): Raw');
                end
                set(fig, 'Position', [1,1,850,450]);
                % -- Plotting the 3D ARPES data
                view_isoSlice(app, dataStr, isoslice_args);
                
            % - For a 3D Eb(kx,kz) scan type
            elseif dataStr.Type == "Eb(kx,kz)"
                % -- Plotting the Eb(kx,kz) figure object
                if isfield(dataStr, 'kx'); set(fig, 'Name', 'Eb(kx,kz): A, N, K');
                elseif isfield(dataStr, 'data'); set(fig, 'Name', 'Eb(k): A, N');
                elseif isfield(dataStr, 'eb'); set(fig, 'Name', 'Eb(kx,kz): A');
                else; set(fig, 'Name', 'Eb(kx,kz): Raw');
                end
                set(fig, 'Position', [1,1,850,450]);
                % - Plotting the 3D ARPES data
                view_isoSlice(app, dataStr, isoslice_args);
                
            end
        end

        % --- General function to view 2D ARPES data - *'Eb(kx)' scans only*
        function view_2D_data(app, dataStr)
            % view_2D_data(dataStr)
            %   This function plots the 2D ARPES spectrum in the form of D(X,Y), along
            %   with a consistent formatting.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   gca_properties(type)
            %   -   [h=] ImData(X,Y,Z[,style])
            %
            %   IN:
            %   -   dataStr:    data structure of the ARPES data.
            %
            %   OUT:
            %   -   figure output.
            
            %% Default parameters
            % - Extracting the fields to be used with most recent processing
            [xField, yField, ~, dField] = find_data_fields(app, dataStr);
            %% 1 - Plotting the 2D ARPES spectra
            ImData(dataStr.(xField), dataStr.(yField), dataStr.(dField));
            %% 2 - Formatting the figure
            gca_properties(app, string(xField));
            title(string(dataStr.H5file), 'interpreter', 'none', 'fontsize', 12);
        end
        
        % --- General function to view 3D ARPES data - *'Eb(kx,ky)' or 'Eb(kx,kz)' scans only*
        function sliceStr = view_isoSlice(app, dataStr, isoslice_args)
            % view_isoSlice(dataStr, isoslice_args)
            %   This function plots the 3D ARPES data that is in the form 
            %   D(X,Y,Z) into a single figure that includes the Eb(k) dispersion
            %   and an IsoK or IsoE slice.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   [h=] ImData(X,Y,Z[,style])
            %   -   [DSlice,XSlice] = Slice(ACorr,ECorr,Data,xMode,Win) 
            %   -   [XR,YR,DataR] = Remap(XM,YM,Data)
            %
            %   IN:
            %   -   dataStr:            data structure of the ARPES data.
            %   -   isoslice_args:      1x4 cell of {isoType, isoWin, scanIndex, remap} for 3D.
            %
            %   OUT:
            %   -   figure output.
            %   -   sliceStr:           data structure of the slice data /info.
            
            %% Initialising variables for the figure
            isoType         = isoslice_args{1};
            isoWin        = isoslice_args{2};
            scanIndex       = isoslice_args{3};
            remap           = isoslice_args{4};
            % - Extracting the fields to be used with most recent processing
            [xField, yField, zField, dField] = find_data_fields(app, dataStr);
            % - Capping the window to the max/min of the ARPES data
            if isempty(isoWin); WinLims = [-0.1, 0.1];
            else; WinLims = sort(isoWin);
            end
            step_size = 0.05;
            if isoType == "IsoE"
                if WinLims(1) < min(dataStr.(yField)(:)); WinLims(1) = min(dataStr.(yField)(:))+step_size; end
                if WinLims(2) > max(dataStr.(yField)(:)); WinLims(2) = max(dataStr.(yField)(:))-step_size; end
            elseif isoType == "IsoK"
                if WinLims(1) < min(dataStr.(xField)(:)); WinLims(1) = min(dataStr.(xField)(:))+step_size; end
                if WinLims(2) > max(dataStr.(xField)(:)); WinLims(2) = max(dataStr.(xField)(:))-step_size; end
            end
            
            %% 1 - Extracting the Iso slices
            if isoType == "IsoE"
                [DSlice, XSlice] = Slice(dataStr.(xField), dataStr.(yField), dataStr.(dField), 'IsoE', WinLims);
                % - Extracting the scan parameter variables
                if isfield(dataStr, 'kx'); init = ceil(0.5*size(dataStr.(dField), 2)); YSlice = squeeze(dataStr.(zField)(init,:,:))';
                else; YSlice = squeeze(dataStr.(zField))'; YSlice = repmat(YSlice, [1, size(XSlice, 2)]);
                end
            elseif isoType== "IsoK"    
                [DSlice, YSlice] = Slice(dataStr.(xField), dataStr.(yField), dataStr.(dField), 'IsoK', WinLims);
                % - Extracting the scan parameter variables
                if isfield(dataStr, 'kx'); init = ceil(0.5*size(dataStr.(dField), 2)); XSlice = squeeze(dataStr.(zField)(:,init,:));
                else; XSlice = squeeze(dataStr.(zField))'; XSlice = repmat(XSlice, [1, size(YSlice, 1)])';
                end
            end
            
            %% 2 - Re-mapping the Iso slices onto a square grid if required
            if remap == 1
                 [XSlice, YSlice, DSlice] = Remap(XSlice, YSlice, DSlice);
                 YSlice = YSlice';
                 % DSlice(isnan(DSlice)) = 0;
            end
            
            %% 3 - Plotting the Eb(k) at the scan value
            subplot(1,2,1); hold on;
            % -- The plot depends on how far in the analysis 
            if isfield(dataStr, 'kx')
                ImData(dataStr.(xField)(:,:,scanIndex), dataStr.(yField)(:,:,scanIndex), dataStr.(dField)(:,:,scanIndex));
            elseif isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                ImData(dataStr.(xField)(:,:,scanIndex), dataStr.(yField)(:,:,scanIndex), dataStr.(dField)(:,:,scanIndex));
            else
                ImData(dataStr.(xField), dataStr.(yField), dataStr.(dField)(:,:,scanIndex));
            end
            gca_properties(app, string(xField)); colorbar('off');
            axis([min(dataStr.(xField)(:)), max(dataStr.(xField)(:)),min(dataStr.(yField)(:)), max(dataStr.(yField)(:))]);
            % -- Plotting an outline over the integrated sliced region
            if isoType == "IsoE"
                patch([-1e3, 1e3, 1e3, -1e3, -1e3], [WinLims(1), WinLims(1), WinLims(2), WinLims(2), WinLims(1)],...
                    [0 1 0], 'linewidth', 1, 'facealpha', 0, 'edgecolor', [0 1 0]);
            elseif isoType== "IsoK"
                patch([WinLims(1), WinLims(1), WinLims(2), WinLims(2), WinLims(1)],[-1e3, 1e3, 1e3, -1e3, -1e3],...
                    [0 1 0], 'linewidth', 1, 'facealpha', 0, 'edgecolor', [0 1 0]);
            end    
            % -- Adding title to the figure
            title(sprintf(string(dataStr.H5file) + "; scan %i", scanIndex), 'interpreter', 'none', 'fontsize', 12);
            % -- Colorbar properties
            cb = colorbar; 
            % Colorbar font properties
            cb.FontName = 'Helvetica'; cb.FontWeight = 'normal'; cb.FontSize = 10;
            % Colorbar tick properties
            cb.TickLabelInterpreter = 'latex';
            cb.TickLength = 0.04; cb.TickDirection = 'out';
            cb.YAxisLocation = 'right';
            % Colorbar position properties
            pos = get(cb, 'Position'); cb.Position = [0.17*pos(1) 7.5*pos(2) 0.02 0.1];
            % Colorbar box properties
            cb.Color = [0 0 0]; cb.Box = 'on'; cb.LineWidth = 1.2;
            
            %% 4 - Plotting the Iso-Slice
            subplot(1,2,2); hold on;
            % - Plotting the Iso slices
            ImData(XSlice, YSlice, DSlice);
            % - Formatting the figure
            minC = min(DSlice(:)); maxC = max(DSlice(:));
            caxis([minC, maxC]);
            axis([min(XSlice(:)), max(XSlice(:)), min(YSlice(:)), max(YSlice(:))]);
            gca_properties(app, string(xField)); colorbar('off');
            % - Re-labelling the axes depending on what slice is taken
            if isoType== "IsoE"
                if dataStr.Type == "Eb(kx,ky)"
                    if isfield(dataStr, 'kx'); xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex'); ylabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex'); 
                    else; xlabel('$$ \bf  \theta (^{\circ}) $$', 'Interpreter', 'latex'); ylabel('$$ \bf  \tau (^{\circ}) $$', 'Interpreter', 'latex');
                    end
                elseif dataStr.Type == "Eb(kx,kz)"
                    if isfield(dataStr, 'kx'); xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex'); ylabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex'); 
                    else; xlabel('$$ \bf  \theta (^{\circ}) $$', 'Interpreter', 'latex'); ylabel('$$ \bf  hv (eV) $$', 'Interpreter', 'latex');
                    end
                end
            elseif isoType== "IsoK"
                ylabel('$$ \bf  E_B (eV) $$', 'Interpreter', 'latex'); 
                if dataStr.Type == "Eb(kx,ky)"
                    if isfield(dataStr, 'kx'); xlabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex');
                    else; xlabel('$$ \bf  \tau (^{\circ}) $$', 'Interpreter', 'latex');
                    end
                elseif dataStr.Type == "Eb(kx,kz)"
                    if isfield(dataStr, 'kx'); xlabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex'); 
                    else; xlabel('$$ \bf  hv (eV) $$', 'Interpreter', 'latex');
                    end
                end
            end
            % -- Adding title to the figure
            title(sprintf(string(dataStr.H5file) + "; %s; [%.2f,%.2f]", isoType, isoWin(1),isoWin(2)), 'interpreter', 'none', 'fontsize', 12);
            % -- Colorbar properties
            cb = colorbar; 
            % Colorbar font properties
            cb.FontName = 'Helvetica'; cb.FontWeight = 'normal'; cb.FontSize = 10;
            % Colorbar tick properties
            cb.TickLabelInterpreter = 'latex';
            cb.TickLength = 0.04; cb.TickDirection = 'out';
            % Colorbar position properties
            pos = get(cb, 'Position'); cb.Position = [1.08*pos(1) 7.5*pos(2) 0.02 0.1];
            % Colorbar box properties
            cb.Color = [0 0 0]; cb.Box = 'on'; cb.LineWidth = 1.2;
            
            %% 5 - Extracting the curve formed by the Eb(k) scan slice being looked at
            if isoType== "IsoE"
                initIndx = floor(0.5*size(dataStr.(dField), 1));
                if isfield(dataStr, 'kx')
                    x_curve = squeeze(dataStr.(xField)(initIndx,:,scanIndex));
                    y_curve = squeeze(dataStr.(zField)(initIndx,:,scanIndex));
                elseif isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                    x_curve = squeeze(dataStr.(xField)(initIndx,:,scanIndex));
                    y_curve = repmat(squeeze(dataStr.(zField)(scanIndex)), size(x_curve));
                else
                    x_curve = squeeze(dataStr.(xField));
                    y_curve = repmat(squeeze(dataStr.(zField)(scanIndex)), size(x_curve));
                end
                % - Fitting a 4th order polynomial to the slice curve
                x_interp = linspace(min(x_curve(:))-1e2, max(x_curve(:))+1e2, 1e3);
                y_interp = polyval(polyfit(x_curve,y_curve,4),x_interp);
            elseif isoType== "IsoK"
                initIndx = floor(0.5*size(dataStr.(dField), 2));
                if isfield(dataStr, 'kx')
                    y_curve = squeeze(dataStr.(yField)(:,initIndx,scanIndex));
                    x_curve = squeeze(dataStr.(zField)(:,initIndx,scanIndex));
                    x_interp = linspace(min(x_curve(:))-5, max(x_curve(:))+5, 1e3);
                    y_interp = polyval(polyfit(x_curve,y_curve,1),x_interp);
                elseif isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                    y_curve = squeeze(dataStr.(yField)(:,initIndx,scanIndex));
                    x_curve = repmat(squeeze(dataStr.(zField)(scanIndex)), size(y_curve));
                    x_interp = ones(size(x_curve))*mean(x_curve(:));
                    y_interp = linspace(-1e3, 1e3, size(x_interp, 1));
                else
                    y_curve = squeeze(dataStr.(yField));
                    x_curve = repmat(squeeze(dataStr.(zField)(scanIndex)), size(y_curve));
                    x_interp = ones(size(x_curve))*mean(x_curve(:));
                    y_interp = linspace(-1e3, 1e3, size(x_interp, 1));
                end
            end
            % Plotting the scan slice curve
            plot(x_interp, y_interp, 'Color', [0 0 1], 'LineWidth', 1., 'Linestyle', '-');
            
            %% 6 - Appending data to MATLAB data structure
            sliceStr = struct();
            sliceStr.FileName = dataStr.FileName;
            sliceStr.Type = dataStr.Type;
            sliceStr.isoslice_args = isoslice_args;
            sliceStr.XSlice = XSlice;
            sliceStr.YSlice = YSlice;
            sliceStr.DSlice = DSlice;
        end
        
        % --- General function to view 3D ARPES data
        function [cutStr, fig] = view_isoCut(app, dataStr, isocut_args)
            % cutStr = view_isoCut(dataStr, isocut_args)
            %   This function plots a EDC/MDC cut through the ARPES data in 
            %   D(X,Y,cutIndx) into a single figure that includes the Eb(k) 
            %   dispersion.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   [h=] ImData(X,Y,Z[,style])
            %   -   [XCut,DCut] = Cut(ACorr,ECorr,Data,xMode,Win)
            %
            %   IN:
            %   -   dataStr:            data structure of the ARPES data.
            %   -   isoslice_args:      1x4 cell of {isoType, isoWin, scanIndex, cutIndex}.
            %
            %   OUT:
            %   -   figure output.
            %   -   sliceStr:           data structure of the slice data /info.
            
            %% Initialising variables for the figure
            isoType         = isocut_args{1};
            isoWin          = isocut_args{2};
            scanIndex       = isocut_args{3};
            % - Extracting the fields to be used with most recent processing
            [xField, yField, ~, dField] = find_data_fields(app, dataStr);
            % - Capping the window to the max/min of the ARPES data
            if isempty(isoWin); WinLims = [-0.1, 0.1];
            else; WinLims = sort(isoWin);
            end
            step_size = 0.05;
            if isoType == "MDC"
                if WinLims(1) < min(dataStr.(yField)(:)); WinLims(1) = min(dataStr.(yField)(:)) + step_size; end
                if WinLims(2) > max(dataStr.(yField)(:)); WinLims(2) = max(dataStr.(yField)(:)) - step_size; end
            elseif isoType == "EDC"
                if WinLims(1) < min(dataStr.(xField)(:)); WinLims(1) = min(dataStr.(xField)(:)) + step_size; end
                if WinLims(2) > max(dataStr.(xField)(:)); WinLims(2) = max(dataStr.(xField)(:)) - step_size; end
            end
            
            %% 1 - Extracting the Iso cuts
            if isoType == "MDC"
                if isfield(dataStr, 'kx') || isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                    [XCut, DCut] = Cut(dataStr.(xField)(:,:,scanIndex), dataStr.(yField)(:,:,scanIndex), dataStr.(dField)(:,:,scanIndex), 'mdc', WinLims);
                else
                    [XCut, DCut] = Cut(dataStr.(xField), dataStr.(yField), dataStr.(dField)(:,:,scanIndex), 'mdc', WinLims);
                end
            elseif isoType== "EDC"
                if isfield(dataStr, 'kx') || isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                    [XCut, DCut] = Cut(dataStr.(xField)(:,:,scanIndex), dataStr.(yField)(:,:,scanIndex), dataStr.(dField)(:,:,scanIndex), 'edc', WinLims);
                else
                    [XCut, DCut] = Cut(dataStr.(xField), dataStr.(yField), dataStr.(dField)(:,:,scanIndex), 'edc', WinLims);
                end
            end

            %% 2 - Plotting the Eb(k) at the scan value
            if isoType == "MDC"
                fig = figure(); set(fig, 'Position', [5,5, 600, 600]);
                subplot(3,1,2:3); hold on;
            elseif isoType== "EDC"
                fig = figure(); set(fig, 'Position', [5,5, 800, 600]);
                subplot(1,3,1:2); hold on;
            end
            % -- The plot depends on how far in the analysis 
            if isfield(dataStr, 'kx')
                ImData(dataStr.(xField)(:,:,scanIndex), dataStr.(yField)(:,:,scanIndex), dataStr.(dField)(:,:,scanIndex));
            elseif isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                ImData(dataStr.(xField)(:,:,scanIndex), dataStr.(yField)(:,:,scanIndex), dataStr.(dField)(:,:,scanIndex));
            else
                ImData(dataStr.(xField), dataStr.(yField), dataStr.(dField)(:,:,scanIndex));
            end
            gca_properties(app, string(xField)); colorbar('off');
            axis([min(dataStr.(xField)(:)), max(dataStr.(xField)(:)),min(dataStr.(yField)(:)), max(dataStr.(yField)(:))]);
            % -- Plotting an outline over the integrated sliced region
            if isoType == "MDC"
                patch([-1e3, 1e3, 1e3, -1e3, -1e3], [WinLims(1), WinLims(1), WinLims(2), WinLims(2), WinLims(1)],...
                    [0 1 0], 'linewidth', 1, 'facealpha', 0, 'edgecolor', [0 1 0]);
            elseif isoType== "EDC"
                patch([WinLims(1), WinLims(1), WinLims(2), WinLims(2), WinLims(1)],[-1e3, 1e3, 1e3, -1e3, -1e3],...
                    [0 1 0], 'linewidth', 1, 'facealpha', 0, 'edgecolor', [0 0 1]);
            end    
            % -- Adding title to the figure
            title(sprintf(string(dataStr.H5file) + "; scan %i", scanIndex), 'interpreter', 'none', 'fontsize', 12);
            
            %% 3 - Plotting the Iso-Cut
            if isoType == "MDC"; subplot(3,1,1); hold on;
                plot(XCut, DCut, 'k.-', 'color', [0 1 0], 'LineWidth', 1.5);
                %% 1 - Defining the axes properties
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 12;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'out';
                % Ruler properties
                ax.XAxisLocation = 'top';               % 'bottom' | 'top' | 'origin'
                ax.YAxisLocation = 'left';              % 'left' | 'right' | 'origin'
                % Axis labels and limits
                if xField == "raw_tht" || xField == "tht"
                    xlabel('$$ \bf  \theta (^{\circ}) $$', 'Interpreter', 'latex');
                elseif xField == "kx"
                    xlabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex');
                end
                ylabel('$$ \bf  Intensity $$', 'Interpreter', 'latex');
                %% 2 - Plotting the x- and y-axes
                xl = [min(dataStr.(xField)(:)), max(dataStr.(xField)(:))]; yl = ylim;
                axis([xl(1), xl(2), yl(1), yl(2)]);
                line([0 0], [-1e5, 1e5], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
                line([-1e5, 1e5], [0 0], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
            elseif isoType== "EDC"; subplot(1,3,3); hold on;
                plot(DCut, XCut, 'k.-', 'color', [0 0 1], 'LineWidth', 1.5);
                %% 1 - Defining the axes properties
                ax = gca;
                % Font properties
                ax.FontName = 'Helvetica'; ax.FontSize = 12;
                % Tick properties
                ax.TickLabelInterpreter = 'latex';
                ax.TickDir = 'out';
                % Ruler properties
                ax.XAxisLocation = 'bottom';            % 'bottom' | 'top' | 'origin'
                ax.YAxisLocation = 'right';             % 'left' | 'right' | 'origin'
                % Axis labels and limits
                ylabel('$$ \bf  E_{B} (eV) $$', 'Interpreter', 'latex');
                xlabel('$$ \bf  Intensity $$', 'Interpreter', 'latex');
                %% 2 - Plotting the x- and y-axes
                xl = xlim; yl = [min(dataStr.(yField)(:)), max(dataStr.(yField)(:))];
                axis([xl(1), xl(2), yl(1), yl(2)]);
                line([0 0], [-1e5, 1e5], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
                line([-1e5, 1e5], [0 0], 'Color', [0 0 0], 'LineWidth', 0.5, 'Linestyle', '--');
            end

            %% 6 - Appending data to MATLAB data structure
            cutStr = struct();
            cutStr.FileName = dataStr.FileName;
            cutStr.Type = dataStr.Type;
            cutStr.isocut_args = isocut_args;
            cutStr.XCut = XCut;
            cutStr.DCut = DCut;
        end
        
        % --- General function to view a series of all scans from 3D ARPES data - *Eb(kx,ky) or Eb(kx,kz) scans only*
        function view_data_series(app, dataStr)
            % view_data_series(dataStr)
            %   This function plots the ARPES data in the form of D(X,Y)
            %   for all the scan parameter values (tltM or hv) in the form of a 
            %   downsampled image series. This function ensures that the 
            %   most recent processing is shown in the figure.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   gca_properties(type)
            %   -   [h=] ImData(X,Y,Z[,style])
            %
            %   IN:
            %   -   dataStr:          data structure of the ARPES data.
            
            disp('-> view series of all scans...')
            wbar = waitbar(0., 'Plotting a view series of all scans...', 'Name', 'view_data_series');
            
            %% Default parameters
            % - Extracting the fields to be used with most recent processing
            [xField, yField, zField, dField] = find_data_fields(app, dataStr);
            % - Each series is an nRows x nCols sub-plot
            nRows = 3; nCols = 3;
            %% 1 - Down-sampling the form of the data
            step_size = 2;
            zMidIndx = ceil(size(dataStr.(dField), 3)/2);
            %% 2 - Plotting a series of Eb(k) Images
            % Filing through all the pages of the figures
            for ipage=1:ceil(size(dataStr.(dField), 3)/nRows/nCols)
                figure_cell{ipage} = figure(); orient tall; 
                set(figure_cell{ipage}, 'Position', [1, 1, 800, 700], 'Name', dataStr.H5file);
                % Filing through all the frames in a single figure
                for iframe=1:nRows*nCols
                    % - Finding the n'th data object to be plotted
                    n = (ipage-1)*nRows*nCols+iframe;
                    waitbar(n/size(dataStr.(dField), 3), wbar, 'Plotting a view series of all scans...', 'Name', 'view_data_series');
                    % - If n > number of plotting elements, break the statement
                    if n > size(dataStr.(dField), 3)
                        break; 
                    else
                        % -- Plotting the n'th data object
                        subplot(nRows,nCols,iframe); 
                        gca_properties(app, string(xField)); colorbar('off'); 
                        xlabel(''); ylabel('');
                        % -- The plot depends on how far in the analysis 
                        % - 2.1 - EbAlign->Normalise->kConvert fields
                        if isfield(dataStr, 'kx')
                            ImData(dataStr.(xField)(1:step_size:end,1:step_size:end,n), dataStr.(yField)(1:step_size:end,1:step_size:end,n), dataStr.(dField)(1:step_size:end,1:step_size:end,n));
                            title(sprintf('scan = %.2f', dataStr.(zField)(zMidIndx,zMidIndx,n)), 'fontsize', 10);
                        % - 2.2 - EbAlign->Normalise fields
                        elseif isfield(dataStr, 'data')
                            ImData(dataStr.(xField)(1:step_size:end,1:step_size:end,n), dataStr.(yField)(1:step_size:end,1:step_size:end,n), dataStr.(dField)(1:step_size:end,1:step_size:end,n));
                            title(sprintf('scan = %.2f', dataStr.(zField)(n)), 'fontsize', 10);
                        % - 2.3 - EbAlign fields
                        elseif isfield(dataStr, 'eb')
                            ImData(dataStr.(xField)(1:step_size:end,1:step_size:end,n), dataStr.(yField)(1:step_size:end,1:step_size:end,n), dataStr.(dField)(1:step_size:end,1:step_size:end,n));
                            title(sprintf('scan = %.2f', dataStr.(zField)(n)), 'fontsize', 10);
                        % - 2.4 - Raw, unprocessed data fields
                        else
                            ImData(dataStr.(xField)(1:step_size:end), dataStr.(yField)(1:step_size:end), dataStr.(dField)(1:step_size:end,1:step_size:end,n));
                            title(sprintf('scan = %.2f', dataStr.(zField)(n)), 'fontsize', 10);
                        end
                        % - 2.5 - Formatting the figure
                        minC = min(min(min(dataStr.(dField)(1:step_size:end,1:step_size:end,iframe))));
                        maxC = max(max(max(dataStr.(dField)(1:step_size:end,1:step_size:end,iframe))));
                        caxis([minC, maxC]);
    
                        % -- Remove the colorbar for all plots, except the first one
                        if iframe == 1
                            % Setting the colorbar properties
                            cb = colorbar; 
                            % Colorbar font properties
                            cb.FontName = 'Helvetica'; cb.FontWeight = 'normal'; cb.FontSize = 10;
                            % Colorbar tick properties
                            if cb.Limits(1) < 0 || cb.Limits(1) == 0; cb.Ticks = sort(round([0, 0.5*(0.95*cb.Limits(2)), 0.95*cb.Limits(2)], 3));
                            else; cb.Ticks = sort(round([1.1*cb.Limits(1), 0.5*(0.95*cb.Limits(2)), 0.95*cb.Limits(2)], 3)); 
                            end
                            cb.TickLabelInterpreter = 'latex'; cb.TickLength = 0.04;
                            cb.TickDirection = 'out';
                            % Colorbar position properties
                            cb.Position = [0.92, 0.8, 0.02 0.1];
                            % Colorbar box properties
                            cb.Color = [0 0 0]; cb.Box = 'on'; cb.LineWidth = 1.2;
                        end
                    end
                end
            end
            close(wbar);
        end
    
        % --- General function to view 3D ARPES data
        function [cubeStr, fig] = view_3Ddatacube(app, dataStr, view3D_args)
            % view_3Ddatacube(dataStr, view3D_args)
            %   This function plots a 3D ARPES data data cube in the form
            %   of D(X, Y, Z).
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   [h=] ImData(X,Y,Z[,style])
            %
            %   IN:
            %   -   dataStr:        data structure of the ARPES data.
            %   -   view3D_args:    1x3 cell of {threshold, interpolate, squareaxes}.
            %
            %   OUT:
            %   -   figure output.
            
            disp('-> Plotting 3D data cube...')
            wbar = waitbar(0.5, 'Plotting ARPES data as 3D cube (takes a while!)...', 'Name', 'view_3Ddatacube');
            
            %% Initialising variables for the figure
            threshold       = view3D_args{1};
            interpolate     = view3D_args{2};
            squareaxes      = view3D_args{3};
            %% Default parameters
            % - Extracting the fields to be used with most recent processing
            [xField, yField, zField, dField] = find_data_fields(app, dataStr);
            
            %% 1 - Initialising and permuting the variable / data arrays to be consistent in 3D
            % - 1.1 - EbAlign->Normalise->kConvert fields
            if isfield(dataStr, 'kx')
                v = permute(dataStr.(dField), [3 2 1]);
                x = permute(dataStr.(xField), [3 2 1]);
                y = permute(dataStr.(yField), [3 2 1]);
                z = permute(dataStr.(zField), [3 2 1]);
            % - 1.2 - EbAlign->Normalise fields
            elseif isfield(dataStr, 'data')
                v = permute(dataStr.(dField), [3 2 1]);
                x = permute(dataStr.(xField), [3 2 1]);
                y = permute(dataStr.(yField), [3 2 1]);
                % - Converting the scan variable into 3D
                z = repmat(dataStr.(zField), [size(dataStr.(dField),1),1,size(dataStr.(dField),2)]);
                z = permute(z, [2 3 1]);
            % - 1.3 - EbAlign fields
            elseif isfield(dataStr, 'eb')
                v = permute(dataStr.(dField), [3 2 1]);
                x = permute(dataStr.(xField), [3 2 1]);
                y = permute(dataStr.(yField), [3 2 1]);
                % - Converting the scan variable into 3D
                z = repmat(dataStr.(zField), [size(dataStr.(dField),1),1,size(dataStr.(dField),2)]);
                z = permute(z, [2 3 1]);
            % - 1.4 - Raw, unprocessed data fields
            else
                v = permute(dataStr.(dField), [3 2 1]);
                % - Converting the theta variable into 3D
                x = repmat(dataStr.(xField), [size(dataStr.(dField),1),1,size(dataStr.(dField),3)]);
                x = permute(x, [3 2 1]);
                % - Converting the eb variable into 3D
                y = repmat(dataStr.(yField), [1,size(dataStr.(dField),2),size(dataStr.(dField),3)]);
                y = permute(y, [3 2 1]);
                % - Converting the scan variable into 3D
                z = repmat(dataStr.(zField), [size(dataStr.(dField),1),1,size(dataStr.(dField),2)]);
                z = permute(z, [2 3 1]);
            end
            
            %% 2 - Plotting the iso-surfaces of the cube
            % - If necessary, smooth the data beforehand
            if interpolate == 1; v = smooth3(v, 'gaussian', 5); end
            % - Defining the threshold 
            cLim = threshold;
            % - Plotting the 3D data-cube
            fig = figure(); hold on;
            p1L = patch(isocaps(z, x, y, v, cLim), 'FaceColor', 'interp', 'EdgeColor', 'none');
            p2L = patch(isosurface(z, x, y, v, cLim),'FaceColor', 'black', 'EdgeColor', 'none');
            isonormals(v, p2L);
            %% 3 - Formatting the figure
            view([-65, 7]);
            axis([min(z(:)), max(z(:)), min(x(:)), max(x(:)), min(y(:)), max(y(:))]);
            % - 3.1 - Defining the axes properties
            ax = gca;
            if squareaxes == 1; pbaspect([1,1,1]); daspect([1 1 1]); end
            % Font properties
            ax.FontName = 'Helvetica'; ax.FontWeight = 'normal'; ax.FontSize = 13;
            % Tick properties
            ax.TickLabelInterpreter = 'latex';
            ax.TickDir = 'out';
            ax.TickLength = [0.01 0.025];
            ax.XColor = [0 0 0]; ax.YColor = [0 0 0]; ax.ZColor = [0 0 0];
            % Box Styling properties
            ax.LineWidth = 1.5;
            ax.Box = 'on'; ax.BoxStyle = 'full'; ax.Layer = 'Top';
            % Axis labels and limits
            if dataStr.Type == "Eb(kx,ky)" 
                if isfield(dataStr, 'kx'); ylabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex'); xlabel('$$ \bf  k_y (\AA^{-1}) $$', 'Interpreter', 'latex');
                else; ylabel('$$ \bf  \theta (^{\circ}) $$', 'Interpreter', 'latex'); xlabel('$$ \bf  \tau (^{\circ}) $$', 'Interpreter', 'latex');
                end
            elseif dataStr.Type == "Eb(kx,kz)" 
                if isfield(dataStr, 'kx'); ylabel('$$ \bf  k_x (\AA^{-1}) $$', 'Interpreter', 'latex'); xlabel('$$ \bf  k_z (\AA^{-1}) $$', 'Interpreter', 'latex');
                else; ylabel('$$ \bf  \theta (^{\circ}) $$', 'Interpreter', 'latex'); xlabel('$$ \bf  hv (eV) $$', 'Interpreter', 'latex');
                end
            end
            zlabel('$$ \bf  E_B (eV) $$', 'Interpreter', 'latex');
            % - 3.2 - Defining the figure properties
            fig.Color = [1 1 1]; fig.InvertHardcopy = 'off';
            set(fig, 'Name', dataStr.FileName);
            % - 3.3 - Colorbar properties
            colormap(app.colmap_arg);
            cb = colorbar; 
            % Colorbar font properties
            cb.FontName = 'Helvetica'; cb.FontWeight = 'normal'; cb.FontSize = 10;
            % Colorbar tick properties
            cb.TickLabelInterpreter = 'latex';
            cb.TickLength = 0.04; cb.TickDirection = 'out';
            % Colorbar position properties
            pos = get(cb, 'Position'); cb.Position = [1.11*pos(1) 5.1*pos(2) 0.02 0.1];
            % Colorbar box properties
            cb.Color = [0 0 0]; cb.Box = 'on'; cb.LineWidth = 1.2;
            %% Close wait-bar
            close(wbar);
            if squareaxes == 1; pbaspect([1,1,1]); end
            
            %% 6 - Appending data to MATLAB data structure
            cubeStr = struct();
            cubeStr.FileName        = dataStr.FileName;
            cubeStr.Type            = dataStr.Type;
            cubeStr.view3D_args     = view3D_args;
            cubeStr.cLim    = cLim;
            cubeStr.x       = x;
            cubeStr.y       = y;
            cubeStr.z       = z;
            cubeStr.v       = v;
        end
        

        % --- General function to view Scan Videos of 3D ARPES data
        function view_ScanVideo(app, dataStr, viewVideo_args)
            % view_ScanVideo(dataStr, view3D_args)
            %   This function plots the Eb(kx) ARPES image as a function
            %   of the scan parameter with the addition of a zoomed area 
            %   of interest.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   gca_properties(type)
            %   -   [h=] ImData(X,Y,Z[,style])
            %
            %   IN:
            %   -   dataStr:        data structure of the ARPES data.
            %   -   viewVideo_args: 1x5 cell of {zLims, interpolate, ROI, xLimsROI, yLimsROI}.
            %
            %   OUT:
            %   -   figure output.
            
            %% Initialisation to save the video as a .avi file
            filter = {'*.avi'};
            [save_filename, save_filepath] = uiputfile(filter);
            fname = char(string(save_filepath) + string(save_filename));
            % If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            
            % Else proceed with the video plotting
            disp('-> Eb(k) vs scan parameter zoomed video...')
            wbar = waitbar(0., 'Plotting scan video...', 'Name', 'view_ScanVideo');
            
            %% Initialising variables for the figure
            zLims           = viewVideo_args{1};
            interpolate     = viewVideo_args{2};
            ROI             = viewVideo_args{3};
            xLimsROI        = viewVideo_args{4};
            yLimsROI        = viewVideo_args{5};

            %% 1 - Initialising variables for the video
            % - Initialising the data structures
            dataStr = crop_data(app, dataStr, [], [], zLims);
            if ROI == 1; dataStr_roi = crop_data(app, dataStr, xLimsROI, yLimsROI, zLims); end
            % - Extracting the fields to be used with most recent processing
            [xField, yField, zField, dField] = find_data_fields(app, dataStr);
            % - Initialising the frames
            nFrames = size(dataStr.(dField), 3);
            vidObj = VideoWriter(fname);
            vidObj.FrameRate = 5;
            open(vidObj);
            %% 2 - Running the Eb(k) vs scan parameter frame-by-frame
            figure();
            for f = 1:nFrames
                % - Reset the figure
                cla reset; clf reset; delete(findall(gcf,'type','annotation')); hold on;
                % - Extracting the scan parameter for the given frame
                if dataStr.Type == "Eb(kx,ky)" 
                    if isfield(dataStr, 'kx'); scanVal = sprintf('$$ \\bf ky_{%i} = %.3f \\AA^{-1}$$', f, dataStr.(zField)(1,1,f));
                    else; scanVal = sprintf('$$ \\bf \\tau_{%i} = %.2f^{\\circ}$$', f, dataStr.(zField)(f));
                    end
                elseif dataStr.Type == "Eb(kx,kz)" 
                    if isfield(dataStr, 'kx'); scanVal = sprintf('$$ \\bf kz_{%i} = %.3f \\AA^{-1}$$', f, dataStr.(zField)(1,1,f));
                    else; scanVal = sprintf('$$ \\bf hv_{%i} = %.2f eV $$', f, dataStr.(zField)(f));
                    end
                end
                %% 2.1 - Plotting the full Eb(k) image first
                if interpolate == 1
                    if isfield(dataStr, 'kx')
                        ImData(dataStr.(xField)(:,:,f), dataStr.(yField)(:,:,f), dataStr.(dField)(:,:,f), 'interp');
                    elseif isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                        ImData(dataStr.(xField)(:,:,f), dataStr.(yField)(:,:,f), dataStr.(dField)(:,:,f), 'interp');
                    else
                        ImData(dataStr.(xField), dataStr.(yField), dataStr.(dField)(:,:,f), 'interp');
                    end
                else
                    if isfield(dataStr, 'kx')
                        ImData(dataStr.(xField)(:,:,f), dataStr.(yField)(:,:,f), dataStr.(dField)(:,:,f));
                    elseif isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                        ImData(dataStr.(xField)(:,:,f), dataStr.(yField)(:,:,f), dataStr.(dField)(:,:,f));
                    else
                        ImData(dataStr.(xField), dataStr.(yField), dataStr.(dField)(:,:,f));
                    end
                end
                % - 2.2 - Formatting the figure
                minC = min(min(min(dataStr.(dField)(:,:,f))));
                maxC = max(max(max(dataStr.(dField)(:,:,f))));
                caxis([minC, maxC]);
                axis([min(dataStr.(xField)(:)), max(dataStr.(xField)(:)), min(dataStr.(yField)(:)), max(dataStr.(yField)(:))]);
                gca_properties(app, string(xField));
                title(dataStr.FileName, 'interpreter', 'none', 'fontsize', 14);
                % - 2.3 - Adding text for the scan parameter
                annotation('textbox', [0.22 0.2 0.17 0.06], 'String',scanVal, 'FitBoxToText','on',...
                    'color', [0 0 0], 'fontsize', 13, 'backgroundcolor', [1 1 1], 'facealpha', 0.80,...
                    'linewidth', 2, 'horizontalalignment', 'center', 'verticalalignment', 'middle',...
                    'interpreter', 'latex');
                %% 3 - Plot the ROI if required
                if ROI == 1
                    %% 3.1 - Plotting the region of interest that is zoomed in on
                    x = [xLimsROI(1), xLimsROI(2), xLimsROI(2), xLimsROI(1), xLimsROI(1)];
                    y = [yLimsROI(1), yLimsROI(1), yLimsROI(2), yLimsROI(2), yLimsROI(1)] ;
                    plot(x, y, 'LineWidth', 1.5, 'Color', 'y', 'linestyle', '-');
                    %% 3.2 - Plotting the cropped Eb(k) on top
                    % - Creating a new inset axes to the figure
                    if mean(xLimsROI) < 0; new_ax = axes('Position',[.67 .655 .25 .25]);
                    else; new_ax = axes('Position',[.12 .655 .25 .25]);
                    end
                    % - Plotting the cropped ARPES data
                    if interpolate == 1
                        if isfield(dataStr_roi, 'kx')
                            ImData(dataStr_roi.(xField)(:,:,f), dataStr_roi.(yField)(:,:,f), dataStr_roi.(dField)(:,:,f), 'interp');
                        elseif isfield(dataStr_roi, 'data') || isfield(dataStr_roi, 'eb')
                            ImData(dataStr_roi.(xField)(:,:,f), dataStr_roi.(yField)(:,:,f), dataStr_roi.(dField)(:,:,f), 'interp');
                        else
                            ImData(dataStr_roi.(xField), dataStr_roi.(yField), dataStr_roi.(dField)(:,:,f), 'interp');
                        end
                    else
                        if isfield(dataStr_roi, 'kx')
                            ImData(dataStr_roi.(xField)(:,:,f), dataStr_roi.(yField)(:,:,f), dataStr_roi.(dField)(:,:,f));
                        elseif isfield(dataStr_roi, 'data') || isfield(dataStr_roi, 'eb')
                            ImData(dataStr_roi.(xField)(:,:,f), dataStr_roi.(yField)(:,:,f), dataStr_roi.(dField)(:,:,f));
                        else
                            ImData(dataStr_roi.(xField), dataStr_roi.(yField), dataStr_roi.(dField)(:,:,f));
                        end
                    end
                    % - 3.3 - Formatting the figure
                    minC = min(min(min(dataStr_roi.(dField)(:,:,f))));
                    maxC = max(max(max(dataStr_roi.(dField)(:,:,f))));
                    caxis([minC, maxC]);
                    axis([min(dataStr_roi.(xField)(:)), max(dataStr_roi.(xField)(:)), min(dataStr_roi.(yField)(:)), max(dataStr_roi.(yField)(:))]);
                    gca_properties(app, string(xField)); 
                    colorbar off; xlabel(''); ylabel('');
                    % Other properties
                    ax = gca;  ax.FontSize = 10; ax.TickDir = 'both';
                    ax.Color = [1 1 1];ax.LineWidth = 0.75;
                    ax.Box = 'on'; ax.Layer = 'Top';
                    new_ax.XColor = [1 1 1]; new_ax.YColor = [1 1 1]; pbaspect([1 1 1]);
                    if mean(xLimsROI) < 0
                        new_ax.XAxisLocation = 'bottom';
                        new_ax.YAxisLocation = 'left';
                    else
                        new_ax.XAxisLocation = 'bottom';
                        new_ax.YAxisLocation = 'right';
                    end
                end
                %% Adding the current figure as a frame to the video dataObject
                mov = getframe(gcf);            % add current figure as frame
                writeVideo(vidObj,mov);         % write frame to vidObj
            end
            close(vidObj);
            %% Close wait-bar
            close(wbar);
        end
        
        % --- General function to save all scan images as a series
        function view_ImageSeries(app, dataStr, viewVideo_args)
            % view_ImageSeries(dataStr, view3D_args)
            %   This function plots the Eb(kx) ARPES image as a function
            %   of the scan parameter with the addition of a zoomed area 
            %   of interest as an image series.
            %
            %   REQ. FUNCTIONS:
            %   -   [xField, yField, zField, dField] = find_data_fields(dataStr);
            %   -   gca_properties(type)
            %   -   [h=] ImData(X,Y,Z[,style])
            %
            %   IN:
            %   -   dataStr:        data structure of the ARPES data.
            %   -   viewVideo_args: 1x5 cell of {zLims, interpolate, ROI, xLimsROI, yLimsROI}.
            %
            %   OUT:
            %   -   figure output.
            
            %% Initialisation
            filter = {'*.*'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the image series...', dataStr.FileName);
            fName = string(string(save_filepath) + string(save_filename));
            % If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% Initialising the figure
            fig = figure();
            
            %% Initialising variables for the figure
            zLims           = viewVideo_args{1};
            interpolate     = viewVideo_args{2};
            ROI             = viewVideo_args{3};
            xLimsROI        = viewVideo_args{4};
            yLimsROI        = viewVideo_args{5};

            %% 1 - Initialising variables for the video
            % - Initialising the data structures
            dataStr = crop_data(app, dataStr, [], [], zLims);
            if ROI == 1; dataStr_roi = crop_data(app, dataStr, xLimsROI, yLimsROI, zLims); end
            % - Extracting the fields to be used with most recent processing
            [xField, yField, zField, dField] = find_data_fields(app, dataStr);
            % - Initialising the frames
            nFrames = size(dataStr.(dField), 3);
            %% 2 - Running the Eb(k) vs scan parameter frame-by-frame
            for f = 1:nFrames
                % - Reset the figure
                cla reset; clf reset; delete(findall(gcf,'type','annotation')); hold on;
                % - Initialising variables
                ifName = fName + "_" + string(f);
                % - Extracting the scan parameter for the given frame
                if dataStr.Type == "Eb(kx,ky)" 
                    if isfield(dataStr, 'kx'); scanVal = sprintf('$$ \\bf ky_{%i} = %.3f \\AA^{-1}$$', f, dataStr.(zField)(1,1,f));
                    else; scanVal = sprintf('$$ \\bf \\tau_{%i} = %.2f^{\\circ}$$', f, dataStr.(zField)(f));
                    end
                elseif dataStr.Type == "Eb(kx,kz)" 
                    if isfield(dataStr, 'kx'); scanVal = sprintf('$$ \\bf kz_{%i} = %.3f \\AA^{-1}$$', f, dataStr.(zField)(1,1,f));
                    else; scanVal = sprintf('$$ \\bf hv_{%i} = %.2f eV $$', f, dataStr.(zField)(f));
                    end
                end
                %% 2.1 - Plotting the full Eb(k) image first
                if interpolate == 1
                    if isfield(dataStr, 'kx')
                        ImData(dataStr.(xField)(:,:,f), dataStr.(yField)(:,:,f), dataStr.(dField)(:,:,f), 'interp');
                    elseif isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                        ImData(dataStr.(xField)(:,:,f), dataStr.(yField)(:,:,f), dataStr.(dField)(:,:,f), 'interp');
                    else
                        ImData(dataStr.(xField), dataStr.(yField), dataStr.(dField)(:,:,f), 'interp');
                    end
                else
                    if isfield(dataStr, 'kx')
                        ImData(dataStr.(xField)(:,:,f), dataStr.(yField)(:,:,f), dataStr.(dField)(:,:,f));
                    elseif isfield(dataStr, 'data') || isfield(dataStr, 'eb')
                        ImData(dataStr.(xField)(:,:,f), dataStr.(yField)(:,:,f), dataStr.(dField)(:,:,f));
                    else
                        ImData(dataStr.(xField), dataStr.(yField), dataStr.(dField)(:,:,f));
                    end
                end
                % - 2.2 - Formatting the figure
                minC = min(min(min(dataStr.(dField)(:,:,f))));
                maxC = max(max(max(dataStr.(dField)(:,:,f))));
                caxis([minC, maxC]);
                axis([min(dataStr.(xField)(:)), max(dataStr.(xField)(:)), min(dataStr.(yField)(:)), max(dataStr.(yField)(:))]);
                gca_properties(app, string(xField));
                title(dataStr.FileName, 'interpreter', 'none', 'fontsize', 14);
                % - 2.3 - Adding text for the scan parameter
                annotation('textbox', [0.22 0.2 0.17 0.06], 'String',scanVal, 'FitBoxToText','on',...
                    'color', [0 0 0], 'fontsize', 13, 'backgroundcolor', [1 1 1], 'facealpha', 0.80,...
                    'linewidth', 2, 'horizontalalignment', 'center', 'verticalalignment', 'middle',...
                    'interpreter', 'latex');
                %% 3 - Plot the ROI if required
                if ROI == 1
                    %% 3.1 - Plotting the region of interest that is zoomed in on
                    x = [xLimsROI(1), xLimsROI(2), xLimsROI(2), xLimsROI(1), xLimsROI(1)];
                    y = [yLimsROI(1), yLimsROI(1), yLimsROI(2), yLimsROI(2), yLimsROI(1)] ;
                    plot(x, y, 'LineWidth', 1.5, 'Color', 'y', 'linestyle', '-');
                    %% 3.2 - Plotting the cropped Eb(k) on top
                    % - Creating a new inset axes to the figure
                    if mean(xLimsROI) < 0; new_ax = axes('Position',[.67 .655 .25 .25]);
                    else; new_ax = axes('Position',[.12 .655 .25 .25]);
                    end
                    % - Plotting the cropped ARPES data
                    if interpolate == 1
                        if isfield(dataStr_roi, 'kx')
                            ImData(dataStr_roi.(xField)(:,:,f), dataStr_roi.(yField)(:,:,f), dataStr_roi.(dField)(:,:,f), 'interp');
                        elseif isfield(dataStr_roi, 'data') || isfield(dataStr_roi, 'eb')
                            ImData(dataStr_roi.(xField)(:,:,f), dataStr_roi.(yField)(:,:,f), dataStr_roi.(dField)(:,:,f), 'interp');
                        else
                            ImData(dataStr_roi.(xField), dataStr_roi.(yField), dataStr_roi.(dField)(:,:,f), 'interp');
                        end
                    else
                        if isfield(dataStr_roi, 'kx')
                            ImData(dataStr_roi.(xField)(:,:,f), dataStr_roi.(yField)(:,:,f), dataStr_roi.(dField)(:,:,f));
                        elseif isfield(dataStr_roi, 'data') || isfield(dataStr_roi, 'eb')
                            ImData(dataStr_roi.(xField)(:,:,f), dataStr_roi.(yField)(:,:,f), dataStr_roi.(dField)(:,:,f));
                        else
                            ImData(dataStr_roi.(xField), dataStr_roi.(yField), dataStr_roi.(dField)(:,:,f));
                        end
                    end
                    % - 3.3 - Formatting the figure
                    minC = min(min(min(dataStr_roi.(dField)(:,:,f))));
                    maxC = max(max(max(dataStr_roi.(dField)(:,:,f))));
                    caxis([minC, maxC]);
                    axis([min(dataStr_roi.(xField)(:)), max(dataStr_roi.(xField)(:)), min(dataStr_roi.(yField)(:)), max(dataStr_roi.(yField)(:))]);
                    gca_properties(app, string(xField)); 
                    colorbar off; xlabel(''); ylabel('');
                    % Other properties
                    ax = gca;  ax.FontSize = 10; ax.TickDir = 'both';
                    ax.Color = [1 1 1];ax.LineWidth = 0.75;
                    ax.Box = 'on'; ax.Layer = 'Top';
                    new_ax.XColor = [1 1 1]; new_ax.YColor = [1 1 1]; pbaspect([1 1 1]);
                    if mean(xLimsROI) < 0
                        new_ax.XAxisLocation = 'bottom';
                        new_ax.YAxisLocation = 'left';
                    else
                        new_ax.XAxisLocation = 'bottom';
                        new_ax.YAxisLocation = 'right';
                    end
                end
                %% SAVE THE FIGURE
                saveas(fig, ifName,'png');
            end
        end
    end
     
    methods (Access = private)
        % Code that executes to initialise the app once data is loaded
        function initialiseFcn(app)
            %% 1 - Updating handle text and gui elements
            % - 1.1 - Show the name, type and information of the ARPES data
            app.DataFile_Label.Text         = app.myData.FileName;
            app.DataType_EditField.Value    = app.myData.Type;
            app.ScanInfo_Label.Text         = app.myData.meta.info;
            % - 1.2 - Activating push-buttons that can be used
            % -- General buttons
            app.SAVE_Button.Enable          = 'on';
            app.TRANSFER_Button.Enable      = 'on';
            app.APPINFO_Button.Enable       = 'on';
            app.ViewLatest_Button.Enable    = 'on';
            app.RESET_Button.Enable         = 'on';
            % -- General UI panel activation
            children = get(app.ScanInformationPanel,'Children');    set(children,'Enable','on');
            children = get(app.CropDataPanel,'Children');           set(children,'Enable','on');
            children = get(app.FilterDataPanel,'Children');         set(children,'Enable','on');
            children = get(app.EbAlignmentPanel,'Children');        set(children,'Enable','on');
            children = get(app.IsoCutBrowserTab,'Children');        set(children,'Enable','on');
            app.AlignType_DropDown.Enable   = 'off';
            app.scanindexs_EditField.Enable = 'off';
            % - Deactivating all 3D processing techniques
            if app.myData.Type == "Eb(k)"
                app.ViewSeries_Button.Enable    = 'off';
                app.zLims_EditField.Enable      = 'off';
                app.CutScanIndex_Slider.Enable      = 'off';
                children = get(app.ScanCorrectionsPanel,'Children');    set(children,'Enable','off');
                children = get(app.IsoSliceBrowserTab,'Children');      set(children,'Enable','off');
                children = get(app.ScanVideosTab,'Children');           set(children,'Enable','off');
                children = get(app.DataCubeTab,'Children');             set(children,'Enable','off');
            % - Activating all 3D processing techniques
            elseif app.myData.Type == "Eb(kx,ky)" || app.myData.Type == "Eb(kx,kz)"
                app.ViewSeries_Button.Enable    = 'on';
                app.zLims_EditField.Enable      = 'on';
                app.CutScanIndex_Slider.Enable      = 'on';
                children = get(app.ScanCorrectionsPanel,'Children');    set(children,'Enable','on');
                children = get(app.IsoSliceBrowserTab,'Children');      set(children,'Enable','on');
                children = get(app.ScanVideosTab,'Children');          set(children,'Enable','on');
                children = get(app.DataCubeTab,'Children');             set(children,'Enable','on');
                % -- Initialising the isoSlice slider
                [~, ~, ~, dField] = find_data_fields(app, app.myData);
                app.ScanIndex_Slider.Limits = [1, size(app.myData.(dField), 3)];
                app.ScanIndex_Slider.Value = 1;
                % -- Initialising the isoCut slider
                app.CutScanIndex_Slider.Limits = [1, size(app.myData.(dField), 3)];
                app.CutScanIndex_Slider.Value = 1;
            end
            
            %% 2 - If processing has already been performed, load to most recent stage
            if isfield(app.myData, 'kx') || isfield(app.myData, 'data')
                children = get(app.IntensityNormalisationPanel,'Children'); set(children,'Enable','on');
                children = get(app.WavevectorConversionsPanel,'Children');  set(children,'Enable','on');
            elseif isfield(app.myData, 'eb')
                app.AlignType_DropDown.Enable   = 'on';
                children = get(app.IntensityNormalisationPanel,'Children'); set(children,'Enable','on');
                children = get(app.WavevectorConversionsPanel,'Children');  set(children,'Enable','off');
            else
                app.AlignType_DropDown.Enable   = 'off';
                app.scanindexs_EditField.Enable = 'off';
                children = get(app.IntensityNormalisationPanel,'Children'); set(children,'Enable','off');
                children = get(app.WavevectorConversionsPanel,'Children');  set(children,'Enable','off');
            end
            
            %% 2 - Running UI call-backs to ensure consistency
            % - Crop arguments
            xLims_EditFieldValueChanged(app);
            yLims_EditFieldValueChanged(app);
            zLims_EditFieldValueChanged(app);
            % - Filter arguments
            FilterType_DropDownValueChanged(app);
            FilterParams_EditFieldValueChanged(app);
            % - Scan correction arguments
            ScanIndxs_EditFieldValueChanged(app);
            % - Eb alignment arguments
            AlignType_DropDownValueChanged(app);
            scanindexs_EditFieldValueChanged(app);
            ebWin_EditFieldValueChanged(app);
            dEWin_EditFieldValueChanged(app);
            dESmooth_EditFieldValueChanged(app);
            feat_DropDownValueChanged(app);
            % - Intensity normalisation arguments
            NormType_DropDownValueChanged(app);
            intWin_EditFieldValueChanged(app);
            intScale_EditFieldValueChanged(app);
            NormScans_DropDownValueChanged(app);
            % - k conversion arguments
            eb_ref_EditFieldValueChanged(app);
            kx_refEditFieldValueChanged(app);
            thtA_refEditFieldValueChanged(app);
            V000_EditFieldValueChanged(app);
            % - iso-cut arguments
            isoCutType_DropDownValueChanged(app);
            isoCutWin_EditFieldValueChanged(app);
            app.CutScanIndex_Slider.Value = 1; CutScanIndex_SliderValueChanged(app);
            % - iso-slice arguments
            isoSliceType_DropDownValueChanged(app);
            isoSliceWin_EditFieldValueChanged(app);
            Remap_CheckBoxValueChanged(app);
            app.ScanIndex_Slider.Value = 1; ScanIndex_SliderValueChanged(app);
            % - 3D cube arguments
            Threshold_EditFieldValueChanged(app);
            Interpolate_CheckBoxValueChanged(app);
            MakeSquare_CheckBoxValueChanged(app);
            % - Scan video arguments
            zLimsVid_EditFieldValueChanged(app);
            InterpolateVid_CheckBoxValueChanged(app);
            ROI_CheckBoxValueChanged(app);
            xLimsROI_EditFieldValueChanged(app);
            yLimsROI_EditFieldValueChanged(app);
        end
        
        % Code that executes to update dependencies if the size of the data changes
        function updateFcn(app)
            %% 1 - Updating arguments
            % - Crop arguments
            xLims_EditFieldValueChanged(app);
            yLims_EditFieldValueChanged(app);
            zLims_EditFieldValueChanged(app);
            % - Scan correction arguments
            ScanIndxs_EditFieldValueChanged(app);
            % - iso-cut arguments
            isoCutWin_EditFieldValueChanged(app);
            app.CutScanIndex_Slider.Value = 1; CutScanIndex_SliderValueChanged(app);
            % - iso-slice arguments
            isoSliceWin_EditFieldValueChanged(app);
            app.ScanIndex_Slider.Value = 1; ScanIndex_SliderValueChanged(app);
            % - normalisation arguments
            intWin_EditFieldValueChanged(app);
            % - 3D cube arguments
            Threshold_EditFieldValueChanged(app);
            % - Scan video arguments
            xLimsROI_EditFieldValueChanged(app);
            yLimsROI_EditFieldValueChanged(app);
            zLimsVid_EditFieldValueChanged(app);
            
            %% 2 - Updating UI dependencies
            % - Deactivating all 3D processing techniques
            if app.myData.Type == "Eb(k)"
                app.ViewSeries_Button.Enable    = 'off';
                app.zLims_EditField.Enable      = 'off';
                app.CutScanIndex_Slider.Enable    = 'off';
                app.AlignType_DropDown.Value = "global";
                AlignType_DropDownValueChanged(app); 
                app.AlignType_DropDown.Enable = 'off';
                children = get(app.ScanCorrectionsPanel,'Children');    set(children,'Enable','off');
                children = get(app.IsoSliceBrowserTab,'Children');      set(children,'Enable','off');
                children = get(app.ScanVideosTab,'Children');           set(children,'Enable','off');
                children = get(app.DataCubeTab,'Children');             set(children,'Enable','off');
            % - Activating all 3D processing techniques
            elseif app.myData.Type == "Eb(kx,ky)" || app.myData.Type == "Eb(kx,kz)"
                app.ViewSeries_Button.Enable    = 'on';
                app.zLims_EditField.Enable      = 'on';
                app.CutScanIndex_Slider.Enable  = 'on';
                AlignType_DropDownValueChanged(app); app.AlignType_DropDown.Enable = 'on';
                children = get(app.ScanCorrectionsPanel,'Children');    set(children,'Enable','on');
                children = get(app.IsoSliceBrowserTab,'Children');      set(children,'Enable','on');
                children = get(app.ScanVideosTab,'Children');          set(children,'Enable','on');
                children = get(app.DataCubeTab,'Children');             set(children,'Enable','on');
                % -- Updating the isoSlice slider
                [~, ~, ~, dField] = find_data_fields(app, app.myData);
                app.ScanIndex_Slider.Limits = [1, size(app.myData.(dField), 3)];
                app.ScanIndex_Slider.Value = 1;
                % -- Updating the IsoCut slider
                app.CutScanIndex_Slider.Limits = [1, size(app.myData.(dField), 3)];
                app.CutScanIndex_Slider.Value = 1;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% 1 - Setting push-buttons to inactive, until data is loaded
            app.SAVE_Button.Enable = 'off';
            app.TRANSFER_Button.Enable = 'off';
            app.APPINFO_Button.Enable = 'off';
            app.ViewLatest_Button.Enable = 'off';
            app.ViewSeries_Button.Enable = 'off';
            app.RESET_Button.Enable = 'off';
            %% 2 - Setting all children to data-processing panels to inactive, until data is loaded
            children = get(app.ScanInformationPanel,'Children'); set(children,'Enable','off');
            children = get(app.CropDataPanel,'Children'); set(children,'Enable','off');
            children = get(app.FilterDataPanel,'Children'); set(children,'Enable','off');
            children = get(app.ScanCorrectionsPanel,'Children'); set(children,'Enable','off');
            children = get(app.EbAlignmentPanel,'Children'); set(children,'Enable','off');
            children = get(app.IntensityNormalisationPanel,'Children'); set(children,'Enable','off');
            children = get(app.WavevectorConversionsPanel,'Children'); set(children,'Enable','off');
            %% 3 - Setting all children to data-browser panels to inactive, until data is loaded
            children = get(app.IsoCutBrowserTab,'Children'); set(children,'Enable','off');
            children = get(app.IsoSliceBrowserTab,'Children'); set(children,'Enable','off');
            children = get(app.ScanVideosTab,'Children'); set(children,'Enable','off');
            children = get(app.DataCubeTab,'Children'); set(children,'Enable','off');
        end

        % Button pushed function: ARPESGUI_Button
        function ARPESGUI_ButtonPushed(app, event)
            run ARPESGUI;
        end

        % Button pushed function: CLOSEFIGS_Button
        function CLOSEFIGS_ButtonPushed(app, event)
            %% 1 - Close all open MATLAB figures
            hPlots = findobj('Type','Figure');
            close(hPlots);
            %% 2 - Close all open MATLAB Apps
            hPlots = findall(0, 'HandleVisibility', 'off');
            hGUI = app.UIFigure; hPlots = hPlots(hPlots~=hGUI);
            close(hPlots);
        end

        % Button pushed function: RESTART_Button
        function RESTART_ButtonPushed(app, event)
            %% Remove the GUI handle and restart
            close(gcbf); run ARPESprocess;
        end

        % Button pushed function: RESIZEGUI_Button
        function RESIZEGUI_ButtonPushed(app, event)
            %% Resetting the native size of the whole GUI figure
            screen_size = get(0, 'ScreenSize');
            screen_pos = get(gcbf, 'Position');
            screen_size(1) = screen_pos(1);
            screen_size(2) = screen_pos(2);
            screen_size(3) = 1130;
            screen_size(4) = 520;
            app.UIFigure.Position = screen_size;
        end

        % Button pushed function: TRANSFER_Button
        function TRANSFER_ButtonPushed(app, event)
            assignin('base', 'arpes_data', app.myData);
        end

        % Button pushed function: APPINFO_Button
        function APPINFO_ButtonPushed(app, event)
            disp('APP : '); disp(app);
            disp('DATA (app.myData) : '); disp(app.myData);
        end

        % Button pushed function: LOAD_Button
        function LOAD_ButtonPushed(app, event)
            %% 1 - Loading in the FileName and PathName of the selected data
            [FileName, PathName] = uigetfile({'*.h5;*.mat'}, 'Pick a *.h5 or *.mat data file...');
            % - If Cancel is pressed, then return nothing
            if isequal(PathName,0) || isequal(FileName,0); return; end
            % - 1.1 - Load in the file selected
            app.myData = [];
            app.myData = load_arpes_data(app, FileName);
            %% 2 - Reseting the app to the initial state
            runStartupFcn(app, @startupFcn);
            %% 3 - Initialising the app to the data that has been loaded
            initialiseFcn(app);
        end

        % Button pushed function: SAVE_Button
        function SAVE_ButtonPushed(app, event)
            %% 1 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', app.myData.FileName);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 2 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStruc = app.myData; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(app.myData);
            close(wbar);
            %% 3 - Saving a figure with the data
            fig = view_data(app, app.myData, app.isoslice_args);
            print(fig, char(save_fullfile(1:end-4)+"_snap.png"), '-dpng');
            %% 4 - Saving a text file with all the info
            fileID = fopen(char(save_fullfile(1:end-4)+"_info.txt"), 'w');
            fprintf(fileID, app.myData.meta.info);
            fclose(fileID);
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: RESET_Button
        function RESET_ButtonPushed(app, event)
            %% 1 - Re-load the raw data-file
            FileName = char(string(app.myData.H5file) + ".h5");
            app.myData = [];
            app.myData = load_arpes_data(app, FileName);
            %% 2 - Reseting the app to the initial state
            runStartupFcn(app, @startupFcn);
            %% 3 - Initialising the app to the data that has been loaded
            initialiseFcn(app);
            %% 4 - View the loaded data
            % view_data(app, app.myData, app.isoslice_args);
        end

        % Button pushed function: ViewLatest_Button
        function ViewLatest_ButtonPushed(app, event)
            view_data(app, app.myData, app.isoslice_args);
        end

        % Button pushed function: ViewSeries_Button
        function ViewSeries_ButtonPushed(app, event)
            view_data_series(app, app.myData);
        end

        % Value changed function: Colormap_DropDown
        function Colormap_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.Colormap_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.colmap_arg = value;
            fprintf("--> Colormap: " + app.colmap_arg + " \n");
        end

        % Value changed function: xLims_EditField
        function xLims_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.xLims_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            %% 2 - Defining the limits of the input
            [xField, ~, ~, ~] = find_data_fields(app, app.myData);
            minVal = round(min(app.myData.(xField)(:)) + 0.01, 2);
            maxVal = round(max(app.myData.(xField)(:)) - 0.01, 2);
            %% 3 - Validity check on input
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) == 1 || length(data_entry) > 2
                data_entry = [minVal, maxVal];
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
                if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            end
            data_entry = sort(round([data_entry(1), data_entry(2)], 4));
            %% 4 - Assigning output and printing change
            app.crop_args{1}  = data_entry;
            app.xLims_EditField.Value = string(app.crop_args{1}(1) + ":" + app.crop_args{1}(2)); 
            fprintf("--> xLims: " + string(app.crop_args{1}(1) + ":" + app.crop_args{1}(2)) + " \n");
        end

        % Value changed function: yLims_EditField
        function yLims_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.yLims_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            %% 2 - Defining the limits of the input
            [~, yField, ~, ~] = find_data_fields(app, app.myData);
            minVal = round(min(app.myData.(yField)(:)) + 0.01, 2);
            maxVal = round(max(app.myData.(yField)(:)) - 0.01, 2);
            %% 3 - Validity check on input
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) == 1 || length(data_entry) > 2
                data_entry = [minVal, maxVal];
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
                if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            end
            data_entry = sort(round([data_entry(1), data_entry(2)], 4));
            %% 4 - Assigning output and printing change
            app.crop_args{2}  = data_entry;
            app.yLims_EditField.Value = string(app.crop_args{2}(1) + ":" + app.crop_args{2}(2)); 
            fprintf("--> yLims: " + string(app.crop_args{2}(1) + ":" + app.crop_args{2}(2)) + " \n");
        end

        % Value changed function: zLims_EditField
        function zLims_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.zLims_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            %% 2 - Defining the limits of the input
            [~, ~, zField, ~] = find_data_fields(app, app.myData);
            minVal = round(min(app.myData.(zField)(:)), 3);
            maxVal = round(max(app.myData.(zField)(:)), 3);
            %% 3 - Validity check on input
            % - If the data type is 2D only, choose mean value only
            if app.myData.Type == "Eb(k)"
                app.zLims_EditField.Enable      = 'off';
                data_entry = round(mean(app.myData.(zField)(:)), 4);
            % - If the data type is 3D proceed to validity check
            elseif app.myData.Type == "Eb(kx,ky)" || app.myData.Type == "Eb(kx,kz)"
                app.zLims_EditField.Enable      = 'on';
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) == 1 || length(data_entry) > 2
                    data_entry = [minVal, maxVal];
                % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = minVal; end
                    if isnan(data_entry(2)); data_entry(2) = maxVal; end
                    % -- If the values are identical, make them different
                    if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                    % -- Forcing the max / min limits if exceeded
                    if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
                    if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
                    data_entry = sort(round([data_entry(1), data_entry(2)], 4));
                end
            end
            %% 4 - Assigning output and printing change
            app.crop_args{3}  = data_entry;
            if app.myData.Type == "Eb(k)"
                app.zLims_EditField.Value = string(app.crop_args{3}); 
                fprintf("--> zLims: " + string(app.crop_args{3}) + " \n");
            elseif app.myData.Type == "Eb(kx,ky)" || app.myData.Type == "Eb(kx,kz)"
                app.zLims_EditField.Value = string(app.crop_args{3}(1) + ":" + app.crop_args{3}(2)); 
                fprintf("--> zLims: " + string(app.crop_args{3}(1) + ":" + app.crop_args{3}(2)) + " \n");
            end
        end

        % Button pushed function: ExecuteDataCrop_Button
        function ExecuteDataCrop_ButtonPushed(app, event)
            %% 1 - Performing the cropping operation
            temp_data = crop_data(app, app.myData, app.crop_args{1}, app.crop_args{2}, app.crop_args{3});
            temp_args = app.isoslice_args; temp_args{3} = 1;
            
            %% 2 - View the updated figure
            view_data(app, temp_data, temp_args);
            
            %% 3 - Ask the user if they want to keep the new cropped data
            answer = questdlg('Would you like to store the cropped data?', ...
            	'Crop data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing but update figure again
            if isempty(answer) || string(answer) == "No"; return; end
            
            %% 4 - Updating dependencies
            app.myData = temp_data;
            updateFcn(app);
        end

        % Value changed function: FilterType_DropDown
        function FilterType_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.FilterType_DropDown.Value;
            %% 2 - Validity check on input
            % For 'Gaco2' smoothing
            if value == "Gaco2"     
                app.hwXhwYLabel.Text = 'hwX, hwY:';
            % For 'GaussFlt2' smoothing
            elseif value == "GaussFlt2"  
                app.hwXhwYLabel.Text = 'hwX, hwY:';
            % For 'LaplaceFlt2' smoothing
            elseif value == "LaplaceFlt2"
                app.hwXhwYLabel.Text = 'y2xRatio:';
            % For 'CurvatureFlt2' smoothing
            elseif value == "CurvatureFlt2"
                app.hwXhwYLabel.Text = 'CX, CY:';
            end
            %% 3 - Printing the change that has occurred
            app.filter_args{1} = value;
            fprintf("--> Filter Type: " + app.filter_args{1} + " \n");
            %% 4 - Updating dependencies
            app.FilterParams_EditField.Value = ''; 
            FilterParams_EditFieldValueChanged(app);
        end

        % Value changed function: FilterParams_EditField
        function FilterParams_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.FilterParams_EditField.Value;
            data_entry = round(abs(str2double(split(value,','))), 4);
            
            %% 2 - Validity check on input
            % For 'Gaco2' or 'CurvatureFlt2' smoothing
            if app.filter_args{1} == "Gaco2" || app.filter_args{1} == "CurvatureFlt2"
                defVal = 0.5;
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = [defVal, defVal];
                % - If a single entry is made, make a row vector that is identical
                elseif length(data_entry) == 1
                    if isnan(data_entry); data_entry = defVal; end
                    data_entry = [data_entry, data_entry];
                % - If a double entry is made, check for NaN values
                elseif length(data_entry) == 2
                    % -- Forcing to default on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = defVal; end
                    if isnan(data_entry(2)); data_entry(2) = defVal; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
            % For 'GaussFlt2' smoothing
            elseif app.filter_args{1} == "GaussFlt2"
                defVal = 1;
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = [defVal, defVal];
                % - If a single entry is made, make a row vector that is identical
                elseif length(data_entry) == 1
                    if isnan(data_entry); data_entry = defVal; end
                    data_entry = [data_entry, data_entry];
                    if data_entry(1) < 1; data_entry(1) = 1; end
                    if data_entry(2) < 1; data_entry(2) = 1; end
                % - If a double entry is made, check for NaN values
                elseif length(data_entry) == 2
                    % -- Forcing to default on the entries if NaN or < 1
                    if isnan(data_entry(1)) || data_entry(1) < 1; data_entry(1) = defVal; end
                    if isnan(data_entry(2)) || data_entry(2) < 1; data_entry(2) = defVal; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
            % For 'LaplaceFlt2' smoothing
            elseif app.filter_args{1} == "LaplaceFlt2"
                defVal = 0;
                 % - If no entry is made, default to a given value
                if isempty(data_entry) || length(data_entry) > 1
                    data_entry = defVal;
                % - If a single entry is made, check for NaN
                elseif length(data_entry) == 1
                    if isnan(data_entry); data_entry = defVal; end
                end
            end
            %% 4 - Assigning output and printing change
            app.filter_args{2} = round(abs(data_entry), 4);
            if app.filter_args{1} == "LaplaceFlt2"
                app.FilterParams_EditField.Value = string(app.filter_args{2}); 
                fprintf("--> Filter params: " + string(app.filter_args{2}) + " \n");
            else
                app.FilterParams_EditField.Value = string(app.filter_args{2}(1) + "," + app.filter_args{2}(2)); 
                fprintf("--> Filter params: : " + string(app.filter_args{2}(1) + "," + app.filter_args{2}(2)) + " \n");
            end
            
        end

        % Button pushed function: ExecuteFiltering_Button
        function ExecuteFiltering_ButtonPushed(app, event)
            %% 1 - Performing the filtering operation
            temp_data = filter_data(app, app.myData, app.filter_args);
            
            %% 2 - View the figure update
            view_data(app, temp_data, app.isoslice_args);
            
            %% 3 - Ask the user if they want to keep the new filtered data
            answer = questdlg('Would you like to store the filtered data?', ...
            	'Filter data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            app.myData = temp_data;
        end

        % Value changed function: ScanIndxs_EditField
        function ScanIndxs_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.ScanIndxs_EditField.Value;
            data_entry = sort(floor(abs(str2double(split(value,',')))))';
            if isnan(data_entry); data_entry = sort(floor(abs(str2double(split(value,':'))))); end
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            [~, ~, ~, dField] = find_data_fields(app, app.myData);
            minVal = 1;
            maxVal = size(app.myData.(dField), 3);
            %% 3 - Validity check on input
            % - If no entry is made, default to minimum
            if isempty(data_entry)
                data_entry = minVal;
            % Using ':' separator for a range of scans
            elseif size(data_entry, 1) > 1
                data_entry(isnan(data_entry)) = minVal;
                data_entry = data_entry(1):1:data_entry(end);
            % Using ',' separator for certain scans
            elseif size(data_entry, 2) > 1
                % - Deleting any NaN values
                data_entry(isnan(data_entry)) = minVal;
            end
            % - Checking max/min are not exceeded
            data_entry(data_entry < minVal) = minVal;
            data_entry(data_entry > maxVal) = maxVal;
            % - Checking that there are not duplicate numbers
            data_entry = unique(data_entry);
            %% 4 - Assigning output and printing change
            app.scancorr_args{1} = data_entry;
            % - Defining comma-delimited string
            scancorr_str = sprintf('%.0f,' , app.scancorr_args{1});
            scancorr_str = scancorr_str(1:end-1);
            app.ScanIndxs_EditField.Value = scancorr_str; 
            fprintf("--> Scan indices: " + scancorr_str + " \n");
        end

        % Button pushed function: DeleteScans_Button
        function DeleteScans_ButtonPushed(app, event)
            %% 1 - Performing the deletion operation
            temp_data = delete_scans(app, app.myData, app.scancorr_args);
            temp_args = app.isoslice_args; temp_args{3} = 1;
            
            %% 2 - View the updated figure
            view_data(app, temp_data, temp_args);
            
            %% 3 - Ask the user if they want to keep the new cropped data
            answer = questdlg('Would you like to store the new data after deletion?', ...
            	'Delete scans?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing but update figure again
            if isempty(answer) || string(answer) == "No"; return; end
            
            %% 4 - Updating dependencies
            app.myData = temp_data;
            updateFcn(app);
        end

        % Button pushed function: SumScans_Button
        function SumScans_ButtonPushed(app, event)
            %% 1 - Performing the cross-correlation operation
            temp_data = xcorr_scans(app, app.myData, app.scancorr_args);
            temp_args = app.isoslice_args; temp_args{3} = 1;
            
            %% 2 - View the updated figure
            view_data(app, temp_data, temp_args);
            
            %% 3 - Ask the user if they want to keep the new cross-correlated data
            answer = questdlg('Would you like to store the new Eb(k) data after xcorr summing?', ...
            	'Save data as Eb(k) data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            
            %% 4 - Updating dependencies
            app.myData = temp_data;
            updateFcn(app);
        end

        % Value changed function: isoSliceType_DropDown
        function isoSliceType_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.isoSliceType_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.isoslice_args{1} = value;
            fprintf("--> IsoSlice Type: " + app.isoslice_args{1} + " \n");
            %% 3 - Updating dependencies
            isoSliceWin_EditFieldValueChanged(app);
        end

        % Value changed function: isoSliceWin_EditField
        function isoSliceWin_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.isoSliceWin_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            [xField, yField, ~, ~] = find_data_fields(app, app.myData);
            XminVal = round(min(app.myData.(xField)(:)) + 0.1, 2);
            XmaxVal = round(max(app.myData.(xField)(:)) - 0.1, 2);
            YminVal = round(min(app.myData.(yField)(:)) + 0.1, 2);
            YmaxVal = round(max(app.myData.(yField)(:)) - 0.1, 2);
            %% 3 - Validity check on input
            if app.isoslice_args{1} == "IsoE"
                defVal = [YminVal, YmaxVal];
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = defVal;
                % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
                elseif length(data_entry) == 1
                    data_entry = [-0.1, 0.1] + data_entry; 
                % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = YminVal; end
                    if isnan(data_entry(2)); data_entry(2) = YmaxVal; end
                    % -- If the values are identical, make them different
                    if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < YminVal || data_entry(1) > YmaxVal; data_entry(1) = YminVal; end
                if data_entry(2) < YminVal || data_entry(2) > YmaxVal; data_entry(2) = YmaxVal; end
            elseif app.isoslice_args{1} == "IsoK"
                defVal = [XminVal, XmaxVal];
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = defVal;
                % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
                elseif length(data_entry) == 1
                    data_entry = [-0.1, 0.1] + data_entry;
                % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = XminVal; end
                    if isnan(data_entry(2)); data_entry(2) = XmaxVal; end
                    % -- If the values are identical, make them different
                    if data_entry(1) == data_entry(2); data_entry = data_entry + [-0.1, 0.1]; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < XminVal || data_entry(1) > XmaxVal; data_entry(1) = XminVal; end
                if data_entry(2) < XminVal || data_entry(2) > XmaxVal; data_entry(2) = XmaxVal; end
            end
            %% 4 - Assigning output and printing change
            app.isoslice_args{2}  = sort(round(data_entry, 4));
            app.isoSliceWin_EditField.Value = string(app.isoslice_args{2}(1) + ":" + app.isoslice_args{2}(2)); 
            fprintf("--> IsoSlice Win: " + string(app.isoslice_args{2}(1) + ":" + app.isoslice_args{2}(2)) + " \n");
        end

        % Value changed function: ScanIndex_Slider
        function ScanIndex_SliderValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.ScanIndex_Slider.Value;
            data_entry = floor(value);
            %% 2 - Defining the limits of the input
            [~, ~, ~, dField] = find_data_fields(app, app.myData);
            minVal = 1;
            maxVal = size(app.myData.(dField), 3);
            %% 3 - Validity check on input
            % - Checking max/min are not exceeded
            if data_entry < minVal; data_entry = minVal; end
            if data_entry > maxVal; data_entry = minVal; end
            %% 4 - Printing the change that has occurred
            app.isoslice_args{3} = data_entry;
            fprintf("--> Scan Index: " + app.isoslice_args{3} + " \n");
            %% 5 - Updating dependencies
            app.ScanIndex_Slider.Value = app.isoslice_args{3};
            app.ScanIndex_EditField.Value = app.isoslice_args{3};
            [~, ~, zField, ~] = find_data_fields(app, app.myData);
            if size(app.myData.(zField), 3) > 1
                app.ScanValue_EditField.Value = app.myData.(zField)(1,1,app.isoslice_args{3});
            else
                app.ScanValue_EditField.Value = app.myData.(zField)(1,app.isoslice_args{3});
            end
            %% 4 - Updating the figure
            view_data(app, app.myData, app.isoslice_args);
        end

        % Value changed function: Remap_CheckBox
        function Remap_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.Remap_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.isoslice_args{4} = value;
            if app.isoslice_args{4} == 1;   fprintf("--> Remap: True \n");
            else;                           fprintf("--> Remap: False \n");
            end
        end

        % Button pushed function: SaveSlice_Button
        function SaveSlice_ButtonPushed(app, event)
            %% 1 - Show the slice to save
            sliceStr = view_isoSlice(app, app.myData, app.isoslice_args);
            %% 2 - Ask the user if they want to save this slice
            answer = questdlg('Save this Iso-Slice?', ...
            	'Filter data?', ...
            	'Yes','No','No');
            %% 3 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', app.myData.FileName);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 4 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStruc = sliceStr; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(sliceStr);
            close(wbar);
            %% 5 - Saving a figure with the data
            fig = view_data(app, app.myData, app.isoslice_args);
            print(fig, char(save_fullfile(1:end-4)+"_slice.png"), '-dpng');
        end

        % Value changed function: isoCutType_DropDown
        function isoCutType_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.isoCutType_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.isocut_args{1} = value;
            fprintf("--> IsoCut Type: " + app.isocut_args{1} + " \n");
            %% 3 - Updating dependencies
            isoCutWin_EditFieldValueChanged(app);
        end

        % Value changed function: isoCutWin_EditField
        function isoCutWin_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.isoCutWin_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            [xField, yField, ~, ~] = find_data_fields(app, app.myData);
            XminVal = round(min(app.myData.(xField)(:)) + 0.1, 2);
            XmaxVal = round(max(app.myData.(xField)(:)) - 0.1, 2);
            YminVal = round(min(app.myData.(yField)(:)) + 0.1, 2);
            YmaxVal = round(max(app.myData.(yField)(:)) - 0.1, 2);
            %% 3 - Validity check on input
            if app.isocut_args{1} == "MDC"
                defVal = [YminVal, YmaxVal];
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = defVal;
                % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
                elseif length(data_entry) == 1
                    data_entry = [-0.1, 0.1] + data_entry;
                % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = YminVal; end
                    if isnan(data_entry(2)); data_entry(2) = YmaxVal; end
                    % -- If the values are identical, make them different
                    if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < YminVal || data_entry(1) > YmaxVal; data_entry(1) = YminVal; end
                if data_entry(2) < YminVal || data_entry(2) > YmaxVal; data_entry(2) = YmaxVal; end
            elseif app.isocut_args{1} == "EDC"
                defVal = [XminVal, XmaxVal];
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = defVal;
                % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
                elseif length(data_entry) == 1
                    data_entry = [-0.1, 0.1] + data_entry;
                % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = XminVal; end
                    if isnan(data_entry(2)); data_entry(2) = XmaxVal; end
                    % -- If the values are identical, make them different
                    if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < XminVal || data_entry(1) > XmaxVal; data_entry(1) = XminVal; end
                if data_entry(2) < XminVal || data_entry(2) > XmaxVal; data_entry(2) = XmaxVal; end
            end

            %% 4 - Assigning output and printing change
            app.isocut_args{2}  = sort(round(data_entry, 4));
            app.isoCutWin_EditField.Value = string(app.isocut_args{2}(1) + ":" + app.isocut_args{2}(2));
            fprintf("--> IsoCut Win: " + string(app.isocut_args{2}(1) + ":" + app.isocut_args{2}(2)) + " \n");
        end

        % Value changed function: CutScanIndex_Slider
        function CutScanIndex_SliderValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.CutScanIndex_Slider.Value;
            data_entry = floor(value);
            %% 2 - Defining the limits of the input
            [~, ~, ~, dField] = find_data_fields(app, app.myData);
            minVal = 1;
            maxVal = size(app.myData.(dField), 3);
            %% 3 - Validity check on input
            % - Checking max/min are not exceeded
            if data_entry < minVal; data_entry = minVal; end
            if data_entry > maxVal; data_entry = minVal; end
            %% 4 - Printing the change that has occurred
            app.isocut_args{3} = data_entry;
            fprintf("--> Scan Index: " + app.isocut_args{3} + " \n");
            %% 5 - Updating dependencies
            app.CutScanIndex_Slider.Value = app.isocut_args{3};
            app.CutScanIndex_EditField.Value = app.isocut_args{3};
            [~, ~, zField, ~] = find_data_fields(app, app.myData);
            if size(app.myData.(zField), 3) > 1
                app.CutScanValue_EditField.Value = app.myData.(zField)(1,1,app.isocut_args{3});
            else
                app.CutScanValue_EditField.Value = app.myData.(zField)(1,app.isocut_args{3});
            end
            %% 4 - Updating the figure
            view_isoCut(app, app.myData, app.isocut_args);
        end

        % Button pushed function: ViewCut_Button
        function ViewCut_ButtonPushed(app, event)
            view_isoCut(app, app.myData, app.isocut_args);
        end

        % Button pushed function: SaveCut_Button
        function SaveCut_ButtonPushed(app, event)
            %% 1 - Show the cut to save
            [cutStr, fig] = view_isoCut(app, app.myData, app.isocut_args);
            %% 2 - Ask the user if they want to save this slice
            answer = questdlg('Save this Iso-Cut?', ...
            	'Filter data?', ...
            	'Yes','No','No');
            %% 3 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', app.myData.FileName);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 4 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStruc = cutStr; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(cutStr);
            close(wbar);
            %% 5 - Saving a figure with the data
            print(fig, char(save_fullfile(1:end-4)+"_cut.png"), '-dpng');
        end

        % Value changed function: AlignType_DropDown
        function AlignType_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.AlignType_DropDown.Value;
            data_entry = value;
            %% 2 - Validity check on input
            if data_entry == "global";           app.scanindexs_EditField.Enable = 'off';
            elseif data_entry == "scan index";   app.scanindexs_EditField.Enable = 'on';
            end
            if app.myData.Type == "Eb(k)"
                data_entry = "global";
                app.AlignType_DropDown.Value = "global";
                app.scanindexs_EditField.Value = '1';
                app.scanindexs_EditFieldValueChanged(app);
                app.scanindexs_EditField.Enable = 'off';
            end
            %% 3 - Printing the change that has occurred
            app.align_args{1} = data_entry;
            fprintf("--> Align Type: " + app.align_args{1} + " \n");
            
        end

        % Value changed function: scanindexs_EditField
        function scanindexs_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.scanindexs_EditField.Value;
            data_entry = floor(sort(abs(str2double(split(value,':')))));
            %% 2 - Defining the limits of the input
            [~, ~, ~, dField] = find_data_fields(app, app.myData);
            minVal = 1;
            maxVal = size(app.myData.(dField), 3);
            %% 3 - Validity check on input
            % - If no entry is made, default to the minimum value
            if isempty(data_entry) || length(data_entry) > 2
                data_entry = minVal;
            % - If a single entry is made, ensure it does not exceed the max / min limits  
            elseif length(data_entry) == 1
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < minVal; data_entry(1) = minVal; end
                if data_entry(1) > maxVal; data_entry(1) = maxVal; end
                data_entry = floor(data_entry(1));
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [0, 1]; end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
                if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
                data_entry = floor([data_entry(1), data_entry(2)]);
            end
            %% 4 - Assigning output and printing change
            app.align_args{2}  = data_entry;
            if length(app.align_args{2}) == 1
                app.scanindexs_EditField.Value = string(app.align_args{2}); 
                fprintf("--> Scan Index/s: " + string(app.align_args{2}) + " \n");
            else
                app.scanindexs_EditField.Value = string(app.align_args{2}(1) + ":" + app.align_args{2}(2)); 
                fprintf("--> Scan Index/s: " + string(app.align_args{2}(1) + ":" + app.align_args{2}(2)) + " \n");
            end
        end

        % Value changed function: ebWin_EditField
        function ebWin_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.ebWin_EditField.Value;
            data_entry = round(str2double(split(value,':')), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            minVal = -1e5;
            maxVal = 1e5;
            %% 3 - Validity check on input
            % - If no entry is made, default to the minimum value
            if isempty(data_entry) || length(data_entry) > 2
                data_entry = 'Auto';
            % - If a single entry is made, ensure it does not exceed the max / min limits  
            elseif length(data_entry) == 1
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry); data_entry = 'Auto'; end
                % -- Forcing the max / min limits if exceeded
                if data_entry < minVal; data_entry = minVal; end
                if data_entry > maxVal; data_entry = maxVal; end
                data_entry = round(data_entry(1), 4);
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
                if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
                data_entry = round([data_entry(1), data_entry(2)], 4);
                % -- If the values are identical, only use one
                if data_entry(1) == data_entry(2); data_entry = data_entry(1); end
            end
            %% 4 - Assigning output and printing change
            app.align_args{3}  = data_entry;
            if length(app.align_args{3}) == 1 || ischar(app.align_args{3})
                app.ebWin_EditField.Value = string(app.align_args{3}); 
                fprintf("--> ebWin: " + string(app.align_args{3}) + " \n");
            else
                app.ebWin_EditField.Value = string(app.align_args{3}(1) + ":" + app.align_args{3}(2)); 
                fprintf("--> ebWin: " + string(app.align_args{3}(1) + ":" + app.align_args{3}(2)) + " \n");
            end
        end

        % Value changed function: dEWin_EditField
        function dEWin_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.dEWin_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            % - If no entry is made, default to 'Auto'
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = 'Auto';
            % - If a single entry is made, ensure it does not exceed the max / min limits  
            elseif length(data_entry) == 1
                % -- For NaN, default to 'Auto'
                if isnan(data_entry); data_entry = 'Auto'; end
                data_entry = round(data_entry, 4);
            end
            %% 4 - Assigning output and printing change
            app.align_args{4}  = data_entry;
            app.dEWin_EditField.Value = string(app.align_args{4}); 
            fprintf("--> dEWin: " + string(app.align_args{4}) + " \n");
        end

        % Value changed function: dESmooth_EditField
        function dESmooth_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.dESmooth_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Validity check on input
            % - If no entry is made, default to 'Auto'
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = 'Auto';
            % - If a single entry is made, ensure it does not exceed the max / min limits  
            elseif length(data_entry) == 1
                % -- For NaN, default to 'Auto'
                if isnan(data_entry); data_entry = 'Auto'; end
                data_entry = round(data_entry, 4);
            end
            %% 4 - Assigning output and printing change
            app.align_args{5}  = data_entry;
            app.dESmooth_EditField.Value = string(app.align_args{5}); 
            fprintf("--> dESmooth: " + string(app.align_args{5}) + " \n");
        end

        % Value changed function: feat_DropDown
        function feat_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.feat_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.align_args{6} = value;
            fprintf("--> Feat: " + app.align_args{6} + " \n");
        end

        % Button pushed function: ExecuteAlignment_Button
        function ExecuteAlignment_ButtonPushed(app, event)
            %% 1 - Executing the Eb alignment operation
            temp_data = align_energy(app, app.myData, app.align_args);
            temp_args = app.isoslice_args;
            
            %% 2 - View the updated figure
            view_data(app, temp_data, temp_args);
            
            %% 3 - Ask the user if they want to keep the new aligned data
            answer = questdlg('Would you like to store the aligned data?', ...
            	'Align data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            
            %% 4 - Updating dependencies
            app.myData = temp_data;
            yLims_EditFieldValueChanged(app);
            isoCutWin_EditFieldValueChanged(app);
            isoSliceWin_EditFieldValueChanged(app);
            
            %% 5 - Activating next panels for analysis
            app.AlignType_DropDown.Enable   = 'on';
            children = get(app.IntensityNormalisationPanel,'Children'); set(children,'Enable','on');
        end

        % Value changed function: NormType_DropDown
        function NormType_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.NormType_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.norm_args{1} = value;
            fprintf("--> Norm Type: " + app.norm_args{1} + " \n");
            %% 3 - Updating dependencies
            intWin_EditFieldValueChanged(app);
        end

        % Value changed function: intWin_EditField
        function intWin_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.intWin_EditField.Value;
            data_entry = round(str2double(split(value,':')), 4);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            [xField, yField, ~, ~] = find_data_fields(app, app.myData);
            XminVal = round(min(app.myData.(xField)(:)) + 0.1, 2);
            XmaxVal = round(max(app.myData.(xField)(:)) - 0.1, 2);
            YminVal = round(min(app.myData.(yField)(:)) + 0.1, 2);
            YmaxVal = round(max(app.myData.(yField)(:)) - 0.1, 2);
            %% 3 - Validity check on input
            if app.norm_args{1} == "eb-int" || app.norm_args{1} == "none"
                defVal = [YminVal, YmaxVal];
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = defVal;
                % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
                elseif length(data_entry) == 1
                    data_entry(1) = [-0.1, 0.1] + data_entry(1); 
                    if data_entry(1) < YminVal || data_entry(1) > YmaxVal; data_entry(1) = YminVal; end
                    if data_entry(2) < YminVal || data_entry(2) > YmaxVal; data_entry(2) = YmaxVal; end
                % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = YminVal; end
                    if isnan(data_entry(2)); data_entry(2) = YmaxVal; end
                    % -- If the values are identical, make them different
                    if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                    % -- Forcing the max / min limits if exceeded
                    if data_entry(1) < YminVal || data_entry(1) > YmaxVal; data_entry(1) = YminVal; end
                    if data_entry(2) < YminVal || data_entry(2) > YmaxVal; data_entry(2) = YmaxVal; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
            elseif app.norm_args{1} == "angle/kx-int" || app.norm_args{1} == "none"
                defVal = [XminVal, XmaxVal];
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) > 2
                    data_entry = defVal;
                % - If a single entry is made, ensure it does not exceed the max / min limits and add a small range
                elseif length(data_entry) == 1
                    data_entry = [-0.1, 0.1] + data_entry;
                    if data_entry(1) < XminVal || data_entry(1) > XmaxVal; data_entry(1) = XminVal; end
                    if data_entry(2) < XminVal || data_entry(2) > XmaxVal; data_entry(2) = XmaxVal; end
                % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = XminVal; end
                    if isnan(data_entry(2)); data_entry(2) = XmaxVal; end
                    % -- If the values are identical, make them different
                    if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                    % -- Forcing the max / min limits if exceeded
                    if data_entry(1) < XminVal || data_entry(1) > XmaxVal; data_entry(1) = XminVal; end
                    if data_entry(2) < XminVal || data_entry(2) > XmaxVal; data_entry(2) = XmaxVal; end
                    data_entry = [data_entry(1), data_entry(2)];
                end
            end
            %% 4 - Assigning output and printing change
            app.norm_args{2}  = sort(round(data_entry, 4));
            app.intWin_EditField.Value = string(app.norm_args{2}(1) + ":" + app.norm_args{2}(2)); 
            fprintf("--> intWin: " + string(app.norm_args{2}(1) + ":" + app.norm_args{2}(2)) + " \n");
        end

        % Value changed function: intScale_EditField
        function intScale_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.intScale_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.norm_args{3} = value;
            fprintf("--> Norm Intensity Scale: " + app.norm_args{3} + " \n");
        end

        % Value changed function: NormScans_DropDown
        function NormScans_DropDownValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.NormScans_DropDown.Value;
            %% 2 - Printing the change that has occurred
            app.norm_args{4} = value;
            fprintf("--> Norm Scans: " + app.norm_args{4} + " \n");
        end

        % Button pushed function: ExecuteNormalisation_Button
        function ExecuteNormalisation_ButtonPushed(app, event)
            %% 1 - Executing the Eb alignment operation
            temp_data = normalise_data(app, app.myData, app.norm_args);
            temp_args = app.isoslice_args;
            
            %% 2 - View the updated figure
            view_data(app, temp_data, temp_args);
            
            %% 3 - Ask the user if they want to keep the new aligned data
            answer = questdlg('Would you like to store the normalised data?', ...
            	'Normalise data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            
            %% 4 - Updating dependencies
            app.myData = temp_data;
            
            %% 5 - Activating next panels for analysis
            children = get(app.WavevectorConversionsPanel,'Children');  set(children,'Enable','on');
        end

        % Value changed function: eb_ref_EditField
        function eb_ref_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.eb_ref_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.conv_args{1} = value;
            fprintf("--> eb_ref: " + app.conv_args{1} + " \n");
        end

        % Value changed function: kx_refEditField
        function kx_refEditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.kx_refEditField.Value;
            %% 2 - Printing the change that has occurred
            app.conv_args{2} = value;
            fprintf("--> kx_ref: " + app.conv_args{2} + " \n");
        end

        % Value changed function: thtA_refEditField
        function thtA_refEditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.thtA_refEditField.Value;
            %% 2 - Printing the change that has occurred
            app.conv_args{3} = value;
            fprintf("--> thtA_ref: " + app.conv_args{3} + " \n");
        end

        % Value changed function: V000_EditField
        function V000_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.V000_EditField.Value;
            %% 2 - Printing the change that has occurred
            app.conv_args{4} = value;
            fprintf("--> V000: " + app.conv_args{4} + " \n");
        end

        % Button pushed function: ExecuteConversion_Button
        function ExecuteConversion_ButtonPushed(app, event)
            %% 1 - Executing the Eb alignment operation
            temp_data = convert_to_k(app, app.myData, app.conv_args);
            temp_args = app.isoslice_args;
            
            %% 2 - View the updated figure
            view_data(app, temp_data, temp_args);
            
            %% 3 - Ask the user if they want to keep the new aligned data
            answer = questdlg('Would you like to store the wave-vector converted data?', ...
            	'Convert data?', ...
            	'Yes','No','No');
            % - If No or Cancel is pressed, then return nothing
            if isempty(answer) || string(answer) == "No"; return; end
            
            %% 4 - Updating dependencies
            app.myData = temp_data;
            updateFcn(app);
        end

        % Value changed function: Threshold_EditField
        function Threshold_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.Threshold_EditField.Value;
            data_entry = sort(round(str2double(split(value,',')), 3));
            if isnan(data_entry); data_entry = sort(round(str2double(split(value,':')), 3)); end
            data_entry = data_entry(1);
            data_entry(isnan(data_entry)) = [];
            %% 2 - Defining the limits of the input
            [~, ~, ~, dField] = find_data_fields(app, app.myData);
            minVal = round(min(app.myData.(dField)(:)), 3);
            maxVal = round(max(app.myData.(dField)(:)), 3);
            [~, ~, ~, dField] = find_data_fields(app, app.myData);
            minVal = round(min(app.myData.(dField)(:)), 3);
            avgVal = round(mean(app.myData.(dField)(:)), 3);
            maxVal = round(max(app.myData.(dField)(:)), 3);
            %% 3 - Validity check on input
            defVal = minVal;
            % - If no entry is made, default to minimum
            if isempty(data_entry) || length(data_entry) > 1
                data_entry = defVal;
            % - If a single entry is made, check min/max values
            else
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry); data_entry = defVal; end
                % -- Forcing the max / min limits if exceeded
                if data_entry < minVal ; data_entry = minVal; end
                if data_entry > maxVal ; data_entry = maxVal; end
                data_entry = round(data_entry(1), 4);
            end
            %% 4 - Assigning output and printing change
            app.view3D_args{1}  = data_entry;
            app.Threshold_EditField.Value = string(app.view3D_args{1}); 
            fprintf("--> Threshold: " + string(app.view3D_args{1}) + " \n");
        end

        % Value changed function: Interpolate_CheckBox
        function Interpolate_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.Interpolate_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.view3D_args{2} = value;
            if app.view3D_args{2} == 1; fprintf("--> Interpolate: True \n");
            else;                       fprintf("--> Interpolate: False \n");
            end
        end

        % Value changed function: MakeSquare_CheckBox
        function MakeSquare_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.MakeSquare_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.view3D_args{3} = value;
            if app.view3D_args{3} == 1; fprintf("--> Remap: True \n");
            else;                       fprintf("--> Remap: False \n");
            end
        end

        % Button pushed function: View3Ddata_Button
        function View3Ddata_ButtonPushed(app, event)
            view_3Ddatacube(app, app.myData, app.view3D_args);
        end

        % Button pushed function: Save3Ddata_Button
        function Save3Ddata_ButtonPushed(app, event)
            %% 1 - Show the cut to save
            [cubeStr, fig] = view_3Ddatacube(app, app.myData, app.view3D_args);
            %% 2 - Ask the user if they want to save this slice
            answer = questdlg('Save this 3D cube?', ...
            	'Filter data?', ...
            	'Yes','No','No');
            %% 3 - User defined FileName and Path for the processed data
            filter = {'*.mat'};
            [save_filename, save_filepath] = uiputfile(filter, 'Save the data...', app.myData.FileName);
            save_fullfile = char(string(save_filepath) + string(save_filename));
            % - If Cancel is pressed, then return nothing
            if isequal(save_filepath,0) || isequal(save_filename,0); return; end
            %% 4 - Executing the saving of the data
            wbar = waitbar(0.5,'Saving...'); 
            dataStruc = cubeStr; 
            save(char(save_fullfile), 'dataStruc', '-v7.3');
            disp('-> saved data : '); display(cubeStr);
            close(wbar);
            %% 5 - Saving a figure with the data
            print(fig, char(save_fullfile(1:end-4)+"_cube.png"), '-dpng');
        end

        % Value changed function: zLimsVid_EditField
        function zLimsVid_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.zLimsVid_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            %% 2 - Defining the limits of the input
            [~, ~, zField, ~] = find_data_fields(app, app.myData);
            minVal = round(min(app.myData.(zField)(:)), 3);
            maxVal = round(max(app.myData.(zField)(:)), 3);
            %% 3 - Validity check on input
            % - If the data type is 2D only, choose mean value only
            if app.myData.Type == "Eb(k)"
                app.zLimsVid_EditField.Enable      = 'off';
                data_entry = round(mean(app.myData.(zField)(:)), 4);
            % - If the data type is 3D proceed to validity check
            elseif app.myData.Type == "Eb(kx,ky)" || app.myData.Type == "Eb(kx,kz)"
                app.zLimsVid_EditField.Enable      = 'on';
                % - If no entry is made, default to the maximum range
                if isempty(data_entry) || length(data_entry) == 1 || length(data_entry) > 2
                    data_entry = [minVal, maxVal];
                % - If a double entry is made, ensure it does not exceed the max / min limits
                elseif length(data_entry) == 2
                    % -- Forcing the max / min limits on the entries if NaN
                    if isnan(data_entry(1)); data_entry(1) = minVal; end
                    if isnan(data_entry(2)); data_entry(2) = maxVal; end
                    % -- If the values are identical, make them different
                    if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                    % -- Forcing the max / min limits if exceeded
                    if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
                    if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
                    data_entry = sort(round([data_entry(1), data_entry(2)], 4));
                end
            end
            %% 4 - Assigning output and printing change
            app.viewVideo_args{1}  = data_entry;
            if app.myData.Type == "Eb(k)"
                app.zLimsVid_EditField.Value = string(app.viewVideo_args{1}); 
                fprintf("--> zLims(Video): " + string(app.viewVideo_args{1}) + " \n");
            elseif app.myData.Type == "Eb(kx,ky)" || app.myData.Type == "Eb(kx,kz)"
                app.zLimsVid_EditField.Value = string(app.viewVideo_args{1}(1) + ":" + app.viewVideo_args{1}(2)); 
                fprintf("--> zLims(Video): " + app.viewVideo_args{1}(1) + ":" + app.viewVideo_args{1}(2) + " \n");
            end
        end

        % Value changed function: InterpolateVid_CheckBox
        function InterpolateVid_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.InterpolateVid_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.viewVideo_args{2} = value;
            if app.viewVideo_args{2} == 1;  fprintf("--> Interpolate: True \n");
            else;                           fprintf("--> Interpolate: False \n");
            end
        end

        % Value changed function: ROI_CheckBox
        function ROI_CheckBoxValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.ROI_CheckBox.Value;
            %% 2 - Printing the change that has occurred
            app.viewVideo_args{3} = value;
            if app.viewVideo_args{3} == 1;  fprintf("--> Include ROI: True \n");
            else;                           fprintf("--> Include ROI: False \n");
            end
        end

        % Value changed function: xLimsROI_EditField
        function xLimsROI_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.xLimsROI_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            %% 2 - Defining the limits of the input
            [xField, ~, ~, ~] = find_data_fields(app, app.myData);
            minVal = round(min(app.myData.(xField)(:)) + 0.01, 2);
            maxVal = round(max(app.myData.(xField)(:)) - 0.01, 2);
            %% 3 - Validity check on input
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) == 1 || length(data_entry) > 2
                data_entry = [minVal, maxVal];
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
                if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            end
            data_entry = sort(round([data_entry(1), data_entry(2)], 4));
            %% 4 - Assigning output and printing change
            app.viewVideo_args{4}  = data_entry;
            app.xLimsROI_EditField.Value = string(app.viewVideo_args{4}(1) + ":" + app.viewVideo_args{4}(2)); 
            fprintf("--> xLimsROI: " + string(app.viewVideo_args{4}(1) + ":" + app.viewVideo_args{4}(2)) + " \n");
        end

        % Value changed function: yLimsROI_EditField
        function yLimsROI_EditFieldValueChanged(app, event)
            %% 1 - Extracting the input defined by the user
            value = app.yLimsROI_EditField.Value;
            data_entry = round(sort(str2double(split(value,':'))), 4);
            %% 2 - Defining the limits of the input
            [~, yField, ~, ~] = find_data_fields(app, app.myData);
            minVal = round(min(app.myData.(yField)(:)) + 0.01, 2);
            maxVal = round(max(app.myData.(yField)(:)) - 0.01, 2);
            %% 3 - Validity check on input
            % - If no entry is made, default to the maximum range
            if isempty(data_entry) || length(data_entry) == 1 || length(data_entry) > 2
                data_entry = [minVal, maxVal];
            % - If a double entry is made, ensure it does not exceed the max / min limits
            elseif length(data_entry) == 2
                % -- Forcing the max / min limits on the entries if NaN
                if isnan(data_entry(1)); data_entry(1) = minVal; end
                if isnan(data_entry(2)); data_entry(2) = maxVal; end
                % -- If the values are identical, make them different
                if data_entry(1) == data_entry(2); data_entry = data_entry(1) + [-0.1, 0.1]; end
                % -- Forcing the max / min limits if exceeded
                if data_entry(1) < minVal || data_entry(1) > maxVal; data_entry(1) = minVal; end
                if data_entry(2) < minVal || data_entry(2) > maxVal; data_entry(2) = maxVal; end
            end
            data_entry = sort(round([data_entry(1), data_entry(2)], 4));
            %% 4 - Assigning output and printing change
            app.viewVideo_args{5}  = data_entry;
            app.yLimsROI_EditField.Value = string(app.viewVideo_args{5}(1) + ":" + app.viewVideo_args{5}(2)); 
            fprintf("--> yLimsROI: " + string(app.viewVideo_args{5}(1) + ":" + app.viewVideo_args{5}(2)) + " \n");
        end

        % Button pushed function: SaveVideo_Button
        function SaveVideo_ButtonPushed(app, event)
            view_ScanVideo(app, app.myData, app.viewVideo_args);
            waitbar(1,'Save complete!'); 
        end

        % Button pushed function: SaveSeries_Button
        function SaveSeries_ButtonPushed(app, event)
            view_ImageSeries(app, app.myData, app.viewVideo_args);
            waitbar(1,'Save complete!'); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CLOSEFIGS_Button = uibutton(app.UIFigure, 'push');
            app.CLOSEFIGS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLOSEFIGS_ButtonPushed, true);
            app.CLOSEFIGS_Button.Tooltip = {'Closes all open figures, but leaves the current one open.'};
            app.CLOSEFIGS_Button.Text = 'CLOSE FIGS';
            app.RESTART_Button = uibutton(app.UIFigure, 'push');
            app.RESTART_Button.ButtonPushedFcn = createCallbackFcn(app, @RESTART_ButtonPushed, true);
            app.RESTART_Button.Tooltip = {'Restarts the app to its default state.'};
            app.RESTART_Button.Text = 'RESTART';
            app.RESIZEGUI_Button = uibutton(app.UIFigure, 'push');
            app.RESIZEGUI_Button.ButtonPushedFcn = createCallbackFcn(app, @RESIZEGUI_ButtonPushed, true);
            app.RESIZEGUI_Button.Tooltip = {'Resizes the app to its default size.'};
            app.RESIZEGUI_Button.Text = 'RESIZE GUI';
            app.ARPESGUI_Button = uibutton(app.UIFigure, 'push');
            app.ARPESGUI_Button.ButtonPushedFcn = createCallbackFcn(app, @ARPESGUI_ButtonPushed, true);
            app.ARPESGUI_Button.Tooltip = {'Back to the main ARPESGUI menu.'};
            app.ARPESGUI_Button.Text = 'ARPESGUI (main)';
            app.SAVE_Button = uibutton(app.UIFigure, 'push');
            app.SAVE_Button.ButtonPushedFcn = createCallbackFcn(app, @SAVE_ButtonPushed, true);
            app.SAVE_Button.Tooltip = {'Save the data from the app as a MATLAB structure file.'};
            app.SAVE_Button.Text = 'SAVE';
            app.TRANSFER_Button = uibutton(app.UIFigure, 'push');
            app.TRANSFER_Button.ButtonPushedFcn = createCallbackFcn(app, @TRANSFER_ButtonPushed, true);
            app.TRANSFER_Button.Tooltip = {'Transfer the data to the MATLAB Workspace.'};
            app.TRANSFER_Button.Text = 'TRANSFER';
            app.APPINFO_Button = uibutton(app.UIFigure, 'push');
            app.APPINFO_Button.ButtonPushedFcn = createCallbackFcn(app, @APPINFO_ButtonPushed, true);
            app.APPINFO_Button.Tooltip = {'View all app information / variables.'};
            app.APPINFO_Button.Text = 'APP INFO';
            app.LOAD_Button = uibutton(app.UIFigure, 'push');
            app.LOAD_Button.ButtonPushedFcn = createCallbackFcn(app, @LOAD_ButtonPushed, true);
            app.LOAD_Button.Tooltip = {'Load in the ARPES data as *.h5 (or *.mat format if previously processed).'};
            app.LOAD_Button.Text = {'LOAD'; ''};
            app.AppTitle = uilabel(app.UIFigure);
            app.AppTitle.Text = 'ARPES data processing';
            app.ViewLatest_Button = uibutton(app.UIFigure, 'push');
            app.ViewLatest_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewLatest_ButtonPushed, true);
            app.ViewLatest_Button.Tooltip = {'View latest figure that summarises the processing.'};
            app.ViewLatest_Button.Text = 'View Latest';
            app.ViewSeries_Button = uibutton(app.UIFigure, 'push');
            app.ViewSeries_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewSeries_ButtonPushed, true);
            app.ViewSeries_Button.Tooltip = {'View series of the most recent data processing.'};
            app.ViewSeries_Button.Text = 'View Series';
            app.RESET_Button = uibutton(app.UIFigure, 'push');
            app.RESET_Button.ButtonPushedFcn = createCallbackFcn(app, @RESET_ButtonPushed, true);
            app.RESET_Button.Tooltip = {'Reset all data processing and re-load the initial data.'};
            app.RESET_Button.Text = 'RESET';
            app.ScanInformationPanel = uipanel(app.UIFigure);
            app.ScanInformationPanel.Title = 'Scan Information';
            app.DataTypeEditFieldLabel = uilabel(app.ScanInformationPanel);
            app.DataTypeEditFieldLabel.Text = 'Data Type:';
            app.DataType_EditField = uieditfield(app.ScanInformationPanel, 'text');
            app.DataType_EditField.Editable = 'off';
            app.DataType_EditField.Value = '[]';
            app.ScanInfo_Label = uilabel(app.ScanInformationPanel);
            app.ScanInfo_Label.Text = {''; ''};
            app.DataManipulationPanel = uipanel(app.UIFigure);
            app.DataManipulationPanel.Title = 'Data manipulation';
            app.CropDataPanel = uipanel(app.DataManipulationPanel);
            app.CropDataPanel.Title = 'Crop Data';
            app.xLimsthtEditFieldLabel = uilabel(app.CropDataPanel);
            app.xLimsthtEditFieldLabel.Text = 'xLims (tht):';
            app.xLims_EditField = uieditfield(app.CropDataPanel, 'text');
            app.xLims_EditField.ValueChangedFcn = createCallbackFcn(app, @xLims_EditFieldValueChanged, true);
            app.xLims_EditField.Value = '[]';
            app.yLimsebEditFieldLabel = uilabel(app.CropDataPanel);
            app.yLimsebEditFieldLabel.Text = 'yLims (eb):';
            app.yLims_EditField = uieditfield(app.CropDataPanel, 'text');
            app.yLims_EditField.ValueChangedFcn = createCallbackFcn(app, @yLims_EditFieldValueChanged, true);
            app.yLims_EditField.Value = '[]';
            app.zLimsscanEditFieldLabel = uilabel(app.CropDataPanel);
            app.zLimsscanEditFieldLabel.Text = 'zLims (scan):';
            app.zLims_EditField = uieditfield(app.CropDataPanel, 'text');
            app.zLims_EditField.ValueChangedFcn = createCallbackFcn(app, @zLims_EditFieldValueChanged, true);
            app.zLims_EditField.Value = '[]';
            app.ExecuteDataCrop_Button = uibutton(app.CropDataPanel, 'push');
            app.ExecuteDataCrop_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteDataCrop_ButtonPushed, true);
            app.ExecuteDataCrop_Button.Tooltip = {''; ''};
            app.ExecuteDataCrop_Button.Text = 'Execture Data Crop';
            app.FilterDataPanel = uipanel(app.DataManipulationPanel);
            app.FilterDataPanel.Title = 'Filter Data';
            app.ExecuteFiltering_Button = uibutton(app.FilterDataPanel, 'push');
            app.ExecuteFiltering_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteFiltering_ButtonPushed, true);
            app.ExecuteFiltering_Button.Tooltip = {''; ''};
            app.ExecuteFiltering_Button.Text = 'Execture Filtering';
            app.FilterTypeDropDownLabel = uilabel(app.FilterDataPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type:';
            app.FilterType_DropDown = uidropdown(app.FilterDataPanel);
            app.FilterType_DropDown.Items = {'Gaco2', 'GaussFlt2', 'LaplaceFlt2', 'CurvatureFlt2'};
            app.FilterType_DropDown.ValueChangedFcn = createCallbackFcn(app, @FilterType_DropDownValueChanged, true);
            app.FilterType_DropDown.Value = 'Gaco2';
            app.hwXhwYLabel = uilabel(app.FilterDataPanel);
            app.hwXhwYLabel.Text = 'hwX, hwY:';
            app.FilterParams_EditField = uieditfield(app.FilterDataPanel, 'text');
            app.FilterParams_EditField.ValueChangedFcn = createCallbackFcn(app, @FilterParams_EditFieldValueChanged, true);
            app.FilterParams_EditField.Value = '0.5,0.5';
            app.ScanCorrectionsPanel = uipanel(app.DataManipulationPanel);
            app.ScanCorrectionsPanel.Title = 'Scan Corrections';
            app.scanindexsLabel = uilabel(app.ScanCorrectionsPanel);
            app.scanindexsLabel.Text = 'scan index/s:';
            app.ScanIndxs_EditField = uieditfield(app.ScanCorrectionsPanel, 'text');
            app.ScanIndxs_EditField.ValueChangedFcn = createCallbackFcn(app, @ScanIndxs_EditFieldValueChanged, true);
            app.ScanIndxs_EditField.Value = '[]';
            app.DeleteScans_Button = uibutton(app.ScanCorrectionsPanel, 'push');
            app.DeleteScans_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteScans_ButtonPushed, true);
            app.DeleteScans_Button.Tooltip = {''; ''};
            app.DeleteScans_Button.Text = 'Delete Scans';
            app.SumScans_Button = uibutton(app.ScanCorrectionsPanel, 'push');
            app.SumScans_Button.ButtonPushedFcn = createCallbackFcn(app, @SumScans_ButtonPushed, true);
            app.SumScans_Button.Tooltip = {''; ''};
            app.SumScans_Button.Text = 'Sum Scans (XCorr)';
            app.ColormapLabel = uilabel(app.DataManipulationPanel);
            app.ColormapLabel.Text = 'Colormap:';
            app.Colormap_DropDown = uidropdown(app.DataManipulationPanel);
            app.Colormap_DropDown.Items = {'hot', 'parula', 'jet', 'gray', 'bone', 'hsv', 'cool', 'spring', 'summer', 'autumn', 'winter', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'flag'};
            app.Colormap_DropDown.ValueChangedFcn = createCallbackFcn(app, @Colormap_DropDownValueChanged, true);
            app.Colormap_DropDown.Value = 'hot';
            app.EbAlignmentPanel = uipanel(app.UIFigure);
            app.EbAlignmentPanel.Title = '(1) Eb Alignment';
            app.AlignTypeDropDownLabel = uilabel(app.EbAlignmentPanel);
            app.AlignTypeDropDownLabel.Text = 'Align Type:';
            app.AlignType_DropDown = uidropdown(app.EbAlignmentPanel);
            app.AlignType_DropDown.Items = {'global', 'scan index'};
            app.AlignType_DropDown.ValueChangedFcn = createCallbackFcn(app, @AlignType_DropDownValueChanged, true);
            app.AlignType_DropDown.Value = 'global';
            app.ebWinEditFieldLabel = uilabel(app.EbAlignmentPanel);
            app.ebWinEditFieldLabel.Text = 'ebWin:';
            app.ebWin_EditField = uieditfield(app.EbAlignmentPanel, 'text');
            app.ebWin_EditField.ValueChangedFcn = createCallbackFcn(app, @ebWin_EditFieldValueChanged, true);
            app.ebWin_EditField.Value = 'Auto';
            app.dEWinLabel = uilabel(app.EbAlignmentPanel);
            app.dEWinLabel.Text = 'dEWin:';
            app.dEWin_EditField = uieditfield(app.EbAlignmentPanel, 'text');
            app.dEWin_EditField.ValueChangedFcn = createCallbackFcn(app, @dEWin_EditFieldValueChanged, true);
            app.dEWin_EditField.Value = 'Auto';
            app.dESmoothEditFieldLabel = uilabel(app.EbAlignmentPanel);
            app.dESmoothEditFieldLabel.Text = 'dESmooth:';
            app.dESmooth_EditField = uieditfield(app.EbAlignmentPanel, 'text');
            app.dESmooth_EditField.ValueChangedFcn = createCallbackFcn(app, @dESmooth_EditFieldValueChanged, true);
            app.dESmooth_EditField.Value = 'Auto';
            app.featDropDownLabel = uilabel(app.EbAlignmentPanel);
            app.featDropDownLabel.Text = 'feat:';
            app.feat_DropDown = uidropdown(app.EbAlignmentPanel);
            app.feat_DropDown.Items = {'edge', 'peak'};
            app.feat_DropDown.ValueChangedFcn = createCallbackFcn(app, @feat_DropDownValueChanged, true);
            app.feat_DropDown.Value = 'edge';
            app.scanindexsEditFieldLabel = uilabel(app.EbAlignmentPanel);
            app.scanindexsEditFieldLabel.Text = 'scan index/s:';
            app.scanindexs_EditField = uieditfield(app.EbAlignmentPanel, 'text');
            app.scanindexs_EditField.ValueChangedFcn = createCallbackFcn(app, @scanindexs_EditFieldValueChanged, true);
            app.scanindexs_EditField.Value = '1';
            app.ExecuteAlignment_Button = uibutton(app.EbAlignmentPanel, 'push');
            app.ExecuteAlignment_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteAlignment_ButtonPushed, true);
            app.ExecuteAlignment_Button.Tooltip = {''; ''};
            app.ExecuteAlignment_Button.Text = 'Execute Alignment';
            app.IntensityNormalisationPanel = uipanel(app.UIFigure);
            app.IntensityNormalisationPanel.Title = '(2) Intensity Normalisation';
            app.NormTypeLabel = uilabel(app.IntensityNormalisationPanel);
            app.NormTypeLabel.Text = 'Norm. Type:';
            app.NormType_DropDown = uidropdown(app.IntensityNormalisationPanel);
            app.NormType_DropDown.Items = {'angle/kx-int', 'eb-int', 'none'};
            app.NormType_DropDown.ValueChangedFcn = createCallbackFcn(app, @NormType_DropDownValueChanged, true);
            app.NormType_DropDown.Value = 'angle/kx-int';
            app.intWinLabel = uilabel(app.IntensityNormalisationPanel);
            app.intWinLabel.Text = 'intWin:';
            app.intWin_EditField = uieditfield(app.IntensityNormalisationPanel, 'text');
            app.intWin_EditField.ValueChangedFcn = createCallbackFcn(app, @intWin_EditFieldValueChanged, true);
            app.intWin_EditField.Value = 'Auto';
            app.intScaleEditFieldLabel = uilabel(app.IntensityNormalisationPanel);
            app.intScaleEditFieldLabel.Text = 'intScale';
            app.intScale_EditField = uieditfield(app.IntensityNormalisationPanel, 'numeric');
            app.intScale_EditField.Limits = [0 1];
            app.intScale_EditField.ValueDisplayFormat = '%.4f';
            app.intScale_EditField.ValueChangedFcn = createCallbackFcn(app, @intScale_EditFieldValueChanged, true);
            app.intScale_EditField.Value = 0.75;
            app.ExecuteNormalisation_Button = uibutton(app.IntensityNormalisationPanel, 'push');
            app.ExecuteNormalisation_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteNormalisation_ButtonPushed, true);
            app.ExecuteNormalisation_Button.Tooltip = {''; ''};
            app.ExecuteNormalisation_Button.Text = 'Execute Normalisation';
            app.NormScansLabel = uilabel(app.IntensityNormalisationPanel);
            app.NormScansLabel.Text = 'Norm. Scans:';
            app.NormScans_DropDown = uidropdown(app.IntensityNormalisationPanel);
            app.NormScans_DropDown.Items = {'max', 'average', 'none', ''};
            app.NormScans_DropDown.ValueChangedFcn = createCallbackFcn(app, @NormScans_DropDownValueChanged, true);
            app.NormScans_DropDown.Value = 'max';
            app.WavevectorConversionsPanel = uipanel(app.UIFigure);
            app.WavevectorConversionsPanel.Title = '(3) Wave-vector Conversions';
            app.eb_refEditFieldLabel = uilabel(app.WavevectorConversionsPanel);
            app.eb_refEditFieldLabel.Text = 'eb_ref:';
            app.eb_ref_EditField = uieditfield(app.WavevectorConversionsPanel, 'numeric');
            app.eb_ref_EditField.ValueDisplayFormat = '%.4f';
            app.eb_ref_EditField.ValueChangedFcn = createCallbackFcn(app, @eb_ref_EditFieldValueChanged, true);
            app.kx_refEditFieldLabel = uilabel(app.WavevectorConversionsPanel);
            app.kx_refEditFieldLabel.Text = 'kx_ref:';
            app.kx_refEditField = uieditfield(app.WavevectorConversionsPanel, 'numeric');
            app.kx_refEditField.ValueDisplayFormat = '%.4f';
            app.kx_refEditField.ValueChangedFcn = createCallbackFcn(app, @kx_refEditFieldValueChanged, true);
            app.thtA_refEditFieldLabel = uilabel(app.WavevectorConversionsPanel);
            app.thtA_refEditFieldLabel.Text = 'thtA_ref:';
            app.thtA_refEditField = uieditfield(app.WavevectorConversionsPanel, 'numeric');
            app.thtA_refEditField.ValueDisplayFormat = '%.4f';
            app.thtA_refEditField.ValueChangedFcn = createCallbackFcn(app, @thtA_refEditFieldValueChanged, true);
            app.V000EditFieldLabel = uilabel(app.WavevectorConversionsPanel);
            app.V000EditFieldLabel.Text = 'V000:';
            app.V000_EditField = uieditfield(app.WavevectorConversionsPanel, 'numeric');
            app.V000_EditField.Limits = [0 Inf];
            app.V000_EditField.ValueDisplayFormat = '%.4f';
            app.V000_EditField.ValueChangedFcn = createCallbackFcn(app, @V000_EditFieldValueChanged, true);
            app.V000_EditField.Value = 12.57;
            app.ExecuteConversion_Button = uibutton(app.WavevectorConversionsPanel, 'push');
            app.ExecuteConversion_Button.ButtonPushedFcn = createCallbackFcn(app, @ExecuteConversion_ButtonPushed, true);
            app.ExecuteConversion_Button.Tooltip = {''; ''};
            app.ExecuteConversion_Button.Text = 'Execute Conversion';
            app.DataFile_Label = uilabel(app.UIFigure);
            app.DataFile_Label.Text = '<<   select a raw *.h5 or processed *.mat ARPES data file';
            app.IsoSliceBrowser = uitabgroup(app.UIFigure);
            app.IsoSliceBrowserTab = uitab(app.IsoSliceBrowser);
            app.IsoSliceBrowserTab.Title = 'Iso-Slice Browser';
            app.SaveSlice_Button = uibutton(app.IsoSliceBrowserTab, 'push');
            app.SaveSlice_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveSlice_ButtonPushed, true);
            app.SaveSlice_Button.Tooltip = {''; ''};
            app.SaveSlice_Button.Text = 'Save Slice';
            app.ScanIndexLabel_2 = uilabel(app.IsoSliceBrowserTab);
            app.ScanIndexLabel_2.Text = 'Scan Index:';
            app.ScanIndex_EditField = uieditfield(app.IsoSliceBrowserTab, 'numeric');
            app.ScanIndex_EditField.Limits = [1 Inf];
            app.ScanIndex_EditField.ValueDisplayFormat = '%.0f';
            app.ScanIndex_EditField.Editable = 'off';
            app.ScanIndex_EditField.Value = 1;
            app.ScanValueLabel = uilabel(app.IsoSliceBrowserTab);
            app.ScanValueLabel.Text = 'Scan Value:';
            app.ScanValue_EditField = uieditfield(app.IsoSliceBrowserTab, 'numeric');
            app.ScanValue_EditField.ValueDisplayFormat = '%.2f';
            app.ScanValue_EditField.Editable = 'off';
            app.isoSliceTypeLabel = uilabel(app.IsoSliceBrowserTab);
            app.isoSliceTypeLabel.Text = 'isoSlice Type:';
            app.isoSliceType_DropDown = uidropdown(app.IsoSliceBrowserTab);
            app.isoSliceType_DropDown.Items = {'IsoK', 'IsoE', '', ''};
            app.isoSliceType_DropDown.ValueChangedFcn = createCallbackFcn(app, @isoSliceType_DropDownValueChanged, true);
            app.isoSliceType_DropDown.Value = 'IsoK';
            app.isoSliceWinLabel_2 = uilabel(app.IsoSliceBrowserTab);
            app.isoSliceWinLabel_2.Text = 'isoSlice Win:';
            app.isoSliceWin_EditField = uieditfield(app.IsoSliceBrowserTab, 'text');
            app.isoSliceWin_EditField.ValueChangedFcn = createCallbackFcn(app, @isoSliceWin_EditFieldValueChanged, true);
            app.isoSliceWin_EditField.Value = '-0.1:0.1';
            app.ScanIndexSliderLabel = uilabel(app.IsoSliceBrowserTab);
            app.ScanIndexSliderLabel.Text = 'Scan Index:';
            app.ScanIndex_Slider = uislider(app.IsoSliceBrowserTab);
            app.ScanIndex_Slider.Limits = [1 100];
            app.ScanIndex_Slider.ValueChangedFcn = createCallbackFcn(app, @ScanIndex_SliderValueChanged, true);
            app.ScanIndex_Slider.MinorTicks = [];
            app.ScanIndex_Slider.Value = 1;
            app.Remap_CheckBox = uicheckbox(app.IsoSliceBrowserTab);
            app.Remap_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Remap_CheckBoxValueChanged, true);
            app.Remap_CheckBox.Text = 'Remap?';
            app.ScanVideosTab = uitab(app.IsoSliceBrowser);
            app.ScanVideosTab.Title = 'Scan Videos';
            app.zLimsscanEditFieldLabel_2 = uilabel(app.ScanVideosTab);
            app.zLimsscanEditFieldLabel_2.Text = 'zLims (scan):';
            app.zLimsVid_EditField = uieditfield(app.ScanVideosTab, 'text');
            app.zLimsVid_EditField.ValueChangedFcn = createCallbackFcn(app, @zLimsVid_EditFieldValueChanged, true);
            app.zLimsVid_EditField.Value = '[]';
            app.InterpolateVid_CheckBox = uicheckbox(app.ScanVideosTab);
            app.InterpolateVid_CheckBox.ValueChangedFcn = createCallbackFcn(app, @InterpolateVid_CheckBoxValueChanged, true);
            app.InterpolateVid_CheckBox.Text = 'Interpolate?';
            app.SaveVideo_Button = uibutton(app.ScanVideosTab, 'push');
            app.SaveVideo_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveVideo_ButtonPushed, true);
            app.SaveVideo_Button.Text = 'Save Video';
            app.ROI_CheckBox = uicheckbox(app.ScanVideosTab);
            app.ROI_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ROI_CheckBoxValueChanged, true);
            app.ROI_CheckBox.Text = ' Zoom in to ROI?';
            app.xLimsthtEditFieldLabel_2 = uilabel(app.ScanVideosTab);
            app.xLimsthtEditFieldLabel_2.Text = 'xLims (tht):';
            app.xLimsROI_EditField = uieditfield(app.ScanVideosTab, 'text');
            app.xLimsROI_EditField.ValueChangedFcn = createCallbackFcn(app, @xLimsROI_EditFieldValueChanged, true);
            app.xLimsROI_EditField.Value = '[]';
            app.yLimsebEditFieldLabel_2 = uilabel(app.ScanVideosTab);
            app.yLimsebEditFieldLabel_2.Text = 'yLims (eb):';
            app.yLimsROI_EditField = uieditfield(app.ScanVideosTab, 'text');
            app.yLimsROI_EditField.ValueChangedFcn = createCallbackFcn(app, @yLimsROI_EditFieldValueChanged, true);
            app.yLimsROI_EditField.Value = '[]';
            app.SaveSeries_Button = uibutton(app.ScanVideosTab, 'push');
            app.SaveSeries_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveSeries_ButtonPushed, true);
            app.SaveSeries_Button.Text = 'Save Image Series';
            app.DataCubeTab = uitab(app.IsoSliceBrowser);
            app.DataCubeTab.Title = '3D Cube';
            app.Interpolate_CheckBox = uicheckbox(app.DataCubeTab);
            app.Interpolate_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Interpolate_CheckBoxValueChanged, true);
            app.Interpolate_CheckBox.Text = 'Interpolate?';
            app.MakeSquare_CheckBox = uicheckbox(app.DataCubeTab);
            app.MakeSquare_CheckBox.ValueChangedFcn = createCallbackFcn(app, @MakeSquare_CheckBoxValueChanged, true);
            app.MakeSquare_CheckBox.Text = 'Make axes square?';
            app.View3Ddata_Button = uibutton(app.DataCubeTab, 'push');
            app.View3Ddata_Button.ButtonPushedFcn = createCallbackFcn(app, @View3Ddata_ButtonPushed, true);
            app.View3Ddata_Button.Text = 'View 3D data';
            app.Save3Ddata_Button = uibutton(app.DataCubeTab, 'push');
            app.Save3Ddata_Button.ButtonPushedFcn = createCallbackFcn(app, @Save3Ddata_ButtonPushed, true);
            app.Save3Ddata_Button.Text = 'Save 3D data';
            app.ThresholdLabel = uilabel(app.DataCubeTab);
            app.ThresholdLabel.Text = 'Threshold:';
            app.Threshold_EditField = uieditfield(app.DataCubeTab, 'text');
            app.Threshold_EditField.ValueChangedFcn = createCallbackFcn(app, @Threshold_EditFieldValueChanged, true);
            app.Threshold_EditField.Value = 'Auto';
            app.IsoCutBrowser = uitabgroup(app.UIFigure);
            app.IsoCutBrowserTab = uitab(app.IsoCutBrowser);
            app.IsoCutBrowserTab.Title = 'Iso-Cut Browser';
            app.ScanIndexLabel_4 = uilabel(app.IsoCutBrowserTab);
            app.ScanIndexLabel_4.Text = 'Scan Index:';
            app.CutScanIndex_EditField = uieditfield(app.IsoCutBrowserTab, 'numeric');
            app.CutScanIndex_EditField.Limits = [1 Inf];
            app.CutScanIndex_EditField.ValueDisplayFormat = '%.0f';
            app.CutScanIndex_EditField.Editable = 'off';
            app.CutScanIndex_EditField.Value = 1;
            app.ScanValueLabel_2 = uilabel(app.IsoCutBrowserTab);
            app.ScanValueLabel_2.Text = 'Scan Value:';
            app.CutScanValue_EditField = uieditfield(app.IsoCutBrowserTab, 'numeric');
            app.CutScanValue_EditField.ValueDisplayFormat = '%.2f';
            app.CutScanValue_EditField.Editable = 'off';
            app.isoCutTypeDropDownLabel = uilabel(app.IsoCutBrowserTab);
            app.isoCutTypeDropDownLabel.Text = 'isoCut Type:';
            app.isoCutType_DropDown = uidropdown(app.IsoCutBrowserTab);
            app.isoCutType_DropDown.Items = {'EDC', 'MDC', ''};
            app.isoCutType_DropDown.ValueChangedFcn = createCallbackFcn(app, @isoCutType_DropDownValueChanged, true);
            app.isoCutType_DropDown.Value = 'EDC';
            app.ViewCut_Button = uibutton(app.IsoCutBrowserTab, 'push');
            app.ViewCut_Button.ButtonPushedFcn = createCallbackFcn(app, @ViewCut_ButtonPushed, true);
            app.ViewCut_Button.Tooltip = {''; ''};
            app.ViewCut_Button.Text = 'View Cut';
            app.SaveCut_Button = uibutton(app.IsoCutBrowserTab, 'push');
            app.SaveCut_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveCut_ButtonPushed, true);
            app.SaveCut_Button.Tooltip = {''; ''};
            app.SaveCut_Button.Text = 'Save Cut';
            app.ScanIndexLabel_3 = uilabel(app.IsoCutBrowserTab);
            app.ScanIndexLabel_3.Text = 'Scan Index:';
            app.CutScanIndex_Slider = uislider(app.IsoCutBrowserTab);
            app.CutScanIndex_Slider.Limits = [1 100];
            app.CutScanIndex_Slider.ValueChangedFcn = createCallbackFcn(app, @CutScanIndex_SliderValueChanged, true);
            app.CutScanIndex_Slider.MinorTicks = [];
            app.CutScanIndex_Slider.Value = 1;
            app.isoCutWinLabel = uilabel(app.IsoCutBrowserTab);
            app.isoCutWinLabel.Text = 'isoCut Win:';
            app.isoCutWin_EditField = uieditfield(app.IsoCutBrowserTab, 'text');
            app.isoCutWin_EditField.ValueChangedFcn = createCallbackFcn(app, @isoCutWin_EditFieldValueChanged, true);
            app.isoCutWin_EditField.Value = '-0.1:0.1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARPESprocess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end